classdef FC_visualize_new_side_plots_groups < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayFCUIFigure               matlab.ui.Figure
        SaveplotasimageButton           matlab.ui.control.Button
        TotalnumberofSubjectsinGroup2Label  matlab.ui.control.Label
        TotalnumberofSubjectsinGroup1Label  matlab.ui.control.Label
        Group2NameEditField             matlab.ui.control.EditField
        Group2SubjectsSelectButton      matlab.ui.control.Button
        SubjectlistDropDown_3           matlab.ui.control.DropDown
        SubjectlistDropDownLabel_3      matlab.ui.control.Label
        Group1NameEditField             matlab.ui.control.EditField
        Group1SubjectsSelectButton      matlab.ui.control.Button
        SubjectlistDropDown_2           matlab.ui.control.DropDown
        SubjectlistDropDownLabel_2      matlab.ui.control.Label
        UploadCSVforSubjectGroupsButton  matlab.ui.control.Button
        DivideingroupsCheckBox          matlab.ui.control.CheckBox
        DataTipsCheckBox                matlab.ui.control.CheckBox
        TotalnumberofSubjectsLabel      matlab.ui.control.Label
        SubjectlistDropDown             matlab.ui.control.DropDown
        SubjectlistDropDownLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AllfrequenciesTab               matlab.ui.container.Tab
        WMfileSelectDropDown            matlab.ui.control.DropDown
        WMfileSelectLabel               matlab.ui.control.Label
        WMFunctionalNetworkconnectvityButton  matlab.ui.control.Button
        GMfileSelectDropDown            matlab.ui.control.DropDown
        GMfileSelectLabel               matlab.ui.control.Label
        WMGMNetworkConnectivityButton   matlab.ui.control.Button
        CCfileSelectDropDown            matlab.ui.control.DropDown
        CCfileSelectDropDownLabel       matlab.ui.control.Label
        WMCorpusCallosumNetworksButton  matlab.ui.control.Button
        AtlasbasedFCButton              matlab.ui.control.Button
        AtlasfileSelectDropDown         matlab.ui.control.DropDown
        AtlasfileSelectLabel            matlab.ui.control.Label
        FrequencyBandsTab               matlab.ui.container.Tab
        Band_selectDropDown             matlab.ui.control.DropDown
        FrequencyBandSelectLabel        matlab.ui.control.Label
        WMfileSelectDropDown_2          matlab.ui.control.DropDown
        WMfileSelectLabel_2             matlab.ui.control.Label
        WMFunctionalNetworkconnectvityButton_2  matlab.ui.control.Button
        GMfileSelectDropDown_2          matlab.ui.control.DropDown
        GMfileSelectLabel_2             matlab.ui.control.Label
        WMGMNetworkConnectivityButton_2  matlab.ui.control.Button
        CCfileSelectDropDown_2          matlab.ui.control.DropDown
        CCfileSelectDropDownLabel_2     matlab.ui.control.Label
        WMCorpusCallosumNetworksButton_2  matlab.ui.control.Button
        AtlasbasedFCButton_2            matlab.ui.control.Button
        AtlasfileSelectDropDown_2       matlab.ui.control.DropDown
        AtlasfileSelectLabel_2          matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main App
        final_result
        final_result_cc
        Subj_list
        slide_select = 0
        final_result_gm
        output_folder
        final_result_atlas
        atlas_file
        atlas_folders
        bands_info
        folders_info
        Tabposition
        initial_UIAxes_pos
        initial_UIAxes2_pos
        initial_UIAxes2_2_pos
        Subj_list1  
        Subj_list2 
        all_sub_names
        data_path

        dropselect = 1; % Description
    end
    
    
    methods (Access = private)
        
        function load_sub_dropdown(app,Subj_list)
            
            app.Subj_list = Subj_list;  
            Subj_names = struct2cell(app.Subj_list);
            Subj_names(2:end,:) = [];
            Subj_names = ['Mean','STD',Subj_names];
            app.SubjectlistDropDown.Items = Subj_names;

            num_sub = length(Subj_names)-2;

            app.TotalnumberofSubjectsLabel.Text = ['Total number of Subjects: ' num2str(num_sub)];
        end

        function initialise_freq_buttons(app,current_band)
            
            current_band_atlas_idx = string(app.bands_info(:,1)) == current_band;
%             current_band_atlas = app.bands_info(current_band_atlas_idx,2);
            current_band_atlas_number = app.bands_info(current_band_atlas_idx,3);
            
            WM_reg_files_idx = intersect(find(app.folders_info(:,1)),cell2mat(current_band_atlas_number));
            
            if length(WM_reg_files_idx) == 1
                app.final_result = load(fullfile(app.atlas_folders(WM_reg_files_idx).folder,app.atlas_folders(WM_reg_files_idx).name,[app.atlas_folders(WM_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.atlas_file = app.atlas_folders(WM_reg_files_idx).name;
                app.WMFunctionalNetworkconnectvityButton_2.Enable = 'on';
            elseif length(WM_reg_files_idx) > 1
                app.WMfileSelectDropDown_2.Enable = 'on';
                wm_reg_file = struct2cell(app.atlas_folders(WM_reg_files_idx));
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown_2.Items = wm_reg_file;
                app.WMFunctionalNetworkconnectvityButton_2.Enable = 'on';
            else
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton_2.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton_2.Tooltip = 'White Matter Network average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';
            end
            CC_reg_files_idx = intersect(find(app.folders_info(:,2)),cell2mat(current_band_atlas_number));

            if length(CC_reg_files_idx) == 1
                app.final_result_cc = load(fullfile(app.atlas_folders(CC_reg_files_idx).folder,app.atlas_folders(CC_reg_files_idx).name,[app.atlas_folders(CC_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.CCfileSelectDropDown_2.Enable  = 'off';
                app.WMCorpusCallosumNetworksButton_2.Enable = 'on';
            elseif length(CC_reg_files_idx) > 1
                app.CCfileSelectDropDown_2.Enable = 'on';
                app.WMCorpusCallosumNetworksButton_2.Enable = 'on';
                CC_reg_files = struct2cell(app.atlas_folders(CC_reg_files_idx));
                CC_reg_files(2:end,:) = [];
                app.CCfileSelectDropDown_2.Items = CC_reg_files;

            else
                app.WMCorpusCallosumNetworksButton_2.Enable = "off";
                app.WMCorpusCallosumNetworksButton_2.Tooltip = 'Corpus Callosum average timeseries file not found: Please check the Corpus callosum networks check box and run "?" again';

            end

            GM_reg_files_idx = intersect(find(app.folders_info(:,3)),cell2mat(current_band_atlas_number));

            if length(GM_reg_files_idx) == 1
                app.final_result_gm = load(fullfile(app.atlas_folders(GM_reg_files_idx).folder,app.atlas_folders(GM_reg_files_idx).name,[app.atlas_folders(GM_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.GMfileSelectDropDown_2.Enable  = 'off';
                app.WMGMNetworkConnectivityButton_2.Enable = "on";
            elseif length(GM_reg_files_idx) > 1
                app.GMfileSelectDropDown_2.Enable = 'on';
                gm_reg_file = struct2cell(app.atlas_folders(GM_reg_files_idx));
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown_2.Items = gm_reg_file;
                app.WMGMNetworkConnectivityButton_2.Enable = "on";

            else
                app.WMGMNetworkConnectivityButton_2.Enable = "off";
                app.GMfileSelectDropDown_2.Enable = 'off';
                app.WMGMNetworkConnectivityButton_2.Tooltip = 'Gray Matter Network average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';

            end

            Atlas_reg_files_idx = intersect(find(app.folders_info(:,4)),cell2mat(current_band_atlas_number));

            if length(Atlas_reg_files_idx) == 1
                app.final_result_atlas = load(fullfile(app.atlas_folders(Atlas_reg_files_idx).folder,app.atlas_folders(Atlas_reg_files_idx).name,[app.atlas_folders(Atlas_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.AtlasfileSelectDropDown_2.Enable  = 'off';
                app.AtlasbasedFCButton_2.Enable = "on";
            elseif length(Atlas_reg_files_idx) > 1
                app.AtlasfileSelectDropDown_2.Enable = 'on';
                atlas_reg_file = struct2cell(app.atlas_folders(Atlas_reg_files_idx));
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown_2.Items = atlas_reg_file;
                app.AtlasbasedFCButton_2.Enable = "on";

            else
                app.AtlasbasedFCButton_2.Enable = "off";
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.AtlasbasedFCButton_2.Tooltip = 'Atlas average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';

            end
        end

        function WMFC(app,final_result,Subj_list)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            

            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list)
                subid = find(app.SubjectlistDropDown.Value == string(app.SubjectlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list1));
                final_result = final_result(:,:,ia);
                Subj_list = Subj_list(ia);
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list2));
                final_result = final_result(:,:,ib);
                Subj_list = Subj_list(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 1;


            if subid == 1
                mean_corr = zeros(size(final_result,2),size(final_result,2),length(Subj_list));
                for i = 1:length(Subj_list)
                    mean_corr(:,:,i) = corr(final_result(:,:,i));
                end
                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])

                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM FC Mean";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(size(final_result,2),size(final_result,2),length(Subj_list));
                for i = 1:length(Subj_list)
                    std_corr(:,:,i) = corr(final_result(:,:,i));
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM FC STD";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(final_result(:,:,subid-2)))

                app.UIAxes.Title.String = string([ 'WM FC for Subject: ' Subj_list(subid-2).name]); 
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            set(app.UIAxes,'YLim',[0.5 size(final_result,2) + 0.5])
            set(app.UIAxes,'XLim',[0.5 size(final_result,2) + 0.5])
            xlabel(app.UIAxes,'WM Networks')
            ylabel(app.UIAxes,'WM Networks')
            
            
            %% side maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            WM_net_no = size(final_result,2);
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_networks',['WM_clustering_K',num2str(WM_net_no),'_BrainNet_Images']);%'F:\WhiFuN_results_HCP_100_motion_reject\Analysis\WM_Networks\WM_network_BrainNet_Images';


            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);
            
            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])

            a = imtile(WM_im_filename,'GridSize',[1,WM_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'WM Networks')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')

            x_tick_pos = round((m_size(2)/WM_net_no)/2:(m_size(2)/WM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:WM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            axis(app.UIAxes,'off')
            axis(app.UIAxes,'off')

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
            
            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
        end
        
        function WM_CC_FC(app,final_result,final_result_ccn,Subj_list)

            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')


            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list)
                subid = find(app.SubjectlistDropDown.Value == string(app.SubjectlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list1));
                final_result = final_result(:,:,ia);
                final_result_ccn = final_result_ccn(:,:,ia);
                Subj_list = Subj_list(ia);
                
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list2));
                final_result = final_result(:,:,ib);
                final_result_ccn = final_result_ccn(:,:,ib);
                Subj_list = Subj_list(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 2;

            if subid == 1
                mean_corr = zeros(size(final_result,2),size(final_result_ccn,2),length(Subj_list));
                for i = 1:length(Subj_list)
                    mean_corr(:,:,i) = corr(final_result(:,:,i),final_result_ccn(:,:,i));
                end


                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM-CC FC Mean";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM-CC FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM-CC FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(size(final_result,2),size(final_result_ccn,2),length(Subj_list));
                for i = 1:length(Subj_list)
                    std_corr(:,:,i) = corr(final_result(:,:,i),final_result_ccn(:,:,i));
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM-CC FC STD";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM-CC FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM-CC FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(final_result(:,:,subid-2),final_result_ccn(:,:,subid-2)))

                app.UIAxes.Title.String = string([ 'WM-CC FC for Subject: ' Subj_list(subid-2).name]); 
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            set(app.UIAxes,'YLim',[0.5 size(final_result,2) + 0.5])
            set(app.UIAxes,'XLim',[0.5 size(final_result_ccn,2) + 0.5])
            
            xlabel(app.UIAxes,'Corpus calosum Regions')
            ylabel(app.UIAxes,'WM Networks')

            
            %% side maps
            WM_net_no = size(final_result,2);
            CC_net_no = size(final_result_ccn,2);
            CC_image_folder = fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K',num2str(CC_net_no),'_BrainNet_Images']);
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_networks',['WM_clustering_K',num2str(WM_net_no),'_BrainNet_Images']);


            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
                
            CC_im_filename = cell(CC_net_no,1);
            for i = 1:CC_net_no
                CC_im_filename{i} = fullfile(CC_image_folder,['CC_' num2str(i) '_left.png']);
            end
            a = imtile(CC_im_filename,'GridSize',[1,CC_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'Corpus Callosum Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/CC_net_no)/2:(m_size(2)/CC_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:CC_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
%             map_pos2_in = app.UIAxes2_2.InnerPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), map_pos2(3), map_pos2(4)])
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), map_pos2(3), map_pos(4)])

            xlabel(app.UIAxes,'Corpus calosum Regions')
            ylabel(app.UIAxes,'WM Networks')
            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
        end
        
        function WM_GM_FC(app,final_result,final_result_gm,Subj_list_wm,Subj_list_gm)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')

            Sub_names_wm = string(struct2cell(Subj_list_wm));
            Sub_names_wm(2:end,:) = [] ; 

            Sub_names_gm = string(struct2cell(Subj_list_gm));
            Sub_names_gm(2:end,:) = [] ; 
            if length(Sub_names_gm) ~= length(Sub_names_wm)
                msgbox('Number of Subjects used to compute the WM networks is different from that used to compute the GM networks. Please use the same subjects to compute WM and GM and try again.')
                return
            end
            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list_gm)
                subid = find(app.SubjectlistDropDown.Value == string(app.SubjectlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list_gm),substruc2string(app,app.Subj_list1));
                final_result = final_result(:,:,ia);
                final_result_gm = final_result_gm(:,:,ia);
                Subj_list_gm = Subj_list_gm(ia);
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list_gm),substruc2string(app,app.Subj_list2));
                final_result = final_result(:,:,ib);
                final_result_gm = final_result_gm(:,:,ib);
                Subj_list_gm = Subj_list_gm(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 3;

            if subid == 1
                mean_corr = zeros(size(final_result,2),size(final_result_gm,2),length(Subj_list_gm));
                for i = 1:length(Subj_list_gm)
                    mean_corr(:,:,i) = corr(final_result(:,:,i),final_result_gm(:,:,i));
                end
                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM-GM FC Mean";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM-GM FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM-GM FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(size(final_result,2),size(final_result_gm,2),length(Subj_list_gm));
                for i = 1:length(Subj_list_gm)
                    std_corr(:,:,i) = corr(final_result(:,:,i),final_result_gm(:,:,i));
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM-GM FC STD";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM-GM FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM-GM FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(final_result(:,:,subid-2),final_result_gm(:,:,subid-2)))
                app.UIAxes.Title.String = string([ 'WM-GM FC for Subject: ' Subj_list_gm(subid-2).name]); 
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            set(app.UIAxes,'YLim',[0.5 size(final_result,2) + 0.5])
            set(app.UIAxes,'XLim',[0.5 size(final_result_gm,2) + 0.5])
            xlabel(app.UIAxes,'GM Networks')
            ylabel(app.UIAxes,'WM Networks')
            
%% Side Maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";

            WM_net_no = size(final_result,2);
            GM_net_no = size(final_result_gm,2);

            GM_image_folder = fullfile(app.output_folder,'Analysis','GM_networks',['GM_clustering_K',num2str(GM_net_no),'_BrainNet_Images']);%'F:\WhiFuN_results_HCP_100_motion_reject\Analysis\GM_Networks\GM_network_BrainNet_Images';
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_networks',['WM_clustering_K',num2str(WM_net_no),'_BrainNet_Images']);%'F:\WhiFuN_results_HCP_100_motion_reject\Analysis\WM_Networks\WM_network_BrainNet_Images';


            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
            GM_im_filename = cell(GM_net_no,1);
            for i = 1:GM_net_no
                GM_im_filename{i} = fullfile(GM_image_folder,['GM_' num2str(i) '_left.png']);
            end
            a = imtile(GM_im_filename,'GridSize',[1,GM_net_no]);
            m_size = size(a);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            xlabel(app.UIAxes2_2,'GM Networks')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/GM_net_no)/2:(m_size(2)/GM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:GM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];

        end
        
        function Atlas_based_FC(app,reg_ts,Subj_list)
            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')

            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list)
                subid = find(app.SubjectlistDropDown.Value == string(app.SubjectlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list1));
                reg_ts = reg_ts(:,:,ia);
                Subj_list = Subj_list(ia);
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list2));
                reg_ts = reg_ts(:,:,ib);
                Subj_list = Subj_list(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 4;
            axis(app.UIAxes,'on')
            if subid == 1
                mean_corr = zeros(size(reg_ts,2),size(reg_ts,2),length(Subj_list));
                for i = 1:length(Subj_list)
                    mean_corr(:,:,i) = corr(reg_ts(:,:,i));
                end
                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM FC Mean";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(size(reg_ts,2),size(reg_ts,2),length(Subj_list));
                for i = 1:length(Subj_list)
                    std_corr(:,:,i) = corr(reg_ts(:,:,i));
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.dropselect == 1
                    app.UIAxes.Title.String = "WM FC STD";
                elseif app.dropselect == 2
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                elseif app.dropselect == 3
                    app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(reg_ts(:,:,subid-2)))

                app.UIAxes.Title.String = string([ char(app.atlas_file) 'FC for Subject: ' Subj_list(subid-2).name]); 
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            
            set(app.UIAxes,'YLim',[0.5 size(reg_ts,2) + 0.5])
            set(app.UIAxes,'XLim',[0.5 size(reg_ts,2) + 0.5])

            xticks(app.UIAxes,round(linspace(1,size(reg_ts,2),10)))
            yticks(app.UIAxes,round(linspace(1,size(reg_ts,2),10)))
            xlabel(app.UIAxes,[num2str(size(reg_ts,2)) ' ROI'])
            ylabel(app.UIAxes,[num2str(size(reg_ts,2)) ' ROI'])
            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
        end
        
        function sub_string = substruc2string(~,Subj_list)
            if ~isempty(Subj_list)
                sub_string = struct2cell(Subj_list);
                sub_string(2:end,:) = [];
                sub_string = string(sub_string);
            else
                sub_string= "";
            end
        end
        
        function subset_group(app)

            if app.DivideingroupsCheckBox.Value    % Do the following only if Divide groups checkbox is on
                if isempty(app.Subj_list1)
                    msgbox('Select Subjects in Group 1 or upload CSV file')
                    return
                end

                if isempty(app.Subj_list2)
                    msgbox('Select Subjects in Group 2 or upload CSV file')
                    return
                end
                app.all_sub_names = app.SubjectlistDropDown.Items;
                app.all_sub_names = app.all_sub_names(2:end);

                [Subj_names,~] = intersect(string(app.all_sub_names),squeeze(struct2cell(app.Subj_list1)));
                
                app.SubjectlistDropDown_2.Items =[ string(['Mean ' app.Group1NameEditField.Value]); string(['STD ' app.Group1NameEditField.Value]); Subj_names];
                app.TotalnumberofSubjectsinGroup1Label.Text = ['Total number of Subjects in Group 1: ' num2str(length(app.SubjectlistDropDown_2.Items)-2)];

                [Subj_names,~] = intersect(string(app.all_sub_names),squeeze(struct2cell(app.Subj_list2)));
                app.SubjectlistDropDown_3.Items = [ string(['Mean ' app.Group2NameEditField.Value]); string(['STD ' app.Group2NameEditField.Value]); Subj_names];
                app.TotalnumberofSubjectsinGroup2Label.Text = ['Total number of Subjects in Group 2: ' num2str(length(app.SubjectlistDropDown_3.Items)-2)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path, data_path)
            app.CallingApp = caller;
            app.output_folder = path;
            app.Tabposition = 1;
            app.data_path = data_path;
            WM_reg_files = dir(fullfile(path,'Analysis','WM_networks','network_signals_wm*.mat'));

            app.UploadCSVforSubjectGroupsButton.Enable = "off";
            app.UploadCSVforSubjectGroupsButton.Visible = "off";
            app.Group1NameEditField.Visible = "off";
            app.Group1SubjectsSelectButton.Visible = "off";
            app.SubjectlistDropDown_2.Visible = "off";
            app.SubjectlistDropDownLabel_2.Visible = "off";
            app.Group2NameEditField.Visible = "off";
            app.Group2SubjectsSelectButton.Visible = "off";
            app.SubjectlistDropDownLabel_3.Visible = "off";
            app.SubjectlistDropDown_3.Visible = "off";
            app.TotalnumberofSubjectsinGroup1Label.Visible = 'off';
            app.TotalnumberofSubjectsinGroup2Label.Visible = 'off';
            if length(WM_reg_files) == 1
                app.final_result = load(fullfile(WM_reg_files.folder,WM_reg_files.name));
                app.WMfileSelectDropDown.Enable = 'off';
                app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton.Tooltip = 'White Matter Network average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';
            end
            cc_reg_files = dir(fullfile(path,'Analysis','Corpus_Callosum_Networks','network_signals_cc_K*.mat'));

            if length(cc_reg_files) == 1
                app.final_result_cc = load(fullfile(cc_reg_files.folder,cc_reg_files.name));
                app.CCfileSelectDropDown.Enable  = 'off';
            elseif length(cc_reg_files) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_files);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCorpusCallosumNetworksButton.Enable = "off";
                app.CCfileSelectDropDown.Enable = 'off';
                app.WMCorpusCallosumNetworksButton.Tooltip = 'Corpus Callosum average timeseries file not found: Please check the Corpus callosum networks check box and run "?" again';

            end

            Atlas_reg_files = dir(fullfile(path,'Analysis','Atlas_Based_FC','*reg_ts.mat'));

            if length(Atlas_reg_files) == 1
                app.final_result_atlas = load(fullfile(Atlas_reg_files.folder,Atlas_reg_files.name));
                app.AtlasfileSelectDropDown.Enable  = 'off';
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasbasedFCButton.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasbasedFCButton.Tooltip = 'Atlas average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';

            end


            GM_reg_files = dir(fullfile(path,'Analysis','GM_networks','network_signals_gm*.mat'));

            if length(GM_reg_files) == 1
                app.final_result_gm = load(fullfile(GM_reg_files.folder,GM_reg_files.name));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMNetworkConnectivityButton.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMNetworkConnectivityButton.Tooltip = 'Gray Matter Network average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';

            end


            app.initial_UIAxes_pos = app.UIAxes.Position;
            app.initial_UIAxes2_pos = app.UIAxes2.Position;
            app.initial_UIAxes2_2_pos = app.UIAxes2_2.Position;
            c = colorbar(app.UIAxes,'eastoutside','AxisLocation','out');
            c.Color = [1 1 1];
            axis(app.UIAxes,'off')

%             WMFC(app,app.final_result.final_result,app.final_result.Subj_list)
        end

        % Button pushed function: WMFunctionalNetworkconnectvityButton
        function WMFunctionalNetworkconnectvityButtonPushed(app, event)
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load(fullfile(app.output_folder,'Analysis','WM_networks',wm_file));
            end
            subset_group(app)
            WMFC(app,app.final_result.final_result,app.final_result.Subj_list)
            
        end

        % Button pushed function: WMCorpusCallosumNetworksButton
        function WMCorpusCallosumNetworksButtonPushed(app, event)
            if strcmp(app.CCfileSelectDropDown.Enable,'on')
                cc_file = app.CCfileSelectDropDown.Value;
                app.final_result_cc = load(fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks',cc_file));
            end
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load(fullfile(app.output_folder,'Analysis','WM_networks',wm_file));
            end
            subset_group(app)
            WM_CC_FC(app,app.final_result.final_result,app.final_result_cc.final_result_ccn,app.final_result_cc.Subj_list)

        end

        % Button pushed function: WMGMNetworkConnectivityButton
        function WMGMNetworkConnectivityButtonPushed(app, event)
            if strcmp(app.GMfileSelectDropDown.Enable,'on')
                gm_file = app.GMfileSelectDropDown.Value;
                app.final_result_gm = load(fullfile(app.output_folder,'Analysis','GM_networks',gm_file));
            end

            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load(fullfile(app.output_folder,'Analysis','WM_networks',wm_file));
            end
            subset_group(app)
            WM_GM_FC(app,app.final_result.final_result,app.final_result_gm.final_result_gm,app.final_result.Subj_list,app.final_result_gm.Subj_list)
            

        end

        % Button pushed function: AtlasbasedFCButton
        function AtlasbasedFCButtonPushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown.Value;
                app.final_result_atlas = load(fullfile(app.output_folder,'Analysis','Atlas_Based_FC',app.atlas_file));
            end
            subset_group(app)
            Atlas_based_FC(app,app.final_result_atlas.reg_ts,app.final_result_atlas.Subj_list)
            
        end

        % Value changed function: AtlasfileSelectDropDown
        function AtlasfileSelectDropDownValueChanged(app, event)
            AtlasbasedFCButtonPushed(app, event)
        end

        % Value changed function: GMfileSelectDropDown
        function GMfileSelectDropDownValueChanged(app, event)
            WMGMNetworkConnectivityButtonPushed(app, event)
        end

        % Value changed function: WMfileSelectDropDown
        function WMfileSelectDropDownValueChanged(app, event)
            switch app.slide_select
                case 1
                    WMFunctionalNetworkconnectvityButtonPushed(app, event)
                    
                case 2

                    WMCorpusCallosumNetworksButtonPushed(app, event)
                    
                case 3
                    WMGMNetworkConnectivityButtonPushed(app, event)
            end
        end

        % Button down function: AllfrequenciesTab
        function AllfrequenciesTabButtonDown(app, event)
            app.Tabposition = 1;
            WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_networks','network_signals_wm*.mat'));

            if length(WM_reg_files) == 1
                app.final_result = load(fullfile(WM_reg_files.folder,WM_reg_files.name));
                app.WMfileSelectDropDown.Enable = 'off';
                app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton.Tooltip = 'White Matter Network average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';
            end
            cc_reg_files = dir(fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks','network_signals_cc_K*.mat'));

            if length(cc_reg_files) == 1
                app.final_result_cc = load(fullfile(cc_reg_files.folder,cc_reg_files.name));
                app.CCfileSelectDropDown.Enable  = 'off';
            elseif length(cc_reg_files) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_files);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCorpusCallosumNetworksButton.Enable = "off";
                app.CCfileSelectDropDown.Enable = 'off';
                app.WMCorpusCallosumNetworksButton.Tooltip = 'Corpus Callosum average timeseries file not found: Please check the Corpus callosum networks check box and run "?" again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','Atlas_Based_FC','*reg_ts.mat'));

            if length(Atlas_reg_files) == 1
                app.final_result_atlas = load(fullfile(Atlas_reg_files.folder,Atlas_reg_files.name));
                app.AtlasfileSelectDropDown.Enable  = 'off';
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasbasedFCButton.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasbasedFCButton.Tooltip = 'Atlas average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';

            end


            GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_networks','network_signals_gm*.mat'));

            if length(GM_reg_files) == 1
                app.final_result_gm = load(fullfile(GM_reg_files.folder,GM_reg_files.name));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMNetworkConnectivityButton.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMNetworkConnectivityButton.Tooltip = 'Gray Matter Network average timeseries file not found: Please check the WM-FC timeseries check box and run "?" again';

            end
        end

        % Button down function: FrequencyBandsTab
        function FrequencyBandsTabButtonDown(app, event)
            app.Tabposition = 0;
            ex_time_folder = fullfile(app.output_folder,'Analysis','Extract_Timeseries');

            app.atlas_folders = dir(ex_time_folder);
            
            subset_group(app)
            if isempty(app.atlas_folders)
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton_2.Enable = "off";
                app.WMCorpusCallosumNetworksButton_2.Enable = "off";
                app.CCfileSelectDropDown_2.Enable = "off";
                app.WMGMNetworkConnectivityButton_2.Enable = "off";
                app.GMfileSelectDropDown_2.Enable = "off";
                app.AtlasbasedFCButton_2.Enable = 'off';
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.Band_selectDropDown.Enable = "off";
                return
            end

            if strcmp(app.atlas_folders(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                app.atlas_folders(1) = [];
            end

            if strcmp(app.atlas_folders(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                app.atlas_folders(1) = [];
            end

            nt_dir = false(1,length(app.atlas_folders));                                        % make a list of other files that are not directories
            app.folders_info = false(length(app.atlas_folders),4);                                  %no. of folders x 4, col 1 --> WM networks, col 2 = CC networks, col 3 GM, col 4--> others 
            app.bands_info = cell(length(app.atlas_folders),3);
            p = 1;
            for i = 1:length(app.atlas_folders)
                if app.atlas_folders(i).isdir ~= 1
                    nt_dir(i) = true;
                end
            end
            app.atlas_folders(nt_dir) = [];
            for i = 1:length(app.atlas_folders)
               
                if strcmp(app.atlas_folders(i).name(1:13),'WM_clustering')
                    app.folders_info(i,1) = true;
                elseif strcmp(app.atlas_folders(i).name(1:16),'cc_network_atlas')
                    app.folders_info(i,2) = true;
                elseif strcmp(app.atlas_folders(i).name(1:13),'GM_clustering')
                    app.folders_info(i,3) = true;
                else
                    app.folders_info(i,4) = true;
                end
                

                temp = dir(fullfile(app.atlas_folders(i).folder,app.atlas_folders(i).name,[app.atlas_folders(i).name '*_reg_ts.mat']));
                for i1 = 1:length(temp)
                    app.bands_info(p,1) = {temp(i1).name(length(app.atlas_folders(i).name)+2:end-11)};  % band_name
                    app.bands_info(p,2) = {app.atlas_folders(i).name};                                  % atlas_name
                    app.bands_info(p,3) = {i};                                                        % atlas_number
                    p = p+1;
                end

                
            end
            
            
            bands_found = unique(app.bands_info(:,1));
            app.Band_selectDropDown.Items = string(bands_found);
            current_band = app.Band_selectDropDown.Value;
            
            initialise_freq_buttons(app,current_band)
        end

        % Value changed function: Band_selectDropDown
        function Band_selectDropDownValueChanged(app, event)
            current_band = app.Band_selectDropDown.Value;
            initialise_freq_buttons(app,current_band)
%             cla(app.UIAxes)
            switch app.slide_select
                case 1
                    WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
                    
                case 2

                    WMCorpusCallosumNetworksButton_2Pushed(app, event)
                    
                case 3
                    WMGMNetworkConnectivityButton_2Pushed(app, event)

                case 4
                    AtlasbasedFCButton_2Pushed(app,event)
            end
        end

        % Button pushed function: WMFunctionalNetworkconnectvityButton_2
        function WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load(fullfile(app.atlas_folders(1).folder,wm_file,[wm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            subset_group(app)
            WMFC(app,app.final_result.reg_ts_freq,app.final_result.Subj_list)
        end

        % Button pushed function: WMCorpusCallosumNetworksButton_2
        function WMCorpusCallosumNetworksButton_2Pushed(app, event)
            if strcmp(app.CCfileSelectDropDown_2.Enable,'on')
                cc_file = app.CCfileSelectDropDown_2.Value;
                app.final_result_cc = load(fullfile(app.atlas_folders(1).folder,cc_file,[cc_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load(fullfile(app.atlas_folders(1).folder,wm_file,[wm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            subset_group(app)
            WM_CC_FC(app,app.final_result.reg_ts_freq,app.final_result_cc.reg_ts_freq,app.final_result.Subj_list)
        end

        % Button pushed function: WMGMNetworkConnectivityButton_2
        function WMGMNetworkConnectivityButton_2Pushed(app, event)
            if strcmp(app.GMfileSelectDropDown_2.Enable,'on')
                gm_file = app.GMfileSelectDropDown_2.Value;
                app.final_result_gm = load(fullfile(app.atlas_folders(1).folder,gm_file,[gm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end

            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load(fullfile(app.atlas_folders(1).folder,wm_file,[wm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            subset_group(app)
            WM_GM_FC(app,app.final_result.reg_ts_freq,app.final_result_gm.reg_ts_freq,app.final_result.Subj_list,app.final_result_gm.Subj_list)
            
        end

        % Button pushed function: AtlasbasedFCButton_2
        function AtlasbasedFCButton_2Pushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown_2.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown_2.Value;
                app.atlas_name = app.AtlasfileSelectDropDown_2.Value;
                app.final_result_atlas = load(fullfile(app.atlas_folders(1).folder,app.atlas_file,[app.atlas_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            subset_group(app)
            Atlas_based_FC(app,app.final_result_atlas.reg_ts_freq,app.final_result_atlas.Subj_list)

        end

        % Value changed function: DataTipsCheckBox
        function DataTipsCheckBoxValueChanged(app, event)
            value = app.DataTipsCheckBox.Value;
            dcm_obj = datacursormode(app.DisplayFCUIFigure);

            if value == 1
                dcm_obj.Enable = 'on';  
               
            else
                dcm_obj.Enable = 'off';
               
            end
        end

        % Value changed function: DivideingroupsCheckBox
        function DivideingroupsCheckBoxValueChanged(app, event)
            value = app.DivideingroupsCheckBox.Value;
            
            if value
                app.UploadCSVforSubjectGroupsButton.Enable = "on";
                app.UploadCSVforSubjectGroupsButton.Visible = "on";
                app.Group1NameEditField.Visible = "on";
                app.Group1SubjectsSelectButton.Visible = "on";
                app.SubjectlistDropDown_2.Visible = "on";
                app.SubjectlistDropDownLabel_2.Visible = "on";
                app.Group2NameEditField.Visible = "on";
                app.Group2SubjectsSelectButton.Visible = "on";
                app.SubjectlistDropDownLabel_3.Visible = "on";
                app.SubjectlistDropDown_3.Visible = "on";
                app.TotalnumberofSubjectsinGroup1Label.Visible = 'on';
                app.TotalnumberofSubjectsinGroup2Label.Visible = 'on';
                
            else
                app.UploadCSVforSubjectGroupsButton.Enable = "off";
                app.UploadCSVforSubjectGroupsButton.Visible = "off";
                app.Group1NameEditField.Visible = "off";
                app.Group1SubjectsSelectButton.Visible = "off";
                app.SubjectlistDropDown_2.Visible = "off";
                app.SubjectlistDropDownLabel_2.Visible = "off";
                app.Group2NameEditField.Visible = "off";
                app.Group2SubjectsSelectButton.Visible = "off";
                app.SubjectlistDropDownLabel_3.Visible = "off";
                app.SubjectlistDropDown_3.Visible = "off";
                app.TotalnumberofSubjectsinGroup1Label.Visible = 'off';
                app.TotalnumberofSubjectsinGroup2Label.Visible = 'off';
            end
        end

        % Button pushed function: UploadCSVforSubjectGroupsButton
        function UploadCSVforSubjectGroupsButtonPushed(app, event)
            try
                
                [csvfile,csvfolderpath] = uigetfile('.csv','Select CSV file');

            catch
            end
            if prod(csvfolderpath == 0) || isempty(csvfolderpath)
                return
            end
            CSV = readmatrix(fullfile(csvfolderpath,csvfile), 'OutputType','char');

%             if app.CSVhasHeadersCheckBox.Value
%                 CSV(1,:) = [];
%             end

            grps = unique(CSV(:,2));
                    
            app.Subj_list1 = struct;
            app.Subj_list2 = struct;

            if length(grps) ~= 2
                msgbox('Only 2 Groups allowed')
            end

            app.Group1NameEditField.Value = grps{1};
            app.Group1SubjectsSelectButton.Text = grps{1};
            app.Group2NameEditField.Value = grps{2};
            app.Group2SubjectsSelectButton.Text = grps{2};
            p1 = 1;
            p2 = 1;
            for i = 1:length(CSV)
                if strcmp(CSV(i,2),grps{1})
                    app.Subj_list1(p1).name = CSV{i,1};
                    
                    p1 = p1 + 1;
                else
                    app.Subj_list2(p2).name = CSV{i,1};
                    
                    p2 = p2 + 1;
                end
            end
            %             if app.MydatahasheadersCheckBox.Value == 1
            %                 app.Group1NameEditField.Value = CSV{1,1};
            %                 app.Group2NameEditField.Value = CSV{1,2};
            %                 CSV(1,:) = [];
            %             end
            %
            
            app.all_sub_names = app.SubjectlistDropDown.Items;
            app.all_sub_names = app.all_sub_names(2:end);
            
            [Subj_names,~] = intersect(string(app.all_sub_names),squeeze(struct2cell(app.Subj_list1)));
            app.SubjectlistDropDown_2.Items =[ string(['Mean ' app.Group1NameEditField.Value]); string(['STD ' app.Group1NameEditField.Value]); Subj_names];
            app.TotalnumberofSubjectsinGroup1Label.Text = ['Total number of Subjects in Group 1: ' num2str(length(app.SubjectlistDropDown_2.Items)-2)];

            [Subj_names,~] = intersect(string(app.all_sub_names),squeeze(struct2cell(app.Subj_list2)));
            app.SubjectlistDropDown_3.Items = [ string(['Mean ' app.Group2NameEditField.Value]); string(['STD ' app.Group2NameEditField.Value]); Subj_names];
            app.TotalnumberofSubjectsinGroup2Label.Text = ['Total number of Subjects in Group 2: ' num2str(length(app.SubjectlistDropDown_3.Items)-2)];
        end

        % Button pushed function: Group1SubjectsSelectButton
        function Group1SubjectsSelectButtonPushed(app, event)
            try
            
            Group1_subjects = uigetfile_n_dir(app.data_path, ['Choose Subjects for' app.Group1NameEditField.Value]);

            if isempty(Group1_subjects)
                return
            end
            catch
                return
            end
            app.Subj_list1 = struct;    
            for i = 1:length(Group1_subjects)
                [filepath, name] = fileparts(Group1_subjects(i));
                app.Subj_list1(i).name = name;
                app.Subj_list1(i).folder = filepath;
                
            end

            Subj_names1 = struct2cell(app.Subj_list1);
            
            Subj_names1(2:end,:) = [];
            app.SubjectlistDropDown_2.Items = [ string(['Mean ' app.Group1NameEditField.Value]) string(['STD ' app.Group1NameEditField.Value]) Subj_names1];
            app.TotalnumberofSubjectsinGroup1Label.Text = ['Total number of Subjects in Group 1: ' num2str(length(app.SubjectlistDropDown_2.Items)-2)];
        end

        % Button pushed function: Group2SubjectsSelectButton
        function Group2SubjectsSelectButtonPushed(app, event)
            try
            Group2_subjects = uigetfile_n_dir(app.data_path, ['Choose Subjects for ' app.Group2NameEditField.Value]);
            if isempty(Group2_subjects)
                return
            end
            catch
                return
            end
            app.Subj_list2 = struct;    
            for i = 1:length(Group2_subjects)
                [filepath, name] = fileparts(Group2_subjects(i));
                app.Subj_list2(i).name = name;
                app.Subj_list2(i).folder = filepath;
                
            end

            Subj_names = struct2cell(app.Subj_list2);
            Subj_names(2:end,:) = [];
            app.SubjectlistDropDown_3.Items = [ string(['Mean ' app.Group2NameEditField.Value]) string(['STD ' app.Group2NameEditField.Value]) Subj_names];
            app.TotalnumberofSubjectsinGroup2Label.Text = ['Total number of Subjects in Group 2: ' num2str(length(app.SubjectlistDropDown_3.Items)-2)];
        end

        % Value changed function: Group1NameEditField
        function Group1NameEditFieldValueChanged(app, event)
            value = app.Group1NameEditField.Value;
            app.Group1SubjectsSelectButton.Text = value;
        end

        % Value changed function: Group2NameEditField
        function Group2NameEditFieldValueChanged(app, event)
            value = app.Group2NameEditField.Value;
            app.Group2SubjectsSelectButton.Text = value;
        end

        % Clicked callback: SubjectlistDropDown
        function SubjectlistDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

            if ~isempty(item)
                app.dropselect = 1;
                switch app.slide_select

                    case 1
                        if app.Tabposition
                            WMFunctionalNetworkconnectvityButtonPushed(app, event)
                        else
                            WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
                        end

                    case 2
                        if app.Tabposition
                            WMCorpusCallosumNetworksButtonPushed(app, event)
                        else
                            WMCorpusCallosumNetworksButton_2Pushed(app, event)
                        end

                    case 3
                        if app.Tabposition
                            WMGMNetworkConnectivityButtonPushed(app, event)
                        else
                            WMGMNetworkConnectivityButton_2Pushed(app, event)
                        end
                    case 4
                        if app.Tabposition
                            AtlasbasedFCButtonPushed(app, event)
                        else
                            AtlasbasedFCButton_2Pushed(app, event)
                        end

                end

            end
        end

        % Clicked callback: SubjectlistDropDown_2
        function SubjectlistDropDown_2Clicked(app, event)
            item = event.InteractionInformation.Item;

            if ~isempty(item)
                app.dropselect = 2;
                switch app.slide_select

                    case 1
                        if app.Tabposition
                            WMFunctionalNetworkconnectvityButtonPushed(app, event)
                        else
                            WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
                        end

                    case 2
                        if app.Tabposition
                            WMCorpusCallosumNetworksButtonPushed(app, event)
                        else
                            WMCorpusCallosumNetworksButton_2Pushed(app, event)
                        end

                    case 3
                        if app.Tabposition
                            WMGMNetworkConnectivityButtonPushed(app, event)
                        else
                            WMGMNetworkConnectivityButton_2Pushed(app, event)
                        end
                    case 4
                        if app.Tabposition
                            AtlasbasedFCButtonPushed(app, event)
                        else
                            AtlasbasedFCButton_2Pushed(app, event)
                        end

                end

            end
        end

        % Clicked callback: SubjectlistDropDown_3
        function SubjectlistDropDown_3Clicked(app, event)
            item = event.InteractionInformation.Item;

            if ~isempty(item)
                app.dropselect = 3;
                switch app.slide_select

                    case 1
                        if app.Tabposition
                            WMFunctionalNetworkconnectvityButtonPushed(app, event)
                        else
                            WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
                        end

                    case 2
                        if app.Tabposition
                            WMCorpusCallosumNetworksButtonPushed(app, event)
                        else
                            WMCorpusCallosumNetworksButton_2Pushed(app, event)
                        end

                    case 3
                        if app.Tabposition
                            WMGMNetworkConnectivityButtonPushed(app, event)
                        else
                            WMGMNetworkConnectivityButton_2Pushed(app, event)
                        end
                    case 4
                        if app.Tabposition
                            AtlasbasedFCButtonPushed(app, event)
                        else
                            AtlasbasedFCButton_2Pushed(app, event)
                        end

                end

            end
        end

        % Button pushed function: SaveplotasimageButton
        function SaveplotasimageButtonPushed(app, event)
            try
                
                [imgfile,imgfolderpath] = uiputfile('WhiFuN_FC_visualise_figure.png','Save FC visualize figure');

            catch
            end
            if prod(imgfolderpath == 0) || isempty(imgfolderpath)
                return
            end

            c = app.UIAxes.Colorbar;
            c.Color = [0 0 0];
            app.UIAxes2_2.XColor = [0,0,0];
            set(app.UIAxes2,'FontSize',18)
            app.UIAxes2.YColor = [0,0,0];
            set(app.UIAxes2_2,'FontSize',18)
            exportgraphics(app.DisplayFCUIFigure,fullfile(imgfolderpath,imgfile),"BackgroundColor",[1,1,1])
            c.Color = [1 1 1];
            app.UIAxes2_2.XColor = [1 1 1];
            app.UIAxes2.YColor = [1 1 1];
            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DisplayFCUIFigure = uifigure('Visible', 'off');
            app.DisplayFCUIFigure.Name = 'Display FC';
            app.DisplayFCUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.UIAxes = uiaxes(app.DisplayFCUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.DisplayFCUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.DisplayFCUIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.ZTick = [];
            app.UIAxes2_2.Visible = 'off';
            app.TabGroup = uitabgroup(app.DisplayFCUIFigure);
            app.AllfrequenciesTab = uitab(app.TabGroup);
            app.AllfrequenciesTab.Title = 'All frequencies';
            app.AllfrequenciesTab.ButtonDownFcn = createCallbackFcn(app, @AllfrequenciesTabButtonDown, true);
            app.AtlasfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.AtlasfileSelectLabel.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.AtlasfileSelectDropDown.Items = {};
            app.AtlasfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDownValueChanged, true);
            app.AtlasfileSelectDropDown.Value = {};
            app.AtlasbasedFCButton = uibutton(app.AllfrequenciesTab, 'push');
            app.AtlasbasedFCButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasbasedFCButtonPushed, true);
            app.AtlasbasedFCButton.Text = 'Atlas based FC';
            app.WMCorpusCallosumNetworksButton = uibutton(app.AllfrequenciesTab, 'push');
            app.WMCorpusCallosumNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @WMCorpusCallosumNetworksButtonPushed, true);
            app.WMCorpusCallosumNetworksButton.Text = {'WM Corpus Callosum'; 'Networks'};
            app.CCfileSelectDropDownLabel = uilabel(app.AllfrequenciesTab);
            app.CCfileSelectDropDownLabel.Text = 'CC file Select';
            app.CCfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.CCfileSelectDropDown.Items = {};
            app.CCfileSelectDropDown.Value = {};
            app.WMGMNetworkConnectivityButton = uibutton(app.AllfrequenciesTab, 'push');
            app.WMGMNetworkConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @WMGMNetworkConnectivityButtonPushed, true);
            app.WMGMNetworkConnectivityButton.Text = 'WM GM Network Connectivity';
            app.GMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.GMfileSelectLabel.Text = 'GM file Select';
            app.GMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.GMfileSelectDropDown.Items = {};
            app.GMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDownValueChanged, true);
            app.GMfileSelectDropDown.Value = {};
            app.WMFunctionalNetworkconnectvityButton = uibutton(app.AllfrequenciesTab, 'push');
            app.WMFunctionalNetworkconnectvityButton.ButtonPushedFcn = createCallbackFcn(app, @WMFunctionalNetworkconnectvityButtonPushed, true);
            app.WMFunctionalNetworkconnectvityButton.Text = {'WM Functional Network'; 'connectvity'};
            app.WMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.WMfileSelectLabel.Text = 'WM file Select';
            app.WMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.WMfileSelectDropDown.Items = {};
            app.WMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDownValueChanged, true);
            app.WMfileSelectDropDown.Value = {};
            app.FrequencyBandsTab = uitab(app.TabGroup);
            app.FrequencyBandsTab.Title = 'Frequency Bands';
            app.FrequencyBandsTab.ButtonDownFcn = createCallbackFcn(app, @FrequencyBandsTabButtonDown, true);
            app.AtlasfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.AtlasfileSelectLabel_2.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.AtlasfileSelectDropDown_2.Items = {};
            app.AtlasfileSelectDropDown_2.Value = {};
            app.AtlasbasedFCButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.AtlasbasedFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @AtlasbasedFCButton_2Pushed, true);
            app.AtlasbasedFCButton_2.Text = 'Atlas based FC';
            app.WMCorpusCallosumNetworksButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.WMCorpusCallosumNetworksButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMCorpusCallosumNetworksButton_2Pushed, true);
            app.WMCorpusCallosumNetworksButton_2.Text = {'WM Corpus Callosum'; 'Networks'};
            app.CCfileSelectDropDownLabel_2 = uilabel(app.FrequencyBandsTab);
            app.CCfileSelectDropDownLabel_2.Text = 'CC file Select';
            app.CCfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.CCfileSelectDropDown_2.Items = {};
            app.CCfileSelectDropDown_2.Value = {};
            app.WMGMNetworkConnectivityButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.WMGMNetworkConnectivityButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMGMNetworkConnectivityButton_2Pushed, true);
            app.WMGMNetworkConnectivityButton_2.Text = 'WM GM Network Connectivity';
            app.GMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.GMfileSelectLabel_2.Text = 'GM file Select';
            app.GMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.GMfileSelectDropDown_2.Items = {};
            app.GMfileSelectDropDown_2.Value = {};
            app.WMFunctionalNetworkconnectvityButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.WMFunctionalNetworkconnectvityButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMFunctionalNetworkconnectvityButton_2Pushed, true);
            app.WMFunctionalNetworkconnectvityButton_2.Text = {'WM Functional Network'; 'connectvity'};
            app.WMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.WMfileSelectLabel_2.Text = 'WM file Select';
            app.WMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.WMfileSelectDropDown_2.Items = {};
            app.WMfileSelectDropDown_2.Value = {};
            app.FrequencyBandSelectLabel = uilabel(app.FrequencyBandsTab);
            app.FrequencyBandSelectLabel.Text = 'Frequency Band Select';
            app.Band_selectDropDown = uidropdown(app.FrequencyBandsTab);
            app.Band_selectDropDown.Items = {};
            app.Band_selectDropDown.ValueChangedFcn = createCallbackFcn(app, @Band_selectDropDownValueChanged, true);
            app.Band_selectDropDown.Value = {};
            app.SubjectlistDropDownLabel = uilabel(app.DisplayFCUIFigure);
            app.SubjectlistDropDownLabel.Text = 'Subject list';
            app.SubjectlistDropDown = uidropdown(app.DisplayFCUIFigure);
            app.SubjectlistDropDown.Items = {};
            app.SubjectlistDropDown.ClickedFcn = createCallbackFcn(app, @SubjectlistDropDownClicked, true);
            app.SubjectlistDropDown.Value = {};
            app.TotalnumberofSubjectsLabel = uilabel(app.DisplayFCUIFigure);
            app.TotalnumberofSubjectsLabel.Text = 'Total number of Subjects:';
            app.DataTipsCheckBox = uicheckbox(app.DisplayFCUIFigure);
            app.DataTipsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataTipsCheckBoxValueChanged, true);
            app.DataTipsCheckBox.Text = 'Data Tips';
            app.DivideingroupsCheckBox = uicheckbox(app.DisplayFCUIFigure);
            app.DivideingroupsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DivideingroupsCheckBoxValueChanged, true);
            app.DivideingroupsCheckBox.Text = 'Divide in groups';
            app.UploadCSVforSubjectGroupsButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.UploadCSVforSubjectGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCSVforSubjectGroupsButtonPushed, true);
            app.UploadCSVforSubjectGroupsButton.Text = 'Upload CSV for Subject Groups';
            app.SubjectlistDropDownLabel_2 = uilabel(app.DisplayFCUIFigure);
            app.SubjectlistDropDownLabel_2.Text = 'Subject list';
            app.SubjectlistDropDown_2 = uidropdown(app.DisplayFCUIFigure);
            app.SubjectlistDropDown_2.Items = {};
            app.SubjectlistDropDown_2.ClickedFcn = createCallbackFcn(app, @SubjectlistDropDown_2Clicked, true);
            app.SubjectlistDropDown_2.Value = {};
            app.Group1SubjectsSelectButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.Group1SubjectsSelectButton.ButtonPushedFcn = createCallbackFcn(app, @Group1SubjectsSelectButtonPushed, true);
            app.Group1SubjectsSelectButton.Text = {'Group 1 Subjects'; ' Select'};
            app.Group1NameEditField = uieditfield(app.DisplayFCUIFigure, 'text');
            app.Group1NameEditField.ValueChangedFcn = createCallbackFcn(app, @Group1NameEditFieldValueChanged, true);
            app.Group1NameEditField.Value = 'Group 1 name here';
            app.SubjectlistDropDownLabel_3 = uilabel(app.DisplayFCUIFigure);
            app.SubjectlistDropDownLabel_3.Text = 'Subject list';
            app.SubjectlistDropDown_3 = uidropdown(app.DisplayFCUIFigure);
            app.SubjectlistDropDown_3.Items = {};
            app.SubjectlistDropDown_3.ClickedFcn = createCallbackFcn(app, @SubjectlistDropDown_3Clicked, true);
            app.SubjectlistDropDown_3.Value = {};
            app.Group2SubjectsSelectButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.Group2SubjectsSelectButton.ButtonPushedFcn = createCallbackFcn(app, @Group2SubjectsSelectButtonPushed, true);
            app.Group2SubjectsSelectButton.Text = {'Group 2 Subjects'; ' Select'};
            app.Group2NameEditField = uieditfield(app.DisplayFCUIFigure, 'text');
            app.Group2NameEditField.ValueChangedFcn = createCallbackFcn(app, @Group2NameEditFieldValueChanged, true);
            app.Group2NameEditField.Value = 'Group 2 name here';
            app.TotalnumberofSubjectsinGroup1Label = uilabel(app.DisplayFCUIFigure);
            app.TotalnumberofSubjectsinGroup1Label.Text = 'Total number of Subjects in Group 1';
            app.TotalnumberofSubjectsinGroup2Label = uilabel(app.DisplayFCUIFigure);
            app.TotalnumberofSubjectsinGroup2Label.Text = 'Total number of Subjects in Group 2';
            app.SaveplotasimageButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.SaveplotasimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotasimageButtonPushed, true);
            app.SaveplotasimageButton.Text = 'Save plot as image';
            % Show the figure after all components are created
            app.DisplayFCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FC_visualize_new_side_plots_groups(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayFCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayFCUIFigure)
        end
    end
end