classdef PCA_regression_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NoofPCAComponentsUIFigure  matlab.ui.Figure
        submitButton               matlab.ui.control.Button
        NoofPCAcomponentsTextArea  matlab.ui.control.TextArea
        NoofPCAcomponentsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, n_pca, position)
            app.CallingApp = caller;
            app.NoofPCAcomponentsTextArea.Value = num2str(n_pca);

            app.NoofPCAComponentsUIFigure.Position =  [position(1) + 200, position(2) + 200,162,138];

            
        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
            if ~isnan(str2double(app.NoofPCAcomponentsTextArea.Value))
                if str2double(app.NoofPCAcomponentsTextArea.Value) > 0
                    app.CallingApp.NoofPCAcomponents= string(app.NoofPCAcomponentsTextArea.Value);
                    delete(app)
                else
                    msgbox('No. of PCA components cannot be zero or negative','Invalid')
                end
            else
                msgbox('Invalid No. of PCA components ','Invalid')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NoofPCAComponentsUIFigure = uifigure('Visible', 'off');
            app.NoofPCAComponentsUIFigure.Name = 'No. of PCA Components';
            app.NoofPCAComponentsUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.NoofPCAcomponentsLabel = uilabel(app.NoofPCAComponentsUIFigure);
            app.NoofPCAcomponentsLabel.Text = {'No. of PCA'; 'components'};
            app.NoofPCAcomponentsTextArea = uitextarea(app.NoofPCAComponentsUIFigure);
            app.NoofPCAcomponentsTextArea.Value = {'5'};
            app.submitButton = uibutton(app.NoofPCAComponentsUIFigure, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            % Show the figure after all components are created
            app.NoofPCAComponentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA_regression_parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NoofPCAComponentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NoofPCAComponentsUIFigure)
        end
    end
end