classdef Sub_info < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParticipantsInformationUIFigure  matlab.ui.Figure
        ParticipantsinerrormotionexmanualexButton  matlab.ui.control.Button
        TotalnumberofParticipantsgoodforpreprocessanalysisLabel  matlab.ui.control.Label
        TotalnumberofParticipantsexcludedduetomotionLabel  matlab.ui.control.Label
        TotalnumberofParticipantsmanuallyexcludedLabel  matlab.ui.control.Label
        TotalnumberofParticipantsinerrorLabel  matlab.ui.control.Label
        TotalnumberofParticipantsLabel  matlab.ui.control.Label
        savechangesButton               matlab.ui.control.Button
        ParticipantsgoodtopreprocessButton  matlab.ui.control.Button
        AllParticipantsButton           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        output_folder % Description
        button_pressed
        Subj_list_all % Description
    end
    
    methods (Access = private)
        
        function Subj_list_all = load_subjects_all(~,folder,name)
            
            opts = detectImportOptions(fullfile(folder,name),"Delimiter",',');
            opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
            T1 = readtable(fullfile(folder,name),opts);

            T = readtable(fullfile(folder,name),Delimiter=',');

            T.name = T1.name;

            try

                Subj_list_all = table2struct(T);


            catch
                Subj_list_all = [] ;
            end

        end

        
        function Subj_list_all = remove_cols(~,Subj_list_all)

            try
                Subj_list_all(:,'bytes') = [];
            catch
            end
            try
                Subj_list_all(:,'date') = [];
            catch
            end
            try
                Subj_list_all(:,'datenum') = [];
            catch
            end
            try
                Subj_list_all(:,'isdir') = [];
            catch
            end
            
        end
        
        % function [Subj_list,rm] = load_subjects(~,folder,name,first)
        %     if nargin < 4
        %         first = 0;
        %     end
        %     try
        %         opts = detectImportOptions(fullfile(folder,name));
        %         opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
        %         T1 = readtable(fullfile(folder,name),opts);
        % 
        %         T = readtable(fullfile(folder,name));
        % 
        %         T.name = T1.name;
        % 
        %         if first
        %             T.error = zeros(height(T),1);
        %             T.motion_ex = zeros(height(T),1);
        %             rm = logical(T.manual_ex);
        %             Subj_list = table2struct(T(~rm,:));
        %         else
        %             rm = (logical(T.error) | logical(T.motion_ex) | logical(T.manual_ex));
        %             Subj_list = table2struct(T(~rm,:));
        %         end
        %     catch
        %         Subj_list = [] ;
        %     end
        % 
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, output_folder)
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'whifun_functions'))
            app.output_folder = output_folder;
            
            app.Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv');
            app.Subj_list_all = struct2table(app.Subj_list_all);
            app.Subj_list_all = remove_cols(app,app.Subj_list_all);
            app.UITable.Data = app.Subj_list_all;
            app.UITable.ColumnName = app.Subj_list_all.Properties.VariableNames;
            n_error = sum(cell2mat({app.Subj_list_all.error}));
            n_manual = sum(cell2mat({app.Subj_list_all.manual_ex}));
            n_motion = sum(cell2mat({app.Subj_list_all.motion_ex}));

            app.TotalnumberofParticipantsLabel.Text = ['Total number of Participants: ' num2str(height(app.Subj_list_all))];
            app.TotalnumberofParticipantsinerrorLabel.Text = ['Total number of Participants in error: ' num2str(n_error)];
            app.TotalnumberofParticipantsmanuallyexcludedLabel.Text = ['Total number of Participants manually excluded: ' num2str(n_manual)];
            app.TotalnumberofParticipantsexcludedduetomotionLabel.Text = ['Total number of Participants excluded due to motion: ' num2str(n_motion)];
            app.TotalnumberofParticipantsgoodforpreprocessanalysisLabel.Text = ['Total number of Participants good to preprocess/analyse: ' num2str(height(app.Subj_list_all) - n_error - n_manual -n_motion)];

            app.UITable.ColumnEditable = true;
            app.UITable.Multiselect = "on";
            app.savechangesButton.Enable = "on";
        end

        % Button pushed function: AllParticipantsButton
        function AllParticipantsButtonPushed(app, event)
            app.Subj_list_all = load_subjects_all(app,app.output_folder,'Subj_list.csv');
            app.Subj_list_all = struct2table(app.Subj_list_all);
            app.Subj_list_all = remove_cols(app,app.Subj_list_all);
            app.UITable.Data = app.Subj_list_all;
            app.UITable.ColumnName = app.Subj_list_all.Properties.VariableNames;
            app.UITable.ColumnEditable = true;
            

            app.savechangesButton.Enable = "on";
        end

        % Button pushed function: ParticipantsgoodtopreprocessButton
        function ParticipantsgoodtopreprocessButtonPushed(app, event)
            
            Subj_list = load_subjects(app.output_folder,'Subj_list.csv');
            Subj_list = struct2table(Subj_list);
            Subj_list = remove_cols(app,Subj_list);
            app.UITable.Data = Subj_list;
            app.UITable.ColumnName = Subj_list.Properties.VariableNames;
            app.UITable.ColumnEditable = false;
            app.savechangesButton.Enable = "off";
        end

        % Button pushed function: savechangesButton
        function savechangesButtonPushed(app, event)
            app.Subj_list_all = app.UITable.Data;
            my_writetable(app.Subj_list_all, fullfile(app.output_folder,"Subj_list.csv"))
            msgbox('Changes made to Subj_list.csv file','Changes saved')
        end

        % Button pushed function: ParticipantsinerrormotionexmanualexButton
        function ParticipantsinerrormotionexmanualexButtonPushed(app, event)
            Subj_list = load_subjects_bad(app.output_folder,'Subj_list.csv');
            if ~isempty(Subj_list)
                Subj_list = struct2table(Subj_list);
                Subj_list = remove_cols(app,Subj_list);
                app.UITable.Data = Subj_list;
                app.UITable.ColumnName = Subj_list.Properties.VariableNames;
                app.UITable.ColumnEditable = false;
                app.savechangesButton.Enable = "off";
            else
                msgbox('No Participants are in error/manual ex/motion ex')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ParticipantsInformationUIFigure = uifigure('Visible', 'off');
            app.ParticipantsInformationUIFigure.Name = 'Participants Information';
            app.ParticipantsInformationUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.UITable = uitable(app.ParticipantsInformationUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.AllParticipantsButton = uibutton(app.ParticipantsInformationUIFigure, 'push');
            app.AllParticipantsButton.ButtonPushedFcn = createCallbackFcn(app, @AllParticipantsButtonPushed, true);
            app.AllParticipantsButton.Text = 'All Participants';
            app.ParticipantsgoodtopreprocessButton = uibutton(app.ParticipantsInformationUIFigure, 'push');
            app.ParticipantsgoodtopreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantsgoodtopreprocessButtonPushed, true);
            app.ParticipantsgoodtopreprocessButton.Text = 'Participants good to preprocess';
            app.savechangesButton = uibutton(app.ParticipantsInformationUIFigure, 'push');
            app.savechangesButton.ButtonPushedFcn = createCallbackFcn(app, @savechangesButtonPushed, true);
            app.savechangesButton.Text = 'save changes';
            app.TotalnumberofParticipantsLabel = uilabel(app.ParticipantsInformationUIFigure);
            app.TotalnumberofParticipantsLabel.Text = 'Total number of Participants:';
            app.TotalnumberofParticipantsinerrorLabel = uilabel(app.ParticipantsInformationUIFigure);
            app.TotalnumberofParticipantsinerrorLabel.Text = 'Total number of Participants in error:';
            app.TotalnumberofParticipantsmanuallyexcludedLabel = uilabel(app.ParticipantsInformationUIFigure);
            app.TotalnumberofParticipantsmanuallyexcludedLabel.Text = 'Total number of Participants manually excluded:';
            app.TotalnumberofParticipantsexcludedduetomotionLabel = uilabel(app.ParticipantsInformationUIFigure);
            app.TotalnumberofParticipantsexcludedduetomotionLabel.Text = 'Total number of Participants excluded due to motion:';
            app.TotalnumberofParticipantsgoodforpreprocessanalysisLabel = uilabel(app.ParticipantsInformationUIFigure);
            app.TotalnumberofParticipantsgoodforpreprocessanalysisLabel.Text = 'Total number of Participants good for preprocess/analysis:';
            app.ParticipantsinerrormotionexmanualexButton = uibutton(app.ParticipantsInformationUIFigure, 'push');
            app.ParticipantsinerrormotionexmanualexButton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantsinerrormotionexmanualexButtonPushed, true);
            app.ParticipantsinerrormotionexmanualexButton.Text = 'Participants in error /motion ex/manual ex';
            % Show the figure after all components are created
            app.ParticipantsInformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sub_info(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParticipantsInformationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParticipantsInformationUIFigure)
        end
    end
end