classdef Subject_data_folder_details < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SubjectDataFolderDetailsUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AnatomicalfilepathTextArea     matlab.ui.control.TextArea
        AnatomicalfilepathTextAreaLabel  matlab.ui.control.Label
        FunctionalfilepathTextArea     matlab.ui.control.TextArea
        FunctionalfilepathTextAreaLabel  matlab.ui.control.Label
        SubmitButton                   matlab.ui.control.Button
        AnatomicalImageNamewithoutextentionniiorniigzEditField  matlab.ui.control.EditField
        AnatomicalImageNamewithoutextentionniiorniigzEditFieldLabel  matlab.ui.control.Label
        FunctionalImageNamewithoutextentionniiorniigzEditField  matlab.ui.control.EditField
        FunctionalImageNamewithoutextentionniiorniigzLabel  matlab.ui.control.Label
        AnatomicalFolderNameEditField  matlab.ui.control.EditField
        AnatomicalFolderNameEditFieldLabel  matlab.ui.control.Label
        FunctionalFolderNameEditField  matlab.ui.control.EditField
        FunctionalFolderNameEditFieldLabel  matlab.ui.control.Label
        IntermediatefoldersEditField   matlab.ui.control.EditField
        IntermediatefoldersEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private) 
        CallingApp % Main app
        Property2 % Description
        data_path
    end
    
    
    methods (Access = private)
        
        function change_field(app)
            app.FunctionalfilepathTextArea.Value = fullfile(app.data_path, '<Subject_folder_name>',char(app.IntermediatefoldersEditField.Value),char(app.FunctionalFolderNameEditField.Value),[char(app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*']);
            app.AnatomicalfilepathTextArea.Value = fullfile(app.data_path, '<Subject_folder_name>',char(app.IntermediatefoldersEditField.Value),char(app.AnatomicalFolderNameEditField.Value),[char(app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*']);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, comm_sess_name, func_folder_name, anat_folder_name, func_data_name, anat_data_name)
            % Store main app object
            app.CallingApp = caller;
            app.IntermediatefoldersEditField.Value = comm_sess_name;
            app.FunctionalFolderNameEditField.Value = func_folder_name;
            app.AnatomicalFolderNameEditField.Value = anat_folder_name;
            app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value = func_data_name;
            app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value = anat_data_name;
            app.FunctionalfilepathTextArea.Editable = "off";
            app.AnatomicalfilepathTextArea.Editable = "off";
            app.data_path = char(app.CallingApp.DataPathTextArea.Value);
            app.FunctionalfilepathTextArea.Value = fullfile(app.data_path, '<Subject_folder_name>',char(app.IntermediatefoldersEditField.Value),char(app.FunctionalFolderNameEditField.Value),[char(app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*']);
            app.AnatomicalfilepathTextArea.Value = fullfile(app.data_path, '<Subject_folder_name>',char(app.IntermediatefoldersEditField.Value),char(app.AnatomicalFolderNameEditField.Value),[char(app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*']);

            app.IntermediatefoldersEditField.Tooltip = 'Here the path from the subject folder to the folder where the anatomical and functional folders are present should be mentioned. If there are no Intermediate folders, you may keep this field blank. Please look at the functional and anatomical file paths below and see if it matches to the actual functional and anatomical path in your folder stucture.';
            app.IntermediatefoldersEditFieldLabel.Tooltip = 'Here the path from the subject folder to the folder where the anatomical and functional folders are present should be mentioned. If there are no Intermediate folders, you may keep this field blank. Please look at the functional and anatomical file paths below and see if it matches to the actual functional and anatomical path in your folder stucture.';

            app.FunctionalFolderNameEditField.Tooltip = 'Mention the name of the Functional folder where the functional images are present';
            app.FunctionalFolderNameEditFieldLabel.Tooltip = 'Mention the name of the Functional folder where the functional images are present';

            app.AnatomicalFolderNameEditField.Tooltip = 'Mention the name of the Anatomical folder where the anatomical images are present';
            app.AnatomicalFolderNameEditFieldLabel.Tooltip = 'Mention the name of the Anatomical folder where the anatomical images are present';

            app.FunctionalImageNamewithoutextentionniiorniigzEditField.Tooltip = 'Mention the name of the Functional Image here.';
            app.FunctionalImageNamewithoutextentionniiorniigzLabel.Tooltip = 'Mention the name of the Functional Image here.';

            app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Tooltip = 'Mention the name of the Anatomical Image here.';
            app.AnatomicalImageNamewithoutextentionniiorniigzEditFieldLabel.Tooltip = 'Mention the name of the Anatomical Image here.';

            app.FunctionalfilepathTextArea.Tooltip = 'This the path of the functional Image that WhiFuN will consider. The " * " signifies the path name parts that are different for every subject.';
            app.FunctionalfilepathTextAreaLabel.Tooltip = 'This the path of the functional Image that WhiFuN will consider. The " * " signifies the path name parts that are different for every subject.';

            app.AnatomicalfilepathTextArea.Tooltip = 'This the path of the anatomical Image that WhiFuN will consider. The " * " signifies the path name parts that are different for every subject.';
            app.AnatomicalfilepathTextAreaLabel.Tooltip = 'This the path of the functional Image that WhiFuN will consider. The " * " signifies the path name parts that are different for every subject.';

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            if ~isempty(char(app.CallingApp.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.CallingApp.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    return
                end
%                 output_folder = char(app.CallingApp.DataPathTextArea_2.Value);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))


                %%          Get the Anatomical and Functional Image Paths

                if strcmp(app.CallingApp.comm_subj_name,'') == 1
                    app.CallingApp.comm_subj_name = '*';
                end

                Subj_list_all = dir( fullfile(app.data_path,app.CallingApp.comm_subj_name) );
                if strcmp(Subj_list_all(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                if strcmp(Subj_list_all(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end
                else
                msgbox('Please select the Subject Data folder first')

            end
            
            subji = 1; % Checking first Subject folder folder
            now_func_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value),char(app.FunctionalFolderNameEditField.Value),[char(app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value) '.nii'])) ;
            if isempty(now_func_path)
                now_func_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value),char(app.FunctionalFolderNameEditField.Value),[char(app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*'])) ;

                if isempty(now_func_path)
                    
                    if ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value)))
                        msg = ['The intermediate folder name "' char(app.IntermediatefoldersEditField.Value) '" not found for first Subject: ' char(Subj_list_all(subji).name) '. Do you want to still proceed?'];
                        res_ = questdlg(msg,'Folder not found','No','Yes','No');
                    elseif ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value),char(app.FunctionalFolderNameEditField.Value) ))
                        msg = ['The Functional folder name "' char(app.FunctionalFolderNameEditField.Value) '" not found for first Subject ' char(Subj_list_all(subji).name) '. Do you want to still proceed?'];
                        res_ = questdlg(msg,'Folder not found','No','Yes','No');
                    else
                        msg = ['The Functional image name "' char(app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*' '" not found. Do you want to still proceed?'];
                        res_ = questdlg(msg,'Folder not found','No','Yes','No');
                    end

                    switch res_
                        case 'No'
                            return
                    end
                end
            end
            
            now_anat_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value),char(app.AnatomicalFolderNameEditField.Value),[char(app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value) '.nii'])) ;
            if isempty(now_anat_path)
                now_anat_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value),char(app.AnatomicalFolderNameEditField.Value),[char(app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*'])) ;

                if isempty(now_anat_path)
                    
                    
                    if ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,char(app.IntermediatefoldersEditField.Value),char(app.AnatomicalFolderNameEditField.Value) ))
                        msg = ['The Anatomical folder name "' char(app.AnatomicalFolderNameEditField.Value) '" not found for first Subject ' char(Subj_list_all(subji).name) '. Do you want to still proceed?'];
                        res_ = questdlg(msg,'Folder not found','No','Yes','No');
                    else
                        msg = ['The Anatomical image name "' char(app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value) '.nii*' '" not found. Do you want to still proceed?'];
                        res_ = questdlg(msg,'Folder not found','No','Yes','No');
                    end

                    switch res_
                        case 'No'
                            return
                    end
                end
            end

            app.CallingApp.comm_sess_name = string(app.IntermediatefoldersEditField.Value);

            app.CallingApp.func_folder_name = string(app.FunctionalFolderNameEditField.Value);

            app.CallingApp.anat_folder_name = string(app.AnatomicalFolderNameEditField.Value);

            app.CallingApp.func_data_name = string(app.FunctionalImageNamewithoutextentionniiorniigzEditField.Value);

            app.CallingApp.anat_data_name = string(app.AnatomicalImageNamewithoutextentionniiorniigzEditField.Value);

            delete(app)
        end

        % Value changed function: IntermediatefoldersEditField
        function IntermediatefoldersEditFieldValueChanged(app, event)
            change_field(app)
        end

        % Value changed function: FunctionalFolderNameEditField
        function FunctionalFolderNameEditFieldValueChanged(app, event)
            change_field(app)
        end

        % Value changed function: AnatomicalFolderNameEditField
        function AnatomicalFolderNameEditFieldValueChanged(app, event)
            change_field(app)
        end

        % Value changed function: 
        % FunctionalImageNamewithoutextentionniiorniigzEditField
        function FunctionalImageNamewithoutextentionniiorniigzEditFieldValueChanged(app, event)
            change_field(app)
        end

        % Value changed function: 
        % AnatomicalImageNamewithoutextentionniiorniigzEditField
        function AnatomicalImageNamewithoutextentionniiorniigzEditFieldValueChanged(app, event)
            change_field(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SubjectDataFolderDetailsUIFigure = uifigure('Visible', 'off');
            app.SubjectDataFolderDetailsUIFigure.Name = 'Subject Data Folder Details';
            app.SubjectDataFolderDetailsUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.GridLayout = uigridlayout(app.SubjectDataFolderDetailsUIFigure);
            app.IntermediatefoldersEditFieldLabel = uilabel(app.GridLayout);
            app.IntermediatefoldersEditFieldLabel.Text = 'Intermediate folders';
            app.IntermediatefoldersEditField = uieditfield(app.GridLayout, 'text');
            app.IntermediatefoldersEditField.ValueChangedFcn = createCallbackFcn(app, @IntermediatefoldersEditFieldValueChanged, true);
            app.FunctionalFolderNameEditFieldLabel = uilabel(app.GridLayout);
            app.FunctionalFolderNameEditFieldLabel.Text = 'Functional Folder Name';
            app.FunctionalFolderNameEditField = uieditfield(app.GridLayout, 'text');
            app.FunctionalFolderNameEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionalFolderNameEditFieldValueChanged, true);
            app.AnatomicalFolderNameEditFieldLabel = uilabel(app.GridLayout);
            app.AnatomicalFolderNameEditFieldLabel.Text = 'Anatomical Folder Name';
            app.AnatomicalFolderNameEditField = uieditfield(app.GridLayout, 'text');
            app.AnatomicalFolderNameEditField.ValueChangedFcn = createCallbackFcn(app, @AnatomicalFolderNameEditFieldValueChanged, true);
            app.FunctionalImageNamewithoutextentionniiorniigzLabel = uilabel(app.GridLayout);
            app.FunctionalImageNamewithoutextentionniiorniigzLabel.Text = {'Functional Image Name '; '(without extention .nii or .nii.gz)'};
            app.FunctionalImageNamewithoutextentionniiorniigzEditField = uieditfield(app.GridLayout, 'text');
            app.FunctionalImageNamewithoutextentionniiorniigzEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionalImageNamewithoutextentionniiorniigzEditFieldValueChanged, true);
            app.AnatomicalImageNamewithoutextentionniiorniigzEditFieldLabel = uilabel(app.GridLayout);
            app.AnatomicalImageNamewithoutextentionniiorniigzEditFieldLabel.Text = {'Anatomical Image Name'; ' (without extention .nii or .nii.gz)'};
            app.AnatomicalImageNamewithoutextentionniiorniigzEditField = uieditfield(app.GridLayout, 'text');
            app.AnatomicalImageNamewithoutextentionniiorniigzEditField.ValueChangedFcn = createCallbackFcn(app, @AnatomicalImageNamewithoutextentionniiorniigzEditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.GridLayout, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.FunctionalfilepathTextAreaLabel = uilabel(app.GridLayout);
            app.FunctionalfilepathTextAreaLabel.Text = 'Functional file path';
            app.FunctionalfilepathTextArea = uitextarea(app.GridLayout);
            app.AnatomicalfilepathTextAreaLabel = uilabel(app.GridLayout);
            app.AnatomicalfilepathTextAreaLabel.Text = 'Anatomical file path';
            app.AnatomicalfilepathTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.SubjectDataFolderDetailsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Subject_data_folder_details(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SubjectDataFolderDetailsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SubjectDataFolderDetailsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SubjectDataFolderDetailsUIFigure)
        end
    end
end