classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WhiFuNv32UIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        PipelineTab                     matlab.ui.container.Tab
        QCviewerButton                  matlab.ui.control.Button
        SegmentationDropDown            matlab.ui.control.DropDown
        SegmentationDropDownLabel       matlab.ui.control.Label
        NoofWorkersEditField            matlab.ui.control.NumericEditField
        NoofWorkersEditFieldLabel       matlab.ui.control.Label
        ParellelProcessingCheckBox      matlab.ui.control.CheckBox
        RunPreprocessingButton          matlab.ui.control.Button
        Greaterthan20DropDown           matlab.ui.control.DropDown
        Greaterthan20DropDownLabel      matlab.ui.control.Label
        meanDropDown                    matlab.ui.control.DropDown
        meanDropDownLabel               matlab.ui.control.Label
        maxDropDown                     matlab.ui.control.DropDown
        FramewiseDisplacementLabel      matlab.ui.control.Label
        FWHMmmTextArea                  matlab.ui.control.TextArea
        FWHMmmTextAreaLabel             matlab.ui.control.Label
        TemporalFilteringHzTextArea     matlab.ui.control.TextArea
        TemporalFilteringHzLabel        matlab.ui.control.Label
        NormalizationvoxeldimmmTextArea  matlab.ui.control.TextArea
        NormalizationvoxeldimmmTextAreaLabel  matlab.ui.control.Label
        SmoothingDropDown               matlab.ui.control.DropDown
        SmoothingDropDownLabel          matlab.ui.control.Label
        NuisanceRegressionDropDown      matlab.ui.control.DropDown
        NuisanceRegressionLabel         matlab.ui.control.Label
        NoofVolumestoDiscardTextArea    matlab.ui.control.TextArea
        NoofVolumestoDiscardTextAreaLabel  matlab.ui.control.Label
        FramewiseDisplacementFDtootipthresholdsformotionmmLabel  matlab.ui.control.Label
        MotionParametersCheckBox        matlab.ui.control.CheckBox
        FilterCheckBox                  matlab.ui.control.CheckBox
        FilterTextArea_2                matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ToolsTab                        matlab.ui.container.Tab
        DeletePreprocessingfilesfromaparticularstepButton  matlab.ui.control.Button
        manuallycoregisterparticipantsButton  matlab.ui.control.Button
        DetermineFramewiseDisplacementthresholdsButton  matlab.ui.control.Button
        PreprocessPanel                 matlab.ui.container.Panel
        CheckDataPanel                  matlab.ui.container.Panel
        DataCheckReportTextArea         matlab.ui.control.TextArea
        DataCheckReportLabel            matlab.ui.control.Label
        ParticipantsInfoButton          matlab.ui.control.Button
        RunDataCheckButton              matlab.ui.control.Button
        VisualizePanel                  matlab.ui.container.Panel
        DisplayFCButton                 matlab.ui.control.Button
        DisplayFNButton                 matlab.ui.control.Button
        AnalyzeFNandFCPanel             matlab.ui.container.Panel
        CheckFNSymettryButton           matlab.ui.control.Button
        CompareFNButton                 matlab.ui.control.Button
        StatisticsButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        WhiFuNLabel                     matlab.ui.control.Label
        ConstructFNandFCPanel           matlab.ui.container.Panel
        ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel  matlab.ui.container.Panel
        orLabel                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ManuallyexcludeparticipantsCheckBox  matlab.ui.control.CheckBox
        SelectedparticipantswithgooddataDropDown  matlab.ui.control.DropDown
        SelectedSubjectswithgooddataLabel  matlab.ui.control.Label
        AllParticipantsaregoodCheckBox  matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        CreateGroupMasksTab             matlab.ui.container.Tab
        CreateGroupMasksButton          matlab.ui.control.Button
        GroupGMthresholdTextArea        matlab.ui.control.TextArea
        IndividualGMProbabilitythresholdTextArea  matlab.ui.control.TextArea
        IndividualGMProbabilitythresholdTextArea_2Label  matlab.ui.control.Label
        GroupGMmaskthresholdLabel       matlab.ui.control.Label
        IndividualWMProbabilitythresholdTextArea  matlab.ui.control.TextArea
        IndividualWMProbabilitythresholdLabel  matlab.ui.control.Label
        GroupWMthresholdTextArea        matlab.ui.control.TextArea
        GroupWMmaskthresholdLabel       matlab.ui.control.Label
        WMFNTab                         matlab.ui.container.Tab
        WMGroupMaskButton               matlab.ui.control.Button
        grp_wm_mask_path                matlab.ui.control.TextArea
        SubsamplingtheWMvoxelFCmatrixDropDown  matlab.ui.control.DropDown
        SubsamplingtheWMvoxelFCmatrixLabel  matlab.ui.control.Label
        CreateWMFNButton                matlab.ui.control.Button
        NumCVfoldsTextArea              matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel         matlab.ui.control.Label
        CorpusCallosumNetworksCheckBox  matlab.ui.control.CheckBox
        KmeansWMKRangeTextArea          matlab.ui.control.TextArea
        KmeansKRangeLabel               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        KmeansKRangeTextArea_2          matlab.ui.control.TextArea
        GMFNTab                         matlab.ui.container.Tab
        GMGroupMaskButton               matlab.ui.control.Button
        grp_gm_mask_path                matlab.ui.control.TextArea
        SubsamplingtheGMvoxelFCmatrixDropDown  matlab.ui.control.DropDown
        SubsamplingtheGMvoxelFCmatrixDropDownLabel  matlab.ui.control.Label
        KmeansGMKRangeTextArea          matlab.ui.control.TextArea
        KmeansGMKRangeTextAreaLabel     matlab.ui.control.Label
        CreateGMFNButton                matlab.ui.control.Button
        NumCVfoldsTextArea_2            matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel_2       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        KmeansKRangeTextArea_3          matlab.ui.control.TextArea
        ROITimeseriesextractionTab      matlab.ui.container.Tab
        frequencyspecificCheckBox       matlab.ui.control.CheckBox
        SaveQCimagesofregionshavingmissingdataCheckBox  matlab.ui.control.CheckBox
        ExtractROITImeseriesButton      matlab.ui.control.Button
        NumberofFreqBandsSpinner        matlab.ui.control.Spinner
        NumberofFreqBandsLabel          matlab.ui.control.Label
        FrequenciesperbandButton        matlab.ui.control.Button
        NetworksAtlasButton             matlab.ui.control.Button
        DataPathTextArea_3              matlab.ui.control.TextArea
        WMGMFNTab                       matlab.ui.container.Tab
        GMGroupMaskButton_3             matlab.ui.control.Button
        grp_gm_mask_path_2              matlab.ui.control.TextArea
        WMGroupMaskButton_2             matlab.ui.control.Button
        grp_wm_mask_path_2              matlab.ui.control.TextArea
        SubsamplingtheGMvoxelFCmatrixDropDown_2  matlab.ui.control.DropDown
        SubsamplingtheGMvoxelFCmatrixDropDownLabel_2  matlab.ui.control.Label
        KmeansGMKRangeTextArea_2        matlab.ui.control.TextArea
        KmeansGMKRangeTextAreaLabel_2   matlab.ui.control.Label
        CreateWMGMFNButton              matlab.ui.control.Button
        NumCVfoldsTextArea_3            matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel_3       matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        KmeansKRangeTextArea_4          matlab.ui.control.TextArea
        GMWMFNTab                       matlab.ui.container.Tab
        GMGroupMaskButton_4             matlab.ui.control.Button
        grp_gm_mask_path_3              matlab.ui.control.TextArea
        WMGroupMaskButton_3             matlab.ui.control.Button
        grp_wm_mask_path_3              matlab.ui.control.TextArea
        SubsamplingtheGMvoxelFCmatrixDropDown_3  matlab.ui.control.DropDown
        SubsamplingtheGMvoxelFCmatrixDropDownLabel_3  matlab.ui.control.Label
        KmeansGMKRangeTextArea_3        matlab.ui.control.TextArea
        KmeansGMKRangeTextAreaLabel_3   matlab.ui.control.Label
        CreateGMWMFNButton              matlab.ui.control.Button
        NumCVfoldsTextArea_4            matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel_4       matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        KmeansKRangeTextArea_5          matlab.ui.control.TextArea
        GMNoiseFNTab                    matlab.ui.container.Tab
        GMGroupMaskButton_5             matlab.ui.control.Button
        grp_gm_mask_path_4              matlab.ui.control.TextArea
        WMGroupMaskButton_4             matlab.ui.control.Button
        grp_wm_mask_path_4              matlab.ui.control.TextArea
        SubsamplingtheGMvoxelFCmatrixDropDown_4  matlab.ui.control.DropDown
        SubsamplingtheGMvoxelFCmatrixDropDownLabel_4  matlab.ui.control.Label
        KmeansGMKRangeTextArea_4        matlab.ui.control.TextArea
        KmeansGMKRangeTextAreaLabel_4   matlab.ui.control.Label
        CreateGMnoiseFNButton           matlab.ui.control.Button
        NumCVfoldsTextArea_5            matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel_5       matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        KmeansKRangeTextArea_6          matlab.ui.control.TextArea
        NoiseGMFNTab                    matlab.ui.container.Tab
        GMGroupMaskButton_6             matlab.ui.control.Button
        grp_gm_mask_path_5              matlab.ui.control.TextArea
        WMGroupMaskButton_5             matlab.ui.control.Button
        grp_wm_mask_path_5              matlab.ui.control.TextArea
        SubsamplingtheGMvoxelFCmatrixDropDown_5  matlab.ui.control.DropDown
        SubsamplingtheGMvoxelFCmatrixDropDownLabel_5  matlab.ui.control.Label
        KmeansGMKRangeTextArea_5        matlab.ui.control.TextArea
        KmeansGMKRangeTextAreaLabel_5   matlab.ui.control.Label
        CreateNoiseGMFNButton           matlab.ui.control.Button
        NumCVfoldsTextArea_6            matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel_6       matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        KmeansKRangeTextArea_7          matlab.ui.control.TextArea
        SetupPanel                      matlab.ui.container.Panel
        AllfoldersareParticipantsCheckBox  matlab.ui.control.CheckBox
        Label_7                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SelectParticipantsButton        matlab.ui.control.Button
        BIDSCheckBox                    matlab.ui.control.CheckBox
        DataPathTextArea                matlab.ui.control.TextArea
        ParticipantDatafolderButton     matlab.ui.control.Button
        DataPathTextArea_2              matlab.ui.control.TextArea
        OutputsfolderButton             matlab.ui.control.Button
        SaveLoadParametersPanel         matlab.ui.container.Panel
        OverwriteexistingfilesCheckBox  matlab.ui.control.CheckBox
        SaveParametersButton            matlab.ui.control.Button
        LoadParametersButton            matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    properties (Access = private)
        DialogApp % Dialog box app
        ex_tf_atlas                                                       % For Frequency Specific Time series extraction
        ex_tf_atlas_path                                                  % folder where Frequency Specific Time series extraction atlas is stored
        Subject_data_folder_details_changed = 0;                               % For updating csv when Subj_data_folder_details updated
    end

    properties (Access = public)
        comm_subj_name = ''  % Description
    end

    properties (Access = public)
        comm_sess_name = '' % Description
    end

    properties (Access = public)
        func_folder_name = 'func' % Description
    end

    properties (Access = public)
        anat_folder_name = 'anat' % Description
    end

    properties (Access = public)
        SegmentationCSFThreshold = '0.95'
    end

    properties (Access = public)
        %         Subj_list
        Subj_list1
    end
    properties (Access = public)
        func_data_name = 'sub*_task-rest_*' % Description
    end

    properties (Access = public)
        anat_data_name = '*sub*_T1w*'% Description
    end

    properties (Access = public)
        NoofPCAcomponents = 5;
    end

    % For Frequency Specific Time series extraction
    properties (Access = public)
        band1_lp = 0
        band1_hp = 0
        band1_name = 'Band 1'

        band2_lp = 0
        band2_hp = 0
        band2_name = 'Band 2'

        band3_lp = 0
        band3_hp = 0
        band3_name = 'Band 3'

        band4_lp = 0
        band4_hp = 0
        band4_name = 'Band 4'

        band5_lp = 0
        band5_hp = 0
        band5_name = 'Band 5'

        band6_lp = 0
        band6_hp = 0
        band6_name = 'Band 6'
    end
    methods (Access = private)

        function load_param(app,load_folder,load_name)
            load(fullfile(load_folder,load_name)) %#ok<LOAD>
            %             quality_control_path = fullfile(output_folder,'Quality_control');
            if exist(fullfile(output_folder,'Subj_list.csv'),"file")
                Subj_list = load_subjects(output_folder,'Subj_list.csv');
            end

            if exist('bids_check','var')
                app.BIDSCheckBox.Value = bids_check;
            end

            if exist('filter_check','var')
                app.FilterCheckBox.Value = filter_check;
            end

            app.comm_subj_name = comm_subj_name; %#ok<ADPROPLC> % % Can be used for future if researchers want to analyse a subset of participants in the data folder
            app.comm_sess_name = comm_sess_name; %#ok<ADPROPLC> % % Can be used for future if researchers have dataset where there are multiple session folders

            %   Set functional and anatomical data folder name
            app.func_folder_name = func_folder_name ;    %#ok<ADPROPLC> % % Can be used in the future if the Functional folder name is different
            app.anat_folder_name = anat_folder_name;     %#ok<ADPROPLC> % % Can be used in the future if the Anatomical folder name is different

            %   Set functional and anatomical data basic file name
            app.func_data_name = func_data_name ;     %#ok<ADPROPLC> % %   Functional data name
            app.anat_data_name = anat_data_name ;     %#ok<ADPROPLC> % %   Anatomical data name


            app.DataPathTextArea.Value = data_path;
            app.DataPathTextArea_2.Value = output_folder;


            app.NoofVolumestoDiscardTextArea.Value = string(n_vol_dis);

            if exist('max_fd','var')
                try
                    if ~ischar(max_fd) %#ok<NODEF>
                        max_fd = mat2str(max_fd);
                    end
                    app.maxDropDown.Value = max_fd;%string();                                                               % Maximum translation allowed for Framewize displacement
                catch ex
                    er_id = ex.identifier;
                    no_item = 'MATLAB:ui:DropDown:valueNotInText';
                    if strcmp(er_id,no_item)
                        app.maxDropDown.Items(end + 1) = {mat2str(max_fd)};
                        app.maxDropDown.Value = mat2str(max_fd);
                    end
                end
                try
                    if ~ischar(mean_fd) %#ok<NODEF>
                        mean_fd = mat2str(mean_fd);
                    end
                    app.meanDropDown.Value = mean_fd ;
                catch ex
                    er_id = ex.identifier;
                    no_item = 'MATLAB:ui:DropDown:valueNotInText';
                    if strcmp(er_id,no_item)
                        app.meanDropDown.Items(end + 1) = {mat2str(mean_fd)};
                        app.meanDropDown.Value = mat2str(mean_fd);
                    end
                end
                try
                    if ~ischar(greater_than_20) %#ok<NODEF>
                        greater_than_20 = mat2str(greater_than_20);
                    end
                    app.Greaterthan20DropDown.Value = greater_than_20 ;
                catch ex
                    er_id = ex.identifier;
                    no_item = 'MATLAB:ui:DropDown:valueNotInText';
                    if strcmp(er_id,no_item)
                        app.Greaterthan20DropDown.Items(end + 1) = {mat2str(greater_than_20)};
                        app.Greaterthan20DropDown.Value = mat2str(greater_than_20);
                    end
                end
            end

            app.SegmentationCSFThreshold = string(CSF_thres);

            app.NuisanceRegressionDropDown.Value = Reg_drop;
            app.NoofPCAcomponents = string(n_pca);

            app.MotionParametersCheckBox.Value = motion_reg;

            app.TemporalFilteringHzTextArea.Value = string(filter_lp) ;
            app.FilterTextArea_2.Value = string(filter_hp) ;
            app.SmoothingDropDown.Value = smooth_drop ;
            app.FWHMmmTextArea.Value = string(smooth_fwhm);
            app.NormalizationvoxeldimmmTextArea.Value = string(vox);

            % app.GroupWMthresholdTextArea.Value = string(grp_wm_thres);
            % app.GroupGMthresholdTextArea.Value = string(grp_gm_thres);
            % 
            % app.KmeansWMKRangeTextArea.Value = string(K_range_h) ;
            % app.KmeansKRangeTextArea_2.Value = string(K_range_l) ;
            % app.NumCVfoldsTextArea.Value = string(CV_folds);
            % 
            % app.CorpusCallosumNetworksCheckBox.Value = Corpus_check;
            % 
            % if exist('K_range_h_gm','var') && exist('K_range_l_gm','var') && exist('CV_folds_gm','var')
            %     app.KmeansGMKRangeTextArea.Value = string(K_range_h_gm) ;
            %     app.KmeansKRangeTextArea_3.Value = string(K_range_l_gm) ;
            %     app.NumCVfoldsTextArea_2.Value = string(CV_folds_gm);
            % end


            % app.OverwriteexistingfilesCheckBox.Value = over_write;
            if exist(fullfile(output_folder,'Subj_list.csv'),"file")
                load_subjects_dropdown(app,Subj_list)
            end
        end

        function load_subjects_dropdown(app,Subj_list,select_by)
            if nargin < 3
                select_by = 'Load_subject';
            end
            if ~isempty(Subj_list)
                Subj_names = struct2cell(Subj_list);
                Subj_names(2:end,:) = [];

                switch select_by
                    case 'Data_path'
                        app.Label_7.Text = ["No.of folders in" ['Participant data folder: ' num2str(length(Subj_names))]];
                        app.Label_7.Tooltip = 'Number of subfolders in the Participant data folder.';
                    case 'Load_subject'
                        app.Label_4.Text = ['No.of Participants: ' num2str(length(Subj_names))];
                        app.Label_4.Tooltip = 'Number of Participants with good data in the Participant data folder.';
                end
            else
                msgbox('No subjects found in the Subj_list.csv file')
            end
        end

        function load_subjects_dropdown2(app,Subj_list)
            if ~isempty(Subj_list)
                Subj_names = struct2cell(Subj_list);
                Subj_names(2:end,:) = [];

                app.SelectedparticipantswithgooddataDropDown.Items = Subj_names;
                app.Label_5.Text = num2str(length(Subj_names));
            else
                msgbox('No subjects found in the Subj_list.csv file')
            end
        end

        function save_param(app,save_name,save_folder)

            data_path = string(app.DataPathTextArea.Value);
            output_folder = string(app.DataPathTextArea_2.Value);
            bids_check = app.BIDSCheckBox.Value;

            n_vol_dis = string(app.NoofVolumestoDiscardTextArea.Value);
            max_fd = app.maxDropDown.Value;                                                               % Maximum translation allowed for Framewize displacement
            mean_fd = app.meanDropDown.Value;
            greater_than_20 = app.Greaterthan20DropDown.Value;
            Seg_dartel_drop = app.SegmentationDropDown.Value;
            CSF_thres = app.SegmentationCSFThreshold;
            Reg_drop = app.NuisanceRegressionDropDown.Value;
            n_pca = (string(app.NoofPCAcomponents));

            motion_reg = app.MotionParametersCheckBox.Value;

            filter_lp = string(app.TemporalFilteringHzTextArea.Value);
            filter_hp = string(app.FilterTextArea_2.Value);
            smooth_drop = app.SmoothingDropDown.Value;
            smooth_fwhm = (string(app.FWHMmmTextArea.Value));
            vox = string(app.NormalizationvoxeldimmmTextArea.Value);

            filter_check = app.FilterCheckBox.Value;
            whifun_save_parameters(save_folder,save_name,...
                                data_path,output_folder,bids_check,...
                                app.comm_sess_name,app.comm_subj_name,...
                                app.func_folder_name,app.anat_folder_name,...
                                app.func_data_name,app.anat_data_name,...
                                n_vol_dis,...
                                max_fd,mean_fd,greater_than_20,...
                                Seg_dartel_drop,...
                                CSF_thres,...
                                Reg_drop,n_pca,motion_reg,...
                                filter_check,filter_lp,filter_hp,...
                                smooth_drop,smooth_fwhm,...
                                vox)
        end

        function Dice_coef_LR = get_symettry(~,current_clustering_solution)
            % separating the left and right sides of the clustering solutions, and flipping the right side, for whifun_direct comparison to the left
            Mid_sagittal_slice = round(size(current_clustering_solution,1)/2);
            current_clustering_solution_L = current_clustering_solution(1:Mid_sagittal_slice,:,:);
            current_clustering_solution_R = flip(current_clustering_solution,1);
            current_clustering_solution_R = current_clustering_solution_R(1:Mid_sagittal_slice,:,:);

            % looking only at places where white-matter exists in both hemispheres
            % (zeroing places where that is not true)
            current_clustering_solution_L((current_clustering_solution_L & current_clustering_solution_R) ==0) = 0;
            current_clustering_solution_R((current_clustering_solution_L & current_clustering_solution_R) ==0) = 0;
            clustered_voxels = find(current_clustering_solution_L(:)>0);    % finding all voxels belonging to the white-matter in both hemispheres

            % creating adjacency matrices for similarity between clustering solutions
            % (1 indicates the two voxels belong to the same cluster)
            adjmat_L = zeros(length(clustered_voxels)); adjmat_R = zeros(length(clustered_voxels));
            for i=1:length(clustered_voxels)
                for j=1:length(clustered_voxels)
                    if current_clustering_solution_L(clustered_voxels(i))==current_clustering_solution_L(clustered_voxels(j))
                        adjmat_L(i,j)=1;
                    end
                    if current_clustering_solution_R(clustered_voxels(i))==current_clustering_solution_R(clustered_voxels(j))
                        adjmat_R(i,j)=1;
                    end
                end
            end
            % computing Dice's coefficient of similarity between the clustering results for both hemispheres
            Dice_coef_LR = 2*sum(adjmat_R(:) & adjmat_L(:)) / (sum(adjmat_R(:)) + sum(adjmat_L(:)));

        end

        function Dice_coef_LR = get_symettry_indi(~,current_clustering_solution)
            % separating the left and right sides of the clustering solutions, and flipping the right side, for whifun_direct comparison to the left
            Mid_sagittal_slice = round(size(current_clustering_solution,1)/2);
            current_clustering_solution_L = current_clustering_solution(1:Mid_sagittal_slice,:,:);
            current_clustering_solution_R = flip(current_clustering_solution,1);
            current_clustering_solution_R = current_clustering_solution_R(1:Mid_sagittal_slice,:,:);

            % computing Dice's coefficient of similarity between the clustering results for both hemispheres
            [Dice_coef_LR,~] = dice_iou(current_clustering_solution_L,current_clustering_solution_R,0,0);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tooltip(app)
            % addpaths
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(preproc_code_path)
            addpath(fullfile(preproc_code_path,'whifun_functions'))
            addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
            %% Setup Tab
            app.OutputsfolderButton.Tooltip = 'Select the folder path where all the quality control plots generated during preprocessing, analysis results of the toolbox will be stored. This should be an empty folder.';
            app.DataPathTextArea_2.Tooltip = 'Paste the folder path where all the quality control plots generated during preprocessing, analysis results of the toolbox will be stored. This should be an empty folder.';

            app.ParticipantDatafolderButton.Tooltip = 'Select the main folder containing subfolders for each participant. Each sub folder should contain the anatomical and the functional MRI scans. If the main folder data structure is not BIDS then uncheck the BIDS check box on the right and specify the details of the data structure.';
            app.DataPathTextArea.Tooltip = 'Paste the folder path of the main folder containing subfolders for each participant. Each sub folder should contain the anatomical and the functional MRI scans. If the main folder data structure is not BIDS then uncheck the BIDS check box on the right and specify the details of the data structure.';

            app.SelectParticipantsButton.Tooltip = 'Select the folders that correspond to participant data which needs to be processed.';

            app.AllfoldersareParticipantsCheckBox.Tooltip = 'Check to select all the folders as participant folders';


            %% Check data tab

            app.RunDataCheckButton.Tooltip = 'Check the Image files and associated parameters of all the participants';

            app.ParticipantsInfoButton.Tooltip = 'List all participants and the parameters corresponding to every participant detected during the Data check';
            %% Preprocessing Tab
            app.NoofVolumestoDiscardTextArea.Tooltip = 'Discard initial volumes/timepoints to achieve magnetization stability, put 0 if no volumes should be discarded';
            app.NoofVolumestoDiscardTextAreaLabel.Tooltip = 'Discard initial volumes/imepoints to achieve magnetization stability, put 0 if no volumes should be discarded';


            app.FramewiseDisplacementFDtootipthresholdsformotionmmLabel.Tooltip = 'Framewise displacement (FD): A measure to reject participants having excessive motion. FD is calculated for every time point using equations given by Power et. al. (2011). These thresholds can also be revised/changed after preprocesssing is complete using the tools tab.';
            app.FramewiseDisplacementLabel.Tooltip = 'Threshold on max FD , if the max FD (across every time point) of a participant is greater than this threshold then the participant will be rejected';
            app.maxDropDown.Tooltip = 'Threshold on max FD, if the max FD of a participant is greater than this threshold then the participant will be rejected';

            app.meanDropDown.Tooltip = 'Threshold on mean FD, if the mean FD computed over all timepoints of a participant is greater than this threshold then the participant will be rejected';
            app.meanDropDownLabel.Tooltip = 'Threshold on mean FD, if the mean FD computed over all timepoints of a participant is greater than this threshold then the participant will be rejected';

            app.Greaterthan20DropDown.Tooltip = 'Threshold on greater than 20%, if the FD values is greater than this threshold for 20% or more volumes, then the participant will be rejected';
            app.Greaterthan20DropDownLabel.Tooltip = 'Threshold on greater than 20%, if the FD values is greater than this threshold for 20% or more volumes, then the participant will be rejected';

            app.NuisanceRegressionDropDown.Tooltip = 'Regress out the noise from all the voxels in the brain. It is assumed that the BOLD signal from CSF regions is noisy as it contains no neuronal information. Thus the user can regress out, either the mean CSF BOLD signal or the First n PCA components of the CSF BOLD signal. ';
            app.NuisanceRegressionLabel.Tooltip = 'Regress out the noise from all the voxels in the brain.';
            app.MotionParametersCheckBox.Tooltip = 'Checking this will also regress out the 24 Friston motion parameters (Friston et. al. 1996) from every voxel in the brain.';

            app.SmoothingDropDownLabel.Tooltip = 'Spatial smoothing. Used to increase the signal to noise ratio in fMRI timeseries.';
            app.SmoothingDropDown.Tooltip = 'Smoothing options: Since the focus of WhiFuN is White Matter(WM) regions, it is recommended to smooth the WM and Gray Matter (GM) seperately to ensure there is no influence of GM voxels on WM.';
            app.FWHMmmTextArea.Tooltip = 'Fixed width half maxima';
            app.FWHMmmTextAreaLabel.Tooltip = 'Fixed width half maxima';

            app.TemporalFilteringHzLabel.Tooltip = 'Temporal Butterworth bandpass filter of 2nd order';
            app.FilterCheckBox.Tooltip = 'If checked, Butterworth bandpass filter of 2nd order will be applied on every brain voxel';
            app.TemporalFilteringHzTextArea.Tooltip = 'Low frequency cutoff';
            app.FilterTextArea_2.Tooltip = 'High frequency cutoff';

            app.NormalizationvoxeldimmmTextArea.Tooltip = 'Voxel Size of the Normalized (MNI) space';
            app.NormalizationvoxeldimmmTextAreaLabel.Tooltip = 'Normalization transforms the images from participant space to Montreal Neurological Institute (MNI) space. The voxel size of the transformed image in MNI space is required. The default is 3mm as later, during the creation of WM and GM Functional Networks, a voxel level Functional Connectivity Matrix is created, if a smaller voxel size is choosen computation of WM and GM networks could cause memory problems.';

            app.OverwriteexistingfilesCheckBox.Tooltip = 'If checked any existing files if found will be whifun_deleted and the preprocessing will start from the begining';

            %% Construct FN and FC Tab
            app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel.Tooltip = 'After preprocessing, please check the "Quality Control" folder in the outputs folder and observe the Quality control plots corresponding to the different preprocessing steps. If the quality plot corresponding to a participant does not seem right then that participant should be discarded.';
            app.ManuallyexcludeparticipantsCheckBox.Tooltip = 'Manually Select participants that should be excluded, usually based on the quality control plots and images';
            app.SelectedparticipantswithgooddataDropDown.Tooltip = 'participants selected for further processing';
            app.Label_5.Tooltip = 'Total number of participants selected for further processing';
            app.SelectedSubjectswithgooddataLabel.Tooltip = 'participants selected for further processing';
            app.AllParticipantsaregoodCheckBox.Tooltip = 'Use data from all participants for further processing.';

            % White matter networks

            % app.GroupWMmaskthresholdLabel.Tooltip = 'Threshold for Creating Group White Matter mask: If the max tissue probability of 100% of participants say that a voxel is White Matter, then it will be included in the group white matter mask';
            % app.GroupWMthresholdTextArea.Tooltip = 'Threshold for Creating Group White Matter mask: If the max tissue probability of 100% of participants say that a voxel is White Matter, then it will be included in the group white matter mask';

            app.KmeansKRangeTextArea_2.Tooltip = 'lower K value for the Grid search';
            app.KmeansWMKRangeTextArea.Tooltip = 'Higher K value for the Grid search';

            app.NumCVfoldsTextAreaLabel.Tooltip = 'Number of Cross validation folds for the optimal K value search';
            app.NumCVfoldsTextArea.Tooltip = 'Number of Cross validation folds for the optimal K value search';
            app.SubsamplingtheWMvoxelFCmatrixLabel.Tooltip = 'A voxel level FC matrix is created to make the WM FNs, It may be possible that the PC used to compute this might not have enough memory to compute the complete voxel level FC matrix. WhiFuN can compute the available memory and decide whether to use the complete FC matrix or use a subsampled version of it to save memory.';

            app.CorpusCallosumNetworksCheckBox.Tooltip = 'If Checked the Group white matter networks wont contain the corpus callosum and Corpus callosum networks will be created  using a predefined Corpus callosum mask';

            % Gray matter Networks
            % app.GroupGMmaskthresholdLabel.Tooltip = 'Threshold for Creating Group Gray Matter mask: If the max tissue probability of 20% of participants say that a voxel is Gray Matter and that it was not included in the White matter mask, then it will be included in the group gray matter mask';
            % app.GroupGMthresholdTextArea.Tooltip = 'Threshold for Creating Group Gray Matter mask: If the max tissue probability of 20% of participants say that a voxel is Gray Matter and that it was not included in the White matter mask, then it will be included in the group gray matter mask';

            app.KmeansKRangeTextArea_3.Tooltip = 'lower K value for the Grid search';
            app.KmeansGMKRangeTextArea.Tooltip = 'Higher K value for the Grid search';
            app.KmeansGMKRangeTextAreaLabel.Tooltip = 'Search range for K-means K values. The average dice coeficient and the elbow will be calculated for the range of K values specified here with cross validation technique as described in (Peer et. al. 2017)';

            app.NumCVfoldsTextAreaLabel.Tooltip = 'Number of Cross validation folds for the optimal K value search';
            app.NumCVfoldsTextArea_2.Tooltip = 'Number of Cross validation folds for the optimal K value search';
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel.Tooltip = 'A voxel level FC matrix is created to make the GM FNs, It may be possible that the PC used to compute this might not have enough memory to compute the complete voxel level FC matrix. WhiFuN can compute the available memory and decide whether to use the complete FC matrix or use a subsampled version of it to save memory.';

            % Extract time Series

            app.NetworksAtlasButton.Tooltip = 'Choose a network or an atlas nifti file (.nii)';
            %             app.AllFreqCheckBox.Tooltip = 'If checked all the no filter will be applied to the averaged timeseries for every Region of interest';
            app.NumberofFreqBandsLabel.Tooltip = 'Choose the number of Frequency bands';
            app.FrequenciesperbandButton.Tooltip = 'Choose the name and the frequency range for each band';

            %             app.sMRIfilenameTextAreaLabel.Tooltip = 'Write the name of the anatomical image without extension (.nii or .nii.gz)';
            %             app.fMRIfilenameLabel.Tooltip = 'Write the name of the functional image without extension (.nii or .nii.gz)';

            %% Analyze FN and FC

            app.AnalyzeFNandFCPanel.Tooltip = 'Analyze Functional Networks and Functional Connectivity';
            app.StatisticsButton.Tooltip = ' A general linear model (glm) is fit to every element in the FC matrix to find the associations of the behavioural data with the FC. This module lets you upload a CSV file containing the participant name/identifier in column 1 and other predictors (participant group, demographics, behavioral scores, participant head motion parameters, etc.) in the other columns.';
            app.CheckFNSymettryButton.Tooltip = 'Check FN calculates how symmetric the FNs are between the two hemispheres';
            app.CompareFNButton.Tooltip = 'Compare FNs can be used to compare two sets of FNs. This function compares the FNs based on the Dice coefficient for two sets user specified FNs. ';

            app.DisplayFNButton.Tooltip = 'After the WM or GM FNs are constructed, a visual display of the FNs can be done by using the display FN module.';
            app.DisplayFCButton.Tooltip = 'Display FC module can be used to observe the FC values';
        end

        % Button pushed function: ParticipantDatafolderButton
        function ParticipantDatafolderButtonPushed(app, event)
            try
                Sub_folder = uigetdir(pwd,'Select the participant folder');
            catch
            end
            if Sub_folder ~= 0
                app.DataPathTextArea.Value = Sub_folder;


                data_path = char(string(app.DataPathTextArea.Value));

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list = whifun_dir( app.comm_subj_name );
                Subj_list = Subj_list(~ismember({Subj_list.name},{'.','..','.DS_Store'}));
                nt_dir = false(1,length(Subj_list));                                        % make a list of other files that are not whifun_directories
                for i = 1:length(Subj_list)
                    if Subj_list(i).isdir ~= 1
                        nt_dir(i) = true;
                    end
                end
                Subj_list(nt_dir) = [];                                                     % Remove all the files that are not whifun_directories (Now Subj_list should have all participant files)
                clear nt_dir                                                                % Clear nt_dir after use

                load_subjects_dropdown(app,Subj_list,'Data_path')
            end
        end

        % Button pushed function: OutputsfolderButton
        function OutputsfolderButtonPushed(app, event)
            try
                output_folder = uigetdir(pwd,'Select the Output folder');
            catch
            end
            if output_folder ~= 0
                app.DataPathTextArea_2.Value = output_folder;
            end
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            try
                [save_name,save_folder] = uiputfile('*.mat','Save Parameters',pwd);
            catch
            end
            if save_folder ~= 0
                save_param(app,save_name,save_folder)
            end
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            try
                [load_name,load_folder] = uigetfile('*.mat','Load Parameters',pwd);
            catch
            end
            %             global app.comm_subj_name app.comm_sess_name app.func_folder_name app.anat_folder_name %#ok<NUSED,GVMIS>
            if load_name ~= 0
                load_param(app,load_folder,load_name)
            end
        end

        % Button pushed function: RunPreprocessingButton
        function RunPreprocessingButtonPushed(app, event)

            %% This code written by Pratik Jain.
            %% This code was written with the help of different preprocessing scripts given by
            %% Dr. Xin Di, Dr. Rakibul Hafeez, Dr. Wang Pan, Donna Chen and Wonbum Sohn
            %% Under guidance of Dr Andrew Micheal and Dr. Bharat Biswal.

            %%          Defining Important paths and Creating Output whifun_directories

            data_path = char(string(app.DataPathTextArea.Value));

            if isempty(data_path)
                msgbox('Please specify the Participant folder, Run Data Check, define Preprocessing parameters and then Run Preprocessing.','Participant Data folder path empty')
                return
            end
            output_folder = char(string(app.DataPathTextArea_2.Value));

            if isempty(output_folder)
                msgbox('Please specify the output_folder, Run Data Check, define Preprocessing parameters and then Run Preprocessing.','Output folder Path empty')
                return
            end
            quality_control_path = fullfile(output_folder,'Quality_control');
            if ~exist(quality_control_path,'dir')
                msgbox('Please Run Initial Data check before Running the Preprocessing and Analyses ', 'Run Initial Data Check')
                return
            end

            load(fullfile(output_folder,'parameters.mat'))                           %#ok<LOAD> % load parameters saved during initial data check, if they were changed after initial data check, the change will be applied later in the code.
            Subj_list_all = load_subjects_all(output_folder,'Subj_list.csv');
            Subj_list = load_subjects(output_folder,'Subj_list.csv');
            
            try
                n_image = [Subj_list.nt];
            catch ex
                if strcmp(ex.identifier,'MATLAB:nonExistentField')
                    msgbox('Please Run Data Check Before Running Preprocessing','Run Data Check First')
                    return
                else
                    write_error(ex,quality_control_path,'Start')
                end
            end

            % spm_path = which('spm');
            % spm_path = spm_path(1:end-5);
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);

            addpath(preproc_code_path)
            addpath(fullfile(preproc_code_path,'whifun_functions'))
            addpath(fullfile(preproc_code_path,'parTicToc'));
            % if app.CorpusCallosumNetworksCheckBox.Value == 1
            %     mkdir(fullfile(output_folder,'Analysis','ttest'))
            % end


            %%          Get the Anatomical and Functional Image Paths

            if strcmp(app.comm_subj_name,'') == 1
                app.comm_subj_name = '*';
            end
            % cd (data_path);
            filter_check = app.FilterCheckBox.Value;
            
            app.func_data_name = char(app.func_data_name) ;       % %   Functional data name
            app.anat_data_name = char(app.anat_data_name) ;     % %   Anatomical data name

           [Cut_pre,Realign_pre,skull_pre,Reg_pre,f_pre,Smooth_pre,Norm_pre] = whifun_prefixes;
            %%          Parameters

            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard
            if n_vol_dis == 0
                Cut_pre = '';
            end
            % head motion control parameters
            max_fd = str2double(app.maxDropDown.Value);                                                               % Maximum translation allowed for Framewize displacement
            mean_fd = str2double(app.meanDropDown.Value);
            greater_than_20 = str2double(app.Greaterthan20DropDown.Value);

            %Segmentation Parameters
            CSF_thres = char(string(app.SegmentationCSFThreshold));
            Seg_dartel_drop = app.SegmentationDropDown.Value;

            switch Seg_dartel_drop
                case 'No Dartel'
                    dartel_ = 0;
                case 'Dartel'
                    dartel_ = 1;
            end

            motion_reg = app.MotionParametersCheckBox.Value;
            % PCA or Mean for WM and CSF timeseries data that will be used as a
            % regressor in temporal Regression
            % 1 --> Will use PCA for getting the CSF Components
            % 0 --> will use mean WM and CSF components (faster)
            Reg_drop = app.NuisanceRegressionDropDown.Value;
            switch Reg_drop
                case 'Mean CSF'
                    pca_for_temp_reg = 0;
                    Reg_CSF = 1;
                    n_pca = 0;
                case 'PCA CSF'
                    pca_for_temp_reg = 1;
                    n_pca = double(string(app.NoofPCAcomponents));                   % number of pca components for WM and CSF (choose a value between 1 and 5)
                    Reg_CSF = 1;
                case 'No CSF Regression'
                    if ~motion_reg
                        Reg_pre = '';
                    end
                        Reg_CSF = 0;
                        pca_for_temp_reg = 0;
                        n_pca = 0;
            end
            if filter_check


                filter_lp = double(string(app.TemporalFilteringHzTextArea.Value));
                filter_hp = double(string(app.FilterTextArea_2.Value));

                if filter_lp > filter_hp
                    error(sprintf('Please check the filter cutoffs, lower_cutoff should be less than higher cutoff, \nbut found otherwise\n'))
                end
            else
                f_pre = '';

            end
            vox = double(string(app.NormalizationvoxeldimmmTextArea.Value));
            smooth_fwhm = double(string(app.FWHMmmTextArea.Value));                                                            % Smoothing Full width half maximum
            smooth_drop = app.SmoothingDropDown.Value;
            switch smooth_drop
                case 'WM-GM Seperate'
                    WM_GM = 1;
                    Smooth_ = 1;
                case 'All Together'
                    WM_GM = 0;
                    Smooth_ = 1;
                case 'No Smoothing'
                    Smooth_pre = '';
                    Smooth_ = 0;
                    WM_GM = 0;
            end
           
            if ~all(mean(n_image) == n_image)
                for_warning = struct2table(Subj_list);
                disp(for_warning)
                warning('The number of timepoints (nt) for all the participants is not the same. The number of time points corresponding to every participant is displayed above')

            end
            %%          Error handeling parameters
            % Ignore any previous results
            % -> 1 If previous preprocessing files exist (whifun_delete them and rerun)
            % -> 0 If previous preprocessing files exits (Keep them and skip)
            %             rm_sub = false(length(app.Subj_list),1);
            over_write = app.OverwriteexistingfilesCheckBox.Value;

            % Saving all the parameters in Output Folder for future
            % reference
            save_param(app,'parameters.mat',output_folder)
            if over_write
                res_ = questdlg('Overwrite is enabled, this will whifun_delete any files that were already created and start everything from start. Want to proceed?','Overwrite enabled','Yes','No','No');
                switch res_
                    case 'No'
                        return
                end
            end

            %   Loop all participants in this group
            %             Subj_list = app.Subj_list; % % This is loaded from the saved parameters file during initial data check
            n_tot = length(Subj_list);
            par_on = app.ParellelProcessingCheckBox.Value;
            if ~par_on
                parforArg = 0; % 0 workers means serial execution
            else
                parforArg = app.NoofWorkersEditField.Value; % Use as many workers as available
                par_p = Par(n_tot);
            end
            
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Title','WhiFuN Preprocessing ','Message','Please wait, See Matlab Command window for progress.',...
                'Indeterminate','on');
            Subj_list_all = whifun_create_fields_preproc(Subj_list_all);
            Subj_list = whifun_create_fields_preproc(Subj_list);
            dq = parallel.pool.DataQueue;
            hWaitbar = waitbar(0, 'No. of Participants done...', 'Name', 'Preprocessing');
            afterEach(dq, @(~) whifun_update_waitbar(hWaitbar, n_tot));

            
            parfor (subji=1:n_tot,parforArg) %par

                Subj_list(subji)= whifun_preproc(quality_control_path,Subj_list(subji),...
                                                    'over_write',over_write,...                                                                        General Inputs 
                                                    'Cut_pre',Cut_pre,'n_vol_dis',n_vol_dis,...                                                       Discard Volumes
                                                    'Realign_pre',Realign_pre,...                                                                     Realignment
                                                    'max_fd',max_fd,'mean_fd',mean_fd,'greater_than_20',greater_than_20,...                           Framewise Displacement
                                                    'skull_pre',skull_pre,...                                                                         Skull stripping
                                                    'Reg_CSF',Reg_CSF,'CSF_thres',CSF_thres,...                                                             CSF MASK func
                                                    'pca_for_temp_reg',pca_for_temp_reg,'n_pca',n_pca,...                                             CSF Timeseries extraction
                                                    'Reg_pre',Reg_pre,'motion_reg',motion_reg,...                                                     Nuisance Regression
                                                    'filter_check',filter_check,'f_pre',f_pre,'filter_lp',filter_lp,'filter_hp',filter_hp,...         Filtering
                                                    'Smooth_',Smooth_,'Smooth_pre',Smooth_pre,'WM_GM_seperate',WM_GM,'smooth_fwhm',smooth_fwhm,...    Smoothing
                                                    'dartel_',dartel_,'Norm_pre',Norm_pre,'vox',vox...                                                Normalization
                                                    );


                send(dq, 1);
             
            end
            close(hWaitbar)
            if par_on
                stop(par_p)
            end

            %% update CSV
            for subji = 1:length(Subj_list)
                Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)) = Subj_list(subji);
                Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)) = Subj_list(subji);

                Subj_list_all = update_csv(Subj_list(subji),Subj_list_all,output_folder);
            end

            Subj_list = load_subjects(output_folder,'Subj_list.csv');

            %% Dartel Normalisation

            % Create Dartel Template

            if dartel_
                [log_fileID,errmsg] = fopen(fullfile(quality_control_path,'logs','Dartel_log_info.txt'),'a');
                %
                if log_fileID == -1
                    error(errmsg)
                end

                try
                    if over_write == 1

                        norm_dir_d = whifun_dir(fullfile(output_folder,'Dartel_group_template','Template_6.nii')) ;
                        if ~isempty(norm_dir_d)
                            whifun_delete(fullfile(norm_dir_d.folder,norm_dir_d.name))
                        end
                        norm_dir_d = [];
                    else
                        norm_dir_d = whifun_dir(fullfile(output_folder,'Dartel_group_template','Template_6.nii')) ;
                    end
                    if isempty(norm_dir_d)
                        disp('Creating Dartel template')
                        norm_op = whifun_dartel(Subj_list);
                        mkdir(fullfile(output_folder,'Dartel_group_template'))
                        copyfile(fullfile(Subj_list(1).anat_folder,'Template_6.nii'),fullfile(output_folder,'Dartel_group_template','Template_6.nii'))
                        fprintf(log_fileID,'#####################################################################################################################\n \n');
                        fprintf(log_fileID, ' Creating Dartel template \n');
                        fprintf(log_fileID,'%s',  norm_op);
                    else
                        disp('Dartel template found, hence skipping this step ');

                    end
                catch exception
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp('Preprocessing has encountered errors in Dartel Create Template, I have saved the variables in the participant folder :-) ')
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                    write_error(exception,quality_control_path, 'Dartel_Template')                % write error to text file and display                % write error to text file, update csv and display

                end

                if log_fileID == -1
                    error(errmsg)
                end

                % Dartel Normalization to MNI space

                try
                    if over_write == 1
                        for subji = 1:length(Subj_list)
                            norm_dir_d = whifun_dir(fullfile(Subj_list(subji).func_folder,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre Subj_list(subji).func_name])) ;
                            if ~isempty(norm_dir_d)
                                whifun_delete(fullfile(norm_dir_d.folder,norm_dir_d.name))
                            end

                        end
                        norm_dir_d = [];
                    else
                        for subji = 1:length(Subj_list)
                            norm_dir_d = whifun_dir(fullfile(Subj_list(subji).func_folder,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre Subj_list(subji).func_name])) ;
                            if isempty(norm_dir_d)
                                break
                            end
                        end
                    end
                    if isempty(norm_dir_d)
                        disp('Dartel Normalization is started')
                        norm_op = whifun_dartel_normalize_smooth(Subj_list,1,Smooth_pre,f_pre,Reg_pre,Realign_pre,Cut_pre,skull_pre,output_folder,vox); % 1  for func
                        fprintf(log_fileID,'#####################################################################################################################\n \n');
                        fprintf(log_fileID, ' Dartel Normalisation \n');
                        fprintf(log_fileID,'%s',  norm_op);
                    else
                        disp('Normalization file found, hence skipping this step');
                    end
                catch exception
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp('Preprocessing has encountered errors in Dartel Normalization Template, I have saved the variables in the participant folder :-) ')
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                    write_error(exception,quality_control_path, 'Dartel_Template')                % write error to text file and display                % write error to text file, update csv and display

                end
                % Anatomical normalization
                try
                    if over_write == 1
                        for subji = 1:length(Subj_list)
                            norm_dir_d = whifun_dir(fullfile(Subj_list(subji).anat_folder,[Norm_pre skull_pre Subj_list(subji).anat_name])) ;
                            if ~isempty(norm_dir_d)
                                whifun_delete(fullfile(norm_dir_d.folder,norm_dir_d.name))
                            end

                        end
                        norm_dir_d = [];
                    else
                        for subji = 1:length(Subj_list)
                            norm_dir_d = whifun_dir(fullfile(Subj_list(subji).anat_folder,[Norm_pre skull_pre Subj_list(subji).anat_name])) ;
                            if isempty(norm_dir_d)
                                break
                            end
                        end
                    end
                    if isempty(norm_dir_d)
                        disp('Dartel Normalization is started')
                        norm_op0 = whifun_dartel_normalize_smooth(Subj_list,0,Smooth_pre,f_pre,Reg_pre,Realign_pre,Cut_pre,skull_pre,output_folder,nan); % 0 for anat
                        norm_op2 = whifun_dartel_normalize_smooth(Subj_list,2,Smooth_pre,f_pre,Reg_pre,Realign_pre,Cut_pre,skull_pre,output_folder,nan); % 2 for anat c1
                        norm_op3 = whifun_dartel_normalize_smooth(Subj_list,3,Smooth_pre,f_pre,Reg_pre,Realign_pre,Cut_pre,skull_pre,output_folder,nan); % 3 for anat c2
                        norm_op4 = whifun_dartel_normalize_smooth(Subj_list,4,Smooth_pre,f_pre,Reg_pre,Realign_pre,Cut_pre,skull_pre,output_folder,nan); % 4 for anat c3
                        fprintf(log_fileID,'#####################################################################################################################\n \n');
                        fprintf(log_fileID, ' Dartel Normalisation skullstrip anat file\n');
                        fprintf(log_fileID,'%s',  norm_op0);
                        fprintf(log_fileID, ' Dartel Normalisation c1 file\n');
                        fprintf(log_fileID,'%s',  norm_op2);
                        fprintf(log_fileID, ' Dartel Normalisation c2 file\n');
                        fprintf(log_fileID,'%s',  norm_op3);
                        fprintf(log_fileID, ' Dartel Normalisation c3 file\n');
                        fprintf(log_fileID,'%s',  norm_op4);
                    else
                        disp('Normalization file found, hence skipping this step');
                    end
                catch exception
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp('Preprocessing has encountered errors in Dartel Normalization Template, I have saved the variables in the participant folder :-) ')
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                    write_error(exception,quality_control_path, 'Dartel_Template')                % write error to text file and display                % write error to text file, update csv and display

                end

            end


            close(d)
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Quality Check','Interpreter','html','Cancelable','on');

            %% ######################################################################################################################################################################################################################
            %% ######################################################################################################################################################################################################################

            %% QC

            Subj_list = Subj_list_all;
            fg = spm_figure('Create','Graphics','Visible','off');
            temp = spm('WinSize','Graphics');
            set(fg,'Position',[temp(1),temp(2),temp(4),temp(3)])
            set(fg,'PaperPosition',[temp(1),temp(2),temp(4),temp(3)])
            for subji = 1:length(Subj_list)
                if d.CancelRequested
                    msg = 'Quality check canceled by the User';
                    disp(msg)
                    return
                end

                d.Value = subji/length(Subj_list);
                d.Message = ['Currently Processing ' Subj_list(subji).name];
                disp('..')
                disp(['Currently Processing ' Subj_list(subji).name])

                Subj_list_1 = Subj_list(subji);
                
                whifun_qc(quality_control_path,Subj_list_1,...
                        'over_write',over_write,...
                        'max_fd',max_fd,'mean_fd',mean_fd,'greater_than_20',greater_than_20,...
                        'Reg_',logical(Reg_CSF),'n_pca',n_pca,'motion_reg',logical(motion_reg),'pca_for_temp_reg',logical(pca_for_temp_reg));
            end
            close(fg)
            close(d)
            save_param(app,'parameters.mat',output_folder)

        end

        % Button pushed function: RunDataCheckButton
        function RunDataCheckButtonPushed(app, event)

            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);

            if isempty(char(app.DataPathTextArea.Value))
                msgbox('Please select participant Folder','Select participant Folder')
                return
            end

            if isempty(char(app.DataPathTextArea_2.Value))
                msgbox('Please select an output folder','Select Output Folder')
                return
            end

            data_path = char(string(app.DataPathTextArea.Value));
            output_folder = char(string(app.DataPathTextArea_2.Value));

            quality_control_path = whifun_addpath_and_create_preproc_folders(preproc_code_path,output_folder);

            % mkdir(fullfile(output_folder,'Analysis','Group_Masks'))

            if exist(fullfile(output_folder,'Subj_list.csv'),"file") && app.Subject_data_folder_details_changed == 1
                resp = questdlg('The Participant data folder path details seems to be changed. This will change the paths in the Subj_list.csv file. Do you want to change Subj_list.csv?','Participant data folder path details seems to be changed','Yes','No','No');

                switch resp
                    case 'No'
                        app.Subject_data_folder_details_changed = 1;
                end

            end
            if ~exist(fullfile(output_folder,'Subj_list.csv'),"file") || app.Subject_data_folder_details_changed == 1
                %%          Get the Anatomical and Functional Image Paths
                
                Subj_list_all = whifun_create_Subj_list_all_from_Subj_folder_details(data_path,app.comm_subj_name,app.comm_sess_name,app.func_folder_name,app.func_data_name,app.anat_folder_name,app.anat_data_name);
                
                app.Subject_data_folder_details_changed = 0;

            else
                Subj_list_all = load_subjects_all(output_folder,'Subj_list.csv',1);
            end

            app.func_data_name = char(app.func_data_name) ;       % %   Functional data name
            app.anat_data_name = char(app.anat_data_name) ;     % %   Anatomical data name

            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Data Check','Interpreter','html','Cancelable','on');

            [report,n_image,tr,voxel_func,voxel_anat,mis_data] =  whifun_check_data(output_folder,Subj_list_all,app.comm_sess_name,app.func_folder_name,app.func_data_name,app.anat_folder_name,app.anat_data_name,d);
            
            save_param(app,'parameters.mat',output_folder)


            Subj_list = load_subjects(output_folder,'Subj_list.csv');
            load_subjects_dropdown(app,Subj_list);
           
            app.DataCheckReportTextArea.Value = report;

            % Plot all the parameters across participants
            whifun_plot_data_check_figures(quality_control_path,n_image,tr,voxel_func,voxel_anat,mis_data)

            close(d)
        end

        % Value changed function: BIDSCheckBox
        function BIDSCheckBoxValueChanged(app, event)
            value = app.BIDSCheckBox.Value;
            if value == 0
                d = uiprogressdlg(app.WhiFuNv32UIFigure,'Title','Waiting for Participant Data Folder Details ','Message','Waiting for Participant Data Folder Details',...
                    'Indeterminate','on');
                app.DialogApp = Subject_data_folder_details(app,app.comm_sess_name,app.func_folder_name,app.anat_folder_name,app.func_data_name,app.anat_data_name);
                close(d)
                app.Subject_data_folder_details_changed = 1;
            elseif value == 1
                answer = questdlg('Do you wish to use the BIDS format? Any changes that you have done for the folder names will be whifun_deleted!',...
                    'BIDS format','Yes','No','See Folder Names','No');
                switch answer
                    case 'Yes'
                        app.comm_sess_name = '';
                        app.func_folder_name = 'func';
                        app.anat_folder_name = 'anat';
                        app.func_data_name = '*_task-rest_*';
                        app.anat_data_name = '*_T1w*';
                        app.Subject_data_folder_details_changed = 1;
                    case 'No'
                        app.BIDSCheckBox.Value = 0;
                        return
                    case 'See Folder Names'
                        app.BIDSCheckBox.Value = 0;

                        d = uiprogressdlg(app.WhiFuNv32UIFigure,'Title','Waiting for Participant Data Folder Details ','Message','Waiting for Participant Data Folder Details ',...
                            'Indeterminate','on');
                        app.DialogApp = Subject_data_folder_details(app,app.comm_sess_name,app.func_folder_name,app.anat_folder_name,app.func_data_name,app.anat_data_name);
                        close(d)
                        app.Subject_data_folder_details_changed = 1;

                end
            end
        end

        % Value changed function: NuisanceRegressionDropDown
        function NuisanceRegressionDropDownValueChanged(app, event)
            value = app.NuisanceRegressionDropDown.Value;

            switch value
                case 'PCA CSF'
                    Position = app.WhiFuNv32UIFigure.Position;
                    app.DialogApp = PCA_regression_parameters(app,app.NoofPCAcomponents,Position);                      % number of pca components for WM and CSF (choose a value between 1 and 5)
                    app.MotionParametersCheckBox.Enable = "on";
                case 'No Regression'
                    app.MotionParametersCheckBox.Value = 0;
                    app.MotionParametersCheckBox.Enable = "off";
                case 'Mean CSF'
                    app.MotionParametersCheckBox.Enable = "on";
            end

        end

        % Button pushed function: DisplayFNButton
        function DisplayFNButtonPushed(app, event)
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'BrainNetViewer_20191031'))
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [cluster_file,cluster_folder] = uigetfile(fullfile(pwd,'*.nii'),'Select the clustering Output file','MultiSelect','on');
                else
                    [cluster_file,cluster_folder] = uigetfile(fullfile(char(app.DataPathTextArea_2.Value),'Analysis','*.nii'),'Select the clustering file','MultiSelect','on');
                end
            catch
            end
            if isnumeric(cluster_file)
                return
            end
            if ischar(cluster_file)
                toold = questdlg('Which toolbox would to like to use for the Display?','Display Toolbox','SPM','BrainNet Viewer','SPM');

                switch toold
                    case 'SPM'
                        %             spm_check_registration(fullfile(WM_cluster_folder,WM_cluster_file));
                        %                         vol = niftiread(fullfile(cluster_folder,cluster_file));
                        %                         roi_levels = unique(vol);
                        %                         roi_levels(roi_levels==0) = [];
                        matlabbatch{1}.spm.util.disp.data = {[fullfile(cluster_folder,cluster_file) ',1']};
                        spm('defaults', 'FMRI');
                        spm_jobman('run', matlabbatch);
                        colormap([0 0 0; hsv])

                    case 'BrainNet Viewer'
                        BrainNet_MapCfg_WhifuN(fullfile(preproc_code_path,'BrainNetViewer_20191031','Data','SurfTemplate','BrainMesh_Ch2withCerebellum.nv'),fullfile(cluster_folder,cluster_file),fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters1.mat'));

                end
            elseif iscell(cluster_file) && length(cluster_file) > 1 && length(cluster_file) <= 4
                if length(cluster_file) == 2
                    spm_check_registration(fullfile(cluster_folder,cluster_file{1}),fullfile(cluster_folder,cluster_file{2}));
                elseif length(cluster_file) == 3
                    spm_check_registration(fullfile(cluster_folder,cluster_file{1}),fullfile(cluster_folder,cluster_file{2}),fullfile(cluster_folder,cluster_file{3}));
                elseif length(cluster_file) == 4
                    spm_check_registration(fullfile(cluster_folder,cluster_file{1}),fullfile(cluster_folder,cluster_file{2}),fullfile(cluster_folder,cluster_file{3}),fullfile(cluster_folder,cluster_file{4}));
                end
                colormap([0 0 0; hsv])
            end

        end

        % Button pushed function: DisplayFCButton
        function DisplayFCButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');
                else
                    path = char(app.DataPathTextArea_2.Value);
                end
                if exist(fullfile(path,'parameters.mat'),'file')

                    load_param(app,path,'parameters.mat')
                else
                    msgbox('Parameter file not found, please run Preprocessing first')
                    return
                end
                if isempty(char(app.DataPathTextArea.Value))
                    data_path = uigetdir(fullfile(pwd),'Select the participant folder');
                else
                    data_path = char(app.DataPathTextArea.Value);
                end
            catch
            end
            if path == 0
                return
            end

            app.DialogApp = whifun_display_FC(app,path,data_path);


        end

        % Button pushed function: CreateGroupMasksButton
        function CreateGroupMasksButtonPushed(app, event)
            output_folder = char(string(app.DataPathTextArea_2.Value));                        % Path of the output folder
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)                                           % If no participants detected notify the user
                msgbox('Please select participants first')
                return
            end
            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file

            grp_thres_wm = double(string(app.GroupWMthresholdTextArea.Value));             % Group threshold on WM
            indi_thres_wm = double(string(app.IndividualWMProbabilitythresholdTextArea.Value));   % Inidividual threshold on WM tissue prob
            grp_thres_gm = double(string(app.GroupGMthresholdTextArea.Value));             % Group threshold on WM
            indi_thres_gm = double(string(app.IndividualGMProbabilitythresholdTextArea.Value));   % Inidividual threshold on WM tissue prob

            over_write = app.OverwriteexistingfilesCheckBox.Value;
            steps_ = 0;
            n_tot = length(Subj_list);
            d_flag = 1;
            tot_steps = 2*n_tot;           
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create Group Masks (WM and GM)','Interpreter','html','Cancelable','on');

            grp_WM_mask_name = 'WMmask_allsubjs.nii.gz';
            grp_GM_mask_name = 'GMmask_allsubjs.nii.gz';
            out_analysis_path = fullfile(output_folder,'Analysis');
            whifun_create_group_mask(out_analysis_path,Subj_list,grp_WM_mask_name,indi_thres_wm,grp_thres_wm,grp_GM_mask_name,indi_thres_gm,grp_thres_gm,over_write,d_flag,d,steps_,tot_steps)
            close(d)
        end

        % Button pushed function: WMGroupMaskButton
        function WMGroupMaskButtonPushed(app, event)
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)                                           % If no participants detected notify the user
                msgbox('Please select participants first')
                return
            end

            try
                [wm_grp_mask_path_name,wm_grp_mask_path_path] = uigetfile('*.nii*','Select the WM Group Mask file (.nii or .nii.gz)');
                
            catch
                return
            end
            wm_grp_mask = whifun_niftiread(fullfile(wm_grp_mask_path_path,wm_grp_mask_path_name));

            levels = unique(wm_grp_mask);
            ideal_levels = [0 1];    
            if all(levels == ideal_levels)
                msgbox('Please Choose a file with Binary values 0 and 1')
                error('Invalid Mask: Please Choose a file with Binary values 0 and 1');
            end
            if wm_grp_mask_path_path ~= 0
                app.grp_wm_mask_path.Value = fullfile(wm_grp_mask_path_path,wm_grp_mask_path_name);
            end
        end

        % Button down function: WMFNTab
        function WMFNTabButtonDown(app, event)
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)                                           % If no participants detected notify the user
                msgbox('Please select participants first')
                return
            end
            if isempty(char(app.grp_wm_mask_path.Value))
                output_folder = char(string(app.DataPathTextArea_2.Value));
                whifun_grp_mask_path = fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs.nii.gz');
                whifun_grp_mask_file = whifun_create_file(0,whifun_grp_mask_path);% Path of the output folder
                if isempty(whifun_grp_mask_file)
                    msgbox('White matter Group mask not found, Either create from the ''Create Group masks'' tab or specify your own WM mask')
                else
                    app.grp_wm_mask_path.Value = whifun_grp_mask_path;
                end
            end
        end

        % Button pushed function: CreateWMFNButton
        function CreateWMFNButtonPushed(app, event)
            %% Clustering WM Functional networks
               
            output_folder = char(string(app.DataPathTextArea_2.Value));                        % Path of the output folder
            over_write = app.OverwriteexistingfilesCheckBox.Value;
            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file

            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)                                           % If no participants detected notify the user
                msgbox('Please select participants first')
                return
            end

            K_range_l = double(string(app.KmeansKRangeTextArea_2.Value));
            K_range_h = double(string(app.KmeansWMKRangeTextArea.Value));

            CV_folds = double(string(app.NumCVfoldsTextArea.Value));

            Corpus_check = app.CorpusCallosumNetworksCheckBox.Value;

            wm_steps = 0;
            n_tot = length(Subj_list);
            d_flag = 1;
            if Corpus_check
                tot_wm_steps = n_tot*6 + (K_range_h - K_range_l) + 5;
                try
                    [~] = Subj_list(1).nt_dis;       
                catch
                    msgbox('The Subj_list.csv file is modified after Preprocessing and has some fields missing (such as nt_dis), Please Run Preprocessing again. If the preprocessing was already done, WhiFuN will skip the steps and just update the CSV file as needed.','Subj_list.csv nt_dis field missing')
                    return
                end
            else
                tot_wm_steps = n_tot*4 + (K_range_h - K_range_l) + 2;
            end
           
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create WM-FNs','Interpreter','html','Cancelable','on');
            sub_sample_choose = app.SubsamplingtheWMvoxelFCmatrixDropDown.Value;

            if Corpus_check
                cc_mask_path = fullfile(preproc_code_path,'Templates','Original_corpus_callosum.nii');     % Get the CC mask path
                resample_mask = 1;
            else
                cc_mask_path = 0;
                resample_mask = 0;
            end
            num_replicates = 10;
            size_chunk = 100;
            WM_or_GM = 'WM';
            group_mask_path = char(app.grp_wm_mask_path.Value);

            whifun_create_FN_Kmeans(output_folder,WM_or_GM,K_range_l,K_range_h,group_mask_path,...
                                    'sub_sample_choose',sub_sample_choose,...
                                    'CV_folds',CV_folds,...
                                    'focus_check',Corpus_check,...
                                    'focus_mask',cc_mask_path,...
                                    'Resample_focus_mask',resample_mask,...
                                    'num_replicates',num_replicates,...
                                    'size_chunk',size_chunk,...
                                    'over_write',over_write,...
                                    'd',d,'d_flag',d_flag,'steps_',wm_steps,'tot_steps',tot_wm_steps)
           
        end

        % Button down function: GMFNTab
        function GMFNTabButtonDown(app, event)
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)                                           % If no participants detected notify the user
                msgbox('Please select participants first')
                return
            end
           if isempty(char(app.grp_gm_mask_path.Value))
               output_folder = char(string(app.DataPathTextArea_2.Value));
               whifun_grp_mask_path = fullfile(output_folder,'Analysis','Group_Masks','GMmask_allsubjs.nii.gz');
               whifun_grp_mask_file = whifun_create_file(0,whifun_grp_mask_path);% Path of the output folder
               if isempty(whifun_grp_mask_file)
                   msgbox('Gray matter group mask not found, Either create from the ''Create Group masks'' tab or specify your own GM mask')
               else
                   app.grp_gm_mask_path.Value = whifun_grp_mask_path;
               end
           end
        end

        % Button pushed function: GMGroupMaskButton
        function GMGroupMaskButtonPushed(app, event)
             if isempty(app.SelectedparticipantswithgooddataDropDown.Items)                                           % If no participants detected notify the user
                msgbox('Please select participants first')
                return
            end

            try
                [gm_grp_mask_path_name,gm_grp_mask_path_path] = uigetfile('*.nii*','Select the GM Group Mask file (.nii or .nii.gz)');
                
            catch
                return
            end
            gm_grp_mask = whifun_niftiread(fullfile(gm_grp_mask_path_path,gm_grp_mask_path_name));

            levels = unique(gm_grp_mask);
            ideal_levels = [0 1];    
            if all(levels == ideal_levels)
                msgbox('Please Choose a file with Binary values 0 and 1')
                error('Invalid Mask: Please Choose a file with Binary values 0 and 1');
            end
            if gm_grp_mask_path_path ~= 0
                app.grp_gm_mask_path.Value = fullfile(gm_grp_mask_path_path,gm_grp_mask_path_name);
            end
        end

        % Button pushed function: CreateGMFNButton
        function CreateGMFNButtonPushed(app, event)
            %% Clustering GM Functional networks
            output_folder = char(string(app.DataPathTextArea_2.Value));
            over_write = app.OverwriteexistingfilesCheckBox.Value;
            
            
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                msgbox('Please select participants first')
                return
            end
                        
            K_range_l = double(string(app.KmeansKRangeTextArea_3.Value));
            K_range_h = double(string(app.KmeansGMKRangeTextArea.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea_2.Value));

            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create GM-FNs','Interpreter','html','Cancelable','on');
            d_flag = 1;
            n_tot = length(Subj_list);
            gm_steps = 0;
            tot_gm_steps = n_tot*4 + (K_range_h - K_range_l) + 2;
            focus_check = 0;
            focus_mask = 0;
            num_replicates = 10;
            size_chunk = 100;
            sub_sample_choose = app.SubsamplingtheGMvoxelFCmatrixDropDown.Value;
            WM_or_GM = 'GM';
            group_mask_path = char(app.grp_gm_mask_path.Value);
            whifun_create_FN_Kmeans(output_folder,WM_or_GM,K_range_l,K_range_h,group_mask_path,...
                                    'sub_sample_choose',sub_sample_choose,...
                                    'CV_folds',CV_folds,...
                                    'focus_check',focus_check,...
                                    'focus_mask',focus_mask,...
                                    'num_replicates',num_replicates,...
                                    'size_chunk',size_chunk,...
                                    'over_write',over_write,...
                                    'd',d,'d_flag',d_flag,'steps_',gm_steps,'tot_steps',tot_gm_steps)
            
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');

                    load_param(app,path,'parameters.mat')

                else
                    path = char(app.DataPathTextArea_2.Value);
                end
            catch
            end
            if path == 0
                return
            end


            Subj_list = load_subjects(path,'Subj_list.csv');
            app.DialogApp = whifun_statistics(app,path,Subj_list,app.OverwriteexistingfilesCheckBox.Value);

        end

        % Button pushed function: CompareFNButton
        function CompareFNButtonPushed(app, event)
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [file_name1,path1] = uigetfile(fullfile(pwd,'*.nii*'),'Select the first Network');
                else
                    [file_name1,path1] = uigetfile(fullfile(app.DataPathTextArea_2.Value,'*.nii*'),'Select the first Network');
                end
            catch
            end
            if path1 == 0
                return
            end

            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [file_name2,path2] = uigetfile(fullfile(pwd,'*.nii*'),'Select the second network');
                else
                    [file_name2,path2] = uigetfile(fullfile(preproc_code_path,'*.nii*'),'Select the second network');
                end
            catch
            end
            if path2 == 0
                return
            end

            addpath(fullfile(preproc_code_path,'whifun_functions'))
            [~,~] = dice_iou(fullfile(path1,file_name1),fullfile(path2,file_name2),0,1);


        end

        % Value changed function: FilterCheckBox
        function FilterCheckBoxValueChanged(app, event)
            value = app.FilterCheckBox.Value;
            if value
                app.TemporalFilteringHzTextArea.Enable = "on";
                app.FilterTextArea_2.Enable = "on";
            else
                app.TemporalFilteringHzTextArea.Enable = "off";
                app.FilterTextArea_2.Enable = "off";
            end
        end

        % Button pushed function: FrequenciesperbandButton
        function FrequenciesperbandButtonPushed(app, event)
            no_f_bands = app.NumberofFreqBandsSpinner.Value;

            app.DialogApp = freq_per_band(app,no_f_bands,app.band1_name,app.band1_lp,app.band1_hp,app.band2_name,app.band2_lp,app.band2_hp,...
                app.band3_name,app.band3_lp,app.band3_hp,app.band4_name,app.band4_lp,app.band4_hp,...
                app.band5_name,app.band5_lp,app.band5_hp,app.band6_name,app.band6_lp,app.band6_hp);


        end

        % Button pushed function: ExtractROITImeseriesButton
        function ExtractROITImeseriesButtonPushed(app, event)
            if app.frequencyspecificCheckBox.Value
                ROI_path = char(app.DataPathTextArea_3.Value);
                [~,ROI_name,~] = fileparts(ROI_path);
                [~,ROI_name,~] = fileparts(ROI_name);
                over_write = app.OverwriteexistingfilesCheckBox.Value;

                if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                    msgbox('Please select participants first')
                    return
                end

                try
                    if isempty(char(app.DataPathTextArea_2.Value))
                        output_folder = uigetdir(pwd,'Select the Output folder');
                        app.DataPathTextArea_2.Value = output_folder;
                    else
                        output_folder = char(app.DataPathTextArea_2.Value);
                    end
                catch
                end
                Subj_list = load_subjects(output_folder,'Subj_list.csv');
                Subj_list_all = load_subjects_all(output_folder,'Subj_list.csv');

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                %%          These values can be changed but default is recommended

                at_steps = 0;
                tot_at_steps = length(Subj_list);
                d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                    'Title','Create Average ROI timeseries using Atlas','Interpreter','html','Cancelable','on');
                d_flag = 1;

                band_info = struct;
                for i = 1:app.NumberofFreqBandsSpinner.Value
                    eval(['filter_lp = app.band',num2str(i),'_lp;']); %#ok<*EVLDOT>
                    eval(['filter_hp = app.band',num2str(i),'_hp;']);

                    eval(['band_name = app.band',num2str(i),'_name;'])

                    band_info(i).band_name = band_name;
                    band_info(i).lp = filter_lp;
                    band_info(i).hp = filter_hp;
                end
                QC_plots = app.SaveQCimagesofregionshavingmissingdataCheckBox.Value;
                out_folder = fullfile(output_folder,'Analysis','Freq_Specific_ROI_Timeseries',ROI_name);
                [~,Subj_list] = whifun_create_avg_ts(out_folder,ROI_path,Subj_list,'final_func_MNI',band_info,QC_plots,over_write,d_flag,d,at_steps,tot_at_steps);
                for subji = 1:length(Subj_list)
                    Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)) = Subj_list(subji);
                    Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)) = Subj_list(subji);

                    Subj_list_all = update_csv(Subj_list(subji),Subj_list_all,output_folder);
                end
                
                close(d)


            else
                output_folder = char(string(app.DataPathTextArea_2.Value));
                ROI_path = char(app.DataPathTextArea_3.Value);
                [~,ROI_name,~] = fileparts(ROI_path);
                [~,ROI_name,~] = fileparts(ROI_name);
                over_write = app.OverwriteexistingfilesCheckBox.Value;
                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);
                out_folder = fullfile(output_folder,'Analysis','ROI_Timeseries',ROI_name);

                if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                    msgbox('Please select participants first')
                    return
                end

                if ~exist(fullfile(out_folder),'dir')
                    mkdir(fullfile(out_folder))
                end
                Subj_list = load_subjects(output_folder,'Subj_list.csv');
                Subj_list_all = load_subjects_all(output_folder,'Subj_list.csv');

                QC_plots = app.SaveQCimagesofregionshavingmissingdataCheckBox.Value;

                disp('##########################################################################################')
                disp(['Computing Average ROI timeseries using : ' char(ROI_name)])
                at_steps = 0;
                tot_at_steps = length(Subj_list);
                d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                    'Title','Create Average ROI timeseries using Atlas','Interpreter','html','Cancelable','on');
                d_flag = 1;
                [~,Subj_list] = whifun_create_avg_ts(out_folder,ROI_path,Subj_list,'final_func_MNI',[],QC_plots,over_write,d_flag,d,at_steps,tot_at_steps);
                for subji = 1:length(Subj_list)
                    Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)) = Subj_list(subji);
                    Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)) = Subj_list(subji);

                    Subj_list_all = update_csv(Subj_list(subji),Subj_list_all,output_folder);
                end
                close(d)
            end
        end

        % Button pushed function: NetworksAtlasButton
        function NetworksAtlasButtonPushed(app, event)
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            try
                [atlas_name,atlas_path] = uigetfile(fullfile(preproc_code_path,'Atlases','*.nii*'),'Select the Network/Atlas Nifti file');
                if strcmp(atlas_name(end-2:end),'.gz')
                    gunzip(fullfile(atlas_path,atlas_name))
                    atlas_name = atlas_name(1:end-3);
                end
            catch
            end
            if atlas_path ~= 0
                app.DataPathTextArea_3.Value = fullfile(atlas_path,atlas_name);
            end
        end

        % Button pushed function: SelectParticipantsButton
        function SelectParticipantsButtonPushed(app, event)
            %             if ~isempty(app.DataPathTextArea.Value)
            %                 data_path = app.DataPathTextArea.Value;
            %                 output_folder =
            %             end

            if ~isempty(char(app.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    return
                end
                output_folder = char(app.DataPathTextArea_2.Value);
                data_path = char(app.DataPathTextArea.Value);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))


                %                 if ~exist(fullfile(output_folder,'Subj_list.csv'),"file")
                %%          Get the Anatomical and Functional Image Paths

                if exist(fullfile(output_folder,'Subj_list.csv'),"file")

                    responce_ = questdlg('Subj_list.csv file found, do you want to whifun_delete it and start again?','Subj_list.csv file found','Yes','No','No');

                    switch responce_
                        case 'Yes'
                            whifun_delete(fullfile(output_folder,'Subj_list.csv'))
                        case 'No'
                            return
                    end

                end
                while 1
                    Subjects = uigetfile_n_dir(data_path,'Select participants');
                    [filepath, ~] = fileparts(Subjects(1));
                    if isempty(Subjects)
                        msgbox('No participants selected!')
                        return
                    end

                    if strcmp(filepath,data_path)
                        break
                    end
                    if isempty(Subjects)
                        return
                    end
                    f = questdlg('Select participant folders, which are in the Participant Data Folder only.','Wrong folder selected','Try again','Cancel','Try again');
                    switch f
                        case "Cancel"
                            return
                    end
                end


                %%          Get the Anatomical and Functional Image Paths

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list_all = whifun_dir( app.comm_subj_name );
                Subj_list_all = Subj_list_all(~ismember({Subj_list_all.name},{'.','..','.DS_Store'}));
                nt_dir = false(1,length(Subj_list_all));                                        % make a list of other files that are not whifun_directories
                for i = 1:length(Subj_list_all)
                    if Subj_list_all(i).isdir ~= 1
                        nt_dir(i) = true;

                    end
                    Subj_list_all(i).error = 0;

                    Subj_list_all(i).manual_ex = 1;
                    Subj_list_all(i).motion_ex = 0;
                    Subj_list_all(i).func_folder = fullfile(Subj_list_all(i).folder, Subj_list_all(i).name ,char(app.comm_sess_name),char(app.func_folder_name));
                    Subj_list_all(i).func_name = [char(app.func_data_name) '.nii*'];
                    Subj_list_all(i).anat_folder = fullfile(Subj_list_all(i).folder, Subj_list_all(i).name,char(app.comm_sess_name),char(app.anat_folder_name));
                    Subj_list_all(i).anat_name = [char(app.anat_data_name) '.nii*'];
                end
                Subj_list_all(nt_dir) = [];                                                     % Remove all the files that are not whifun_directories (Now Subj_list should have all participant files)
                clear nt_dir                                                                % Clear nt_dir after use


                Subj_names = struct2cell(Subj_list_all);
                Subj_names(2:end,:) = [];
                idx = zeros(1,length(Subjects));
                for i = 1:length(Subjects)


                    [file_path,name] = fileparts(char(Subjects(i)));

                    if strcmp(file_path,char(app.DataPathTextArea.Value))
                        idx(i) = find(string(name)==string(Subj_names));
                        Subj_list_all(idx(i)).manual_ex = 0;
                    else
                        msgbox('Choose participants from the Participants Data folder only')
                    end
                end

                if ~isfolder(output_folder)
                    mkdir(output_folder)
                end

                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                [Subj_list,~] = load_subjects(output_folder,"Subj_list.csv");
                load_subjects_dropdown(app,Subj_list)
                app.AllfoldersareParticipantsCheckBox.Value = 0;
            else
                msgbox('Please select the participant Data folder first')

            end
        end

        % Value changed function: DataPathTextArea
        function DataPathTextAreaValueChanged(app, event)
            data_path = char(string(app.DataPathTextArea.Value));

            if strcmp(app.comm_subj_name,'') == 1
                app.comm_subj_name = '*';
            end
            if ~isempty(data_path)
                data_path = data_path(1,:);
            end
            data_folder = whifun_dir(data_path);
            if isempty(data_folder)
                msgbox('Does not seem a valid path, please try again','Invalid participant Data folder')
                app.DataPathTextArea.Value = '';
                return
            end

             if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    app.AllfoldersareParticipantsCheckBox.Value = 0;
                    return
             end

            % check if Subj_list.csv present
            Subj_list_dir = whifun_dir(fullfile(char(string(app.DataPathTextArea_2.Value)),'Subj_list.csv'));
            if ~isempty(Subj_list_dir)

                responce_ = questdlg('Subj_list.csv file found, do you want to whifun_delete it and start again?','Subj_list.csv file found','Yes','No','No');

                switch responce_
                    case 'Yes'
                        whifun_delete(fullfile(char(string(app.DataPathTextArea_2.Value)),'Subj_list.csv'))
                    case 'No'
                        Subj_list = load_subjects_all(char(string(app.DataPathTextArea_2.Value)),'Subj_list.csv');
                        data_folder = Subj_list(1).folder;
                        app.DataPathTextArea.Value = data_folder;
                        return
                end

            end

            Subj_list = whifun_dir( app.comm_subj_name );
            Subj_list = Subj_list(~ismember({Subj_list.name},{'.','..','.DS_Store'}));
            nt_dir = false(1,length(Subj_list));                                        % make a list of other files that are not whifun_directories
            for i = 1:length(Subj_list)
                if Subj_list(i).isdir ~= 1
                    nt_dir(i) = true;
                end
            end
            Subj_list(nt_dir) = [];                                                     % Remove all the files that are not whifun_directories (Now Subj_list should have all participant files)
            clear nt_dir                                                                % Clear nt_dir after use

            load_subjects_dropdown(app,Subj_list,'Data_path')


        end

        % Value changed function: AllParticipantsaregoodCheckBox
        function AllParticipantsaregoodCheckBoxValueChanged(app, event)
            value = app.AllParticipantsaregoodCheckBox.Value;
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end

            if value
                try
                    Subj_list = load_subjects(path,'Subj_list.csv');
                    if isempty(Subj_list)
                        msgbox('No participants found, please specify the correct participant folder path and run initial check.')
                    end
                    load_subjects_dropdown2(app,Subj_list)
                    load_param(app,path,'parameters.mat')
                    app.ManuallyexcludeparticipantsCheckBox.Value = 0;
                catch
                    msgbox('Error loading parameters or participants, make sure the correct output folder is specified. ','Error loading parameters')
                end
            else

                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');
                else
                    path = char(app.DataPathTextArea_2.Value);
                end
                load_param(app,path,'parameters.mat')

                Subj_list_all = load_subjects_all(app,path,'Subj_list.csv');
                if ~isempty(Subj_list_all)

                    temp_path = mfilename('fullpath');                % path of the toolbox
                    preproc_code_path = fileparts(temp_path);
                    addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
                    data_path = Subj_list_all(1).folder;
                    Subjects = uigetfile_n_dir(char(data_path),'Select participants to exclude');
                    Subj_names = struct2cell(Subj_list_all);
                    Subj_names(2:end,:) = [];
                    %                 idx = zeros(1,length(Subjects));

                    for i = 1:length(Subjects)

                        [file_path,name] = fileparts(char(Subjects(i)));

                        if strcmp(file_path,char(app.DataPathTextArea.Value))

                            if sum(name==string(Subj_names))
                                idx = name==string(Subj_names);


                                if Subj_list_all(idx).manual_ex ~= 1
                                    Subj_list_all(idx).manual_ex = 1;
                                else
                                    msgbox([name ' participant Already removed'])
                                end
                            end
                        else
                            msgbox('Choose participants from the Participants Data folder only')
                        end

                    end

                    rep = questdlg('This participant list will be saved for any future analysis. Continue?','Participant list save','Yes','No','Yes');

                    switch rep
                        case 'Yes'
                            writetable(struct2table(Subj_list_all), fullfile(path,"Subj_list.csv"))
                            Subj_list = load_subjects(path,'Subj_list.csv');
                            load_subjects_dropdown2(app,Subj_list)
                            app.AllParticipantsaregoodCheckBox.Value = 0;
                            app.ManuallyexcludeparticipantsCheckBox.Value = 1;
                        case 'No'
                            app.AllParticipantsaregoodCheckBox.Value = 0;
                            app.ManuallyexcludeparticipantsCheckBox.Value = 0;
                            return
                    end

                end
            end
        end

        % Button pushed function: CheckFNSymettryButton
        function CheckFNSymettryButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [file_name,path] = uigetfile(fullfile(pwd,'*.nii'),'Select the first Network');
                else
                    [file_name,path] = uigetfile(fullfile(app.DataPathTextArea_2.Value,'Analysis','*.nii'),'Select the Network');
                end
            catch
            end
            if path == 0
                return
            end

            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'whifun_functions'))

            clustering_solution = niftiread(fullfile(path,file_name));

            Dice_coef_LR = get_symettry(app,clustering_solution);


            Dice_coef_LR_indi = get_symettry_indi(app,clustering_solution);

            figure;
            h = heatmap(round(Dice_coef_LR_indi,2));clim([0 1]);colormap('parula');
            h.NodeChildren(3).Title.Interpreter = 'None';
            h.Title = ['The symmetry dice coefficient for entire network is ', num2str(Dice_coef_LR) ' file: ' file_name];
            h.XLabel = 'Left Hemisphere';
            h.YLabel = 'Right Hemisphere';

            %             title(gca,file_name,'Interpreter','none')

            %             ylabel('Right Hemisphere')
            %             xlabel('Left Hemisphere')
        end

        % Button pushed function: manuallycoregisterparticipantsButton
        function manuallycoregisterparticipantsButtonPushed(app, event)
            
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')
            output_folder = char(app.DataPathTextArea_2.Value);

            %%          Parameters

            % PCA or Mean for WM and CSF timeseries data that will be used as a
            % regressor in temporal Regression
            % 1 --> Will use PCA for getting the CSF Components
            % 0 --> will use mean WM and CSF components (faster)            

            Subj_list_all = load_subjects_all(path,'Subj_list.csv');


            if ~isempty(Subj_list_all)


                app.AllParticipantsaregoodCheckBox.Value = 0;
                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
                data_path = Subj_list_all(1).folder;
                subject = uigetdir(char(data_path),'Select participants to coregister manually');

                if isnumeric(subject)
                    return
                end
                [subject_folder,subject_name] = fileparts(subject);
                Subj_names = struct2cell(Subj_list_all);
                Subj_names(2:end,:) = [];
                %                 idx = zeros(1,length(Subjects));


                if strcmp(subject_folder,char(app.DataPathTextArea.Value))

                    if sum(subject_name==string(Subj_names))
                        idx = subject_name==string(Subj_names);

                        if ~isfield(Subj_list_all(idx),'manual_coreg')
                            Subj_list_all(idx).manual_coreg = 0;
                        elseif isempty(Subj_list_all(idx).manual_coreg)
                            Subj_list_all(idx).manual_coreg = 0;
                        end

                        now_anat_path = Subj_list_all(idx).skull_stripped_anat_native;%fullfile(Subj_list_all(subji).anat_folder,[skull_pre Subj_list_all(subji).func_name]);

                        now_func_path = Subj_list_all(idx).coregistered_func_native;%(fullfile(Subj_list_all(subji).func_folder,[Realign_pre Cut_pre Subj_list_all(subji).func_name])) ;

                        if isempty(dir(now_func_path))
                            msgbox(['Realignment file not found in the functional whifun_directory. The following was searched for: ' now_func_path])
                        elseif length(dir(now_func_path)) > 1
                            [file,path] = uigetfile(now_func_path,'Multiple functional realigned files found please choose one');

                            now_func_path = whifun_dir(fullfile(path,file));
                        else
                            now_func_path = whifun_dir(now_func_path);
                        end

                        if isempty(dir(now_anat_path))
                            msgbox(['Skull Stripped file not found in the anatomical whifun_directory. The following was searched for: ' now_anat_path])
                        elseif length(dir(now_anat_path)) > 1
                            [file,path] = uigetfile(now_anat_path,'Multiple anatomical skull stripped files found please choose one');

                            now_anat_path = whifun_dir(fullfile(path,file));
                        else
                            now_anat_path = whifun_dir(now_anat_path);
                        end

                        response_ = questdlg('This will whifun_delete all preprocessing files created after realignment step, do you want to proceed?','whifun_delete files created after co-registeration','Yes','No','Yes');

                        switch response_
                            case 'No'
                                return
                        end

                        % Quality Control
                        % dsplay the anatomical and the Realigned func image after co-registeration
                        spm_check_registration(char(fullfile(now_anat_path.folder,now_anat_path.name),[fullfile(now_func_path.folder,now_func_path.name),',1']))

                        % Display the participant's ID
                        spm_orthviews('Caption', 1, Subj_list_all(idx).name);

                        spm_orthviews('reorient','context_init', 2);
                        Subj_list_all(idx).manual_coreg = 1;

                        % deleting any previous preprocessing file
                        coreg_qc = whifun_dir(fullfile(output_folder,'Quality_control','d_Co_registeration','*','*',[subject_name '*.png']));

                        if length(coreg_qc)>=1
                            for i = 1:length(coreg_qc)
                                whifun_delete(fullfile(coreg_qc(i).folder,coreg_qc(i).name))
                            end
                        end

                        csf_timeseries = whifun_dir(char(Subj_list_all(idx).nuisance_regression_csf_covariates));

                        if isscalar(csf_timeseries)
                            whifun_delete(fullfile(csf_timeseries.folder,csf_timeseries.name))
                        end

                        mask_qc = whifun_dir(fullfile(output_folder,'Quality_control','e_CSF_Masks_for_Regression','*','*',[subject_name '*.png']));

                        if length(mask_qc)>=1
                            for i = 1:length(mask_qc)
                                whifun_delete(fullfile(mask_qc(i).folder,mask_qc(i).name))
                            end
                        end

                        Reg_file = whifun_dir(char(Subj_list_all(idx).nuisance_regressed_func_native));

                        if isscalar(Reg_file)
                            whifun_delete(fullfile(Reg_file.folder,Reg_file.name))
                        end

                        reg_qc = whifun_dir(fullfile(output_folder,'Quality_control','f_Nuisance_Regression','*','*',[subject_name '*.png']));

                        if isscalar(reg_qc)
                            whifun_delete(fullfile(reg_qc.folder,reg_qc.name))
                        end

                        reg_qc = whifun_dir(fullfile(output_folder,'Quality_control','f_Nuisance_Regression','*','*','*',[subject_name '*.png']));

                        if length(reg_qc)>=1
                            for i = 1:length(reg_qc)
                                whifun_delete(fullfile(reg_qc(i).folder,reg_qc(i).name))
                            end
                        end

                        reg_qc = whifun_dir(fullfile(output_folder,'Quality_control','f_Nuisance_Regression','*','*','*','*',[subject_name '*.png']));

                        if length(reg_qc)>=1
                            for i = 1:length(reg_qc)
                                whifun_delete(fullfile(reg_qc(i).folder,reg_qc(i).name))
                            end
                        end

                        filter_file = whifun_dir(char(Subj_list_all(idx).filtered_func_native));

                        if isscalar(filter_file)
                            whifun_delete(fullfile(filter_file.folder,filter_file.name))
                        end

                        f_qc = whifun_dir(fullfile(output_folder,'Quality_control','g_Filtering','*','*',[subject_name '*.png']));

                        if length(f_qc)>=1
                            for i = 1:length(f_qc)
                                whifun_delete(fullfile(f_qc(i).folder,f_qc(i).name))
                            end
                        end

                        smooth_file = whifun_dir(char(Subj_list_all(idx).smoothed_func));

                        if isscalar(smooth_file)
                            whifun_delete(fullfile(smooth_file.folder,smooth_file.name))
                        end

                        s_qc = whifun_dir(fullfile(output_folder,'Quality_control','h_Smoothing','*','*',[subject_name '*.png']));

                        if length(s_qc)>=1
                            for i = 1:length(s_qc)
                                whifun_delete(fullfile(s_qc(i).folder,s_qc(i).name))
                            end
                        end


                        norm_file = whifun_dir(char(Subj_list_all(idx).func_MNI));

                        if isscalar(norm_file)
                            whifun_delete(fullfile(norm_file.folder,norm_file.name))
                        end

                        n_qc = whifun_dir(fullfile(output_folder,'Quality_control','i_Final_func_MNI','*','*',[subject_name '*.png']));

                        if length(n_qc)>=1
                            for i = 1:length(n_qc)
                                whifun_delete(fullfile(n_qc(i).folder,n_qc(i).name))
                            end
                        end

                        n_qc = whifun_dir(fullfile(output_folder,'Quality_control','i_Final_func_MNI','*','*','*',[subject_name '*.png']));

                        if length(n_qc)>=1
                            for i = 1:length(n_qc)
                                whifun_delete(fullfile(n_qc(i).folder,n_qc(i).name))
                            end
                        end


                        rest_mask = whifun_dir(char(Subj_list_all(idx).func_mask_MNI));

                        if isscalar(rest_mask)
                            whifun_delete(fullfile(rest_mask.folder,rest_mask.name))
                        end

                        ts_qc = whifun_dir(fullfile(output_folder,'Quality_control','j_Time_series_check',[subject_name '*.png']));

                        if isscalar(ts_qc)
                            whifun_delete(fullfile(ts_qc.folder,ts_qc.name))
                        end
                        now_anat_path = whifun_dir(char(Subj_list_all(idx).skull_stripped_anat_native));%fullfile(Subj_list_all(subji).anat_folder,[skull_pre Subj_list_all(subji).func_name]);

                        deep_wm_mask = whifun_dir(fullfile(Subj_list_all(idx).anat_folder,['deep_num_er-4_*c2',now_anat_path.name]));

                        if length(deep_wm_mask)>=1
                            for ii = 1:length(deep_wm_mask)
                                whifun_delete(fullfile(deep_wm_mask(ii).folder,deep_wm_mask(ii).name))
                            end
                        end
                        [~,anat_name,~] = fileparts(now_anat_path.name);
                        [~,anat_name,~] = fileparts(anat_name);

                        [~,wfunc_name,ext] = fileparts(Subj_list_all(idx).func_MNI);
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['wc2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['deep_wc2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['wc1',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['wc3',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['seed_corr_map_Seed_lh_cort_aud_64_-12_2',wfunc_name,ext]))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['seed_corr_map_Seed_lh_pcc_5_-49_40_',wfunc_name,ext]))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['seed_corr_map_Seed_rh_cort_vis_-4_-91_-3',wfunc_name,ext]))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['c2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['deep_c2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['c1',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        whifun_delete(fullfile(Subj_list_all(idx).func_folder,['c3',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        
                    end
                else
                    msgbox('Choose participants from the Participants Data folder only')
                end

            end


            %             'spm_orthviews('reorient','context_init', 1);'
        end

        % Button pushed function: 
        % DetermineFramewiseDisplacementthresholdsButton
        function DetermineFramewiseDisplacementthresholdsButtonPushed(app, event)

            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')
            output_folder = char(app.DataPathTextArea_2.Value);

            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard


            Subj_list_all = load_subjects_all(path,'Subj_list.csv');
            if ~isempty(Subj_list_all)
                app2 = motion_threshold_select(app,Subj_list_all,n_vol_dis,output_folder);
                d = uiprogressdlg(app.WhiFuNv32UIFigure,'Title','Select Motion Thresholds app open','Message','Waiting to get the Motion thresholds',...
                    'Indeterminate','on');
                uiwait(app2.MotionthresholdUIFigure);
                load_param(app,output_folder,'parameters.mat')
                close(d)
                app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
            else
                msgbox('No participants found. Please run the intial data check and try again.')
            end


        end

        % Button pushed function: 
        % DeletePreprocessingfilesfromaparticularstepButton
        function DeletePreprocessingfilesfromaparticularstepButtonPushed(app, event)
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')
            output_folder = char(app.DataPathTextArea_2.Value);
            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard
            Subj_list_all = load_subjects_all(path,'Subj_list.csv');
            if ~isempty(Subj_list_all)
                rerunpreproc(app,Subj_list_all,n_vol_dis,output_folder)
            else
                msgbox('No participants found. Please run the intial data check and try again.')
            end


        end

        % Button pushed function: ParticipantsInfoButton
        function ParticipantsInfoButtonPushed(app, event)

            if ~isempty(char(app.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    return
                end
                output_folder = char(app.DataPathTextArea_2.Value);
                %                 data_path = char(app.DataPathTextArea.Value);

                if exist(fullfile(output_folder,'Subj_list.csv'),"file")
                    Sub_info(app,output_folder,preproc_code_path)

                else
                    msgbox('Please Select participants first','Select Participants')
                end
            else
                msgbox('Please select the Participant Data folder first','Select participant data folder')

            end
        end

        % Value changed function: AllfoldersareParticipantsCheckBox
        function AllfoldersareParticipantsCheckBoxValueChanged(app, event)
            value = app.AllfoldersareParticipantsCheckBox.Value;
            if ~isempty(char(app.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    app.AllfoldersareParticipantsCheckBox.Value = 0;
                    return
                end
                output_folder = char(app.DataPathTextArea_2.Value);
                data_path = char(app.DataPathTextArea.Value);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))


                %                 if ~exist(fullfile(output_folder,'Subj_list.csv'),"file")
                %%          Get the Anatomical and Functional Image Paths

                if exist(fullfile(output_folder,'Subj_list.csv'),"file")

                    responce_ = questdlg('Subj_list.csv file found, do you want to whifun_delete it and start again?','Subj_list.csv file found','Yes','No','No');

                    switch responce_
                        case 'Yes'
                            whifun_delete(fullfile(output_folder,'Subj_list.csv'))
                        case 'No'
                            return
                    end

                end
                if ~value
                    Subjects = uigetfile_n_dir(data_path,'Select participants');

                    if isempty(Subjects)
                        msgbox('No participants selected!')
                        return
                    end
                else
                    Subjects = whifun_dir(data_path);
                end
                %%          Get the Anatomical and Functional Image Paths

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list_all = whifun_dir( app.comm_subj_name );
                Subj_list_all = Subj_list_all(~ismember({Subj_list_all.name},{'.','..','.DS_Store'}));

                nt_dir = false(1,length(Subj_list_all));                                        % make a list of other files that are not whifun_directories
                for i = 1:length(Subj_list_all)
                    if Subj_list_all(i).isdir ~= 1
                        nt_dir(i) = true;

                    end
                    Subj_list_all(i).error = 0;
                    if ~value
                        Subj_list_all(i).manual_ex = 1;
                    else
                        Subj_list_all(i).manual_ex = 0;
                    end
                    Subj_list_all(i).motion_ex = 0;
                    Subj_list_all(i).func_folder = fullfile(Subj_list_all(i).folder, Subj_list_all(i).name ,char(app.comm_sess_name),char(app.func_folder_name));
                    Subj_list_all(i).func_name = [char(app.func_data_name) '.nii*'];
                    Subj_list_all(i).anat_folder = fullfile(Subj_list_all(i).folder, Subj_list_all(i).name,char(app.comm_sess_name),char(app.anat_folder_name));
                    Subj_list_all(i).anat_name = [char(app.anat_data_name) '.nii*'];
                end
                Subj_list_all(nt_dir) = [];                                                     % Remove all the files that are not whifun_directories (Now Subj_list should have all participant files)
                clear nt_dir                                                                % Clear nt_dir after use


                if ~value
                    Subj_names = struct2cell(Subj_list_all);
                    Subj_names(2:end,:) = [];
                    idx = zeros(1,length(Subjects));
                    for i = 1:length(Subjects)


                        [file_path,name] = fileparts(char(Subjects(i)));

                        if strcmp(file_path,char(app.DataPathTextArea.Value))
                            idx(i) = find(string(name)==string(Subj_names));
                            Subj_list_all(idx(i)).manual_ex = 0;
                        else
                            msgbox('Choose participants from the Participants Data folder only')
                        end
                    end

                end
                if ~isfolder(output_folder)
                    mkdir(output_folder)
                end
                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                [Subj_list,~] = load_subjects(output_folder,"Subj_list.csv");
                load_subjects_dropdown(app,Subj_list)

            else
                msgbox('Please select the Participant Data folder first')
                app.AllfoldersareParticipantsCheckBox.Value = 0;
            end
        end

        % Value changed function: ManuallyexcludeparticipantsCheckBox
        function ManuallyexcludeparticipantsCheckBoxValueChanged(app, event)
            value = app.ManuallyexcludeparticipantsCheckBox.Value;

            if value
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');
                else
                    path = char(app.DataPathTextArea_2.Value);
                end
                load_param(app,path,'parameters.mat')

                Subj_list_all = load_subjects_all(path,'Subj_list.csv');
                if ~isempty(Subj_list_all)
                    app.AllParticipantsaregoodCheckBox.Value = 0;
                    temp_path = mfilename('fullpath');                % path of the toolbox
                    preproc_code_path = fileparts(temp_path);
                    addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
                    data_path = Subj_list_all(1).folder;
                    Subjects = uigetfile_n_dir(char(data_path),'Select participants to exclude');
                    Subj_names = struct2cell(Subj_list_all);
                    Subj_names(2:end,:) = [];
                    %                 idx = zeros(1,length(Subjects));

                    for i = 1:length(Subjects)

                        [file_path,name] = fileparts(char(Subjects(i)));

                        if strcmp(file_path,char(app.DataPathTextArea.Value))

                            if sum(name==string(Subj_names))
                                idx = name==string(Subj_names);


                                if Subj_list_all(idx).manual_ex ~= 1
                                    Subj_list_all(idx).manual_ex = 1;
                                else
                                    msgbox([name ' participant Already removed'])
                                end
                            end
                        else
                            msgbox('Choose participants from the Participants Data folder only')
                        end

                    end

                    rep = questdlg('This participant list will be saved for any future analysis. Continue?','Participant list save','Yes','No','Yes');

                    switch rep
                        case 'Yes'
                            writetable(struct2table(Subj_list_all), fullfile(path,"Subj_list.csv"))
                            Subj_list = load_subjects(path,'Subj_list.csv');
                            load_subjects_dropdown2(app,Subj_list)

                        case 'No'
                            app.AllParticipantsaregoodCheckBox.Value = 0;
                            app.ManuallyexcludeparticipantsCheckBox.Value = 0;
                            return
                    end

                end
            else
                app.AllParticipantsaregoodCheckBox.Value = 1;
            end
        end

        % Value changed function: DataPathTextArea_2
        function DataPathTextArea_2ValueChanged(app, event)
            output_folder = char(string(app.DataPathTextArea_2.Value));
            if ~isfolder(output_folder)
                output_folder_pre = fileparts(output_folder);
                if ~isfolder(output_folder_pre)
                    msgbox('Does not seem a valid path, please try again','Invalid Output folder')
                    app.DataPathTextArea_2.Value = '';
                    return
                end
            end

        end

        % Value changed function: OverwriteexistingfilesCheckBox
        function OverwriteexistingfilesCheckBoxValueChanged(app, event)
            value = app.OverwriteexistingfilesCheckBox.Value;
            if value == 1
                response_ = questdlg('This step will whifun_delete all the preprocessing files created before. Do you want to proceed? ','Continue?','Yes','No','No');

                switch response_
                    case 'No'
                        app.OverwriteexistingfilesCheckBox.Value = 0;
                end
            end
        end

        % Value changed function: ParellelProcessingCheckBox
        function ParellelProcessingCheckBoxValueChanged(app, event)
            value = app.ParellelProcessingCheckBox.Value;

            if value == 0
                app.NoofWorkersEditField.Enable ="off";
                app.NoofWorkersEditFieldLabel.Enable = "off";
            else

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);
                addpath(fullfile(preproc_code_path,'parTicToc'))

                try
                    d = uiprogressdlg(app.WhiFuNv32UIFigure,'Title','Starting Parellel pool ','Message','Please wait, Starting Parellel pool.',...
                        'Indeterminate','on');
                    par_p = Par(5); %#ok<NASGU>
                    app.NoofWorkersEditField.Enable ="on";
                    app.NoofWorkersEditFieldLabel.Enable = "on";
                    close(d)
                catch ex 
                    close(d)
                    msgbox('WhiFuN did not find the Parellel Processing toolbox. Please install the parellel processing toolbox and try again.','Parellel Processing toolbox not found')

                    app.NoofWorkersEditField.Enable ="off";
                    app.NoofWorkersEditFieldLabel.Enable = "off";
                    app.ParellelProcessingCheckBox.Value = 0;

                end

            end
        end

        % Button pushed function: QCviewerButton
        function QCviewerButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');
                else
                    path = char(app.DataPathTextArea_2.Value);
                end
            catch
                return
            end
            if path == 0
                return
            end
            qcviewer(path)
        end

        % Value changed function: frequencyspecificCheckBox
        function frequencyspecificCheckBoxValueChanged(app, event)
            value = app.frequencyspecificCheckBox.Value;
            
            if ~value
                app.NumberofFreqBandsSpinner.Enable  = "off";
                app.NumberofFreqBandsSpinner.Visible  = "off";
                app.NumberofFreqBandsLabel.Visible = "off";
                app.FrequenciesperbandButton.Enable  = "off";
                app.FrequenciesperbandButton.Visible = "off";
            else
                app.NumberofFreqBandsSpinner.Enable  = "on";
                app.FrequenciesperbandButton.Enable = "on";
                app.NumberofFreqBandsLabel.Visible = "on";
                app.NumberofFreqBandsSpinner.Visible  = "on";
                app.FrequenciesperbandButton.Visible = "on";
            end

        end

        % Button pushed function: CreateWMGMFNButton
        function CreateWMGMFNButtonPushed(app, event)
             %% Clustering GM Functional networks
            output_folder = char(string(app.DataPathTextArea_2.Value));
            over_write = app.OverwriteexistingfilesCheckBox.Value;
            
            
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                msgbox('Please select participants first')
                return
            end
                        
            K_range_l = double(string(app.KmeansKRangeTextArea_4.Value));
            K_range_h = double(string(app.KmeansGMKRangeTextArea_2.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea_3.Value));

            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create GM-FNs','Interpreter','html','Cancelable','on');
            d_flag = 1;
            n_tot = length(Subj_list);
            gm_steps = 0;
            tot_gm_steps = n_tot*4 + (K_range_h - K_range_l) + 2;
         
            num_replicates = 10;
            size_chunk = 100;
            sub_sample_choose = app.SubsamplingtheGMvoxelFCmatrixDropDown_2.Value;
            WM_or_GM = 'WM-GM';
            group_mask_path_wm = char(app.grp_wm_mask_path_2.Value);
            group_mask_path_gm = char(app.grp_gm_mask_path_2.Value);

            whifun_create_FN_Kmeans_WM_GM(output_folder,WM_or_GM,K_range_l,K_range_h,group_mask_path_wm,group_mask_path_gm,...
                                    'sub_sample_choose',sub_sample_choose,...
                                    'CV_folds',CV_folds,...
                                    'num_replicates',num_replicates,...
                                    'size_chunk',size_chunk,...
                                    'over_write',over_write,...
                                    'd',d,'d_flag',d_flag,'steps_',gm_steps,'tot_steps',tot_gm_steps)
            
        end

        % Button pushed function: CreateGMWMFNButton
        function CreateGMWMFNButtonPushed(app, event)
            %% Clustering GM Functional networks
            output_folder = char(string(app.DataPathTextArea_2.Value));
            over_write = app.OverwriteexistingfilesCheckBox.Value;
            
            
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                msgbox('Please select participants first')
                return
            end
                        
            K_range_l = double(string(app.KmeansKRangeTextArea_5.Value));
            K_range_h = double(string(app.KmeansGMKRangeTextArea_3.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea_4.Value));

            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create GM-FNs','Interpreter','html','Cancelable','on');
            d_flag = 1;
            n_tot = length(Subj_list);
            gm_steps = 0;
            tot_gm_steps = n_tot*4 + (K_range_h - K_range_l) + 2;
         
            num_replicates = 10;
            size_chunk = 100;
            sub_sample_choose = app.SubsamplingtheGMvoxelFCmatrixDropDown_3.Value;
            WM_or_GM = 'GM-WM';
            group_mask_path_wm = char(app.grp_wm_mask_path_3.Value);
            group_mask_path_gm = char(app.grp_gm_mask_path_3.Value);

            whifun_create_FN_Kmeans_WM_GM(output_folder,WM_or_GM,K_range_l,K_range_h,group_mask_path_wm,group_mask_path_gm,...
                                    'sub_sample_choose',sub_sample_choose,...
                                    'CV_folds',CV_folds,...
                                    'num_replicates',num_replicates,...
                                    'size_chunk',size_chunk,...
                                    'over_write',over_write,...
                                    'd',d,'d_flag',d_flag,'steps_',gm_steps,'tot_steps',tot_gm_steps)
        end

        % Button pushed function: CreateGMnoiseFNButton
        function CreateGMnoiseFNButtonPushed(app, event)
            %% Clustering GM Functional networks
            output_folder = char(string(app.DataPathTextArea_2.Value));
            over_write = app.OverwriteexistingfilesCheckBox.Value;
            
            
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                msgbox('Please select participants first')
                return
            end
                        
            K_range_l = double(string(app.KmeansKRangeTextArea_6.Value));
            K_range_h = double(string(app.KmeansGMKRangeTextArea_4.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea_5.Value));

            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create GM-FNs','Interpreter','html','Cancelable','on');
            d_flag = 1;
            n_tot = length(Subj_list);
            gm_steps = 0;
            tot_gm_steps = n_tot*4 + (K_range_h - K_range_l) + 2;
         
            num_replicates = 10;
            size_chunk = 100;
            sub_sample_choose = app.SubsamplingtheGMvoxelFCmatrixDropDown_4.Value;
            WM_or_GM = 'GM-noise';
            group_mask_path_wm = char(app.grp_wm_mask_path_4.Value);
            group_mask_path_gm = char(app.grp_gm_mask_path_4.Value);

            whifun_create_FN_Kmeans_WM_GM(output_folder,WM_or_GM,K_range_l,K_range_h,group_mask_path_wm,group_mask_path_gm,...
                                    'sub_sample_choose',sub_sample_choose,...
                                    'CV_folds',CV_folds,...
                                    'num_replicates',num_replicates,...
                                    'size_chunk',size_chunk,...
                                    'over_write',over_write,...
                                    'd',d,'d_flag',d_flag,'steps_',gm_steps,'tot_steps',tot_gm_steps)
        end

        % Button pushed function: CreateNoiseGMFNButton
        function CreateNoiseGMFNButtonPushed(app, event)
             %% Clustering GM Functional networks
            output_folder = char(string(app.DataPathTextArea_2.Value));
            over_write = app.OverwriteexistingfilesCheckBox.Value;
            
            
            if isempty(app.SelectedparticipantswithgooddataDropDown.Items)
                msgbox('Please select participants first')
                return
            end
                        
            K_range_l = double(string(app.KmeansKRangeTextArea_7.Value));
            K_range_h = double(string(app.KmeansGMKRangeTextArea_5.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea_6.Value));

            Subj_list = load_subjects(output_folder,'Subj_list.csv');                      % Load the good participants using the CSV file
            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            d = uiprogressdlg(app.WhiFuNv32UIFigure,'Icon',fullfile(preproc_code_path,'icon_small_2.png'), ...
                'Title','Create Noise-GM-FNs','Interpreter','html','Cancelable','on');
            d_flag = 1;
            n_tot = length(Subj_list);
            gm_steps = 0;
            tot_gm_steps = n_tot*4 + (K_range_h - K_range_l) + 2;
         
            num_replicates = 10;
            size_chunk = 100;
            sub_sample_choose = app.SubsamplingtheGMvoxelFCmatrixDropDown_5.Value;
            WM_or_GM = 'Noise-GM';
            group_mask_path_wm = char(app.grp_wm_mask_path_5.Value);
            group_mask_path_gm = char(app.grp_gm_mask_path_5.Value);

            whifun_create_FN_Kmeans_WM_GM(output_folder,WM_or_GM,K_range_l,K_range_h,group_mask_path_wm,group_mask_path_gm,...
                                    'sub_sample_choose',sub_sample_choose,...
                                    'CV_folds',CV_folds,...
                                    'num_replicates',num_replicates,...
                                    'size_chunk',size_chunk,...
                                    'over_write',over_write,...
                                    'd',d,'d_flag',d_flag,'steps_',gm_steps,'tot_steps',tot_gm_steps)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WhiFuNv32UIFigure = uifigure('Visible', 'off');
            app.WhiFuNv32UIFigure.Name = 'WhiFuN v3.2';
            app.WhiFuNv32UIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.WhiFuNv32UIFigure.Tag = 'Hello';
            app.GridLayout = uigridlayout(app.WhiFuNv32UIFigure);
            app.SaveLoadParametersPanel = uipanel(app.GridLayout);
            app.SaveLoadParametersPanel.Title = 'Save/Load Parameters';
            app.LoadParametersButton = uibutton(app.SaveLoadParametersPanel, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.SaveParametersButton = uibutton(app.SaveLoadParametersPanel, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.OverwriteexistingfilesCheckBox = uicheckbox(app.SaveLoadParametersPanel);
            app.OverwriteexistingfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverwriteexistingfilesCheckBoxValueChanged, true);
            app.OverwriteexistingfilesCheckBox.Text = {'Overwrite '; 'existing files'};
            app.SetupPanel = uipanel(app.GridLayout);
            app.SetupPanel.Title = 'Setup';
            app.OutputsfolderButton = uibutton(app.SetupPanel, 'push');
            app.OutputsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputsfolderButtonPushed, true);
            app.OutputsfolderButton.Text = 'Outputs folder';
            app.DataPathTextArea_2 = uitextarea(app.SetupPanel);
            app.DataPathTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DataPathTextArea_2ValueChanged, true);
            app.ParticipantDatafolderButton = uibutton(app.SetupPanel, 'push');
            app.ParticipantDatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantDatafolderButtonPushed, true);
            app.ParticipantDatafolderButton.Text = 'Participant Data folder';
            app.DataPathTextArea = uitextarea(app.SetupPanel);
            app.DataPathTextArea.ValueChangedFcn = createCallbackFcn(app, @DataPathTextAreaValueChanged, true);
            app.BIDSCheckBox = uicheckbox(app.SetupPanel);
            app.BIDSCheckBox.ValueChangedFcn = createCallbackFcn(app, @BIDSCheckBoxValueChanged, true);
            app.BIDSCheckBox.Text = 'BIDS';
            app.BIDSCheckBox.Value = true;
            app.SelectParticipantsButton = uibutton(app.SetupPanel, 'push');
            app.SelectParticipantsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParticipantsButtonPushed, true);
            app.SelectParticipantsButton.Text = 'Select Participants';
            app.Label_4 = uilabel(app.SetupPanel);
            app.Label_4.Text = '';
            app.Label_7 = uilabel(app.SetupPanel);
            app.Label_7.Text = '';
            app.AllfoldersareParticipantsCheckBox = uicheckbox(app.SetupPanel);
            app.AllfoldersareParticipantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllfoldersareParticipantsCheckBoxValueChanged, true);
            app.AllfoldersareParticipantsCheckBox.Text = 'All folders are Participants';
            app.ConstructFNandFCPanel = uipanel(app.GridLayout);
            app.ConstructFNandFCPanel.Title = 'Construct FN and FC';
            app.TabGroup = uitabgroup(app.ConstructFNandFCPanel);
            app.CreateGroupMasksTab = uitab(app.TabGroup);
            app.CreateGroupMasksTab.Title = 'Create Group Masks';
            app.GroupWMmaskthresholdLabel = uilabel(app.CreateGroupMasksTab);
            app.GroupWMmaskthresholdLabel.Text = {'Group WM'; 'threshold (%)'};
            app.GroupWMthresholdTextArea = uitextarea(app.CreateGroupMasksTab);
            app.GroupWMthresholdTextArea.Value = {'80'};
            app.IndividualWMProbabilitythresholdLabel = uilabel(app.CreateGroupMasksTab);
            app.IndividualWMProbabilitythresholdLabel.Text = {'Individual WM'; ' Probability threshold'};
            app.IndividualWMProbabilitythresholdTextArea = uitextarea(app.CreateGroupMasksTab);
            app.IndividualWMProbabilitythresholdTextArea.Value = {'0.6'};
            app.GroupGMmaskthresholdLabel = uilabel(app.CreateGroupMasksTab);
            app.GroupGMmaskthresholdLabel.Text = {'  Group GM '; 'threshold (%)'};
            app.IndividualGMProbabilitythresholdTextArea_2Label = uilabel(app.CreateGroupMasksTab);
            app.IndividualGMProbabilitythresholdTextArea_2Label.Text = {'Individual GM'; ' Probability threshold'};
            app.IndividualGMProbabilitythresholdTextArea = uitextarea(app.CreateGroupMasksTab);
            app.IndividualGMProbabilitythresholdTextArea.Value = {'0.6'};
            app.GroupGMthresholdTextArea = uitextarea(app.CreateGroupMasksTab);
            app.GroupGMthresholdTextArea.Value = {'20'};
            app.CreateGroupMasksButton = uibutton(app.CreateGroupMasksTab, 'push');
            app.CreateGroupMasksButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGroupMasksButtonPushed, true);
            app.CreateGroupMasksButton.Text = 'Create Group Masks';
            app.WMFNTab = uitab(app.TabGroup);
            app.WMFNTab.Title = 'WM-FN';
            app.WMFNTab.ButtonDownFcn = createCallbackFcn(app, @WMFNTabButtonDown, true);
            app.KmeansKRangeTextArea_2 = uitextarea(app.WMFNTab);
            app.KmeansKRangeTextArea_2.Value = {'2'};
            app.Label_2 = uilabel(app.WMFNTab);
            app.Label_2.Text = '~';
            app.KmeansKRangeLabel = uilabel(app.WMFNTab);
            app.KmeansKRangeLabel.Tooltip = {'Search range for K-means K values. The average dice coeficient and the elbow will be calculated for the range of K values specified here with cross validation technique as described in (Peer et. al. 2017)'};
            app.KmeansKRangeLabel.Text = {'K-means (WM)'; '(K-Range)'};
            app.KmeansWMKRangeTextArea = uitextarea(app.WMFNTab);
            app.KmeansWMKRangeTextArea.Value = {'22'};
            app.CorpusCallosumNetworksCheckBox = uicheckbox(app.WMFNTab);
            app.CorpusCallosumNetworksCheckBox.Text = 'Corpus Callosum Networks';
            app.NumCVfoldsTextAreaLabel = uilabel(app.WMFNTab);
            app.NumCVfoldsTextAreaLabel.Text = 'Num CV folds';
            app.NumCVfoldsTextArea = uitextarea(app.WMFNTab);
            app.NumCVfoldsTextArea.Value = {'4'};
            app.CreateWMFNButton = uibutton(app.WMFNTab, 'push');
            app.CreateWMFNButton.ButtonPushedFcn = createCallbackFcn(app, @CreateWMFNButtonPushed, true);
            app.CreateWMFNButton.Text = 'Create WM-FN';
            app.SubsamplingtheWMvoxelFCmatrixLabel = uilabel(app.WMFNTab);
            app.SubsamplingtheWMvoxelFCmatrixLabel.Text = 'Sub-sampling the WM voxel FC matrix';
            app.SubsamplingtheWMvoxelFCmatrixDropDown = uidropdown(app.WMFNTab);
            app.SubsamplingtheWMvoxelFCmatrixDropDown.Items = {'Subsample', 'Use entire FC'};
            app.SubsamplingtheWMvoxelFCmatrixDropDown.Value = 'Subsample';
            app.grp_wm_mask_path = uitextarea(app.WMFNTab);
            app.WMGroupMaskButton = uibutton(app.WMFNTab, 'push');
            app.WMGroupMaskButton.ButtonPushedFcn = createCallbackFcn(app, @WMGroupMaskButtonPushed, true);
            app.WMGroupMaskButton.Text = 'WM Group Mask';
            app.GMFNTab = uitab(app.TabGroup);
            app.GMFNTab.Title = 'GM-FN';
            app.GMFNTab.ButtonDownFcn = createCallbackFcn(app, @GMFNTabButtonDown, true);
            app.KmeansKRangeTextArea_3 = uitextarea(app.GMFNTab);
            app.KmeansKRangeTextArea_3.Value = {'2'};
            app.Label_3 = uilabel(app.GMFNTab);
            app.Label_3.Text = '~';
            app.NumCVfoldsTextAreaLabel_2 = uilabel(app.GMFNTab);
            app.NumCVfoldsTextAreaLabel_2.Text = 'Num CV folds';
            app.NumCVfoldsTextArea_2 = uitextarea(app.GMFNTab);
            app.NumCVfoldsTextArea_2.Value = {'4'};
            app.CreateGMFNButton = uibutton(app.GMFNTab, 'push');
            app.CreateGMFNButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGMFNButtonPushed, true);
            app.CreateGMFNButton.Text = 'Create GM-FN';
            app.KmeansGMKRangeTextAreaLabel = uilabel(app.GMFNTab);
            app.KmeansGMKRangeTextAreaLabel.Text = {'K-means (GM)'; '(K-Range)'};
            app.KmeansGMKRangeTextArea = uitextarea(app.GMFNTab);
            app.KmeansGMKRangeTextArea.Value = {'22'};
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel = uilabel(app.GMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel.Text = 'Sub-sampling the GM voxel FC matrix';
            app.SubsamplingtheGMvoxelFCmatrixDropDown = uidropdown(app.GMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDown.Items = {'Subsample', 'Use entire FC'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown.Value = 'Subsample';
            app.grp_gm_mask_path = uitextarea(app.GMFNTab);
            app.GMGroupMaskButton = uibutton(app.GMFNTab, 'push');
            app.GMGroupMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GMGroupMaskButtonPushed, true);
            app.GMGroupMaskButton.Text = 'GM Group Mask';
            app.ROITimeseriesextractionTab = uitab(app.TabGroup);
            app.ROITimeseriesextractionTab.Title = 'ROI Timeseries extraction';
            app.DataPathTextArea_3 = uitextarea(app.ROITimeseriesextractionTab);
            app.NetworksAtlasButton = uibutton(app.ROITimeseriesextractionTab, 'push');
            app.NetworksAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @NetworksAtlasButtonPushed, true);
            app.NetworksAtlasButton.Text = 'Networks/Atlas';
            app.FrequenciesperbandButton = uibutton(app.ROITimeseriesextractionTab, 'push');
            app.FrequenciesperbandButton.ButtonPushedFcn = createCallbackFcn(app, @FrequenciesperbandButtonPushed, true);
            app.FrequenciesperbandButton.Enable = 'off';
            app.FrequenciesperbandButton.Visible = 'off';
            app.FrequenciesperbandButton.Text = 'Frequencies per band';
            app.NumberofFreqBandsLabel = uilabel(app.ROITimeseriesextractionTab);
            app.NumberofFreqBandsLabel.Visible = 'off';
            app.NumberofFreqBandsLabel.Text = {'Number of '; 'Freq. Bands'};
            app.NumberofFreqBandsSpinner = uispinner(app.ROITimeseriesextractionTab);
            app.NumberofFreqBandsSpinner.Limits = [1 6];
            app.NumberofFreqBandsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofFreqBandsSpinner.Enable = 'off';
            app.NumberofFreqBandsSpinner.Visible = 'off';
            app.NumberofFreqBandsSpinner.Value = 1;
            app.ExtractROITImeseriesButton = uibutton(app.ROITimeseriesextractionTab, 'push');
            app.ExtractROITImeseriesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROITImeseriesButtonPushed, true);
            app.ExtractROITImeseriesButton.Text = 'Extract ROI TImeseries';
            app.SaveQCimagesofregionshavingmissingdataCheckBox = uicheckbox(app.ROITimeseriesextractionTab);
            app.SaveQCimagesofregionshavingmissingdataCheckBox.Text = {'Save QC images of regions'; 'having missing data'};
            app.frequencyspecificCheckBox = uicheckbox(app.ROITimeseriesextractionTab);
            app.frequencyspecificCheckBox.ValueChangedFcn = createCallbackFcn(app, @frequencyspecificCheckBoxValueChanged, true);
            app.frequencyspecificCheckBox.Text = {'frequency '; 'specific'};
            app.WMGMFNTab = uitab(app.TabGroup);
            app.WMGMFNTab.Title = 'WM-GM-FN';
            app.KmeansKRangeTextArea_4 = uitextarea(app.WMGMFNTab);
            app.KmeansKRangeTextArea_4.Value = {'2'};
            app.Label_8 = uilabel(app.WMGMFNTab);
            app.Label_8.Text = '~';
            app.NumCVfoldsTextAreaLabel_3 = uilabel(app.WMGMFNTab);
            app.NumCVfoldsTextAreaLabel_3.Text = 'Num CV folds';
            app.NumCVfoldsTextArea_3 = uitextarea(app.WMGMFNTab);
            app.NumCVfoldsTextArea_3.Value = {'4'};
            app.CreateWMGMFNButton = uibutton(app.WMGMFNTab, 'push');
            app.CreateWMGMFNButton.ButtonPushedFcn = createCallbackFcn(app, @CreateWMGMFNButtonPushed, true);
            app.CreateWMGMFNButton.Text = 'Create WM-GM-FN';
            app.KmeansGMKRangeTextAreaLabel_2 = uilabel(app.WMGMFNTab);
            app.KmeansGMKRangeTextAreaLabel_2.Text = {'K-means (GM)'; '(K-Range)'};
            app.KmeansGMKRangeTextArea_2 = uitextarea(app.WMGMFNTab);
            app.KmeansGMKRangeTextArea_2.Value = {'22'};
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_2 = uilabel(app.WMGMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_2.Text = {'Sub-sampling the '; 'WM-GM voxel FC matrix'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_2 = uidropdown(app.WMGMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDown_2.Items = {'Subsample', 'Use entire FC'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_2.Value = 'Subsample';
            app.grp_wm_mask_path_2 = uitextarea(app.WMGMFNTab);
            app.WMGroupMaskButton_2 = uibutton(app.WMGMFNTab, 'push');
            app.WMGroupMaskButton_2.Text = 'WM Group Mask';
            app.grp_gm_mask_path_2 = uitextarea(app.WMGMFNTab);
            app.GMGroupMaskButton_3 = uibutton(app.WMGMFNTab, 'push');
            app.GMGroupMaskButton_3.Text = 'GM Group Mask';
            app.GMWMFNTab = uitab(app.TabGroup);
            app.GMWMFNTab.Title = 'GM-WM-FN';
            app.KmeansKRangeTextArea_5 = uitextarea(app.GMWMFNTab);
            app.KmeansKRangeTextArea_5.Value = {'2'};
            app.Label_9 = uilabel(app.GMWMFNTab);
            app.Label_9.Text = '~';
            app.NumCVfoldsTextAreaLabel_4 = uilabel(app.GMWMFNTab);
            app.NumCVfoldsTextAreaLabel_4.Text = 'Num CV folds';
            app.NumCVfoldsTextArea_4 = uitextarea(app.GMWMFNTab);
            app.NumCVfoldsTextArea_4.Value = {'4'};
            app.CreateGMWMFNButton = uibutton(app.GMWMFNTab, 'push');
            app.CreateGMWMFNButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGMWMFNButtonPushed, true);
            app.CreateGMWMFNButton.Text = 'Create GM-WM-FN';
            app.KmeansGMKRangeTextAreaLabel_3 = uilabel(app.GMWMFNTab);
            app.KmeansGMKRangeTextAreaLabel_3.Text = {'K-means (GM)'; '(K-Range)'};
            app.KmeansGMKRangeTextArea_3 = uitextarea(app.GMWMFNTab);
            app.KmeansGMKRangeTextArea_3.Value = {'22'};
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_3 = uilabel(app.GMWMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_3.Text = {'Sub-sampling the '; 'GM-WM voxel FC matrix'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_3 = uidropdown(app.GMWMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDown_3.Items = {'Subsample', 'Use entire FC'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_3.Value = 'Subsample';
            app.grp_wm_mask_path_3 = uitextarea(app.GMWMFNTab);
            app.WMGroupMaskButton_3 = uibutton(app.GMWMFNTab, 'push');
            app.WMGroupMaskButton_3.Text = 'WM Group Mask';
            app.grp_gm_mask_path_3 = uitextarea(app.GMWMFNTab);
            app.GMGroupMaskButton_4 = uibutton(app.GMWMFNTab, 'push');
            app.GMGroupMaskButton_4.Text = 'GM Group Mask';
            app.GMNoiseFNTab = uitab(app.TabGroup);
            app.GMNoiseFNTab.Title = 'GM-Noise-FN';
            app.KmeansKRangeTextArea_6 = uitextarea(app.GMNoiseFNTab);
            app.KmeansKRangeTextArea_6.Value = {'2'};
            app.Label_10 = uilabel(app.GMNoiseFNTab);
            app.Label_10.Text = '~';
            app.NumCVfoldsTextAreaLabel_5 = uilabel(app.GMNoiseFNTab);
            app.NumCVfoldsTextAreaLabel_5.Text = 'Num CV folds';
            app.NumCVfoldsTextArea_5 = uitextarea(app.GMNoiseFNTab);
            app.NumCVfoldsTextArea_5.Value = {'4'};
            app.CreateGMnoiseFNButton = uibutton(app.GMNoiseFNTab, 'push');
            app.CreateGMnoiseFNButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGMnoiseFNButtonPushed, true);
            app.CreateGMnoiseFNButton.Text = 'Create GM-noise-FN';
            app.KmeansGMKRangeTextAreaLabel_4 = uilabel(app.GMNoiseFNTab);
            app.KmeansGMKRangeTextAreaLabel_4.Text = {'K-means (GM)'; '(K-Range)'};
            app.KmeansGMKRangeTextArea_4 = uitextarea(app.GMNoiseFNTab);
            app.KmeansGMKRangeTextArea_4.Value = {'22'};
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_4 = uilabel(app.GMNoiseFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_4.Text = {'Sub-sampling the '; 'GM-Noise voxel FC matrix'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_4 = uidropdown(app.GMNoiseFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDown_4.Items = {'Subsample', 'Use entire FC'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_4.Value = 'Subsample';
            app.grp_wm_mask_path_4 = uitextarea(app.GMNoiseFNTab);
            app.WMGroupMaskButton_4 = uibutton(app.GMNoiseFNTab, 'push');
            app.WMGroupMaskButton_4.Text = 'WM Group Mask';
            app.grp_gm_mask_path_4 = uitextarea(app.GMNoiseFNTab);
            app.GMGroupMaskButton_5 = uibutton(app.GMNoiseFNTab, 'push');
            app.GMGroupMaskButton_5.Text = 'GM Group Mask';
            app.NoiseGMFNTab = uitab(app.TabGroup);
            app.NoiseGMFNTab.Title = 'Noise-GM-FN';
            app.KmeansKRangeTextArea_7 = uitextarea(app.NoiseGMFNTab);
            app.KmeansKRangeTextArea_7.Value = {'2'};
            app.Label_11 = uilabel(app.NoiseGMFNTab);
            app.Label_11.Text = '~';
            app.NumCVfoldsTextAreaLabel_6 = uilabel(app.NoiseGMFNTab);
            app.NumCVfoldsTextAreaLabel_6.Text = 'Num CV folds';
            app.NumCVfoldsTextArea_6 = uitextarea(app.NoiseGMFNTab);
            app.NumCVfoldsTextArea_6.Value = {'4'};
            app.CreateNoiseGMFNButton = uibutton(app.NoiseGMFNTab, 'push');
            app.CreateNoiseGMFNButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNoiseGMFNButtonPushed, true);
            app.CreateNoiseGMFNButton.Text = 'Create Noise-GM-FN';
            app.KmeansGMKRangeTextAreaLabel_5 = uilabel(app.NoiseGMFNTab);
            app.KmeansGMKRangeTextAreaLabel_5.Text = {'K-means (GM)'; '(K-Range)'};
            app.KmeansGMKRangeTextArea_5 = uitextarea(app.NoiseGMFNTab);
            app.KmeansGMKRangeTextArea_5.Value = {'22'};
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_5 = uilabel(app.NoiseGMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel_5.Text = {'Sub-sampling the '; 'Noise-GM voxel FC matrix'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_5 = uidropdown(app.NoiseGMFNTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDown_5.Items = {'Subsample', 'Use entire FC'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown_5.Value = 'Subsample';
            app.grp_wm_mask_path_5 = uitextarea(app.NoiseGMFNTab);
            app.WMGroupMaskButton_5 = uibutton(app.NoiseGMFNTab, 'push');
            app.WMGroupMaskButton_5.Text = 'WM Group Mask';
            app.grp_gm_mask_path_5 = uitextarea(app.NoiseGMFNTab);
            app.GMGroupMaskButton_6 = uibutton(app.NoiseGMFNTab, 'push');
            app.GMGroupMaskButton_6.Text = 'GM Group Mask';
            app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel = uipanel(app.ConstructFNandFCPanel);
            app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel.Title = 'Check the Quality Control Plots and exclude participants with bad data';
            app.AllParticipantsaregoodCheckBox = uicheckbox(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.AllParticipantsaregoodCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllParticipantsaregoodCheckBoxValueChanged, true);
            app.AllParticipantsaregoodCheckBox.Text = {'All Participants'; 'are good'};
            app.SelectedSubjectswithgooddataLabel = uilabel(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.SelectedSubjectswithgooddataLabel.Text = {'Selected participants'; 'with good data'};
            app.SelectedparticipantswithgooddataDropDown = uidropdown(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.SelectedparticipantswithgooddataDropDown.Items = {};
            app.SelectedparticipantswithgooddataDropDown.Value = {};
            app.ManuallyexcludeparticipantsCheckBox = uicheckbox(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.ManuallyexcludeparticipantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManuallyexcludeparticipantsCheckBoxValueChanged, true);
            app.ManuallyexcludeparticipantsCheckBox.Text = {'Manually exclude'; 'participants'};
            app.Label_5 = uilabel(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.Label_5.Text = '';
            app.orLabel = uilabel(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.orLabel.Text = 'or';
            app.WhiFuNLabel = uilabel(app.GridLayout);
            app.WhiFuNLabel.Text = 'WhiFuN';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon_small.png');
            app.AnalyzeFNandFCPanel = uipanel(app.GridLayout);
            app.AnalyzeFNandFCPanel.Title = 'Analyze FN and FC';
            app.StatisticsButton = uibutton(app.AnalyzeFNandFCPanel, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            app.CompareFNButton = uibutton(app.AnalyzeFNandFCPanel, 'push');
            app.CompareFNButton.ButtonPushedFcn = createCallbackFcn(app, @CompareFNButtonPushed, true);
            app.CompareFNButton.Text = 'Compare FN';
            app.CheckFNSymettryButton = uibutton(app.AnalyzeFNandFCPanel, 'push');
            app.CheckFNSymettryButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFNSymettryButtonPushed, true);
            app.CheckFNSymettryButton.Text = 'Check FN Symettry';
            app.VisualizePanel = uipanel(app.GridLayout);
            app.VisualizePanel.Title = 'Visualize';
            app.DisplayFNButton = uibutton(app.VisualizePanel, 'push');
            app.DisplayFNButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFNButtonPushed, true);
            app.DisplayFNButton.Text = 'Display FN';
            app.DisplayFCButton = uibutton(app.VisualizePanel, 'push');
            app.DisplayFCButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFCButtonPushed, true);
            app.DisplayFCButton.Text = 'Display FC';
            app.CheckDataPanel = uipanel(app.GridLayout);
            app.CheckDataPanel.Title = 'Check Data';
            app.RunDataCheckButton = uibutton(app.CheckDataPanel, 'push');
            app.RunDataCheckButton.ButtonPushedFcn = createCallbackFcn(app, @RunDataCheckButtonPushed, true);
            app.RunDataCheckButton.Text = 'Run Data Check';
            app.ParticipantsInfoButton = uibutton(app.CheckDataPanel, 'push');
            app.ParticipantsInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantsInfoButtonPushed, true);
            app.ParticipantsInfoButton.Text = 'Participants Info';
            app.DataCheckReportLabel = uilabel(app.CheckDataPanel);
            app.DataCheckReportLabel.Text = 'Data Check Report';
            app.DataCheckReportTextArea = uitextarea(app.CheckDataPanel);
            app.PreprocessPanel = uipanel(app.GridLayout);
            app.PreprocessPanel.Title = 'Preprocess';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.PipelineTab = uitab(app.TabGroup2);
            app.PipelineTab.Title = 'Pipeline';
            app.Label = uilabel(app.PipelineTab);
            app.Label.Text = '~';
            app.FilterTextArea_2 = uitextarea(app.PipelineTab);
            app.FilterTextArea_2.Enable = 'off';
            app.FilterTextArea_2.Value = {'0.15'};
            app.FilterCheckBox = uicheckbox(app.PipelineTab);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterCheckBoxValueChanged, true);
            app.FilterCheckBox.Text = '';
            app.MotionParametersCheckBox = uicheckbox(app.PipelineTab);
            app.MotionParametersCheckBox.Text = '24 Motion Parameters';
            app.MotionParametersCheckBox.Value = true;
            app.FramewiseDisplacementFDtootipthresholdsformotionmmLabel = uilabel(app.PipelineTab);
            app.FramewiseDisplacementFDtootipthresholdsformotionmmLabel.Text = 'FD thresholds for motion outlier detection (mm)';
            app.NoofVolumestoDiscardTextAreaLabel = uilabel(app.PipelineTab);
            app.NoofVolumestoDiscardTextAreaLabel.Text = 'No. of Volumes to Discard ';
            app.NoofVolumestoDiscardTextArea = uitextarea(app.PipelineTab);
            app.NoofVolumestoDiscardTextArea.Value = {'10'};
            app.NuisanceRegressionLabel = uilabel(app.PipelineTab);
            app.NuisanceRegressionLabel.Text = {'Nuisance'; 'Regression'};
            app.NuisanceRegressionDropDown = uidropdown(app.PipelineTab);
            app.NuisanceRegressionDropDown.Items = {'Mean CSF', 'PCA CSF', 'No CSF Regression'};
            app.NuisanceRegressionDropDown.ValueChangedFcn = createCallbackFcn(app, @NuisanceRegressionDropDownValueChanged, true);
            app.NuisanceRegressionDropDown.Value = 'Mean CSF';
            app.SmoothingDropDownLabel = uilabel(app.PipelineTab);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.PipelineTab);
            app.SmoothingDropDown.Items = {'WM-GM Seperate', 'All Together', 'No Smoothing'};
            app.SmoothingDropDown.Value = 'WM-GM Seperate';
            app.NormalizationvoxeldimmmTextAreaLabel = uilabel(app.PipelineTab);
            app.NormalizationvoxeldimmmTextAreaLabel.Text = 'Normalization (voxel dim (mm))';
            app.NormalizationvoxeldimmmTextArea = uitextarea(app.PipelineTab);
            app.NormalizationvoxeldimmmTextArea.Value = {'3'};
            app.TemporalFilteringHzLabel = uilabel(app.PipelineTab);
            app.TemporalFilteringHzLabel.Text = {'Temporal'; 'Filtering (Hz)'};
            app.TemporalFilteringHzTextArea = uitextarea(app.PipelineTab);
            app.TemporalFilteringHzTextArea.Enable = 'off';
            app.TemporalFilteringHzTextArea.Value = {'0.01'};
            app.FWHMmmTextAreaLabel = uilabel(app.PipelineTab);
            app.FWHMmmTextAreaLabel.Text = 'FWHM (mm)';
            app.FWHMmmTextArea = uitextarea(app.PipelineTab);
            app.FWHMmmTextArea.Value = {'4'};
            app.FramewiseDisplacementLabel = uilabel(app.PipelineTab);
            app.FramewiseDisplacementLabel.Text = 'max';
            app.maxDropDown = uidropdown(app.PipelineTab);
            app.maxDropDown.Items = {'1', '2.5', '5'};
            app.maxDropDown.Value = '5';
            app.meanDropDownLabel = uilabel(app.PipelineTab);
            app.meanDropDownLabel.Text = 'mean';
            app.meanDropDown = uidropdown(app.PipelineTab);
            app.meanDropDown.Items = {'0.1', '0.2', '0.3', '0.4', '0.5'};
            app.meanDropDown.Value = '0.2';
            app.Greaterthan20DropDownLabel = uilabel(app.PipelineTab);
            app.Greaterthan20DropDownLabel.Text = 'Greater than 20%';
            app.Greaterthan20DropDown = uidropdown(app.PipelineTab);
            app.Greaterthan20DropDown.Items = {'0.1', '0.2', '0.3', '0.4', '0.5'};
            app.Greaterthan20DropDown.Value = '0.2';
            app.RunPreprocessingButton = uibutton(app.PipelineTab, 'push');
            app.RunPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunPreprocessingButtonPushed, true);
            app.RunPreprocessingButton.Text = 'Run Preprocessing';
            app.ParellelProcessingCheckBox = uicheckbox(app.PipelineTab);
            app.ParellelProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParellelProcessingCheckBoxValueChanged, true);
            app.ParellelProcessingCheckBox.Text = 'Parellel Processing';
            app.NoofWorkersEditFieldLabel = uilabel(app.PipelineTab);
            app.NoofWorkersEditFieldLabel.Enable = 'off';
            app.NoofWorkersEditFieldLabel.Text = 'No. of Workers';
            app.NoofWorkersEditField = uieditfield(app.PipelineTab, 'numeric');
            app.NoofWorkersEditField.Limits = [0 Inf];
            app.NoofWorkersEditField.RoundFractionalValues = 'on';
            app.NoofWorkersEditField.Enable = 'off';
            app.NoofWorkersEditField.Value = 2;
            app.SegmentationDropDownLabel = uilabel(app.PipelineTab);
            app.SegmentationDropDownLabel.Text = 'Segmentation';
            app.SegmentationDropDown = uidropdown(app.PipelineTab);
            app.SegmentationDropDown.Items = {'No Dartel', 'Dartel'};
            app.SegmentationDropDown.Value = 'No Dartel';
            app.QCviewerButton = uibutton(app.PipelineTab, 'push');
            app.QCviewerButton.ButtonPushedFcn = createCallbackFcn(app, @QCviewerButtonPushed, true);
            app.QCviewerButton.Text = 'QC viewer';
            app.ToolsTab = uitab(app.TabGroup2);
            app.ToolsTab.Title = 'Tools';
            app.DetermineFramewiseDisplacementthresholdsButton = uibutton(app.ToolsTab, 'push');
            app.DetermineFramewiseDisplacementthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineFramewiseDisplacementthresholdsButtonPushed, true);
            app.DetermineFramewiseDisplacementthresholdsButton.Text = {'Determine Framewise Displacement'; ' thresholds'};
            app.manuallycoregisterparticipantsButton = uibutton(app.ToolsTab, 'push');
            app.manuallycoregisterparticipantsButton.ButtonPushedFcn = createCallbackFcn(app, @manuallycoregisterparticipantsButtonPushed, true);
            app.manuallycoregisterparticipantsButton.Text = 'manually coregister participants';
            app.DeletePreprocessingfilesfromaparticularstepButton = uibutton(app.ToolsTab, 'push');
            app.DeletePreprocessingfilesfromaparticularstepButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePreprocessingfilesfromaparticularstepButtonPushed, true);
            app.DeletePreprocessingfilesfromaparticularstepButton.Text = {'Delete Preprocessing files'; ' from a particular step'};
            app.ContextMenu = uicontextmenu(app.WhiFuNv32UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.ROITimeseriesextractionTab.ContextMenu = app.ContextMenu;
            app.DataPathTextArea_3.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.WhiFuNv32UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WhiFuNv32UIFigure)

            % Execute the startup function
            runStartupFcn(app, @tooltip)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WhiFuNv32UIFigure)
        end
    end
end