classdef main_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WhiFuNv1UIFigure                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        PipelineTab                     matlab.ui.container.Tab
        RunPreprocessingButton          matlab.ui.control.Button
        Greaterthan20DropDown           matlab.ui.control.DropDown
        Greaterthan20DropDownLabel      matlab.ui.control.Label
        meanDropDown                    matlab.ui.control.DropDown
        meanDropDownLabel               matlab.ui.control.Label
        maxDropDown                     matlab.ui.control.DropDown
        FramewiseDisplacementLabel      matlab.ui.control.Label
        FWHMmmTextArea                  matlab.ui.control.TextArea
        FWHMmmTextAreaLabel             matlab.ui.control.Label
        TemporalFilteringHzTextArea     matlab.ui.control.TextArea
        TemporalFilteringHzLabel        matlab.ui.control.Label
        NormalizationvoxeldimmmTextArea  matlab.ui.control.TextArea
        NormalizationvoxeldimmmTextAreaLabel  matlab.ui.control.Label
        SmoothingDropDown               matlab.ui.control.DropDown
        SmoothingDropDownLabel          matlab.ui.control.Label
        NuisanceRegressionDropDown      matlab.ui.control.DropDown
        NuisanceRegressionLabel         matlab.ui.control.Label
        NoofVolumestoDiscardTextArea    matlab.ui.control.TextArea
        NoofVolumestoDiscardTextAreaLabel  matlab.ui.control.Label
        FramewiseDisplacementFDtootipthresholdsformotionmmLabel  matlab.ui.control.Label
        MotionParametersCheckBox        matlab.ui.control.CheckBox
        FilterCheckBox                  matlab.ui.control.CheckBox
        OverwriteexistingfilesCheckBox  matlab.ui.control.CheckBox
        FilterTextArea_2                matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ToolsTab                        matlab.ui.container.Tab
        DeletePreprocessingfilesfromaparticularstepButton  matlab.ui.control.Button
        manuallycoregistersubjectsButton  matlab.ui.control.Button
        DetermineFramewiseDisplacementthresholdsButton  matlab.ui.control.Button
        PreprocessPanel                 matlab.ui.container.Panel
        CheckDataPanel                  matlab.ui.container.Panel
        DataCheckReportTextArea         matlab.ui.control.TextArea
        DataCheckReportLabel            matlab.ui.control.Label
        SubjectInfoButton               matlab.ui.control.Button
        RunDataCheckButton              matlab.ui.control.Button
        VisualizePanel                  matlab.ui.container.Panel
        DisplayFCButton                 matlab.ui.control.Button
        DisplayFNButton                 matlab.ui.control.Button
        AnalyzeFNandFCPanel             matlab.ui.container.Panel
        CheckFNSymettryButton           matlab.ui.control.Button
        CompareFNButton                 matlab.ui.control.Button
        StatisticsButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        WhiFuNLabel                     matlab.ui.control.Label
        ConstructFNandFCPanel           matlab.ui.container.Panel
        ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel  matlab.ui.container.Panel
        orLabel                         matlab.ui.control.Label
        ManuallyexcludesubjectsCheckBox  matlab.ui.control.CheckBox
        SelectedSubjectswithgooddataDropDown  matlab.ui.control.DropDown
        SelectedSubjectswithgooddataLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        AllSubjectsaregoodCheckBox      matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        WMNetworksTab                   matlab.ui.container.Tab
        SubsamplingtheWMvoxelFCmatrixDropDown  matlab.ui.control.DropDown
        SubsamplingtheWMvoxelFCmatrixLabel  matlab.ui.control.Label
        IndividualWMProbabilitythresholdTextArea  matlab.ui.control.TextArea
        IndividualWMProbabilitythresholdLabel  matlab.ui.control.Label
        GroupWMthresholdTextArea        matlab.ui.control.TextArea
        GroupWMmaskthresholdLabel       matlab.ui.control.Label
        CreateWMnetworksButton          matlab.ui.control.Button
        NumCVfoldsTextArea              matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel         matlab.ui.control.Label
        CorpusCallosumNetworksCheckBox  matlab.ui.control.CheckBox
        KmeansWMKRangeTextArea          matlab.ui.control.TextArea
        KmeansKRangeLabel               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        KmeansKRangeTextArea_2          matlab.ui.control.TextArea
        GMNetworksTab                   matlab.ui.container.Tab
        IndividualGMProbabilitythresholdTextArea  matlab.ui.control.TextArea
        IndividualGMProbabilitythresholdTextAreaLabel  matlab.ui.control.Label
        SubsamplingtheGMvoxelFCmatrixDropDown  matlab.ui.control.DropDown
        SubsamplingtheGMvoxelFCmatrixDropDownLabel  matlab.ui.control.Label
        GroupGMthresholdTextArea        matlab.ui.control.TextArea
        GroupGMmaskthresholdLabel       matlab.ui.control.Label
        KmeansGMKRangeTextArea          matlab.ui.control.TextArea
        KmeansGMKRangeTextAreaLabel     matlab.ui.control.Label
        CreateGMnetworksButton          matlab.ui.control.Button
        NumCVfoldsTextArea_2            matlab.ui.control.TextArea
        NumCVfoldsTextAreaLabel_2       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        KmeansKRangeTextArea_3          matlab.ui.control.TextArea
        AtlasBasedFCTab                 matlab.ui.container.Tab
        RunAtlasbasedFCButton           matlab.ui.control.Button
        StandardWMatlasDropDownLabel_2  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        WMatlasDropDown                 matlab.ui.control.DropDown
        StandardWMatlasLabel            matlab.ui.control.Label
        ExtractTimeSeriesinfrequencybandTab  matlab.ui.container.Tab
        SaveQCimagesofregionshavingmissingdataCheckBox  matlab.ui.control.CheckBox
        CreateAverageTimeSeriesButton   matlab.ui.control.Button
        NumberofFreqBandsSpinner        matlab.ui.control.Spinner
        NumberofFreqBandsLabel          matlab.ui.control.Label
        FrequenciesperbandButton        matlab.ui.control.Button
        NetworksAtlasButton             matlab.ui.control.Button
        DataPathTextArea_3              matlab.ui.control.TextArea
        SetupPanel                      matlab.ui.container.Panel
        AllfoldersareSubjectsCheckBox   matlab.ui.control.CheckBox
        Label_7                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SelectSubjectsButton            matlab.ui.control.Button
        BIDSCheckBox                    matlab.ui.control.CheckBox
        DataPathTextArea                matlab.ui.control.TextArea
        SubjectDatafolderButton         matlab.ui.control.Button
        DataPathTextArea_2              matlab.ui.control.TextArea
        OutputsfolderButton             matlab.ui.control.Button
        SaveLoadParametersPanel         matlab.ui.container.Panel
        SaveParametersButton            matlab.ui.control.Button
        LoadParametersButton            matlab.ui.control.Button
    end


    properties (Access = private)
        DialogApp % Dialog box app
        atlas_filename  = 'JHU_MNI_SS_WMPM_Type-I_to_MNI_brain.nii'       % For Atlas_Based_FC
        atlas_filefolder                                                  % folder where the Atlas_Based_FC atlas is stored

        ex_tf_atlas                                                       % For Frequency Specific Time series extraction
        ex_tf_atlas_path                                                  % folder where Frequency Specific Time series extraction atlas is stored
    end

    properties (Access = public)
        comm_subj_name = ''  % Description
    end

    properties (Access = public)
        comm_sess_name = '' % Description
    end

    properties (Access = public)
        func_folder_name = 'func' % Description
    end

    properties (Access = public)
        anat_folder_name = 'anat' % Description
    end

    properties (Access = public)
        SegmentationCSFThreshold = '0.95'
    end

    properties (Access = public)
        %         Subj_list
        Subj_list1
    end
    properties (Access = public)
        func_data_name = 'sub*_task-rest_*' % Description
    end

    properties (Access = public)
        anat_data_name = '*sub*_T1w*'% Description
    end

    properties (Access = public)
        NoofPCAcomponents = 5;
    end

    % For Frequency Specific Time series extraction
    properties (Access = public)
        band1_lp = 0
        band1_hp = 0
        band1_name = 'Band 1'

        band2_lp = 0
        band2_hp = 0
        band2_name = 'Band 2'

        band3_lp = 0
        band3_hp = 0
        band3_name = 'Band 3'

        band4_lp = 0
        band4_hp = 0
        band4_name = 'Band 4'

        band5_lp = 0
        band5_hp = 0
        band5_name = 'Band 5'

        band6_lp = 0
        band6_hp = 0
        band6_name = 'Band 6'
    end
    methods (Access = private)

        function load_param(app,load_folder,load_name)
            load(fullfile(load_folder,load_name)) %#ok<LOAD>
            %             quality_control_path = fullfile(output_folder,'Quality_control');
            if exist(fullfile(output_folder,'Subj_list.csv'),"file")
                Subj_list = load_subjects(app,output_folder,'Subj_list.csv');
            end

            if exist('bids_check','var')
                app.BIDSCheckBox.Value = bids_check;
            end

            if exist('filter_check','var')
                app.FilterCheckBox.Value = filter_check;
            end

            app.comm_subj_name = comm_subj_name; %#ok<ADPROPLC> % % Can be used for future if researchers want to analyse a subset of subjects in the data folder
            app.comm_sess_name = comm_sess_name; %#ok<ADPROPLC> % % Can be used for future if researchers have dataset where there are multiple session folders

            %   Set functional and anatomical data folder name
            app.func_folder_name = func_folder_name ;    %#ok<ADPROPLC> % % Can be used in the future if the Functional folder name is different
            app.anat_folder_name = anat_folder_name;     %#ok<ADPROPLC> % % Can be used in the future if the Anatomical folder name is different

            %   Set functional and anatomical data basic file name
            app.func_data_name = func_data_name ;     %#ok<ADPROPLC> % %   Functional data name
            app.anat_data_name = anat_data_name ;     %#ok<ADPROPLC> % %   Anatomical data name


            app.DataPathTextArea.Value = data_path;
            app.DataPathTextArea_2.Value = output_folder;


            app.NoofVolumestoDiscardTextArea.Value = string(n_vol_dis);

            if exist('max_fd','var')
                app.maxDropDown.Value = max_fd;                                                               % Maximum translation allowed for Framewize displacement
                app.meanDropDown.Value = mean_fd ;
                app.Greaterthan20DropDown.Value = greater_than_20 ;
            end

            app.SegmentationCSFThreshold = string(CSF_thres);

            app.NuisanceRegressionDropDown.Value = Reg_drop;
            app.NoofPCAcomponents = string(n_pca);

            app.MotionParametersCheckBox.Value = motion_reg;

            app.TemporalFilteringHzTextArea.Value = string(filter_lp) ;
            app.FilterTextArea_2.Value = string(filter_hp) ;
            app.SmoothingDropDown.Value = smooth_drop ;
            app.FWHMmmTextArea.Value = string(smooth_fwhm);
            app.NormalizationvoxeldimmmTextArea.Value = string(vox);

            app.GroupWMthresholdTextArea.Value = string(grp_wm_thres);
            app.GroupGMthresholdTextArea.Value = string(grp_gm_thres);

            app.KmeansWMKRangeTextArea.Value = string(K_range_h) ;
            app.KmeansKRangeTextArea_2.Value = string(K_range_l) ;
            app.NumCVfoldsTextArea.Value = string(CV_folds);

            app.CorpusCallosumNetworksCheckBox.Value = Corpus_check;

            if exist('K_range_h_gm','var') && exist('K_range_l_gm','var') && exist('CV_folds_gm','var')
                app.KmeansGMKRangeTextArea.Value = string(K_range_h_gm) ;
                app.KmeansKRangeTextArea_3.Value = string(K_range_l_gm) ;
                app.NumCVfoldsTextArea_2.Value = string(CV_folds_gm);
            end


            app.OverwriteexistingfilesCheckBox.Value = over_write;
            if exist(fullfile(output_folder,'Subj_list.csv'),"file")
                load_subjects_dropdown(app,Subj_list)
            end
        end

        function load_subjects_dropdown(app,Subj_list,select_by)
            if nargin < 3
                select_by = 'Load_subject';
            end
            if ~isempty(Subj_list)
                Subj_names = struct2cell(Subj_list);
                Subj_names(2:end,:) = [];

                switch select_by
                    case 'Data_path'
                        app.Label_7.Text = ["No.of folders in" ['Subject data folder: ' num2str(length(Subj_names))]];
                        app.Label_7.Tooltip = 'Number of subfolders in the Subject data folder.';
                    case 'Load_subject'
                        app.Label_4.Text = ['No.of Subjects: ' num2str(length(Subj_names))];
                        app.Label_4.Tooltip = 'Number of Subjects with good data in the Subject data folder.';
                end
            else
                msgbox('No subjects found in the Subj_list.csv file')
            end
        end

        function load_subjects_dropdown2(app,Subj_list)
            if ~isempty(Subj_list)
                Subj_names = struct2cell(Subj_list);
                Subj_names(2:end,:) = [];

                app.SelectedSubjectswithgooddataDropDown.Items = Subj_names;
                app.SelectedSubjectswithgooddataDropDown
                app.Label_5.Text = num2str(length(Subj_names));
            else
                msgbox('No subjects found in the Subj_list.csv file')
            end
        end

        function save_param(app,save_name,save_folder)
            data_path = string(app.DataPathTextArea.Value);
            output_folder = string(app.DataPathTextArea_2.Value);
            bids_check = app.BIDSCheckBox.Value;
            comm_subj_name = app.comm_subj_name; %#ok<ADPROPLC> % % Can be used for future if researchers want to analyse a subset of subjects in the data folder
            comm_sess_name = app.comm_sess_name; %#ok<ADPROPLC> % % Can be used for future if researchers have dataset where there are multiple session folders

            %   Set functional and anatomical data folder name
            func_folder_name = app.func_folder_name ;    %#ok<ADPROPLC> % % Can be used in the future if the Functional folder name is different
            anat_folder_name = app.anat_folder_name;     %#ok<ADPROPLC> % % % Can be used in the future if the Anatomical folder name is different

            %   Set functional and anatomical data basic file name
            func_data_name = app.func_data_name;  %#ok<ADPROPLC> % %%string(app.fMRIfilenameTextArea.Value)  ;     % %   Functional data name
            anat_data_name = app.anat_data_name;  %#ok<ADPROPLC> % %%string(app.sMRIfilenameTextArea.Value)  ;     % %   Anatomical data name

            n_vol_dis = string(app.NoofVolumestoDiscardTextArea.Value);
            max_fd = app.maxDropDown.Value;                                                               % Maximum translation allowed for Framewize displacement
            mean_fd = app.meanDropDown.Value;
            greater_than_20 = app.Greaterthan20DropDown.Value;

            %                 CSF_thres = string(app.SegmentationCSFThresholdTextArea.Value);
            CSF_thres = app.SegmentationCSFThreshold;
            Reg_drop = app.NuisanceRegressionDropDown.Value;
            n_pca = (string(app.NoofPCAcomponents));

            motion_reg = app.MotionParametersCheckBox.Value;

            filter_lp = string(app.TemporalFilteringHzTextArea.Value);
            filter_hp = string(app.FilterTextArea_2.Value);
            smooth_drop = app.SmoothingDropDown.Value;
            smooth_fwhm = (string(app.FWHMmmTextArea.Value));
            vox = string(app.NormalizationvoxeldimmmTextArea.Value);

            grp_wm_thres = string(app.GroupWMthresholdTextArea.Value);
            grp_gm_thres = string(app.GroupGMthresholdTextArea.Value);

            K_range_l = string(app.KmeansKRangeTextArea_2.Value);
            K_range_h = string(app.KmeansWMKRangeTextArea.Value);
            CV_folds = string(app.NumCVfoldsTextArea.Value);

            Corpus_check = app.CorpusCallosumNetworksCheckBox.Value;

            K_range_l_gm = string(app.KmeansKRangeTextArea_3.Value);
            K_range_h_gm = string(app.KmeansGMKRangeTextArea.Value);
            CV_folds_gm = string(app.NumCVfoldsTextArea_2.Value);

            over_write = app.OverwriteexistingfilesCheckBox.Value;
            filter_check = app.FilterCheckBox.Value;
            save(fullfile(save_folder,save_name),"data_path","output_folder",'comm_sess_name','comm_subj_name','bids_check',...
                'func_folder_name','anat_folder_name','func_data_name',"anat_data_name",'n_vol_dis',...
                'max_fd','CSF_thres','Reg_drop','n_pca','motion_reg','filter_lp','filter_hp',"smooth_drop",...
                "smooth_fwhm","vox","grp_gm_thres","grp_wm_thres","K_range_l",'K_range_h',"CV_folds","K_range_l_gm",'K_range_h_gm'...
                ,"CV_folds_gm","Corpus_check",...
                "over_write",'filter_check','mean_fd','greater_than_20')
        end

        function [Subj_list,rm] = load_subjects(~,folder,name,first)
            if nargin < 4
                first = 0;
            end
            try
                opts = detectImportOptions(fullfile(folder,name));
                opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
                T1 = readtable(fullfile(folder,name),opts);

                T = readtable(fullfile(folder,name));

                T.name = T1.name;
                
                if first
                    T.error = zeros(height(T),1);
                    T.motion_ex = zeros(height(T),1);
                    rm = logical(T.manual_ex);
                    Subj_list = table2struct(T(~rm,:));
                else
                    rm = (logical(T.error) | logical(T.motion_ex) | logical(T.manual_ex));
                    Subj_list = table2struct(T(~rm,:));
                end
            catch
                Subj_list = [] ;
            end

        end

        function Subj_list_all = load_subjects_all(app,folder,name,new_run)
            if nargin < 4
                new_run = 0;
            end

            opts = detectImportOptions(fullfile(folder,name));
            opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
            T1 = readtable(fullfile(folder,name),opts);

            T = readtable(fullfile(folder,name));

            T.name = T1.name;

            try
                if new_run
                    T.error = zeros(height(T),1);
                    if ~ismember('motion_ex', T.Properties.VariableNames)
                        T.motion_ex = zeros(height(T),1);
                    end

                    if app.OverwriteexistingfilesCheckBox.Value
                        T.motion_ex = zeros(height(T),1);
                    end
                    Subj_list_all = table2struct(T);

                else

                    Subj_list_all = table2struct(T);
                end
            catch
                Subj_list_all = [] ;
            end

        end
        
        function Dice_coef_LR = get_symettry(~,current_clustering_solution)
            % separating the left and right sides of the clustering solutions, and flipping the right side, for direct comparison to the left
            Mid_sagittal_slice = round(size(current_clustering_solution,1)/2);
            current_clustering_solution_L = current_clustering_solution(1:Mid_sagittal_slice,:,:);
            current_clustering_solution_R = flipdim(current_clustering_solution,1); 
            current_clustering_solution_R = current_clustering_solution_R(1:Mid_sagittal_slice,:,:);

            % looking only at places where white-matter exists in both hemispheres
            % (zeroing places where that is not true)
            current_clustering_solution_L((current_clustering_solution_L & current_clustering_solution_R) ==0) = 0;
            current_clustering_solution_R((current_clustering_solution_L & current_clustering_solution_R) ==0) = 0;
            clustered_voxels = find(current_clustering_solution_L(:)>0);    % finding all voxels belonging to the white-matter in both hemispheres

            % creating adjacency matrices for similarity between clustering solutions
            % (1 indicates the two voxels belong to the same cluster)
            adjmat_L = zeros(length(clustered_voxels)); adjmat_R = zeros(length(clustered_voxels));
            for i=1:length(clustered_voxels)
                for j=1:length(clustered_voxels)
                    if current_clustering_solution_L(clustered_voxels(i))==current_clustering_solution_L(clustered_voxels(j))
                        adjmat_L(i,j)=1;
                    end
                    if current_clustering_solution_R(clustered_voxels(i))==current_clustering_solution_R(clustered_voxels(j))
                        adjmat_R(i,j)=1;
                    end
                end
            end
            % computing Dice's coefficient of similarity between the clustering results for both hemispheres
            Dice_coef_LR = 2*sum(adjmat_R(:) & adjmat_L(:)) / (sum(adjmat_R(:)) + sum(adjmat_L(:)));

        end

        function Dice_coef_LR = get_symettry_indi(~,current_clustering_solution)
            % separating the left and right sides of the clustering solutions, and flipping the right side, for direct comparison to the left
            Mid_sagittal_slice = round(size(current_clustering_solution,1)/2);
            current_clustering_solution_L = current_clustering_solution(1:Mid_sagittal_slice,:,:);
            current_clustering_solution_R = flipdim(current_clustering_solution,1); 
            current_clustering_solution_R = current_clustering_solution_R(1:Mid_sagittal_slice,:,:);

            % computing Dice's coefficient of similarity between the clustering results for both hemispheres
            [Dice_coef_LR,~] = dice_iou(current_clustering_solution_L,current_clustering_solution_R,0,0);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tooltip(app)
            % addpaths
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(preproc_code_path)
            addpath(fullfile(preproc_code_path,'whifun_functions'))
            %% Setup Tab
            app.OutputsfolderButton.Tooltip = 'Select the folder path where all the quality control plots generated during preprocessing, analysis results of the toolbox will be stored. This should be an empty folder.';
            app.DataPathTextArea_2.Tooltip = 'Paste the folder path where all the quality control plots generated during preprocessing, analysis results of the toolbox will be stored. This should be an empty folder.';

            app.SubjectDatafolderButton.Tooltip = 'Select the main folder containing subfolders for each subject. Each sub folder should contain the anatomical and the functional MRI scans. If the main folder data structure is not BIDS then uncheck the BIDS check box on the right and specify the details of the data structure.';
            app.DataPathTextArea.Tooltip = 'Paste the folder path of the main folder containing subfolders for each subject. Each sub folder should contain the anatomical and the functional MRI scans. If the main folder data structure is not BIDS then uncheck the BIDS check box on the right and specify the details of the data structure.';

            app.SelectSubjectsButton.Tooltip = 'Select the folders that correspond to subject data which needs to be processed.';
            
            app.AllfoldersareSubjectsCheckBox.Tooltip = 'Check to select all the folders as subject folders';

            
            %% Check data tab

            app.RunDataCheckButton.Tooltip = 'Check the Image files and associated parameters of all the subjects';
            
            app.SubjectInfoButton.Tooltip = 'List all subjects and the parameters corresponding to every subject detected during the Data check';
            %% Preprocessing Tab
            app.NoofVolumestoDiscardTextArea.Tooltip = 'Discard initial volumes, put 0 if no volumes should be discarded';
            app.NoofVolumestoDiscardTextAreaLabel.Tooltip = 'Discard initial volumes, put 0 if no volumes should be discarded';


            app.FramewiseDisplacementFDtootipthresholdsformotionmmLabel.Tooltip = 'Framewise displacement (FD): A measure to reject subjects having excessive motion. FD is calculated for every time point. Calculated using equations given by Power et. al. (2011) ';
            app.FramewiseDisplacementLabel.Tooltip = 'Threshold on max FD, if the max FD of a subject is greater than this threshold then the subject will be rejected';
            app.maxDropDown.Tooltip = 'Threshold on max FD, if the max FD of a subject is greater than this threshold then the subject will be rejected';

            app.meanDropDown.Tooltip = 'Threshold on mean FD, if the mean FD computed over all timepoints of a subject is greater than this threshold then the subject will be rejected';
            app.meanDropDownLabel.Tooltip = 'Threshold on mean FD, if the mean FD computed over all timepoints of a subject is greater than this threshold then the subject will be rejected';

            app.Greaterthan20DropDown.Tooltip = 'Threshold on greater than 20%, if the FD values is greater than this threshold for 20% or more volumes, then the subject will be rejected';
            app.Greaterthan20DropDownLabel.Tooltip = 'Threshold on greater than 20%, if the FD values is greater than this threshold for 20% or more volumes, then the subject will be rejected';

            app.NuisanceRegressionDropDown.Tooltip = 'Regress out the noise from all the voxels in the brain. It is assumed that the BOLD signal from CSF regions is noisy as it contains no neuronal information. Thus the user can regress out, either the mean CSF BOLD signal or the First n PCA components of the CSF BOLD signal. ';
            app.NuisanceRegressionLabel.Tooltip = 'Regress out the noise from all the voxels in the brain.';
            app.MotionParametersCheckBox.Tooltip = 'Checking this will also regress out the 24 Friston motion parameters (Friston et. al. 1996) from every voxel in the brain.';

            app.SmoothingDropDown.Tooltip = 'Smoothing options';
            app.FWHMmmTextArea.Tooltip = 'Fixed width half maxima';
            app.FWHMmmTextAreaLabel.Tooltip = 'Fixed width half maxima';

            app.TemporalFilteringHzLabel.Tooltip = 'Temporal Butterworth bandpass filter of 2nd order';
            app.FilterCheckBox.Tooltip = 'If checked, Butterworth bandpass filter of 2nd order will be applied on every brain voxel';
            app.TemporalFilteringHzTextArea.Tooltip = 'Low frequency cutoff';
            app.FilterTextArea_2.Tooltip = 'High frequency cutoff';

            app.NormalizationvoxeldimmmTextArea.Tooltip = 'Voxel Size of the Normalized (MNI) space';
            app.NormalizationvoxeldimmmTextAreaLabel.Tooltip = 'Transform the images from Subject space to Montreal Neurological Institute (MNI) space';

            app.OverwriteexistingfilesCheckBox.Tooltip = 'If checked any existing files if found will be deleted and the preprocessing will start from the start';

            %% Construct FN and FC Tab
            app.ManuallyexcludesubjectsCheckBox.Tooltip = 'Manually Select subjects that should be excluded, usually based on the quality control plots and images';
            app.SelectedSubjectswithgooddataDropDown.Tooltip = 'Subjects selected for further processing';
            app.Label_5.Tooltip = 'Total number of Subjects selected for further processing';
            app.SelectedSubjectswithgooddataLabel.Tooltip = 'Subjects selected for further processing';
            app.AllSubjectsaregoodCheckBox.Tooltip = 'Use data from all subjects for further processing.';
            % White matter networks

            app.GroupWMmaskthresholdLabel.Tooltip = 'Threshold for Creating Group White Matter mask: If the max tissue probability of this threshold % of subjects say that a voxel is White Matter, then it will be included in the group white matter mask';
            app.GroupWMthresholdTextArea.Tooltip = 'Threshold for Creating Group White Matter mask: If the max tissue probability of this threshold % of subjects say that a voxel is White Matter, then it will be included in the group white matter mask';

            app.KmeansKRangeTextArea_2.Tooltip = 'lower K value for the Grid search';
            app.KmeansWMKRangeTextArea.Tooltip = 'Higher K value for the Grid search';

            app.NumCVfoldsTextAreaLabel.Tooltip = 'Number of Cross validation folds for the optimal K value search';
            app.NumCVfoldsTextArea.Tooltip = 'Number of Cross validation folds for the optimal K value search';

            app.CorpusCallosumNetworksCheckBox.Tooltip = 'If Checked the Group white matter networks wont contain the corpus callosum and Corpus callosum networks will be created  using a predefined Corpus callosum mask';

            % Gray matter Networks
            app.GroupGMmaskthresholdLabel.Tooltip = 'Threshold for Creating Group Gray Matter mask: If the max tissue probability of this threshold % of subjects say that a voxel is Gray Matter and that it was not included in the White matter mask, then it will be included in the group gray matter mask';
            app.GroupGMthresholdTextArea.Tooltip = 'Threshold for Creating Group Gray Matter mask: If the max tissue probability of this threshold % of subjects say that a voxel is Gray Matter and that it was not included in the White matter mask, then it will be included in the group gray matter mask';

            app.KmeansKRangeTextArea_3.Tooltip = 'lower K value for the Grid search';
            app.KmeansGMKRangeTextArea.Tooltip = 'Higher K value for the Grid search';
            app.KmeansGMKRangeTextAreaLabel.Tooltip = 'Search range for K-means K values. The average dice coeficient and the elbow will be calculated for the range of K values specified here with cross validation technique as described in (Peer et. al. 2017)';

            app.NumCVfoldsTextAreaLabel.Tooltip = 'Number of Cross validation folds for the optimal K value search';
            app.NumCVfoldsTextArea_2.Tooltip = 'Number of Cross validation folds for the optimal K value search';

            % Extract time Series

            app.NetworksAtlasButton.Tooltip = 'Choose a network or an atlas nifti file (.nii)';
            %             app.AllFreqCheckBox.Tooltip = 'If checked all the no filter will be applied to the averaged timeseries for every Region of interest';
            app.NumberofFreqBandsLabel.Tooltip = 'Choose the number of Frequency bands';
            app.FrequenciesperbandButton.Tooltip = 'Choose the name and the frequency range for each band';

            %             app.sMRIfilenameTextAreaLabel.Tooltip = 'Write the name of the anatomical image without extension (.nii or .nii.gz)';
            %             app.fMRIfilenameLabel.Tooltip = 'Write the name of the functional image without extension (.nii or .nii.gz)';
        end

        % Button pushed function: SubjectDatafolderButton
        function SubjectDatafolderButtonPushed(app, event)
            try
                Sub_folder = uigetdir(pwd,'Select the Subject Directory');
            catch
            end
            if Sub_folder ~= 0
                app.DataPathTextArea.Value = Sub_folder;


                data_path = char(string(app.DataPathTextArea.Value));

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list = dir( app.comm_subj_name );
                if strcmp(Subj_list(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                    Subj_list(1) = [];
                end

                if strcmp(Subj_list(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                    Subj_list(1) = [];
                end

                nt_dir = false(1,length(Subj_list));                                        % make a list of other files that are not directories
                for i = 1:length(Subj_list)
                    if Subj_list(i).isdir ~= 1
                        nt_dir(i) = true;
                    end
                end
                Subj_list(nt_dir) = [];                                                     % Remove all the files that are not directories (Now Subj_list should have all subject files)
                clear nt_dir                                                                % Clear nt_dir after use

                load_subjects_dropdown(app,Subj_list,'Data_path')
            end
        end

        % Button pushed function: OutputsfolderButton
        function OutputsfolderButtonPushed(app, event)
            try
                output_folder = uigetdir(pwd,'Select the Output folder');
            catch
            end
            if output_folder ~= 0
                app.DataPathTextArea_2.Value = output_folder;
            end
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            try
                [save_name,save_folder] = uiputfile('*.mat','Save Parameters',pwd);
            catch
            end
            if save_folder ~= 0
                save_param(app,save_name,save_folder)
            end
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            try
                [load_name,load_folder] = uigetfile('*.mat','Load Parameters',pwd);
            catch
            end
            %             global app.comm_subj_name app.comm_sess_name app.func_folder_name app.anat_folder_name %#ok<NUSED,GVMIS>
            if load_name ~= 0
                load_param(app,load_folder,load_name)
            end
        end

        % Button pushed function: RunPreprocessingButton
        function RunPreprocessingButtonPushed(app, event)

            %% This code written by Pratik Jain.
            %% This code was written with the help of different preprocessing scripts given by
            %% Dr. Xin Di, Dr. Rakibul Hafeez, Dr. Wang Pan, Donna Chen and Wonbum Sohn
            %% Under guidance of Dr Andrew Micheal and Dr. Bharat Biswal.

            %%          Defining Important paths and Creating Output directories

            data_path = char(string(app.DataPathTextArea.Value));

            if isempty(data_path)
                msgbox('Please specify the Subject folder, Run Data Check, define Preprocessing parameters and then Run Preprocessing.','Subject Data folder path empty')
                return
            end
            output_folder = char(string(app.DataPathTextArea_2.Value));

            if isempty(output_folder)
                msgbox('Please specify the output_folder, Run Data Check, define Preprocessing parameters and then Run Preprocessing.','Output folder Path empty')
                return
            end
            quality_control_path = fullfile(output_folder,'Quality_control');

            load(fullfile(output_folder,'parameters.mat'))                           %#ok<LOAD> % load parameters saved during initial data check, if they were changed after initial data check, the change will be applied later in the code.
            Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv',1);
            Subj_list = load_subjects(app,output_folder,'Subj_list.csv',1);
            if ~exist(fullfile(output_folder,'Analysis','Group_Masks'),'dir')
                msgbox('Please Run Initial Data check before Running the Preprocessing and Analyses ', 'Run Initial Data Check')
                return
            end
            %             comm_subj_name = app.comm_subj_name; %#ok<ADPROPLC>
            comm_sess_name = app.comm_sess_name; %#ok<ADPROPLC>
            func_folder_name = app.func_folder_name; %#ok<ADPROPLC>
            anat_folder_name = app.anat_folder_name; %#ok<ADPROPLC>


            spm_path = which('spm');
            spm_path = spm_path(1:end-5);
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);

            addpath(preproc_code_path)

            if app.CorpusCallosumNetworksCheckBox.Value == 1
                mkdir(fullfile(output_folder,'Analysis','ttest'))
            end


            %%          Get the Anatomical and Functional Image Paths

            if strcmp(app.comm_subj_name,'') == 1
                app.comm_subj_name = '*';
            end
            cd (data_path);
            filter_check = app.FilterCheckBox.Value;
            %   Set functional and anatomical data basic file name
            func_data_name = app.func_data_name ;       %#ok<ADPROPLC> % %   Functional data name
            anat_data_name = app.anat_data_name ;     %#ok<ADPROPLC> % %   Anatomical data name

            app.func_data_name = char(app.func_data_name) ;       % %   Functional data name
            app.anat_data_name = char(app.anat_data_name) ;     % %   Anatomical data name

            %%          These values can be changed but default is recommended
            Cut_pre = 'c_';                                                             % Prefix for the Discarding Initial volumes File
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            skull_pre = 'b';                                                            % Prefix for the Skull Stripped File
            Reg_pre = 'REG_';                                                           % Prefix for the Regressed File
            f_pre = 'f';                                                                % prefix for filtered file
            Norm_pre = 'w';                                                             % Prefix for the Normalized File
            Smooth_pre = 's';                                                           % Prefix for the Smoothed File

            %%          Parameters

            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard
            if n_vol_dis == 0
                Cut_pre = '';
            end
            % head motion control parameters
            max_fd_thres = str2double(app.maxDropDown.Value);                                                               % Maximum translation allowed for Framewize displacement
            mean_fd_thres = str2double(app.meanDropDown.Value);
            great20_fd_thres = str2double(app.Greaterthan20DropDown.Value);

            CSF_thres = char(string(app.SegmentationCSFThreshold));

            motion_reg = app.MotionParametersCheckBox.Value;
            % PCA or Mean for WM and CSF timeseries data that will be used as a
            % regressor in temporal Regression
            % 1 --> Will use PCA for getting the CSF Components
            % 0 --> will use mean WM and CSF components (faster)
            Reg_drop = app.NuisanceRegressionDropDown.Value;
            switch Reg_drop
                case 'Mean CSF'
                    pca_for_temp_reg = 0;
                    Reg_ = 1;
                case 'PCA CSF'
                    pca_for_temp_reg = 1;
                    n_pca = double(string(app.NoofPCAcomponents));                   % number of pca components for WM and CSF (choose a value between 1 and 5)
                    Reg_ = 1;
                case 'No Regression'
                    Reg_pre = '';
                    Reg_ = 0;
            end
            if filter_check


                filter_lp = double(string(app.TemporalFilteringHzTextArea.Value));
                filter_hp = double(string(app.FilterTextArea_2.Value));

                if filter_lp > filter_hp
                    error(sprintf('Please check the filter cutoffs, lower_cutoff should be less than higher cutoff, \nbut found otherwise\n'))
                end
            else
                f_pre = '';

            end
            vox = double(string(app.NormalizationvoxeldimmmTextArea.Value));
            smooth_fwhm = double(string(app.FWHMmmTextArea.Value));                                                            % Smoothing Full width half maximum
            smooth_drop = app.SmoothingDropDown.Value;
            switch smooth_drop
                case 'WM-GM Seperate'
                    WM_GM = 1;
                    Smooth_ = 1;
                case 'All Together'
                    WM_GM = 0;
                    Smooth_ = 1;
                case 'No Smoothing'
                    Smooth_pre = '';
                    Smooth_ = 0;
            end

            K_range_l = double(string(app.KmeansKRangeTextArea_2.Value));
            K_range_h = double(string(app.KmeansWMKRangeTextArea.Value));


            if K_range_l > K_range_h
                error('Please check the K-range 1st number in the text field should be less than 2nd number, but found otherwise')
            end

            Corpus_check = app.CorpusCallosumNetworksCheckBox.Value;
            n_image = [Subj_list.nt];
            if Corpus_check == 1
                if ~prod(mean(n_image) == n_image)
                    for_error = struct2table(rmfield(Subj_list,{'date','bytes','isdir','datenum'}));
                    disp(for_error)
                    error('Error in Corpus Callosum Networks: The number of timepoints (nt) for all the subjects should be the same to compute the corpus callosum networks. The number of time points corresponding to every subject is displayed above')

                end
            end
            %%          Error handeling parameters
            % Ignore any previous results
            % -> 1 If previous preprocessing files exist (delete them and rerun)
            % -> 0 If previous preprocessing files exits (Keep them and skip)
            %             rm_sub = false(length(app.Subj_list),1);
            over_write = app.OverwriteexistingfilesCheckBox.Value;

            % Saving all the parameters in Output Folder for future
            % reference
            save_param(app,'parameters.mat',output_folder)

            %%          Unzipping
            disp('##########################################################################################')
            disp('Unzipping files')
            %% Functional Data
            %   Loop all subjects in this group
            %             Subj_list = app.Subj_list; % % This is loaded from the saved parameters file during initial data check
            n_tot = length(Subj_list);
            for subji=1:n_tot %par

                tic
                now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[func_data_name '.nii'])) ; %#ok<ADPROPLC>
                if isempty(now_func_path)
                    now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[func_data_name '.nii*'])) ; %#ok<ADPROPLC>
                end
                if length(now_func_path)>1  % If more than one func files found choose the one that was created the first

                    [~,idx] = sort([now_func_path.datenum]);
                    now_func_path = now_func_path(idx);
                    now_func_path(2:end) = [];
                    warning(['More than one functional files found. Choosing the file ' ,char(now_func_path(1).name), ' as it was created the first.']);
                end
                cd(now_func_path(1).folder)
                %   To extract the names of existing nii.gz files
                file_name = now_func_path.name ;            %   Ex: file name.nii.gz
                separate_name = split(file_name, '.') ;     %   Ex: 3×1 cell array: {'file name'} {'nii'        } {'gz'         }
                data_name = char(separate_name(1)) ;        %   Ex: file name
                data_type = char(separate_name(2)) ;        %   Ex: nii

                %   If there is unzipped .nii file, skip this unzipping step
                if ~isfile([data_name '.' data_type])       %   If there is no nii file
                    if isfile(file_name)                  %   If there is gz file
                        gunzip(file_name)
                        disp(['Complete unziping of functional data in' file_name])
                    elseif ~isfile(file_name)             %   If there is not gz file also
                        disp('You need a .nii or a .gz file of functional data for further processing.')
                    end
                elseif isfile([data_name '.' data_type])    %   If there is nii file already
                    disp(['You have already a .nii file of functional data for subject ,' Subj_list(subji).name,' so you do not need to unzip a .gz file.'])
                end

                %% Anatomical data

                now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,anat_folder_name,[anat_data_name '.nii'])) ; %#ok<ADPROPLC>
                if isempty(now_anat_path)
                    now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,anat_folder_name,[anat_data_name '.nii*'])) ; %#ok<ADPROPLC>
                end
                if length(now_anat_path)>1  % If more than one func files found choose the one that was created the first

                    [~,idx] = sort([now_anat_path.datenum]);
                    now_anat_path = now_anat_path(idx);
                    now_anat_path(2:end) = [];
                    warning(['More than one Anatomical files found. Choosing the file ' ,char(now_anat_path(1).name), ' as it was created the first.']);
                end
                cd(now_anat_path(1).folder)

                %   To extract the names of existing nii.gz files
                file_name = now_anat_path.name ;               %   Ex: file name.nii.gz
                separate_name = split(file_name, '.') ;     %   Ex: 3×1 cell array: {'file name'} {'nii'        } {'gz'         }
                data_name = char(separate_name(1)) ;        %   Ex: file name
                data_type = char(separate_name(2)) ;        %   Ex: nii

                %   If there is unzipped .nii file, skip this unzipping step
                if ~isfile([data_name '.' data_type])       %   If there is not nii file
                    if isfile(file_name)                  %   If there is gz file
                        gunzip(file_name)
                        disp(['Complete unziping of anatomical data in' file_name])
                    elseif ~isfile(file_name)             %   If there is not gz file also
                        disp('You need a .nii or a .gz file of anatomical data for further processing.')
                    end
                elseif isfile([data_name '.' data_type])    %   If there is nii file already
                    disp(['You have already a .nii file of anatomical data,' Subj_list(subji).name,' so skipping to unzip  .gz file.'])
                end


                %%          Anatomical and Functional initial alignment check
                disp('Anatomical and Functional initial alignment check')

                disp(['Currently Processing ' Subj_list(subji).name])
                now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[func_data_name '.nii'])) ; %#ok<ADPROPLC>

                now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,anat_folder_name,[anat_data_name '.nii'])) ; %#ok<ADPROPLC>
                if length(now_anat_path) > 1

                    [~,idx] = sort([now_anat_path.datenum]);
                    now_anat_path = now_anat_path(idx);
                    now_anat_path(2:end) = [];
                    warning(['More than one Anatomical files found. Choosing the file ' ,char(now_anat_path(1).name), ' as it was created the first.']);
                end

                if length(now_func_path) > 1

                    [~,idx] = sort([now_func_path.datenum]);
                    now_func_path = now_func_path(idx);
                    now_func_path(2:end) = [];
                    warning(['More than one functional files found. Choosing the file ' ,char(now_func_path(1).name), ' as it was created the first.']);
                end
                % Check if the discarded volume file already exists
                if over_write == 1 % if overwrite is 1, even if the file already exists delete it and create a new one
                    init_check_func_dir = dir(fullfile(quality_control_path,'Initial_check',[Subj_list(subji).name '_func.png'])) ;
                    init_check_anat_dir = dir(fullfile(quality_control_path,'Initial_check',[Subj_list(subji).name '_anat.png'])) ;
                    if ~isempty(init_check_func_dir) && ~isempty(init_check_anat_dir)
                        delete(fullfile(init_check_func_dir.folder,init_check_func_dir.name))
                        delete(fullfile(init_check_anat_dir.folder,init_check_anat_dir.name))
                    end
                    init_check_func_dir = [];
                    init_check_anat_dir = [];

                else
                    init_check_func_dir = dir(fullfile(quality_control_path,'Initial_check',[Subj_list(subji).name '_func.png'])) ;
                    init_check_anat_dir = dir(fullfile(quality_control_path,'Initial_check',[Subj_list(subji).name '_anat.png'])) ;
                end

                if isempty(init_check_func_dir) || isempty(init_check_anat_dir)
                    % Initial anat file check
                    % Plot the anatomical image and check its intitial position with reference to the MNI template

                    imgs = char(fullfile(now_anat_path.folder,now_anat_path.name),...
                        fullfile(spm_path,'canonical\single_subj_T1.nii'));
                    spm_check_registration(imgs);  % Plot the two images using Check Registration

                    % Display the participant's ID
                    spm_orthviews('Caption', 1, Subj_list(subji).name);
                    spm_orthviews('Caption', 2, 'single_subj_T1 (MNI)');

                    % Display contour of 1st image onto 2nd
                    spm_orthviews('contour','display',1,2);
                    saveas(gcf,fullfile(quality_control_path,'Initial_check',[Subj_list(subji).name '_anat.png']),'png')
                    close gcf

                    % Initial func file check
                    % Plot the first functional image and check its intitial position with reference to the MNI template

                    imgs = char(fullfile(now_func_path.folder,[now_func_path.name ',1']),...
                        fullfile(spm_path,'canonical\single_subj_T1.nii'));
                    spm_check_registration(imgs);  % Plot the two images using Check Registration

                    % Display the participant's ID
                    spm_orthviews('Caption', 1, Subj_list(subji).name);
                    spm_orthviews('Caption', 2, 'single_subj_T1 (MNI)');

                    % Display contour of 1st image onto 2nd
                    spm_orthviews('contour','display',1,2);
                    saveas(gcf,fullfile(quality_control_path,'Initial_check',[Subj_list(subji).name '_func.png']),'png')
                    close gcf
                    fprintf('done!\n')
                else
                    disp(['Anatomical and Functional initial alignment check already done for subject ' Subj_list(subji).name ' hence skipping this step'])
                end

                disp('Anatomical and Functional initial alignment check done')

                if n_vol_dis ~= 0
                    disp('Discarding Initial Volume')

                    disp(['Currently Processing ' Subj_list(subji).name])
                    try
                        % Check if the discarded volume file already exists
                        if over_write == 1 % if overwrite is 1, even if the file already exists delete it and create a new one
                            cfunc_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>
                            if ~isempty(cfunc_dir)
                                delete(fullfile(cfunc_dir.folder,cfunc_dir.name))
                            end
                            cfunc_dir = [];

                        else
                            cfunc_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>
                        end

                        if isempty(cfunc_dir)
                            now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[func_data_name '.nii'])) ; %#ok<ADPROPLC>
                            if length(now_func_path) > 1

                                [~,idx] = sort([now_func_path.datenum]);
                                now_func_path = now_func_path(idx);
                                now_func_path(2:end) = [];
                                warning(['More than one functional files found. Choosing the file ' ,char(now_func_path(1).name), ' as it was created the first.']);
                            end
                            func_info = niftiinfo(fullfile(now_func_path.folder,now_func_path.name));    % read the nifti header
                            func_image = niftiread(fullfile(now_func_path.folder,now_func_path.name));   % read the image

                            cfunc_image = func_image(:,:,:,n_vol_dis+1:end);                             % Discard the volumes
                            Subj_list(subji).nt_dis = Subj_list(subji).nt - n_vol_dis;
                            %                             Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).nt_dis = Subj_list(subji).nt - n_vol_dis;
                            niftisave(cfunc_image,fullfile(now_func_path.folder,[Cut_pre ,now_func_path.name]),func_info); % Save the new file
                            disp(['Discarding volumes is complete for ' Subj_list(subji).name])
                        else
                            info = niftiinfo(fullfile(cfunc_dir.folder,cfunc_dir.name));
                            Subj_list(subji).nt_dis = info.ImageSize(4);
                            %                             Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).nt_dis = info.ImageSize(4);
                        end

                    catch exception                                                                       % If error is found

                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in Discarding Initial Volumes for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')


                        write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                        Subj_list(subji).error = 1;
                        %                         Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).error = 1;         % Remove subject from further preprocessing
                        continue
                    end
                    disp('Discarding Volumes over')
                else
                    Subj_list(subji).nt_dis = Subj_list(subji).nt;

                end

                %%          Realignment
                disp('Realignment Begins')

                try

                    % Check if any previous realignment file exists
                    if over_write == 1
                        rfunc_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Realign_pre Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>
                        if ~isempty(rfunc_dir)
                            delete(fullfile(rfunc_dir.folder,rfunc_dir.name))
                        end
                        rfunc_dir = [];

                    else
                        rfunc_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Realign_pre Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>
                    end

                    if isempty(rfunc_dir)
                        now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>

                        if length(now_func_path) > 1

                            [~,idx] = sort([now_func_path.datenum]);
                            now_func_path = now_func_path(idx);
                            now_func_path(2:end) = [];
                            warning(['More than one functional files found. Choosing the file ' ,char(now_func_path(1).name), ' as it was created the first.']);
                        end
                        cd(now_func_path(1).folder)
                        temp = niftiinfo(fullfile(now_func_path.folder,now_func_path.name));
                        nt = temp.ImageSize(4);
                        whifun_realignment(now_func_path,Realign_pre,nt)

                    else
                        disp(['Subject ' Subj_list(subji).name ' is already realigned hence skipping this step'])
                    end

                catch exception                                                                       % If error is found
                    err_fileID = fopen(fullfile(quality_control_path,'error_info.txt'),'a');
                    fprintf(err_fileID,['Code ran on ' char(datetime) '\n \n']);
                    fclose(err_fileID);
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp(['Preprocessing has encountered errors in Realignment for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display

                    Subj_list(subji).error = 1;                                                       % Remove subject from further preprocessing
                    continue
                end
                disp('Realignment over')
                %% Framewise displacement
                disp('Framewise Displacement Begins')

                disp(['Currently Processing ' Subj_list(subji).name])
                now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                cd(now_func_path(1).folder)


                if over_write == 1
                    head_mot_qc_file = dir(fullfile(quality_control_path,'Head_motion',[Subj_list(subji).name '.png'])); % Check for a previous head motion qc file
                    if ~isempty(head_mot_qc_file)
                        delete(fullfile(head_mot_qc_file.folder,head_mot_qc_file.name))
                    end
                    head_mot_qc_file = [];
                else
                    head_mot_qc_file = dir(fullfile(quality_control_path,'Head_motion',[Subj_list(subji).name '.png'])); % Check for a previous head motion qc file
                end

                if isempty(head_mot_qc_file)
                    func_image = niftiread(fullfile(now_func_path.folder,now_func_path.name)); % Read the func file
                    [x,y,z,nt] = size(func_image);                                             % get the size
                    func_image = reshape(func_image,x*y*z,nt);                                 % Reshape into voxel x timepoints

                    gm = mean(func_image,'all','omitnan');                                               % grand mean (4D)

                    % calculate pairwise variance
                    dt = zeros(1,nt-1);
                    for imagei = 1:nt-1
                        dt(imagei) = (mean((func_image(:,imagei) - func_image(:,imagei+1)).^2,'omitnan'))/gm;
                    end

                    meany = mean(func_image,'omitnan')./gm;                                              % scaled global mean
                end
                now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,['rp_' Cut_pre app.func_data_name '.txt'])) ;
                rp_rest = load(fullfile(now_func_path.folder,now_func_path.name));              % input the text file generated at the Realignment stage

                rp_diff_trans = diff(rp_rest(:,1:3));                      % The first 3 parameters tell the displacement in x,y, and z direction in mm. Here the vector difference operator is used to get the derivative of vector. (framewise difference)
                %                 rp_diff_rotat = diff(rp_rest(:,4:6)*180/pi);             % % The last  3 parameters tell the rotation values pitch, yaw and roll in radians (here we convert them to degrees)
                rp_diff_rotat = diff(rp_rest(:,4:6)*50);                   % Converting angles to mm by asuming a 50mm radius circle


                fd = sum(rp_diff_trans,2) + sum(rp_diff_rotat,2)  ;
                [fd_max,loc_fd_max_trans] = max(fd);                     % Get the maximum framewize displacement
                fd_mean = mean(fd);                                               % Get the mean framewize displacement
                fd_greater_than_02 = nnz(fd>str2double(great20_fd_thres))/length(fd)*100;
                % plots
                if isempty(head_mot_qc_file) || over_write == 1
                    figure('Position', get(0,'screensize'))

                    subplot(2,2,1)
                    plot(meany)
                    title('Global mean (raw)');xlabel('Image number')
                    box off

                    subplot(2,2,3)
                    plot(dt)
                    yline(mean(dt)+3*std(dt),'-','3 SD','color',[0 0.4470 0.7410]);
                    title('Pairwise variance (raw)');xlabel('Image pair')
                    box off

                    subplot(2,2,2)
                    plot([rp_rest(:,1:3) rp_rest(:,4:6)*180/pi])
                    title('Rigid body motion');xlabel('Image number')
                    legend('Trans: x','Trans: y','Trans: z','Rot: pitch','Rot: roll','Rot: yaw','location','best')
                    box off

                    subplot(2,2,4)
                    plot(fd)
                    title('Framewise displacement');xlabel('Image pair')
                    yline(max_fd_thres,'r')
                    yline(str2double(great20_fd_thres),'k')
                    yline(str2double(mean_fd_thres),'y')
                    legend('Framewise displacement','Max Threshold','Mean Threshold','Greater than 20% threshold','location','best','box','off')
                    saveas(gcf,fullfile(quality_control_path,'Head_motion',[Subj_list(subji).name '.png']));
                    close gcf
                else
                    disp(['Head motion Quality check file found hence skipping this step for subject ' Subj_list(subji).name])
                end

                fileID = fopen(fullfile(quality_control_path,'Head_motion',['excluded_' Subj_list(subji).name '_.txt']),'w+');
                if fd_max > str2double(app.maxDropDown.Value)
                    msg = ['Excluded subject ' Subj_list(subji).name ' as it had max motion at location ' ...
                        num2str(loc_fd_max_trans) ' of ' num2str(fd_max) ' which is greater than ' num2str(max_fd_thres) ' mm max threshold'];
                    disp(msg) ;
                    fprintf(fileID, [msg '\n']);
                    Subj_list(subji).motion_ex = 1;
                end

                if fd_mean > str2double(app.meanDropDown.Value)
                    msg = ['Excluded subject ' Subj_list(subji).name ' as it had mean fd motion ',...
                        'of ' num2str(fd_mean) ' which is greater than ' num2str(app.meanDropDown.Value) ' mm mean threshold'];
                    disp(msg) ;
                    fprintf(fileID, [msg '\n']);
                    Subj_list(subji).motion_ex = 1;
                end

                if fd_greater_than_02 > 20
                    msg = ['Excluded subject ' Subj_list(subji).name ' as it had ',num2str(fd_greater_than_02) ' percent of fMRI volumes (timepoints) ',...
                        'greater than ' num2str(app.Greaterthan20DropDown.Value) ' threshold'];
                    disp(msg) ;
                    fprintf(fileID, [msg '\n']);

                    Subj_list(subji).motion_ex = 1;
                end

                if Subj_list(subji).motion_ex == 1
                    continue
                end

                disp('Framewise displacement over')
                %%          Segmentation

                disp('Segmentation has started')

                try
                    now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[app.anat_data_name '.nii'])) ;
                    if length(now_anat_path) > 1

                        [~,idx] = sort([now_anat_path.datenum]);
                        now_anat_path = now_anat_path(idx);
                        now_anat_path(2:end) = [];
                        warning(['More than one Anatomical files found. Choosing the file ' ,char(now_anat_path(1).name), ' as it was created the first.']);
                    end
                    if over_write == 1                                                  % Check for a previous segmentation file
                        y_banat_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['y_' app.anat_data_name '.nii'])) ;
                        if ~isempty(y_banat_dir)
                            delete(fullfile(y_banat_dir.folder,y_banat_dir.name))
                        end
                        y_banat_dir = [];
                    else
                        y_banat_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['y_' app.anat_data_name '.nii'])) ;
                    end
                    if isempty(y_banat_dir)
                        cd(now_anat_path(1).folder)
                        whifun_segment(now_anat_path,spm_path)

                    else
                        disp(['Segmentation file found for ' Subj_list(subji).name,'  hence skipping this step']);
                    end
                catch exception
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp(['Preprocessing has encountered errors in Segmentation for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                    write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                    Subj_list(subji).error = 1;                                                        % Remove subject from further preprocessing
                    continue
                end
                disp('Segmentation done')

                %% Segmentation QC

                now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[app.anat_data_name '.nii'])) ;
                whifun_segment_qc(quality_control_path,now_anat_path,spm_path,Subj_list(subji).name,over_write)

            %%          Skull Stripping

            disp('Skull Stripping started')
            
                disp(['Currently Processing ' Subj_list(subji).name])
                try

                    now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[app.anat_data_name '.nii'])) ;
                    if length(now_anat_path) > 1
                        
                        [~,idx] = sort([now_anat_path.datenum]);
                        now_anat_path = now_anat_path(idx);
                        now_anat_path(2:end) = [];
                        warning(['More than one Anatomical files found. Choosing the file ' ,char(now_anat_path(1).name), ' as it was created the first.']);
                    end
                    cd(now_anat_path(1).folder)
                    if over_write == 1
                        banat_dir = dir(fullfile(now_anat_path.folder,[skull_pre now_anat_path.name]));
                        if ~isempty(banat_dir)
                            delete(fullfile(banat_dir.folder,banat_dir.name))
                        end
                        banat_dir = [];%dir(fullfile(now_anat_path.folder,[skull_pre now_anat_path.name]));
                    else
                        banat_dir = dir(fullfile(now_anat_path.folder,[skull_pre app.anat_data_name '.nii']));
                    end

                    m_file = dir(fullfile(now_anat_path(1).folder, ['m' app.anat_data_name '.nii']));
                    if isempty(banat_dir)
                        
                        if length(m_file)>1
                            m_not_needed_file = dir(fullfile(now_anat_path(1).folder, ['mwc' app.anat_data_name '.nii']));

                            % convert structs into matrices
                            A = struct2cell(m_file); A(2:end,:) = [];
                            B = struct2cell(m_not_needed_file);  B(2:end,:) = [];
                            % intersect the equivalent representation
                            [~, ia] = intersect(A,B );
                            m_file(ia) = [];
                        end
                        whifun_skullstrip(m_file,now_anat_path,app.anat_data_name,skull_pre)
                    else
                        disp('Skull Stripped file found, hence skipping this step');
                    end

                    % Making mask for regression
                    disp('Making Mask for regression')
                    if over_write == 1
                        anat_mask = dir(fullfile(now_anat_path.folder,'anat_mask.nii'));
                        if ~isempty(anat_mask)
                            delete(fullfile(anat_mask.folder,anat_mask.name))
                        end
                        anat_mask = [];
                    else
                        anat_mask = dir(fullfile(now_anat_path.folder,'anat_mask.nii'));
                    end
                    if isempty(anat_mask)

                        whifun_anat_mask(m_file,now_anat_path,app.anat_data_name,0)
                    end

                    % Making anat mask in MNI Space
                    disp('Making Anat mask in MNI Space')
                    if over_write == 1
                        wanat_mask = dir(fullfile(now_anat_path.folder,'wanat_mask.nii'));
                        if ~isempty(wanat_mask)
                            delete(fullfile(wanat_mask.folder,wanat_mask.name))
                        end
                        wanat_mask = [];%dir(fullfile(now_anat_path.folder,[skull_pre now_anat_path.name]));
                    else
                        wanat_mask = dir(fullfile(now_anat_path.folder,'wanat_mask.nii'));
                    end
                    if isempty(wanat_mask)

                        whifun_anat_mask(m_file,now_anat_path,app.anat_data_name,1)
                    end
                catch exception
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp(['Preprocessing has encountered errors in Skull strip for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                    write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                    Subj_list(subji).error = 1;
                    
                    continue
                end

                disp('Skull Stripping done')

                 %% COREGISTRATION REST
                 % Coregister anatomical image to the functional image
                 disp('Coregisteration has started')

                 try
                     now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii'])) ;
                     now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Cut_pre app.func_data_name '.nii'])) ;
                     if over_write == 1
                         coreg_dir = [];
                     else
                         cut_func_header = niftiinfo(fullfile(now_func_path.folder,now_func_path.name));
                         realign_func_header = niftiinfo(fullfile(now_func_path.folder,[Realign_pre now_func_path.name]));

                         if prod(cut_func_header.Transform.T == realign_func_header.Transform.T,'all')
                             coreg_dir = [];
                         else
                             coreg_dir = 1;
                         end
                     end
                     if isempty(coreg_dir)
                        now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                        mean_func = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,['mean' Cut_pre app.func_data_name '.nii']));

                        nt = Subj_list(subji).nt_dis;
                        whifun_coreg(now_anat_path,now_func_path,mean_func,nt)
                     else
                         disp('Co-registeration file found, hence skipping this step');
                     end
                 catch exception
                     disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                     disp(['Preprocessing has encountered errors in Coregisteration for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                     disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                     write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                     Subj_list(subji).error = 1;                                                          % Remove subject from further preprocessing
                     continue
                 end

                 %% Coregisteration QC
                 coreg_qc_file = dir(fullfile(quality_control_path,'Co_registeration',[Subj_list(subji).name '.png']));
                 try
                    if isempty(coreg_qc_file) || over_write == 1
                        now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                        now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii'])) ;
                        % Quality Control
                        % dsplay the anatomical and the Realigned func image after co-registeration
                        spm_check_registration(char(fullfile(now_anat_path.folder,now_anat_path.name),[fullfile(now_func_path.folder,now_func_path.name),',1']))

                        % Display the participant's ID
                        spm_orthviews('Caption', 1, Subj_list(subji).name);

                        spm_orthviews('contour','display',1,2);
                        saveas(gcf,(fullfile(quality_control_path,'Co_registeration',[Subj_list(subji).name '.png'])))
                        close gcf
                    end
                catch exception                                                                       % If error is found

                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp(['Preprocessing has encountered errors in Co-registeration QC for ' Subj_list(subji).name ', I have saved the error text in the error_info.txt :-) '])
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                    Subj_list(subji).error = 1;
                    continue
                 end

                 disp('Co-registeration done')

                 %%          Making CSF_MASK for REST
                 disp('CSF_MASK extractation has started')


                 if Reg_ == 1
                     disp(['Currently Processing ' Subj_list(subji).name])
                     try
                         now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                         now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii'])) ;

                         if over_write == 1
                             csf_mask_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['CSF_MASK' CSF_thres '.nii']));
                             if ~isempty(csf_mask_dir)
                                 delete(fullfile(csf_mask_dir.folder,csf_mask_dir.name))
                             end
                             csf_mask_dir = [];
                         else
                             csf_mask_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['CSF_MASK' CSF_thres '.nii'])) ;
                         end
                         if isempty(csf_mask_dir)
                             whifun_create_csf_mask(app.anat_data_name,now_func_path,now_anat_path,CSF_thres)


                         else
                             disp('CSF_MASK file found, hence skipping this step');
                         end
                         csf_qc_file = dir(fullfile(quality_control_path,'Masks',['CSF_' Subj_list(subji).name '.png']));
                         if isempty(csf_qc_file) || over_write == 1
                             % Quality Control
                             now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                             csf_mask_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['CSF_MASK' CSF_thres '.nii'])) ;

                             % Display the csf mask and the func file
                             spm_check_registration(char(fullfile(csf_mask_dir.folder,csf_mask_dir.name),[fullfile(now_func_path.folder,now_func_path.name),',1']))
                             % Display the participant's ID
                             spm_orthviews('Caption', 1, [Subj_list(subji).name ' CSF Mask']);
                             spm_orthviews('Caption', 2, [Subj_list(subji).name ' Rest image 1']);
                             spm_orthviews('contour','display',1,2);
                             saveas(gcf,(fullfile(quality_control_path,'Masks',['CSF_' Subj_list(subji).name '.png'])))
                             close gcf
                         end
                     catch exception
                         disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                         disp(['Preprocessing has encountered errors in CSF_Mask extraction for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                         disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                         write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                         Subj_list(subji).error = 1;                       % Remove subject from further preprocessing
                         continue
                     end
                 else
                     disp('Since No Regression is Specified, CSF Mask will not be created')
                 end

                 disp('CSF_MASK extraction is done')

                 %%          Extracting CSF time-series

                 if Reg_ == 1
                     disp('Extractation of CSF Timeseries has started')

                     disp(['Currently Processing ' Subj_list(subji).name])
                     try
                         now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                         now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii'])) ;

                         % loading CSF MASKS
                         if over_write == 1
                             Reg_dir = dir(fullfile(now_func_path.folder,'covariance_csf_REST.mat'));
                             if ~isempty(Reg_dir)
                                 delete(fullfile(Reg_dir.folder,Reg_dir.name))
                             end
                             Reg_dir = [];
                         else
                             Reg_dir = dir(fullfile(now_func_path.folder,'covariance_csf_REST.mat')) ;
                         end
                         if isempty(Reg_dir)
                             whifun_extract_csf_ts(now_anat_path,now_func_path,CSF_thres,pca_for_temp_reg,n_pca)
                         else
                             disp('Regresion file found, no need to extract the CSF timeseries')
                         end
                     catch exception
                         disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                         disp(['Preprocessing has encountered errors in CSF timeseries extraction for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                         disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                         % Increament the error pointer

                         write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                         Subj_list(subji).error = 1;   % Remove subject from further preprocessing

                     end

                     disp('Extractation of CSF Timeseries is done')

                     %%          TEMPORAL REGRESSION: removing nusisance signals and motion correction

                     disp('TEMPORAL REGRESSION is started ')

                     disp(['Currently Processing ' Subj_list(subji).name])

                     try
                         now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                         cd(now_func_path(1).folder)

                         now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii'])) ;

                         if over_write == 1
                             Reg_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));
                             if ~isempty(Reg_dir)
                                 delete(fullfile(Reg_dir.folder,Reg_dir.name))
                             end
                             Reg_dir = [];%dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name_func,[Reg_pre Realign_pre app.func_data_name '.nii'])) ;
                         else
                             Reg_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;%dir(fullfile(quality_control_path,'Regression',[Subj_list(subji).name '.png']));%
                         end

                         if isempty(Reg_dir)
                             whifun_regress(now_func_path,Subj_list(subji).name,now_anat_path,motion_reg,Cut_pre,app.func_data_name,n_pca,Reg_pre)
                         else
                             disp('Temporal Regression file found, hence skipping this step');
                         end

                     catch exception
                         disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                         disp(['Preprocessing has encountered errors in Temporal Regression for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                         disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                         write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                         Subj_list(subji).error = 1;  % Remove subject from further preprocessing

                     end
                     disp('TEMPORAL REGRESSION is done ')
                 else 
                     disp('Regression Skipped')
                 end
                %%          Filtering
                if filter_check
                    disp('Filtering is started ')
                    disp(['Currently Processing ' Subj_list(subji).name])
                    try
                        if over_write == 1
                            f_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));
                            if ~isempty(f_dir)
                                delete(fullfile(f_dir.folder,f_dir.name))
                            end
                            f_dir = [];
                        else
                            f_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;%dir(fullfile(quality_control_path,'Regression',[Subj_list(subji).name '.png']));%
                        end
                        if isempty(f_dir)
                            now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                            cd(now_func_path(1).folder)
                            func_info = niftiinfo(fullfile(now_func_path.folder,now_func_path.name));
                            func_image = double(niftiread(fullfile(now_func_path.folder,now_func_path.name)));
                            dim = size(func_image);
                            nt  = dim(4);
                            func_image = reshape(func_image,[],nt);
                            tr = Subj_list(subji).TR;

                            % Loading Rest mask
                            REST_MASK = niftiread(fullfile(now_func_path.folder,'rest_mask.nii'));
                            REST_MASK = logical(reshape(REST_MASK,[],1));

                            fs = 1/tr;
                            disp('Using Butterworth filter of 2th order for filtering')
                            [b,a] = butter(2,[filter_lp,filter_hp]/(fs/2),'bandpass');
                            figure; freqz(b,a,512,fs);          % Plot the frequency responce using 512 points
                            saveas(gcf,fullfile(quality_control_path,'Filter',[Subj_list(subji).name '_filter_freq_response.png']))
                            close gcf

                            f_func_image = zeros(prod(dim(1:3)),nt)';
                            ts = func_image(REST_MASK,:)-mean(func_image(REST_MASK,:),2);
                            f_func_image(:,REST_MASK) = filtfilt(b,a,ts');
                            f_func_image = f_func_image';
                            f_func_image = f_func_image + mean(func_image,2);

                            f_func_image = reshape(f_func_image,dim);

                            niftisave(uint16(f_func_image),fullfile(now_func_path.folder,[f_pre ,now_func_path.name]),func_info);


                        end

                        fil_qc_file = dir(fullfile(quality_control_path,'Filter',[Subj_list(subji).name '.png']));


                        if isempty(fil_qc_file) || over_write == 1

                            now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                            % Quality Control
                            % Plot the mean time series before and after regression

                            % Read the raw file

                            func_image = double(niftiread(fullfile(now_func_path.folder,now_func_path.name)));
                            [x,y,z,nt] = size(func_image);
                            func_image = reshape(func_image,x*y*z,nt);

                            global_ts = mean(func_image);

                            f_func_image = double(niftiread(fullfile(now_func_path.folder,[f_pre now_func_path.name])));
                            [x,y,z,nt] = size(f_func_image);
                            f_file = reshape(f_func_image,x*y*z,nt);
                            global_ts_f = mean(f_file);

                            figure; plot(global_ts-mean(global_ts));
                            hold on
                            plot(global_ts_f -mean(global_ts_f))
                            title(['Global Time Series (mean subtracted)' ' Subject ' Subj_list(subji).name])
                            legend('Before filtering','After filtering')

                            xlabel('time points')
                            ylabel('Bold Signal (mean substracted)')
                            saveas(gcf,fullfile(quality_control_path,'Filter',[Subj_list(subji).name '.png']))

                            close gcf
                        end
                    catch exception
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in filtering for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                        write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                        Subj_list(subji).error = 1;  % Remove subject from further preprocessing
                        continue
                    end

                    disp('Filtering is done ')
                end

                 %%          Smoothing

                 disp('Smoothing is started ')
                 if Smooth_ == 1

                     if WM_GM == 1

                         disp('Smoothing White matter and Gray Matter Seperately')

                         GM_filename_prefix = 'c1';
                         WM_filename_prefix = 'c2';

                         disp(['Currently Processing ' Subj_list(subji).name])

                         try
                             if over_write == 1
                                 smooth_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre func_data_name '.nii'])); %#ok<ADPROPLC>
                                 if ~isempty(smooth_dir)
                                     delete(fullfile(smooth_dir.folder,smooth_dir.name))
                                 end
                                 smooth_dir = [];
                             else
                                 smooth_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>
                             end

                             if isempty(smooth_dir)
                                 now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,func_folder_name,[f_pre Reg_pre Realign_pre Cut_pre func_data_name '.nii'])) ; %#ok<ADPROPLC>
                                 now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,comm_sess_name,anat_folder_name,[skull_pre anat_data_name '.nii'])) ; %#ok<ADPROPLC>

                                 functional_dir = fullfile(now_func_path.folder,now_func_path.name);
                                 segmentation_dir = fullfile(now_anat_path(1).folder);
                                 smooth_name = [Smooth_pre now_func_path.name];
                                 p_smooth_WM_GM_separately_fast(functional_dir, segmentation_dir, now_func_path.folder,smooth_name, smooth_fwhm, GM_filename_prefix, WM_filename_prefix,over_write);

                             else
                                 disp('Smoothing file found, hence skipping this step');
                             end

                         catch exception
                             disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                             disp(['Preprocessing has encountered errors in Smoothing for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                             disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                             write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                             Subj_list(subji).error = 1;  % Remove subject from further preprocessing
                             continue

                         end

                     else
                         disp('Smoothing White matter and Gray Matter together')

                         disp(['Currently Processing ' Subj_list(subji).name])

                         try
                             if over_write == 1
                                 smooth_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));
                                 if ~isempty(smooth_dir)
                                     delete(fullfile(smooth_dir.folder,smooth_dir.name))
                                 end
                                 smooth_dir = [];
                             else
                                 smooth_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                             end

                             if isempty(smooth_dir)
                                 now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;


                                 nt = Subj_list(subji).nt_dis;

                                 whifun_smooth_together(nt,now_func_path,smooth_fwhm,Smooth_pre)

                                 
                             else
                                 disp('Smoothing file found, hence skipping this step');
                             end

                             
                         catch exception
                             disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                             disp(['Preprocessing has encountered errors in Smoothing for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                             disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                             write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                             Subj_list(subji).error = 1;  % Remove subject from further preprocessing

                         end

                     end

                     smooth_qc_file = dir(fullfile(quality_control_path,'Smoothing',[Subj_list(subji).name '.png']));
                     if isempty(smooth_qc_file) || over_write == 1
                         norm_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                         spm_check_registration(char(fullfile(norm_dir.folder,[norm_dir.name ',1'])))
                         spm_orthviews('Caption', 1, [Subj_list(subji).name ' Smooth Image (MNI space)']);
                         saveas(gcf,(fullfile(quality_control_path,'Smoothing',[Subj_list(subji).name '.png'])))
                         close gcf
                     end

                 else
                     disp('No smoothing is selected hence skipping this step')
                 end
                 disp('Smoothing is done ')

                 %%          Normalization
                 disp('Normalization is started ')

                 disp(['Currently Processing ' Subj_list(subji).name])
                 try
                     now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                     now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['y_' app.anat_data_name '.nii'])) ;

                     if over_write == 1
                         norm_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));
                         if ~isempty(norm_dir)
                             delete(fullfile(norm_dir.folder,norm_dir.name))
                         end
                         norm_dir = [];
                     else
                         norm_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                     end
                     if isempty(norm_dir)
                         nt = Subj_list(subji).nt_dis;
                         whifun_normalise(now_anat_path,now_func_path,app.anat_data_name,nt,vox,Norm_pre,skull_pre,1) %func
                     else
                         disp('Normalization file found, hence skipping this step');
                     end

                 catch exception
                     disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                     disp(['Preprocessing has encountered errors in Normalization for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                     disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                     write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                     Subj_list(subji).error = 1;  % Remove subject from further preprocessing
                     continue

                 end

                 %% normalization QC

                 norm_qc_file = dir(fullfile(quality_control_path,'Normalization',[Subj_list(subji).name '_func_image' '.png']));

                 if isempty(norm_qc_file) || over_write == 1
                     % Quality Control
                     norm_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                     spm_check_registration(char(fullfile(norm_dir.folder,[norm_dir.name ',1']),fullfile(spm_path,'canonical','single_subj_T1.nii')))
                     spm_orthviews('contour','display',1,2);
                     spm_orthviews('Caption', 1, [Subj_list(subji).name ' Normalized Image (MNI space)']);
                     spm_orthviews('Caption', 2,  ' Single Subject MNI space Reference');

                     saveas(gcf,(fullfile(quality_control_path,'Normalization',[Subj_list(subji).name '_func_image' '.png'])))
                 end
                 disp('Normalization is done ')

                 %%          Normalization of anatomical images
                 disp('Anatomical Normalization of is started ')

                 disp(['Currently Processing ' Subj_list(subji).name])
                 try
                     if over_write == 1
                         norm_a_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[Norm_pre skull_pre app.anat_data_name '.nii']));
                         if ~isempty(norm_a_dir)
                             delete(fullfile(norm_a_dir.folder,norm_a_dir.name))
                         end
                         norm_a_dir = [];
                     else
                         norm_a_dir = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[Norm_pre skull_pre app.anat_data_name '.nii'])) ;
                     end

                     if isempty(norm_a_dir)
                         now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,['y_' app.anat_data_name '.nii'])) ;
                         now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                         whifun_normalise(now_anat_path,now_func_path,app.anat_data_name,0,vox,Norm_pre,skull_pre,0)
                     else
                         disp('Anatomical Normalization file found, hence skipping this step');
                     end
                 catch exception
                     disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                     disp(['Preprocessing has encountered errors in Anatomical Normalization for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                     disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                     write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display

                     Subj_list(subji).error = 1; % Remove this subject from further preprocessing

                     continue

                 end


                 disp('Anatomical Normalization is done ')

                 %% Time series Quality Check

                 disp('Time-series Quality Check ')

                disp(['Currently Processing ' Subj_list(subji).name])

                try
                    now_func_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Cut_pre app.func_data_name '.nii'])) ;
                    now_anat_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii'])) ;

                    if length(now_func_path)>1  % If more than one func files found choose the one that was created the first

                        [~,idx] = sort([now_func_path.datenum]);
                        now_func_path = now_func_path(idx);
                        warning(['More than one functional files found. Choosing the file ' ,char(now_func_path(1).name), ' as it was created the first.']);
                        now_func_path(2:end) = [];
                    end
                    if over_write == 1
                        tqc_dir = dir(fullfile(quality_control_path,'Time_series_check',[Subj_list(subji).name '.png']));
                        if ~isempty(tqc_dir)
                            delete(fullfile(tqc_dir.folder,tqc_dir.name))
                        end
                        tqc_dir = [];
                    else
                        tqc_dir = dir(fullfile(quality_control_path,'Time_series_check',[Subj_list(subji).name '.png'])) ;
                    end

                    if isempty(tqc_dir)
                        now_txt_path = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,['rp_' Cut_pre app.func_data_name '.txt'])) ;
                        now_func_path_pro = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])) ;
                        whifun_ts_check(now_func_path,now_txt_path,now_func_path_pro,now_anat_path,Reg_,quality_control_path,Subj_list(subji).name,pca_for_temp_reg,n_pca,max_fd_thres)
                    end

                    disp('Time-series Quality Check done')
                    disp('##########################################################################################')
                    disp('')
                catch exception
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                    disp(['Preprocessing has encountered errors in Time-series check for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                    disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')


                    write_error(exception,quality_control_path, Subj_list(subji).name)                % write error to text file and display
                    Subj_list(subji).error = 1;
                    continue
                end
                
                Subj_list(subji).time_preprocess_min = toc;

                disp(['Estimated time to complete preprocessing: ' num2str( (mean(cell2mat({Subj_list(1:subji).time_preprocess_min})*(n_tot-subji),'omitnan'))/ 60 ) ' mins'])
%                 app.Label_6.Text = ['Estimated time to complete preprocessing: ' num2str( (mean(cell2mat({Subj_list(1:subji).time_preprocess_min})*(n_tot-subji),'omitnan'))/ 60 ) ' mins'];
            end
            for subji = 1:length(Subj_list)
                Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).motion_ex = Subj_list(subji).motion_ex;
                Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).error = Subj_list(subji).error;
                Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).nt_dis = Subj_list(subji).nt_dis;
                Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).time_preprocess_min = Subj_list(subji).time_preprocess_min;
            end
            my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
            save_param(app,'parameters.mat',output_folder)
           
        end

        % Button pushed function: RunDataCheckButton
        function RunDataCheckButtonPushed(app, event)
            %%          Defining Important paths and Creating Output directories
            spm_path = which('spm');
            if isempty(spm_path)
                msgbox("Please set the SPM path by using the 'addpath' MATLAB function",'SPM path not set')
                return
            end
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);

            addpath(preproc_code_path)

            if isempty(char(app.DataPathTextArea.Value))
                msgbox('Please select Subject Folder','Select Subject Folder')
                return
            end

            if isempty(char(app.DataPathTextArea_2.Value))
                msgbox('Please select an output folder','Select Output Folder')
                return
            end


            data_path = char(string(app.DataPathTextArea.Value));
            output_folder = char(string(app.DataPathTextArea_2.Value));
            quality_control_path = fullfile(output_folder,'Quality_control');


            %             comm_sess_name = app.comm_sess_name;
            %             func_folder_name = app.func_folder_name;
            %             anat_folder_name = app.anat_folder_name;


            %% Create Output folders


            mkdir(fullfile(output_folder,'Analysis','Group_Masks'))
            mkdir(fullfile(quality_control_path,'Initial_check'))
            mkdir(fullfile(quality_control_path,'Head_motion'));
            mkdir(fullfile(quality_control_path,'Segmentation'))
            mkdir(fullfile(quality_control_path,'Co_registeration'))
            mkdir(fullfile(quality_control_path,'Masks'))
            mkdir(fullfile(quality_control_path,'Regression'))
            mkdir(fullfile(quality_control_path,'Filter'))
            mkdir(fullfile(quality_control_path,'Normalization'))
            mkdir(fullfile(quality_control_path,'Smoothing'))
            mkdir(fullfile(quality_control_path,'Time_series_check'))
            mkdir(fullfile(quality_control_path,'Error_Info'))
            if app.CorpusCallosumNetworksCheckBox.Value == 1
                mkdir(fullfile(output_folder,'Analysis','ttest'))
            end

            if ~exist(fullfile(output_folder,'Subj_list.csv'),"file")
                %%          Get the Anatomical and Functional Image Paths

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list_all = dir( app.comm_subj_name );
                if strcmp(Subj_list_all(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                if strcmp(Subj_list_all(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                nt_dir = false(1,length(Subj_list_all));                                        % make a list of other files that are not directories
                for i = 1:length(Subj_list_all)
                    if Subj_list_all(i).isdir ~= 1
                        nt_dir(i) = true;
                    end
                end
                Subj_list_all(nt_dir) = [];                                                     % Remove all the files that are not directories (Now Subj_list should have all subject files)
                clear nt_dir                                                                % Clear nt_dir after use


            else
                Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv');
            end
            %             %   Set functional and anatomical data folder name
            %             app.func_folder_name = char(string(app.func_folder_name)) ;     %   Functional folder
            %             app.anat_folder_name = char(string(app.anat_folder_name)) ;     %   Anatomical folder

            %   Set functional and anatomical data basic file name
            %             func_data_name = char(app.func_data_name) ;       % %   Functional data name
            %             anat_data_name = char(app.anat_data_name) ;     % %   Anatomical data name

            app.func_data_name = char(app.func_data_name) ;       % %   Functional data name
            app.anat_data_name = char(app.anat_data_name) ;     % %   Anatomical data name


            %%          Parameters

            filter_lp = double(string(app.TemporalFilteringHzTextArea.Value));
            filter_hp = double(string(app.FilterTextArea_2.Value));

            if filter_lp > filter_hp
                error(sprintf('Please check the filter cutoffs, lower_cutoff should be less than higher cutoff, \nbut found otherwise\n'))
            end


            K_range_l = double(string(app.KmeansKRangeTextArea_2.Value));
            K_range_h = double(string(app.KmeansWMKRangeTextArea.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            Corpus_check = app.CorpusCallosumNetworksCheckBox.Value;


            %             Subj_list = Subj_list;

            %%          Data initial Check
            disp('##########################################################################################')
            disp('Initial Data Check started')
            report = "";
            p = 1;
            % Read image information for every participant
            n_image = inf(length(Subj_list_all),1);
            tr = inf(length(Subj_list_all),1);
            voxel_func = inf(length(Subj_list_all),3);
            voxel_anat = inf(length(Subj_list_all),3);
            no_func = inf(length(Subj_list_all),1);
            no_anat = inf(length(Subj_list_all),1);

            mis_data = 0;
            for subji=1:length(Subj_list_all)
                disp('..')
                disp(['Currently Processing ' Subj_list_all(subji).name])
                Subj_list_all(subji).error = 0;

                if ~isfield(Subj_list_all,'motion_ex')
                    Subj_list_all(subji).motion_ex = 0;
                elseif isempty(Subj_list_all(subji).motion_ex)
                    Subj_list_all(subji).motion_ex = 0;
                end

                if ~isfield(Subj_list_all,'manual_ex')
                    Subj_list_all(subji).manual_ex = 0;
                elseif isempty(Subj_list_all(subji).manual_ex)
                    Subj_list_all(subji).manual_ex = 0;
                end
                if ~Subj_list_all(subji).manual_ex
                    try
                        now_func_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name,app.func_folder_name,[app.func_data_name '.nii'])) ;
                        if isempty(now_func_path)
                            now_func_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name,app.func_folder_name,[app.func_data_name '.nii*'])) ;

                            if isempty(now_func_path)
                                msg = ['No func data found for Subject: ' Subj_list_all(subji).name];
                                report(p) = msg;
                                p = p+1;
                                if ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name))
                                    msg = ['The intermediate folder name "' char(app.comm_sess_name) '" not found'];
                                    report(p) = msg;
                                    p = p+1;
                                elseif ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name,app.func_folder_name))
                                    msg = ['The func folder name "' char(app.func_folder_name) '" not found.'];
                                    report(p) = msg;
                                    p = p+1;
                                else
                                    msg = ['The func image name "' char(app.func_data_name) '.nii*' '" not found'];
                                    report(p) = msg;
                                    p = p+1;
                                end
                            end
                        end
                        if length(now_func_path)>1  % If more than one func files found choose the one that was created the first

                            [~,idx] = sort([now_func_path.datenum]);
                            now_func_path = now_func_path(idx);
                            now_func_path(2:end) = [];
                            warning(['More than one functional files found. Choosing the file ' ,char(now_func_path(1).name), ' as it was created the first.']);
                        end
                        if ~isempty(now_func_path)
                            disp(['Reading functional file from ' fullfile(now_func_path(1).folder,now_func_path(1).name)])
                            v_func = niftiinfo(fullfile(now_func_path(1).folder,now_func_path(1).name));% Get info of the functional file

                            if ~sum(isinf(v_func.PixelDimensions))
                                voxel_func(subji,:) = v_func.PixelDimensions(1:3);                                 % voxel size
                            else
                                voxel_func(subji,:) = nan;
                            end

                            if ~sum(isinf(v_func.ImageSize(4)))
                                n_image(subji,1) = v_func.ImageSize(4);                                  % number of images (time points)
                                Subj_list_all(subji).nt = v_func.ImageSize(4);
                            else
                                n_image(subji,1) = nan;
                                Subj_list_all(subji).nt = nan;
                            end
                            
                            if ~sum(isinf(v_func.PixelDimensions(1)))
                                Subj_list_all(subji).x_func = v_func.PixelDimensions(1);
                            else
                                Subj_list_all(subji).x_func = nan;
                            end
                            
                            if ~sum(isinf(v_func.PixelDimensions(2)))
                                Subj_list_all(subji).y_func = v_func.PixelDimensions(2);
                            else
                                Subj_list_all(subji).y_func = nan;
                            end

                            if ~sum(isinf(v_func.PixelDimensions(3)))
                                Subj_list_all(subji).z_func = v_func.PixelDimensions(3);
                            else
                                Subj_list_all(subji).z_func = nan;
                            end
                            
                        else
                            no_func(subji) = 1;
                            Subj_list_all(subji).error = 1;
                            
                            p = p+1;
                        end


                    catch exception
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in Data Initial check (functional) for ' Subj_list_all(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                        write_error(exception,quality_control_path, Subj_list_all(subji).name)                % write error to text file and display
                        Subj_list_all(subji).error = 1;
                        continue
                    end


                    try
                        now_anat_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name,app.anat_folder_name,[app.anat_data_name '.nii'])) ;
                        if isempty(now_anat_path)
                            now_anat_path = dir(fullfile(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name,app.anat_folder_name,[app.anat_data_name '.nii*'])) ;

                            if isempty(now_anat_path)
                                msg = ['No anat data found for Subject: ' Subj_list_all(subji).name];
                                report(p) = msg;
                                p = p+1;
                                if ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name))
                                    msg = ['The intermediate folder name "' char(app.comm_sess_name) '" not found'];
                                    report(p) = msg;
                                    p = p+1;
                                elseif ~isfolder(complete_filepath(Subj_list_all(subji).folder, Subj_list_all(subji).name,app.comm_sess_name,app.anat_folder_name))
                                    msg = ['The anat folder name "' char(app.anat_folder_name) '" not found.'];
                                    report(p) = msg;
                                    p = p+1;
                                else
                                    msg = ['The anat image name "' char(app.anat_data_name) '.nii*' '" not found'];
                                    report(p) = msg;
                                    p = p+1;
                                end
                            end
                        end
                        if length(now_anat_path)>1  % If more than one func files found choose the one that was created the first

                            [~,idx] = sort([now_anat_path.datenum]);
                            now_anat_path = now_anat_path(idx);
                            now_anat_path(2:end) = [];
                            warning(['More than one Anatomical files found. Choosing the file ' ,char(now_anat_path(1).name), ' as it was created the first.']);
                        end
                        if ~isempty(now_anat_path)
                            disp(['Reading anatomical file from ' fullfile(now_anat_path(1).folder,now_anat_path(1).name)])
                            v_anat = niftiinfo(fullfile(now_anat_path(1).folder,now_anat_path(1).name));
                            voxel_anat(subji,:) = v_anat.PixelDimensions(1:3);                                % voxel size
                            
                            if ~sum(isinf(v_anat.PixelDimensions))
                                voxel_anat(subji,:) = v_anat.PixelDimensions(1:3);                                 % voxel size
                            else
                                voxel_anat(subji,:) = nan;
                            end
                            
                            if ~sum(isinf(v_anat.PixelDimensions(1)))
                                Subj_list_all(subji).x_Anat = v_anat.PixelDimensions(1);
                            else
                                Subj_list_all(subji).x_Anat = nan;
                            end
                            
                            if ~sum(isinf(v_anat.PixelDimensions(2)))
                                Subj_list_all(subji).y_Anat = v_anat.PixelDimensions(2);
                            else
                                Subj_list_all(subji).y_Anat = nan;
                            end
                            
                            if ~sum(isinf(v_anat.PixelDimensions(3)))
                                Subj_list_all(subji).z_Anat = v_anat.PixelDimensions(3);
                            else
                                Subj_list_all(subji).z_Anat = nan;
                            end

                        else
                            no_anat(subji) = 1;
                            Subj_list_all(subji).error = 1;
                            p = p+1;
                        end

                    catch exception
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in Data Initial check (anatomical) for ' Subj_list_all(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                        write_error(exception,quality_control_path, Subj_list_all(subji).name)            % write error to text file and display
                        Subj_list_all(subji).error = 1;                                                        % % Remove subject from further preprocessing
                    end

                    try
                        v_func = niftiinfo(fullfile(now_func_path(1).folder,now_func_path(1).name));

                        if ~sum(isinf(v_func.PixelDimensions(4)))
                            tr(subji,1) = v_func.PixelDimensions(4);                                 % voxel size
                            Subj_list_all(subji).TR = v_func.PixelDimensions(4);
                        else
                            tr(subji,1) = nan;
                            Subj_list_all(subji).TR = nan;
                        end


                    catch
                        % Error in fetching the TR value Please manually
                        % mention the TR

                        if no_func(subji) ~=1
                            msg = ['Error in fetching the TR value for subject: ' Subj_list_all(subji).name '. Please manually enter the TR for this subject using the Subject Info button'];
                            warning(msg);
                            report(p) = msg;
                            p = p+1;
                            tr(subji,1) = nan;
                            Subj_list_all(subji).TR = nan;
                        end
                    end


                end
            end
            
            my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
            n_image(isinf(n_image)) = [];
            tr(isinf(tr)) = [];
            voxel_func(isinf(voxel_func(:,1)),:) = [];
            voxel_anat(isinf(voxel_anat(:,1)),:) = [];
            no_func(isinf(no_func)) = [];
            no_anat(isinf(no_anat)) = [];

            if nnz(no_anat) || nnz(no_func)
                msg_test = ['Total Number of subjects found in the Subject folder was ' num2str(length(Subj_list_all)) ' but ' num2str(nnz(no_func+no_anat)) ' were removed as they did not have either the func or anat files'];
                msgbox(msg_test,'Missing anat or func images')
                mis_data = 1;
            end

            Subj_list = load_subjects(app,output_folder,'Subj_list.csv');

            disp('Initial Data Check ended')
            disp('##########################################################################################')

            % Plot all the parameters across participants
            fh = figure('DefaultAxesFontSize',18);
            fh.WindowState = 'maximized';

            subplot(4,1,1)
            bar(n_image)
            title('# of fMRI images')
            box off

            subplot(4,1,2)
            bar(tr)
            title('TR of fMRI images')
            box off

            subplot(4,1,3)
            bar(voxel_func)
            title('Voxel sizes of fMRI images')
            legend({'x','y','z'})
            box off

            subplot(4,1,4)
            bar(voxel_anat)
            title('Voxel sizes of structrual images')
            legend({'x','y','z'})
            box off

            saveas(gcf,fullfile(quality_control_path,'Q1a_scanning_parameters.png'));
            close gcf

            fh1 = figure('DefaultAxesFontSize',14);
            fh1.WindowState = 'maximized';


            subplot(3,2,1)
            histogram(n_image);
            xlabel('Number of time points')
            ylabel('Number of Subjects')

            subplot(3,2,2)
            histogram(tr);
            xlabel('TR')
            ylabel('Number of Subjects')

            subplot(3,3,4)
            histogram(voxel_func(:,1));
            xlabel("Voxel 'x' dimension")
            ylabel('Number of Subjects')

            subplot(3,3,5)
            histogram(voxel_func(:,2));
            xlabel("Voxel 'y' dimension")
            ylabel('Number of Subjects')
            title({'' ;'Voxel sizes of fMRI images'})

            subplot(3,3,6)
            histogram(voxel_func(:,3));
            xlabel("Voxel 'z' dimension")
            ylabel('Number of Subjects')

            subplot(3,3,7)
            histogram(voxel_anat(:,1));
            xlabel("Voxel 'x' dimension")
            ylabel('Number of Subjects')

            subplot(3,3,8)
            histogram(voxel_anat(:,2));
            xlabel("Voxel 'y' dimension")
            ylabel('Number of Subjects')
            title({'' ;'Voxel sizes of sMRI images'})

            subplot(3,3,9)
            histogram(voxel_anat(:,3));
            xlabel("Voxel 'x' dimension")
            ylabel('Number of Subjects')
            sgtitle('Histograms of parameters acorss subjects: Its ideal to have a single bar for all plots');


            if mis_data == 1
                annstr = "Because there were missing files, the parameters corresponding to those are 0";
                annpos = [0.02 0.9 0.1 0.1]; % annotation position in figure coordinates
                annotation('textbox',annpos,'string',annstr);
            end
            saveas(gcf,fullfile(quality_control_path,'Q1a_scanning_parameters_histogram.png'));
            
            if Corpus_check == 1
                [Subj_list, rm]= load_subjects(app,output_folder,'Subj_list.csv');
                n_image(rm) = [];
                if ~prod(mean(n_image) == n_image)
                    struct2table(rmfield(Subj_list,{'date','bytes','isdir','datenum','error','motion_ex','manual_ex'}))
                    error('Error in Corpus Callosum Networks: \n The number of timepoints (nt) for all the subjects should be the same to compute the corpus callosum networks \n The number of time points corresponding to every subject is displayed above')

                end
            end
            load_subjects_dropdown(app,Subj_list);
            % Saving all the parameters in Output Folder for future
            % reference
            if p == 1
                report(p) = 'Data check completed Sucessfully';
            end

            app.DataCheckReportTextArea.Value = report;
            save_param(app,'parameters.mat',output_folder)

            
        end

        % Value changed function: BIDSCheckBox
        function BIDSCheckBoxValueChanged(app, event)
            value = app.BIDSCheckBox.Value;
            if value == 0
                app.DialogApp = Subject_data_folder_details(app,app.comm_sess_name,app.func_folder_name,app.anat_folder_name,app.func_data_name,app.anat_data_name);

            elseif value == 1
                answer = questdlg('Do you wish to use the BIDS format? Any changes that you have done for the folder names will be deleted!',...
                    'BIDS format','Yes','No','See Folder Names','No');
                switch answer
                    case 'Yes'
                        app.comm_sess_name = '';
                        app.func_folder_name = 'func';
                        app.anat_folder_name = 'anat';
                        app.func_data_name = '*_task-rest_*';
                        app.anat_data_name = '*_T1w*';
                    case 'No'
                        app.BIDSCheckBox.Value = 0;
                        return
                    case 'See Folder Names'
                        app.BIDSCheckBox.Value = 0;
                        app.DialogApp = Subject_data_folder_details(app,app.comm_sess_name,app.func_folder_name,app.anat_folder_name,app.func_data_name,app.anat_data_name);

                end
            end
        end

        % Value changed function: NuisanceRegressionDropDown
        function NuisanceRegressionDropDownValueChanged(app, event)
            value = app.NuisanceRegressionDropDown.Value;

            switch value
                case 'PCA CSF'
                    Position = app.WhiFuNv1UIFigure.Position;
                    app.DialogApp = PCA_regression_parameters(app,app.NoofPCAcomponents,Position);                      % number of pca components for WM and CSF (choose a value between 1 and 5)

            end

        end

        % Button pushed function: DisplayFNButton
        function DisplayFNButtonPushed(app, event)
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'BrainNetViewer_20191031'))
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [cluster_file,cluster_folder] = uigetfile(fullfile(pwd,'*_clustering*.nii'),'Select the clustering Output file','MultiSelect','on');
                else
                    [cluster_file,cluster_folder] = uigetfile(fullfile(char(app.DataPathTextArea_2.Value),'Analysis','*_clustering*.nii'),'Select the WM_clustering Output file','MultiSelect','on');
                end
            catch
            end
            if isnumeric(cluster_file)
                return
            end
            if ischar(cluster_file)
                toold = questdlg('Which toolbox would to like to use for the Display?','Display Toolbox','SPM','BrainNet Viewer','SPM');

                switch toold
                    case 'SPM'
                        %             spm_check_registration(fullfile(WM_cluster_folder,WM_cluster_file));
                        %                         vol = niftiread(fullfile(cluster_folder,cluster_file));
                        %                         roi_levels = unique(vol);
                        %                         roi_levels(roi_levels==0) = [];
                        matlabbatch{1}.spm.util.disp.data = {[fullfile(cluster_folder,cluster_file) ',1']};
                        spm('defaults', 'FMRI');
                        spm_jobman('run', matlabbatch);
                        colormap([0 0 0; hsv])

                    case 'BrainNet Viewer'
                        BrainNet_MapCfg_WhifuN(fullfile(preproc_code_path,'BrainNetViewer_20191031','Data','SurfTemplate','BrainMesh_Ch2_smoothed.nv'),fullfile(cluster_folder,cluster_file),fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters1.mat'));

                end
            elseif iscell(cluster_file) && length(cluster_file) > 1 && length(cluster_file) <= 4
                if length(cluster_file) == 2
                    spm_check_registration(fullfile(cluster_folder,cluster_file{1}),fullfile(cluster_folder,cluster_file{2}));
                elseif length(cluster_file) == 3
                    spm_check_registration(fullfile(cluster_folder,cluster_file{1}),fullfile(cluster_folder,cluster_file{2}),fullfile(cluster_folder,cluster_file{3}));
                elseif length(cluster_file) == 4
                    spm_check_registration(fullfile(cluster_folder,cluster_file{1}),fullfile(cluster_folder,cluster_file{2}),fullfile(cluster_folder,cluster_file{3}),fullfile(cluster_folder,cluster_file{4}));
                end
                colormap([0 0 0; hsv])
            end

        end

        % Button pushed function: DisplayFCButton
        function DisplayFCButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');
                else
                    path = char(app.DataPathTextArea_2.Value);
                end
                if exist(fullfile(path,'parameters.mat'),'file')

                    load_param(app,path,'parameters.mat')
                else
                    msgbox('Parameter file not found, please run Preprocessing first')
                    return
                end
                if isempty(char(app.DataPathTextArea.Value))
                    data_path = uigetdir(fullfile(pwd),'Select the Subject folder');
                else
                    data_path = char(app.DataPathTextArea.Value);
                end
            catch
            end
            if path == 0
                return
            end
           
            app.DialogApp = FC_visualize_new_side_plots_groups(app,path,data_path);


        end

        % Button pushed function: CreateWMnetworksButton
        function CreateWMnetworksButtonPushed(app, event)
            %% Clustering WM Functional networks

            temp_path = mfilename('fullpath');                                                 % path of the toolbox
            preproc_code_path = fileparts(temp_path);                                          % path where the code is stored
            addpath(fullfile(preproc_code_path,'BrainNetViewer_20191031'))                     % Add path to the WhiFuN compatible BrainNet toolbox
            cc_mask_filename = fullfile(preproc_code_path,'Original_corpus_callosum.nii');     % Get the CC mask path
%             colon = char("'");
            output_folder = char(string(app.DataPathTextArea_2.Value));                        % Path of the output folder
            build_net = 0;                                                                     % will be used if previous made networks are detected
            vox = string(app.NormalizationvoxeldimmmTextArea.Value);                           % Voxel size of the normalized image (preprocessed image)  

            Subj_list = load_subjects(app,output_folder,'Subj_list.csv');                      % Load the good subjects using the CSV file

            Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv');              % Load all subjects in the dataset 

            mkdir(fullfile(output_folder,'Analysis','WM_Networks'))                            % make the WM Networks results folder 
            if isempty(app.SelectedSubjectswithgooddataDropDown.Items)                                           % If no subjects detected notify the user
                msgbox('Please select subjects first')
                return 
            end
            grp_wm_thres = double(string(app.GroupWMthresholdTextArea.Value))/100;             % Group threshold on WM
            indi_thres = double(string(app.IndividualWMProbabilitythresholdTextArea.Value));   % Inidividual threshold on WM tissue prob
            data_path = char(string(app.DataPathTextArea.Value));                              % Subjects folder
            cd(data_path)

            %%          These values can be changed but default is recommended

            if double(string(app.NoofVolumestoDiscardTextArea.Value)) ~= 0
                Cut_pre = 'c_';                                                         % Prefix for the Discarding Initial volumes File
            else
                Cut_pre = '';
            end
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            
            Norm_pre = 'w';                                                             % Prefix for the Normalized File
            smooth_drop = app.SmoothingDropDown.Value;                                  % check which type of smoothing was done
            Reg_drop = app.NuisanceRegressionDropDown.Value;                                    % check which type of Regression was done
            quality_control_path = fullfile(output_folder,'Quality_control');           % Quality control path to store any error info

            f_check = app.FilterCheckBox.Value;
            if f_check                                                                  % prefix for filtered file
                f_pre = 'f';
            else
                f_pre = '';
            end

            switch smooth_drop
                case 'No Smoothing'
                    Smooth_pre = '';
                otherwise
                    Smooth_pre = 's';                                                   % Prefix for the Smoothed File
            end

            switch Reg_drop
                case 'No Regression'
                    Reg_pre = '';
                otherwise
                    Reg_pre = 'REG_';
            end                                                                         % Prefix for the Regression File

            % definition of prefix of files for each tissue type
            GM_file_prefix = 'wc1';
            WM_file_prefix = 'wc2';
            CSF_file_prefix = 'wc3';

            HO_atlas_filename = fullfile(preproc_code_path,'HarvardOxford-sub-maxprob-thr25-2mm_YCG.nii'); %%% choose the HarvardOxford-sub-maxprob-thr25-2mm_YCG.nii from the mask file

            K_range_l = double(string(app.KmeansKRangeTextArea_2.Value));               
            K_range_h = double(string(app.KmeansWMKRangeTextArea.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea.Value));

            Corpus_check = app.CorpusCallosumNetworksCheckBox.Value;

            over_write = app.OverwriteexistingfilesCheckBox.Value;

            %             n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard

            %% Create Group WM Mask

            disp('##########################################################################################')
            disp('Creating the group WM masks ')


            if over_write == 1
                wm_mask_dir = dir(fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs_buckner.nii'));
                if ~isempty(wm_mask_dir)
                    delete(fullfile(wm_mask_dir.folder,wm_mask_dir.name))
                end
                wm_mask_dir = [];
            else
                wm_mask_dir = dir(fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs_buckner.nii'));
            end


            if isempty(wm_mask_dir)

                func_img1_filename = complete_filepath(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii'])); % get one func file for the nifti header
                func_img1_filename_info = niftiinfo(func_img1_filename);
                size_image = func_img1_filename_info.ImageSize;
                WMmask_full = zeros(size_image(1:3));                         % Initializing the WM Mask
                disp(['Collecting all the voxels that have WM probability greater than ' num2str(indi_thres) ' for every subject'])
                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    disp('..')
                    disp(['Currently Processing ' Subj_list(subji).name])
                    % getting the current subject's segmentation directory
                    now_anat_path = fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name) ;
                   
                    WM_file_temp = dir(fullfile(now_anat_path, [WM_file_prefix '*.nii'])); WM_file = fullfile(WM_file_temp.folder, WM_file_temp.name);
                    current_WM_mask = reslice_data(WM_file, func_img1_filename, 0);      % resize to func space 
                    WMmask_full(current_WM_mask>=indi_thres) = WMmask_full(current_WM_mask>=indi_thres) + 1; % Count of every voxel with prob greater than indi_thresh across all subjects

                end
                    WMmask_full = WMmask_full./length(Subj_list);                        % Probability of every voxel having WM prob greater than indi_thres across subjects
                %% Optional stage - remove subcortical structures from the white-matter mask
                % these structures (putamen, globus pallidus) are erroneously identified in SPM as
                % white-matter due to their high iron content (see Lorio et al. 2016, NeuroImage).
                % We used here the Harvard-Oxford Atlas (obtained from DPABI toolbox) to delineate these subcortical structures,
                % and thrn remove them from the WM mask (and add them to the GM mask).

                % reading the Harvard-Oxford atlas and resampling it to the functional image's resolution
                HO_atlas = reslice_data(HO_atlas_filename, func_img1_filename, 0);

                % find the voxels defined as subcortical structures
                indices_subcortical = [find(HO_atlas==2010);	find(HO_atlas==2049);	find(HO_atlas==3011);	find(HO_atlas==3050);	find(HO_atlas==4012);	find(HO_atlas==4051);	find(HO_atlas==5013);	find(HO_atlas==5052);	find(HO_atlas==8026);	find(HO_atlas==8058);];

                % Remove these voxels from the white-matter mask and add them to the grey-matter mask
                WMmask_full(indices_subcortical)=0;

                %% Thresholding to find voxels defined as WM or GM in a big enough percent of the subjects
                WMmask = WMmask_full >= grp_wm_thres;               % threshold for WM mask - >60% probability of identification as white-matter
                WM_voxels = find(WMmask>0);

                %% Removal of parts of the mask for which functional data exists only in <80% of subjects, such as the spinal cord
                threshold_notnan = 0.8;     % 80% of voxels need to be not NaN for each voxel to be included in the mask

                % defining the directory with average functional data of all subjects,
                % arranged in separate directories for each subject

                % reading these files and counting how many subjects have data in each voxel
                num_subjs_notnan_WM = zeros(length(WM_voxels),1);
                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    disp('..')
                    disp(['Currently Processing ' Subj_list(subji).name])

                    now_func_path = fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) ;

                    % reading the current subject's mean functional file
                    if ~isempty(now_func_path)
                        curr_filename = dir(now_func_path);
                    else
                        disp('No average functional image found!')
                        break;
                    end
                    curr_mean_func = mean(niftiread(fullfile(curr_filename.folder,curr_filename.name)),4);  % loading the subjects' mean functional image

                    % current subject - finding voxels which are not zero or NaN
                    WM_voxels_with_data = find(curr_mean_func(WM_voxels)~=0 & ~isnan(curr_mean_func(WM_voxels)));

                    % counting, for each voxel, in how many subjects it is "good"
                    num_subjs_notnan_WM(WM_voxels_with_data) = num_subjs_notnan_WM(WM_voxels_with_data) + 1;
                    clear curr_mean_func;
                end

                % Removing voxels with <80% subjects in which they are not NaN
                WM_voxels(num_subjs_notnan_WM < threshold_notnan*length(Subj_list)) = [];
                WMmask = zeros(size(WMmask)); WMmask(WM_voxels) = 1;

                %% Saving the resulting masks
                save_mat_to_nifti(func_img1_filename, WMmask, fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs_buckner.nii'));
            else
                disp('Group WM mask is found hence skipping this step')
                
            end

            disp('Group WM mask is done')
            disp('##########################################################################################')

            if over_write == 1
                WM_cluster_file = dir(fullfile(output_folder,'Analysis','WM_Networks','WM_clustering_K*.nii'));
                if ~isempty(WM_cluster_file)
                    delete(fullfile(WM_cluster_file.folder,WM_cluster_file.name))
                end
                WM_cluster_file = [];
            else
                WM_cluster_file = dir(fullfile(output_folder,'Analysis','WM_Networks','WM_clustering_K*.nii'));
            end

            if ~isempty(WM_cluster_file)

                if length(WM_cluster_file) == 1
                    K_old = str2double(WM_cluster_file(1).name(16:end-4));
                elseif length(WM_cluster_file) > 1
                    K_old = zeros(1,length(WM_cluster_file));
                    for i = 1:length(WM_cluster_file)
                        K_old(i) = str2double(WM_cluster_file(i).name(16:end-4));
                    end
                end

                answer = questdlg(['WM Clustering file already found with K = ', num2str(K_old) ,'. Do you want to build the networks again?'],...
                    'WM CLustering file found','Yes','No','No');
                switch answer
                    case 'Yes'
                        build_net = 1;
                    case 'No'
                        if length(K_old) == 1
                            K = K_old;
                        else
                            while (1)
                                ques = inputdlg(['Choose the K you want to proceed with, available options are K = ' num2str(K_old)]);
                                ques = str2double(cell2mat(ques));
                                if sum(ques == K_old)
                                    K_old = ques;
                                    K = K_old;
                                    break
                                else
                                    cancel = questdlg(['Requested K value not found please only choose from K = ' num2str(K_old)],'Not found K','Quit','Re-enter','Re-enter');

                                    switch cancel
                                        case 'Quit'
                                            return
                                    end
                                end
                            end
                        end
                        cc_mask_filename = fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['Corpus_callosum_atlas_MNI_' char(vox) 'mm.nii']);
                    otherwise
                        return
                end
            else
                build_net = 1;
            end
            if build_net == 1
                %                 if ~exist('K_old','var')
                disp('##########################################################################################')
                disp('Creating the WM_Networks using K-Means')
                %% loading pre-made group average WM and GM masks (from the script create_average_WM_and_GM_masks.m)
                % reading the masks using SPM's functions
                %%% input the group white-matter/gray-matter masks
                WMmask = niftiread(fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs_buckner.nii')); %% input the group white-matter mask that excludes callosal voxels
                
                
                %             GMmask = spm_read_vols(spm_vol(fullfile(output_folder,'Analysis','Group_Masks','GMmask_allsubjs_buckner.nii')));

                % Excluding Corpus callousum voxels in WM mask if Corpus
                % callosum networks are to be found
                if Corpus_check == 1
                    cc_mask = reslice_data(cc_mask_filename,fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs_buckner.nii'),0);
                    cc_mask_head = niftiinfo(fullfile(output_folder,'Analysis','Group_Masks','WMmask_allsubjs_buckner.nii'));
                    niftisave(cc_mask,fullfile(output_folder,'Analysis',['Corpus_callosum_atlas_MNI_' char(vox) 'mm.nii']),cc_mask_head)
                    cc_mask_filename = fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['Corpus_callosum_atlas_MNI_' char(vox) 'mm.nii']);
                    WMmask(cc_mask>0) = 0;
                end

                % finding the locations of white-matter 
                WM_voxels = find(WMmask>0);

                user = memory;

                n_WM_voxels = numel(WM_voxels);
                txt_name = 'Subsampling_info.txt';
                switch app.SubsamplingtheWMvoxelFCmatrixDropDown.Value

                    case 'Let toolbox decide'
                        if n_WM_voxels*n_WM_voxels*8 > 0.8*user.MaxPossibleArrayBytes   % if the voxel-FC matrix size is greater than 80% of Max possible array size
                            %% Choose subsampling
                            
                            if (n_WM_voxels*n_WM_voxels) < 0.8*user.MaxPossibleArrayBytes % Try subsampling
                                text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                fprintf(text_sampling, ['Choosing the subsampling statergy as the WM-voxel FC matrix is too big for the PC.', 'Max Memory required for the array = ' num2str(n_GM_voxels*n_GM_voxels*8) ' Available memory in PC = ' num2str(0.8*user.MaxPossibleArrayBytes)]);
                                sub_samp = 1;
                                fclose(text_sampling);
                            else  % Subsampling voxel FC is also too big for the computer to process
                                response_ = questdlg('The subsampled FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. Want to proceed?','Memory low','Yes','No','No');
                                switch response_
                                    case 'Yes'
                                        text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                        fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                                        sub_samp = 1;
                                        warning('May get an Memory error');
                                        fclose(text_sampling);
                                    case 'No'
                                        text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                        fprintf(text_sampling, 'WM Networks not formed as the subsampled matrix was too big for the PC.');
                                        fclose(text_sampling);
                                        return
                                    otherwise
                                        return
                                end
                            end
                        else
                            text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                            fprintf(text_sampling,'Choosing the entire FC matrix ');
                            sub_samp = 0;
                            fclose(text_sampling);
                            
                        end

                    case 'Subsample'
                        if (n_WM_voxels*n_WM_voxels) < 0.8*user.MaxPossibleArrayBytes % Try subsampling,  looking if the voxel subsampled FC is lesser than 80% of Max possible array size
                            text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                            fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                            sub_samp = 1;
                            fclose(text_sampling);
                        else % the voxel FC is too big
                            response_ = questdlg('The subsampled FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. Want to proceed?','Memory low','Yes','No','No');
                            switch response_ 
                                case 'Yes'
                                    text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                    fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                                    sub_samp = 1;
                                    warning('May get an Memory error');
                                    fclose(text_sampling);
                                case 'No'
                                    text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                    fprintf(text_sampling, 'WM Networks not formed as the subsampled matrix was too big for the PC.');
                                    fclose(text_sampling);
                                    return
                                otherwise
                                    return
                            end
                        end
                    case 'Use entire FC'
                        if n_WM_voxels*n_WM_voxels*8 < 0.8*user.MaxPossibleArrayBytes  % if the voxel-FC matrix size is lesser than 80% of Max possible array size
                            %% Choose entire FC
                            text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                            fprintf(text_sampling, 'Choosing the entire FC.');
                            sub_samp = 0;
                            fclose(text_sampling);
                        else
                            response_ = questdlg('The entire FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. You may subsample to matrix to compute the WM networks?','Memory low','Subsample','Still Use entire FC','Subsample');
                            switch response_ 
                                case 'Subsample'
                                    if (n_WM_voxels*n_WM_voxels) < 0.8*user.MaxPossibleArrayBytes % Try subsampling
                                        text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                        fprintf(text_sampling, ['Choosing the subsampling statergy as the WM-voxel FC matrix is too big for the PC.', 'Max Memory required for the array = ' num2str(n_WM_voxels*n_WM_voxels*8) ' Available memory in PC = ' num2str(0.8*user.MaxPossibleArrayBytes)]);
                                        sub_samp = 1;
                                        fclose(text_sampling);
                                    else % subsampled voxel FC is too big as well
                                        response_ = questdlg('The subsampled FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. Want to proceed?','Memory low','Yes','No','No');
                                        switch response_
                                            case 'Yes'
                                                text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                                fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                                                sub_samp = 1;
                                                warning('May get an Memory error');
                                                fclose(text_sampling);
                                            case 'No'
                                                text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                                fprintf(text_sampling, 'WM Networks not formed as the subsampled matrix was too big for the PC.');
                                                fclose(text_sampling);
                                                return
                                            otherwise
                                                return
                                        end
                                    end
                                case 'Still Use entire FC'
                                    text_sampling = fopen(fullfile(output_folder,'Analysis','WM_Networks',txt_name),'a');
                                    fprintf(text_sampling, 'Choosing the entire FC as it was choosen by the user.');
                                    sub_samp = 0;
                                    warning('May get an Memory error');
                                    fclose(text_sampling);
                                otherwise
                                    return
                            end
                        end
                end
                

                %% Computing the data for clustering from each subject
                % this data is a NxM matrix:
                % - rows of the matrix correspond to all WM voxels
                % - columns of the matrix correspond to a subsampled WM matrix, to aid the
                %   next stage of clustering by reducing the number of features
                % for example for 12,000 white-matter voxels, the matrix will be 12k x 3k
                %
                % each matrix cell contains the correlation between the corresponding
                % voxels' signals, averaged across subjects

                % defining a sub-sampling of the mask voxels
                if sub_samp == 1
                    p = zeros(size(WMmask));    % defining a grid of 1s and 0s across the image
                    p(2:2:end, 2:2:end, 2:2:end) = 1; p(1:2:end, 1:2:end, 1:2:end) = 1;
                else
                    p = ones(size(WMmask));    % defining a grid of 1s and 0s across the image
                end
                p = p(WM_voxels);   % choosing only locations of white-matter voxels
                p = find(p);    % getting the indices of subsampled voxels in the whole mask
                p = p(randperm(length(p)));     % randomly mixing the voxels' indices

                % getting the data from each subject - correlation between all WM voxels and the subsampled WM voxels (num_WM_voxels X num_subsampled_voxels)
                data_for_clustering_allsubjs = zeros(length(WM_voxels),length(p));
                num_subjs_notnan = zeros(length(WM_voxels),length(p));

                disp('Creating the correlation matrix')
                % definition of a file name with functional data - so that other files will be resampled to this file's resolution
                func_img1_filename = complete_filepath(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));
                if isempty(dir(fullfile(output_folder,'Analysis','WM_Networks','data_for_clustering_allsubjs.mat')))
                    for subji = 1:length(Subj_list)      % For loop on number of subjects
                        disp('..')
                        disp(['Currently Processing ' Subj_list(subji).name])
                        try
                            now_func_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );
                            func_data = niftiread(now_func_path);
                            all_timecourses = reshape(func_data, [size(func_data,1)*size(func_data,2)*size(func_data,3) size(func_data,4)]); % resampling to 2D - num_WM_voxels X num_timepoints
                            current_voxels_timecourses = double(all_timecourses(WM_voxels,:));  % getting the signal across time from all white-matter voxels
                            clear all_timecourses; clear func_data;

                            % loading the segmentation files of all subjects, for identification of WM voxels in this specific subject
                            current_seg_dir = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name));
                            current_GM_file = dir(fullfile(current_seg_dir, [GM_file_prefix '*.nii']));
                            current_WM_file = dir(fullfile(current_seg_dir, [WM_file_prefix '*.nii']));
                            current_CSF_file = dir(fullfile(current_seg_dir, [CSF_file_prefix '*.nii']));

                            % resampling the segmentation files to the functional image resolution
                            current_GM_mask = reslice_data(fullfile(current_seg_dir,current_GM_file(1).name), func_img1_filename, 0);
                            current_WM_mask = reslice_data(fullfile(current_seg_dir,current_WM_file(1).name), func_img1_filename, 0);
                            current_CSF_mask = reslice_data(fullfile(current_seg_dir,current_CSF_file(1).name), func_img1_filename, 0);

                            % finding where probability for white-matter is larger than 0.2 and
                            % larger than probability for grey-matter or CSF
                            current_seg_mask = (current_WM_mask>current_GM_mask) & (current_WM_mask>current_CSF_mask) & (current_WM_mask>0.2);

                            % finding and ignoring irrelevant voxels (e.g. not defined as WM in this specific subject, or have NaN values)
                            WM_voxels_with_data = find(var(current_voxels_timecourses,[],2)~=0 & ~isnan(var(current_voxels_timecourses,[],2)) & current_seg_mask(WM_voxels)==1);
                            WM_voxels_p_with_data = find(var(current_voxels_timecourses(p,:),[],2)~=0 & ~isnan(var(current_voxels_timecourses(p,:),[],2)) & current_seg_mask(WM_voxels(p))==1);

                            % Calculating correlation matrix of each WM voxel to the subsampled voxels
                            current_corr = corr(current_voxels_timecourses(WM_voxels_with_data,:)', current_voxels_timecourses(p(WM_voxels_p_with_data),:)');

                            % adding the current connectivity matrix to the sum of all subjects, for later averaging
                            data_for_clustering_allsubjs(WM_voxels_with_data, WM_voxels_p_with_data) = data_for_clustering_allsubjs(WM_voxels_with_data, WM_voxels_p_with_data) + current_corr;
                            num_subjs_notnan(WM_voxels_with_data, WM_voxels_p_with_data) = num_subjs_notnan(WM_voxels_with_data, WM_voxels_p_with_data) + 1;

                            clear current_voxels_timecourses; clear current_corr; clear current_seg_mask; clear WM_voxels_with_data; clear WM_voxels_p_with_data;
                        catch exception
                            disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                            disp(['Preprocessing has encountered errors in Creating data matrix for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                            disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                            write_error(exception,quality_control_path, Subj_list(subji).name)            % write error to text file and display
                            Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).error = 1;
                        end
                    end
                    my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                    %                     Subj_list = load_subjects(app,output_folder,'Subj_list.csv');
                    % calculating mean connectivity matrix from all subjects
                    data_for_clustering_allsubjs = data_for_clustering_allsubjs./num_subjs_notnan;
                else
                    load(fullfile(output_folder,'Analysis','WM_Networks','data_for_clustering_allsubjs.mat')) %#ok<LOAD> 
                end
                data_for_clustering_allsubjs(isnan(data_for_clustering_allsubjs))=0;
                missing_voxels = find(std(data_for_clustering_allsubjs,[],2)==0);   % finding voxels with no data
                WM_voxels(missing_voxels) = []; data_for_clustering_allsubjs(missing_voxels,:)=[];

                missing_voxels = std(data_for_clustering_allsubjs,[],1)==0;   % finding voxels with no data
                data_for_clustering_allsubjs(:,missing_voxels)=[];

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %% Selecting the best K on the group-level data, by measuring stability of clustering solutions

                % Here we separate the correlation matrix columns into 4 groups
                % (cross-validation folds), therefore selecting a subset of the features.
                % We perform the clustering on each fold, and then measure the similarity
                % between clustering solutions for all pairs of folds. This is repeated for
                % each K (number of clusters). A "good" K will give the same clustering
                % solution even when using different features, and will therefore have more
                % similarity (stability) between folds (Lange et al., Neural Comput 2004).
                %
                % For a MxN connectivity matrix, we'll get four Mx(N/4) matrices, and check
                % the correspondence of their clustering solutions to one another.


                if K_range_l ~= K_range_h           % If the lower and upper values of Grid search for K are the same, that means the grid search is not necessary skip it and directly calculate the networks
                    disp([num2str(CV_folds) ' Fold Cross validation in process to find out the correct value of K'])
                    % separating the data into subsets of features
                    num_CV_folds = CV_folds;
                    num_replicates = 10;
                    IDX_folds_new = cell(1,num_CV_folds);
                    Dice_coefficient_folds_all = zeros(1,K_range_h - K_range_l + 1);
                    elb = zeros(1,K_range_h - K_range_l + 1);

                    for K= K_range_l:K_range_h          % going over all possible numbers of clusters, to measure each one's stability
                        disp(['K = ' num2str(K)]);
                        IDX_folds = zeros(size(data_for_clustering_allsubjs,1),num_CV_folds); IDX_folds_new{K} = zeros(size(IDX_folds));
                        size_fold = size(data_for_clustering_allsubjs,2)/num_CV_folds;
                        sumD = zeros(K,num_CV_folds);
                        for c=1:num_CV_folds        % going over folds (sub-matrices)
                            disp(c)
                            mat_corr_current = data_for_clustering_allsubjs(:,round((c-1)*size_fold+1):round(c*size_fold));     % the sub-correlation-matrix
                            [IDX_folds(:,c),~,sumD(:,c)] = kmeans(mat_corr_current, K,'distance','correlation','replicates',num_replicates);  % calculating the clustering result for this K
                        end

                        % computing the difference between adjacency matrices for each fold
                        size_chunk = 100;   % need to compute adjacency matrices in parts - otherwise it takes too much memory (~300 million numbers per matrix)
                        num_chunks = floor(size(IDX_folds,1) / size_chunk);
                        sum_diff_adjmats_folds = zeros(num_CV_folds); sum_common_connections_adjmats = zeros(num_CV_folds); sum_all_connections_adjmats = zeros(num_CV_folds);
                        for ch1=1:num_chunks
                            for ch2=ch1:num_chunks    % iterating over all adjacency matrix parts combinations
                                current_clustering_adjmats = zeros(size_chunk,size_chunk,num_CV_folds);
                                current_chunk1 = (ch1-1)*size_chunk+1 : ch1*size_chunk;
                                current_chunk2 = (ch2-1)*size_chunk+1 : ch2*size_chunk;

                                % creating the current adjacency matrix part, for all folds
                                for c=1:num_CV_folds
                                    for i=1:size_chunk
                                        if ch1 == ch2
                                            j_points = i:size_chunk;
                                        else
                                            j_points = 1:size_chunk;
                                        end
                                        for j=j_points
                                            % In the adjacency matrix, cell (i,j) equals 1 if voxels (i,j) belong to the same cluster and 0 otherwise
                                            % This allows comparison of clustering results even if the labels of the same clusters in each result are different
                                            % (e.g. if the occipital cluster in solution 1 is labeled as cluster number 4, and in solution 2 it's labeled as cluster 7)
                                            if IDX_folds(current_chunk1(i),c)==IDX_folds(current_chunk2(j),c)

                                                current_clustering_adjmats(i,j,c)=1;
                                                if ch1 == ch2
                                                    current_clustering_adjmats(j,i,c)=1;
                                                end
                                            end
                                        end
                                    end
                                end
                                %%
                                % Computing the difference between adjacency matrix for different folds, and adding this difference to the sum matrix
                                for c1=1:num_CV_folds
                                    for c2=c1+1:num_CV_folds
                                        sum_diff_adjmats_folds(c1,c2) = sum_diff_adjmats_folds(c1,c2) + (sum(sum(current_clustering_adjmats(:,:,c1)~=current_clustering_adjmats(:,:,c2))));
                                        sum_common_connections_adjmats(c1,c2) = sum_common_connections_adjmats(c1,c2) + (sum(sum(current_clustering_adjmats(:,:,c1) & current_clustering_adjmats(:,:,c2))));
                                        sum_all_connections_adjmats(c1,c2) = sum_all_connections_adjmats(c1,c2) + (sum(sum(current_clustering_adjmats(:,:,c1) + current_clustering_adjmats(:,:,c2))));
                                        sum_diff_adjmats_folds(c2,c1) = sum_diff_adjmats_folds(c1,c2);
                                        sum_common_connections_adjmats(c2,c1) = sum_common_connections_adjmats(c1,c2);
                                        sum_all_connections_adjmats(c2,c1) = sum_all_connections_adjmats(c1,c2);
                                    end
                                end
                            end
                        end

                        % Calculating the average difference between adjacency matrices (across all folds pairs)
                        %                 sum_diff_adjmats_folds_all(K) = mean(sum_diff_adjmats_folds(~eye(num_CV_folds)));   % sum of all differences
                        elb(K) = mean(mean(sumD));
                        Dice_coefficient = sum_common_connections_adjmats * 2 ./ sum_all_connections_adjmats;
                        Dice_coefficient_folds_all(K) = mean(Dice_coefficient(~eye(num_CV_folds)));    % Dice's coef is 1 for perfect match, 0 for no commonalities
                    end

                    % plotting the stability results for all K values, to identify peaks
                    yyaxis left
                    figure; plot(Dice_coefficient_folds_all,'Marker','*'), xlim([K_range_l K_range_h])
                    xlabel('K-values')
                    ylabel('Dice Coefficients')

                    yyaxis right
                    plot(elb,'Marker','+')
                    ylabel('Distortion')
                    saveas(gcf,fullfile(output_folder,'Analysis','WM_Networks','K_grid_search_dice_corficient_WM.png'))
                    K = str2double(cell2mat(inputdlg('Choose the K-value','K-Value')));

                    if isnan(K)
                        return
                    end
                    close gcf
                else
                    K = K_range_l;  % Assign K as the lower K value (doesnt matter as the lower and upper K values are the same)
                end
                %% K-means clustering of subjects' mean connectivity matrix

                for k=K
                    disp(['K = ' num2str(k)]);
                    IDX_allsubjs = kmeans(data_for_clustering_allsubjs, k,'distance','correlation','replicates',10);            % K-means clustering
                    clustering_results_allsubjs = zeros(size(WMmask)); clustering_results_allsubjs(WM_voxels) = IDX_allsubjs;   % putting the clustering results in an image
                    save_mat_to_nifti(func_img1_filename,clustering_results_allsubjs,fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K' num2str(k) '.nii']));    % saving the results to file %% set output path
                    clear clustering_results_allsubjs;
                end
                %                 end

            end

            %% Create brain net viwer images
            if over_write == 1
                WM_brainnet_image = dir(fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K',num2str(K),'_BrainNet_images'],'WM_1_Anterior.png'));
                if ~isempty(WM_brainnet_image)
                    delete(fullfile(WM_brainnet_image.folder,WM_brainnet_image.name))
                end
                WM_brainnet_image = [];
            else
                WM_brainnet_image = dir(fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K',num2str(K),'_BrainNet_images'],'WM_1_Anterior.png'));
            end
            if isempty(WM_brainnet_image)
                mkdir(fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K' num2str(K) '_BrainNet_images']))
                image_folder = fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K' num2str(K) '_BrainNet_images']);

                cluster = dir(fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K' num2str(K) '.nii']));

                mode = 'WM';
                view_angles = [ 0 , 0 ;
                    90, 0 ;
                    0 , 90;
                    180, 0 ;
                    180,-90;
                    -90, 0 ;];
                view_names = {'Posterior';
                    'Right'  ;
                    'Dorsal'  ;
                    'Anterior' ;
                    'Ventral' ;
                    'left'   ;};
                for i = 1:length(view_angles)
                    for j = 1:K
                        BrainNet_MapCfg_WhifuN(fullfile(preproc_code_path,'BrainNetViewer_20191031','Data','SurfTemplate','BrainMesh_Ch2_smoothed.nv'),fullfile(cluster.folder,cluster.name),fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters2.mat'),view_angles(i,:),j,fullfile(image_folder,[mode '_' num2str(j) '_' view_names{i} '.png']));%fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters2.mat')  'temp.mat'
                    end
                end
            end
            %% Obtaining averaged time series of WMFNs
            disp('##########################################################################################')
            disp('Getting average timeseries from WM_Networks')
            if over_write == 1
                wmfn_file = dir(fullfile(output_folder,'Analysis','WM_Networks',['network_signals_wm_K' num2str(K) '.mat']));
                if ~isempty(wmfn_file)
                    delete(fullfile(output_folder,'Analysis','WM_Networks',['network_signals_wm_K' num2str(K) '.mat']));
                    wmfn_file = [];
                end
            else
                if exist("K","var")
                    wmfn_file = dir(fullfile(output_folder,'Analysis','WM_Networks',['network_signals_wm_K' num2str(K) '.mat']));
                else
                    wmfn_file = [];
                end

            end

            if isempty(wmfn_file)
                nt = Subj_list(1).nt_dis;       % nt has to be the same for all subjects for corpus callosum networks
                [new_data_mask,~]=y_Read(fullfile(output_folder,'Analysis','WM_Networks',['WM_clustering_K' num2str(K) '.nii'])); %%% input the mask of white-matter functional netwotks
                index=unique(new_data_mask);index(index==0)=[];
                final_result = zeros(nt,numel(index),length(Subj_list) );    % nt has to be same for all subjects
                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    disp('..')
                    disp(['Currently Processing ' Subj_list(subji).name])
                    now_func_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );

                    [data,~]=y_Read(now_func_path);
                    roi1=isnan(data);data(roi1)=0;[x,y,z,t]=size(data);
                    new_data=reshape(data,x*y*z,t);
                    for k=1:length(index)
                        roi=new_data_mask==k;
                        result=mean(new_data(roi,:));
                        final_result(:,k,subji)=result;
                        clear result
                    end
                end
                save(fullfile(output_folder,'Analysis','WM_Networks',['network_signals_wm_K' num2str(K) '.mat']),'final_result',"Subj_list"); %% added

            else
                disp('White matter Average time series file found, hence skipping this step. ')
                load(fullfile(output_folder,'Analysis','WM_Networks',['network_signals_wm_K' num2str(K) '.mat']),'final_result','Subj_list');
            end
            disp('Average timeseries from WM networks extracted')
            disp('##########################################################################################')

            if Corpus_check == 1


                mkdir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks'))
                mkdir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','Winner take all'))
                %% Obtaining corpus callosum each voxel different Mask

                if over_write == 1
                    cc_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));
                    if ~isempty(cc_file)
                        delete(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));
                        cc_file = [];
                    end
                else
                    cc_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));
                end

                if isempty(cc_file)

                    [data,head] = y_Read(cc_mask_filename);
                    roi = find(data~=0);
                    new_data_mask=zeros(size(data));

                    for i = 1:length(roi)
                        wp = roi(i);
                        new_data_mask(wp) = i;

                    end
                    head.fname = fullfile(output_folder,'Analysis');
                    y_Write(new_data_mask,head,fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));
                else
                    disp('Corpus callosum Mask file found hence skipping this step')
                end


                %% Getting the Corpus callousum signals

                if over_write == 1
                    cc_net_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','cc_signals.mat'));
                    if ~isempty(cc_net_file)
                        delete(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','cc_signals.mat'));
                        cc_net_file = [];
                    end
                else
                    cc_net_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','cc_signals.mat'));
                    new_data_mask = niftiread(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));
                end

                if isempty(cc_net_file)
                    now_func_path_temp = complete_filepath(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );
                    temp_info = niftiinfo(now_func_path_temp);
                    index=unique(new_data_mask);index(index==0)=[];
                    final_result_cc = zeros(length(index),temp_info.ImageSize(4),length(Subj_list));
                    for subji = 1:length(Subj_list)      % For loop on number of subjects
                        disp('..')
                        disp(['Currently Processing ' Subj_list(subji).name])
                        now_func_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );

                        [data,~]=y_Read(now_func_path);
                        roi1=isnan(data);data(roi1)=0;[x,y,z,t]=size(data);
                        new_data=reshape(data,x*y*z,t);

                        for k=1:length(index)
                            roi=new_data_mask==k;
                            result = new_data(roi,:);
                            final_result_cc(k,:,subji)=result;
                            clear result
                        end

                    end
                    save(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','cc_signals.mat'),'final_result_cc','Subj_list'); %% added
                else
                    disp('Corpus callosum voxel timeseries file found hence skipping this step')
                    load(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','cc_signals.mat')); %#ok<LOAD>
                end
                %% Partial Correlation analysis

                if over_write == 1
                    par_cor_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','parcorr_result.mat'));
                    if ~isempty(par_cor_file)
                        delete(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','parcorr_result.mat'));
                        par_cor_file = [];
                    end
                else
                    par_cor_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','parcorr_result.mat'));
                end

                if isempty(par_cor_file)

                    network_final_result=final_result;clear final_result;
                    parcorr_result = zeros(size(final_result_cc,1),size(network_final_result,2),size(network_final_result,3));
                    for i=1:length(network_final_result(1,1,:)) %%% the number of subjects
                        disp(i)
                        voxel=final_result_cc(:,:,i);
                        network=network_final_result(:,:,i);
                        for j=1:length(network_final_result(1,:,1))  %%% the number of white-matter functional networks
                            net=(network(:,j));
                            reg=network;reg(:,j)=[];
                            for k=1:length(voxel(:,1))  %%% the number of callosal voxels
                                y_voxel=(voxel(k,:))';
                                [Rho,~]=partialcorr(y_voxel,net,reg);
                                Rho=0.5*(log((1+Rho)/(1-Rho)));
                                parcorr_result(k,j,i)=Rho;
                            end
                        end
                    end
                    roi=isnan(parcorr_result);
                    parcorr_result(roi)=0;

                    save(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','parcorr_result.mat'),'parcorr_result'); %% added

                else
                    disp('Partial Correlation file found, hence skipping this step')
                    load(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','parcorr_result.mat'),'parcorr_result')
                end

                %% create CC atlas using partial correlation
                if over_write == 1
                    cc_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '.nii']));
                    if ~isempty(cc_file)
                        delete(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '.nii']));
                        cc_file = [];
                    end
                else
                    cc_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '.nii']));
                end
                if isempty(cc_file)
                    wp = zeros(size(parcorr_result(:,:,1)));
                    for i = 1:size(parcorr_result,1)  % cc voxel
                        for j = 1:size(parcorr_result,2) % wm-networks
                            [~,~,~,stat] = ttest(squeeze(parcorr_result(i,j,:)));
                            wp(i,j) = stat.tstat;
                        end
                    end

                    [~,max_idx] = max(wp,[],2);

                    new_data_mask = niftiread(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));
                    head = niftiinfo(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks','CC_thres.nii'));

                    [x,y,z] = size(new_data_mask);

                    new_mask = new_data_mask;
                    cc_atlas = zeros(x,y,z);
                    for k=1:size(parcorr_result,1)   %% the number of callosal voxel
                        a=new_mask==k;
                        cc_atlas(a)=max_idx(k);
                    end

                    niftisave(cc_atlas,fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '.nii']),head);

                else
                    disp('White matter Corpus callosum atlas file found, hence skipping this step. ')
                end

                %% Create brain net viwer images
                if over_write == 1
                    CC_brainnet_image = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K',num2str(K),'_BrainNet_images'],'CC_1_Anterior.png'));
                    if ~isempty(CC_brainnet_image)
                        delete(fullfile(CC_brainnet_image.folder,CC_brainnet_image.name))
                    end
                    CC_brainnet_image = [];
                else
                    CC_brainnet_image = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K',num2str(K),'_BrainNet_images'],'CC_1_Anterior.png'));
                end

                if isempty(CC_brainnet_image)
                    mkdir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '_BrainNet_images']))
                    image_folder = fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '_BrainNet_images']);

                    cluster = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '.nii']));

                    mode = 'CC';
                    view_angles = [ 0 , 0 ;
                        90, 0 ;
                        0 , 90;
                        180, 0 ;
                        180,-90;
                        -90, 0 ;];
                    view_names = {'Posterior';
                        'Right'  ;
                        'Dorsal'  ;
                        'Anterior' ;
                        'Ventral' ;
                        'left'   ;};
                    for i = 1:length(view_angles)
                        for j = 1:K
                            BrainNet_MapCfg_WhifuN(fullfile(preproc_code_path,'BrainNetViewer_20191031','Data','SurfTemplate','BrainMesh_Ch2_smoothed.nv'),fullfile(cluster.folder,cluster.name),fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters2.mat'),view_angles(i,:),j,fullfile(image_folder,[mode '_' num2str(j) '_' view_names{i} '.png']));%fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters2.mat')  'temp.mat'
                        end
                    end
                end
                %% Obtaining averaged time series of Corpus callosum networks
                if over_write == 1
                    cc_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['network_signals_cc_K' num2str(K) '.mat']));
                    if ~isempty(cc_file)
                        delete(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['network_signals_cc_K' num2str(K) '.mat']));
                        cc_file = [];
                    end
                else
                    cc_file = dir(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['network_signals_cc_K' num2str(K) '.mat']));
                end

                if isempty(cc_file)
                    nt = Subj_list(1).nt_dis;       % nt has to be the same for all subjects for Corpus_Callosum_Networks
                    [new_data_mask,~]=y_Read(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['cc_network_atlas_K' num2str(K) '.nii'])); %%% input the mask of white-matter functional netwotks
                    index=unique(new_data_mask);index(index==0)=[];
                    final_result_ccn = zeros(nt,numel(index),length(Subj_list) );    % nt has to be same for all subjects
                    for subji = 1:length(Subj_list)      % For loop on number of subjects
                        disp('..')
                        disp(['Currently Processing ' Subj_list(subji).name])
                        now_func_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );

                        [data,~]=y_Read(now_func_path);
                        roi1=isnan(data);data(roi1)=0;[x,y,z,t]=size(data);
                        new_data=reshape(data,x*y*z,t);
                        for k=1:length(index)
                            roi=new_data_mask==k;
                            result=mean(new_data(roi,:));
                            final_result_ccn(:,k,subji)=result;
                            clear result
                        end
                    end
                    save(fullfile(output_folder,'Analysis','Corpus_Callosum_Networks',['network_signals_cc_K' num2str(K) '.mat']),'final_result_ccn','Subj_list'); %% added
                end
            end
            disp('WM networks created')
            disp('##########################################################################################')

        end

        % Button pushed function: CreateGMnetworksButton
        function CreateGMnetworksButtonPushed(app, event)
            %% Clustering GM Functional networks
            output_folder = char(string(app.DataPathTextArea_2.Value));
            build_net = 0;
            if isempty(app.SelectedSubjectswithgooddataDropDown.Items)
                msgbox('Please select subjects first')
                return
            end
            indi_thres_wm = double(string(app.IndividualWMProbabilitythresholdTextArea.Value));
            indi_thres_gm = double(string(app.IndividualGMProbabilitythresholdTextArea.Value));

            mkdir(fullfile(output_folder,'Analysis','GM_Networks'))
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'BrainNetViewer_20191031'))
            data_path = char(string(app.DataPathTextArea.Value));
            cd(data_path)
            quality_control_path = fullfile(output_folder,'Quality_control');

            Subj_list = load_subjects(app,output_folder,'Subj_list.csv');
            Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv');
            %%          These values can be changed but default is recommended
            if double(string(app.NoofVolumestoDiscardTextArea.Value)) ~= 0
                Cut_pre = 'c_';                                                             % Prefix for the Discarding Initial volumes File
            else
                Cut_pre = '';
            end
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            Norm_pre = 'w';                                                             % Prefix for the Normalized File
            smooth_drop = app.SmoothingDropDown.Value;
            Reg_drop = app.NuisanceRegressionDropDown.Value;

            grp_gm_thres = double(string(app.GroupGMthresholdTextArea.Value))/100;
            grp_wm_thres = double(string(app.GroupWMthresholdTextArea.Value))/100;
            warning('Creating GM networks also uses the Group WM threshold, the voxels identified in the Group WM mask wont be added in the Group GM mask')
            HO_atlas_filename = fullfile(preproc_code_path,'HarvardOxford-sub-maxprob-thr25-2mm_YCG.nii'); %%% choose the HarvardOxford-sub-maxprob-thr25-2mm_YCG.nii from the mask file
            f_check = app.FilterCheckBox.Value;
            if f_check
                f_pre = 'f';   % prefix for filtered file
            else
                f_pre = '';
            end

            switch smooth_drop
                case 'No Smoothing'
                    Smooth_pre = '';
                otherwise
                    Smooth_pre = 's';                                                           % Prefix for the Smoothed File
            end

            switch Reg_drop
                case 'No Regression'
                    Reg_pre = '';
                otherwise
                    Reg_pre = 'REG_';
            end
            % definition of prefix of files for each tissue type
            GM_file_prefix = 'wc1';
            WM_file_prefix = 'wc2';
            CSF_file_prefix = 'wc3';

            K_range_l = double(string(app.KmeansKRangeTextArea_3.Value));
            K_range_h = double(string(app.KmeansGMKRangeTextArea.Value));

            if K_range_l > K_range_h
                error(sprintf('Please check the K-range 1st number in the text field should be less than 2nd number, \nbut found otherwise\n'))
            end

            CV_folds = double(string(app.NumCVfoldsTextArea_2.Value));

            over_write = app.OverwriteexistingfilesCheckBox.Value;

            disp('##########################################################################################')
            disp('Creating the group GM masks ')

            if over_write == 1
                gm_mask_dir = dir(fullfile(output_folder,'Analysis','Group_Masks','GMmask_allsubjs_buckner.nii'));
                if ~isempty(gm_mask_dir)
                    delete(fullfile(gm_mask_dir.folder,gm_mask_dir.name))
                end
                gm_mask_dir = [];
            else
                gm_mask_dir = dir(fullfile(output_folder,'Analysis','Group_Masks','GMmask_allsubjs_buckner.nii'));
            end

            if isempty(gm_mask_dir)

                % definition of a file name with functional data - so that other files will be resampled to this file's resolution
                func_img1_filename = complete_filepath(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));
                size_mask = niftiinfo(func_img1_filename); size_mask = size_mask.ImageSize(1:3);
                WMmask_full = zeros(size_mask); GMmask_full = zeros(size_mask);

                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    try
                        disp('..')
                        disp(['Currently Processing ' Subj_list(subji).name])

                        % getting the current subject's segmentation directory
                        now_anat_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name));

                        WM_file_temp = dir(fullfile(now_anat_path, [WM_file_prefix '*.nii'])); WM_file = fullfile(WM_file_temp.folder, WM_file_temp.name);
                        GM_file_temp = dir(fullfile(now_anat_path, [GM_file_prefix '*.nii'])); GM_file = fullfile(GM_file_temp.folder, GM_file_temp.name);

                        current_WM_mask = reslice_data(WM_file, func_img1_filename, 0);
                        current_GM_mask = reslice_data(GM_file, func_img1_filename, 0);
                        % loading the full segmentation mask, created in the previous stage
                        % (each voxel shows the tissue type with the maximum probability in that voxel)
%                         current_seg_mask = fullfile(now_anat_path, 'wSegmentation.nii');
% 
%                         % resampling of the current segmentation file to the resolution of the
%                         % functional image
%                         current_seg_mask = reslice_data(current_seg_mask, func_img1_filename, 0);

                        % adding the current segmentation mask to the sum of all subjects'
                        % segmentation masks, for later averaging
                        WMmask_full(current_WM_mask>=indi_thres_wm) = WMmask_full(current_WM_mask>=indi_thres_wm) + 1;
                        GMmask_full(current_GM_mask>=indi_thres_gm) = WMmask_full(current_GM_mask>=indi_thres_gm) + 1;
%                         GMmask_full = GMmask_full + (current_seg_mask==1);
%                         WMmask_full = WMmask_full + (current_seg_mask==2);
                    catch exception
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in GM Grp Mask (anatomical) for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                        write_error(exception,quality_control_path, Subj_list(subji).name)            % write error to text file and display
                        Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).error = 1; % Remove subject from further preprocessing
                    end
                end
                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                Subj_list = load_subjects(app,output_folder,'Subj_list.csv');

                % calculating the average of all masks (dividing the sum by number of
                % subjects)
                GMmask_full = GMmask_full./length(Subj_list);
                WMmask_full = WMmask_full./length(Subj_list);
                % reading the Harvard-Oxford atlas and resampling it to the functional image's resolution

                HO_atlas = reslice_data(HO_atlas_filename, func_img1_filename, 0);

                % find the voxels defined as subcortical structures
                indices_subcortical = [find(HO_atlas==2010);	find(HO_atlas==2049);	find(HO_atlas==3011);	find(HO_atlas==3050);	find(HO_atlas==4012);	find(HO_atlas==4051);	find(HO_atlas==5013);	find(HO_atlas==5052);	find(HO_atlas==8026);	find(HO_atlas==8058);];

                % Remove these voxels from the white-matter mask and add them to the grey-matter mask
                WMmask_full(indices_subcortical)=0;
                GMmask_full(indices_subcortical)=1;

                %% Thresholding to find voxels defined as WM or GM in a big enough percent of the subjects
                WMmask = WMmask_full > grp_wm_thres;               % threshold for WM mask - >60% probability of identification as white-matter
                GMmask = GMmask_full > grp_gm_thres & ~WMmask;     % threshold for GM mask is more relaxed (20% probability of being GM, and not recognized as WM), for regions with very thin GM such as the top of the brain (otherwise we'll have holes in the mask)
                GM_voxels = find(GMmask>0);

                %% Removal of parts of the mask for which functional data exists only in <80% of subjects, such as the spinal cord
                threshold_notnan = 0.8;     % 80% of voxels need to be not NaN for each voxel to be included in the mask
                % defining the directory with average functional data of all subjects,
                % arranged in separate directories for each subject

                % reading these files and counting how many subjects have data in each voxel
                num_subjs_notnan_GM = zeros(length(GM_voxels),1);

                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    try
                        disp('..')
                        disp(['Currently Processing ' Subj_list(subji).name])

                        now_func_path = fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) ;

                        % reading the current subject's mean functional file
                        if ~isempty(now_func_path)
                            curr_filename = dir(now_func_path);
                        else
                            disp('No average functional image found!')
                            break;
                        end
                        curr_mean_func = mean(spm_read_vols(spm_vol(fullfile(curr_filename.folder,curr_filename.name))),4);  % loading the subjects' mean functional image

                        % current subject - finding voxels which are not zero or NaN
                        GM_voxels_with_data = find(curr_mean_func(GM_voxels)~=0 & ~isnan(curr_mean_func(GM_voxels)));

                        % counting, for each voxel, in how many subjects it is "good"
                        num_subjs_notnan_GM(GM_voxels_with_data) = num_subjs_notnan_GM(GM_voxels_with_data) + 1;
                        clear curr_mean_func;
                    catch exception
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in GM Grp Mask (anatomical) for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        write_error(exception,quality_control_path, Subj_list(subji).name)            % write error to text file and display
                        Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).error = 1;  % Remove subject from further preprocessing
                    end
                end
                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                Subj_list = load_subjects(app,output_folder,'Subj_list.csv');
                % Removing voxels with <80% subjects in which they are not NaN
                GM_voxels(num_subjs_notnan_GM < threshold_notnan*length(Subj_list)) = [];
                GMmask = zeros(size(GMmask)); GMmask(GM_voxels) = 1;


                %% Saving the resulting masks
                save_mat_to_nifti(func_img1_filename, GMmask, fullfile(output_folder,'Analysis','Group_Masks','GMmask_allsubjs_buckner.nii'));
            else
                disp('Group GM mask is found hence skipping this step')
            end

            disp('Group GM mask is done')
            disp('##########################################################################################')


            if over_write == 1
                GM_cluster_file = dir(fullfile(output_folder,'Analysis','GM_Networks','GM_clustering_K*.nii'));
                if ~isempty(GM_cluster_file)
                    delete(fullfile(GM_cluster_file.folder,GM_cluster_file.name))
                end
                GM_cluster_file = [];
            else
                GM_cluster_file = dir(fullfile(output_folder,'Analysis','GM_Networks','GM_clustering_K*.nii'));
            end

            if ~isempty(GM_cluster_file)

                if length(GM_cluster_file) == 1
                    K_old = str2double(GM_cluster_file(1).name(16:end-4));
                elseif length(GM_cluster_file) > 1
                    K_old = zeros(1,length(GM_cluster_file));
                    for i = 1:length(GM_cluster_file)
                        K_old(i) = str2double(GM_cluster_file(i).name(16:end-4));
                    end
                end
                answer = questdlg(['GM Clustering file already found with K = ',num2str(K_old),' Do you want to build the networks again?'],...
                    'GM CLustering file found','Yes','No','No');
                switch answer
                    case 'Yes'
                        build_net = 1;
                    case 'No'
                        if length(K_old) == 1
                            K = K_old;
                        else
                            while (1)
                                ques = inputdlg(['Choose the K you want to proceed with, available options are K = ' num2str(K_old)]);
                                ques = str2double(cell2mat(ques));
                                if sum(ques == K_old)
                                    K_old = ques;
                                    K = K_old;
                                else
                                    cancel = questdlg(['Requested K value not found please only choose from K = ' num2str(K_old)],'Not found K','Quit','Re-enter','Re-enter');

                                    switch cancel
                                        case 'Quit'
                                            return
                                    end
                                end
                            end
                        end
                    otherwise
                        return
                end
            else
                build_net = 1;
            end

            if build_net == 1

                %                 if ~exist('K_old','var')
                disp('##########################################################################################')
                disp('Creating the GM Networks using K-Means')
                %% loading pre-made group average WM and GM masks
                % reading the masks using SPM's functions
                %%% input the group white-matter/gray-matter masks
                GMmask = spm_read_vols(spm_vol(fullfile(output_folder,'Analysis','Group_Masks','GMmask_allsubjs_buckner.nii')));

                % finding the locations of white-matter and grey-matter voxels
                GM_voxels = find(GMmask>0);
                
                user = memory;

                n_GM_voxels = numel(GM_voxels);
                txt_name = 'Subsampling_info.txt';
                switch app.SubsamplingtheGMvoxelFCmatrixDropDown.Value

                    case 'Let toolbox decide'
                        if n_GM_voxels*n_GM_voxels*8 > 0.8*user.MaxPossibleArrayBytes
                            %% Choose subsampling
                            if (n_GM_voxels*n_GM_voxels) < 0.8*user.MaxPossibleArrayBytes
                                text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                fprintf(text_sampling,'Choosing entire FC Matrix');
                                sub_samp = 1;
                                fclose(text_sampling);
                            elseif (n_GM_voxels*n_GM_voxels) > 0.8*user.MaxPossibleArrayBytes
                                response_ = questdlg('The subsampled FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. Want to proceed?','Memory low','Yes','No','No');
                                switch response_
                                    case 'Yes'
                                        text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                        fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                                        sub_samp = 1;
                                        warning('May get an Memory error');
                                        fclose(text_sampling);
                                    case 'No'
                                        text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                        fprintf(text_sampling, 'WM Networks not formed as the subsampled matrix was too big for the PC.');
                                        fclose(text_sampling);
                                        return
                                    otherwise
                                        return
                                end
                            else
                                text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                fprintf(text_sampling, ['Choosing the subsampling statergy as the WM-voxel FC matrix is too big for the PC.', 'Max Memory required for the array = ' num2str(n_GM_voxels*n_GM_voxels*8) ' Available memory in PC = ' num2str(0.8*user.MaxPossibleArrayBytes)]);
                                sub_samp = 1;
                                fclose(text_sampling);
                            end
                      
                        else
                            text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                            fprintf(text_sampling,'Choosing the entire FC matrix ');
                            sub_samp = 0;
                            fclose(text_sampling);
                        end
                            

                    case 'Subsample'
                        if (n_GM_voxels*n_GM_voxels) < 0.8*user.MaxPossibleArrayBytes
                            text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                            fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                            sub_samp = 1;
                            fclose(text_sampling);
                        else
                            response_ = questdlg('The subsampled FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. Want to proceed?','Memory low','Yes','No','No');
                            switch response_ 
                                case 'Yes'
                                    text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                    fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                                    sub_samp = 1;
                                    warning('May get an Memory error');
                                    fclose(text_sampling);
                                case 'No'
                                    text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                    fprintf(text_sampling, 'WM Networks not formed as the subsampled matrix was too big for the PC.');
                                    fclose(text_sampling);
                                    return
                                otherwise
                                    return
                            end
                        end
                    case 'Use entire FC'
                        if n_GM_voxels*n_GM_voxels*8 < 0.8*user.MaxPossibleArrayBytes
                            %% Choose entire FC
                            text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                            fprintf(text_sampling, 'Choosing the entire FC.');
                            sub_samp = 0;
                            fclose(text_sampling);
                        else
                            response_ = questdlg('The entire FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. You may subsample to matrix to compute the WM networks?','Memory low','Subsample','Still Use entire FC','Subsample');
                            switch response_ 
                                case 'Subsample'
                                    if (n_GM_voxels*n_GM_voxels) < 0.8*user.MaxPossibleArrayBytes
                                        text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                        fprintf(text_sampling, ['Choosing the subsampling statergy as the WM-voxel FC matrix is too big for the PC.', 'Max Memory required for the array = ' num2str(n_GM_voxels*n_GM_voxels*8) ' Available memory in PC = ' num2str(0.8*user.MaxPossibleArrayBytes)]);
                                        sub_samp = 1;
                                        fclose(text_sampling);
                                    else
                                        response_ = questdlg('The subsampled FC is also too big for this PC to process, you may get a memmory error or the computation will be very slow. Want to proceed?','Memory low','Yes','No','No');
                                        switch response_
                                            case 'Yes'
                                                text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                                fprintf(text_sampling, 'Choosing the subsampling statergy as it was choosen by the user.');
                                                sub_samp = 1;
                                                warning('May get an Memory error');
                                                fclose(text_sampling);
                                            case 'No'
                                                text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                                fprintf(text_sampling, 'WM Networks not formed as the subsampled matrix was too big for the PC.');
                                                fclose(text_sampling);
                                                return
                                            otherwise
                                                return
                                        end
                                    end
                                case 'Still Use entire FC'
                                    text_sampling = fopen(fullfile(output_folder,'Analysis','GM_Networks',txt_name),'a');
                                    fprintf(text_sampling, 'Choosing the entire FC as it was choosen by the user.');
                                    sub_samp = 0;
                                    warning('May get an Memory error');
                                    fclose(text_sampling);
                                otherwise
                                    return
                            end
                        end
                end
                
                %% Computing the data for clustering from each subject
                % this data is a NxM matrix:
                % - rows of the matrix correspond to all GM voxels
                % - columns of the matrix correspond to a subsampled GM matrix, to aid the
                %   next stage of clustering by reducing the number of features
                % for example for 12,000 white-matter voxels, the matrix will be 12k x 3k
                %
                % each matrix cell contains the correlation between the corresponding
                % voxels' signals, averaged across subjects

                % defining a sub-sampling of the mask voxels (every fourth voxel)
                 % defining a sub-sampling of the mask voxels (every fourth voxel)
                if sub_samp == 1
                    p = zeros(size(GMmask));    % defining a grid of 1s and 0s across the image
                    p(2:3:end, 2:3:end, 2:2:end) = 1; p(1:3:end, 1:3:end, 1:2:end) = 1;   % for grey-matter -     ;
                else
                    p = ones(size(GMmask));    % defining a grid of 1s and 0s across the image
                end
                
                p = p(GM_voxels);   % choosing only locations of gray-matter voxels
                p = find(p);    % getting the indices of subsampled voxels in the whole mask
                p = p(randperm(length(p)));     % randomly mixing the voxels' indices

                % getting the data from each subject - correlation between all GM voxels and the subsampled GM voxels (num_GM_voxels X num_subsampled_voxels)
                data_for_clustering_allsubjs = zeros(length(GM_voxels),length(p));
                num_subjs_notnan = zeros(length(GM_voxels),length(p));

                disp('Creating the correlation matrix')
                % definition of a file name with functional data - so that other files will be resampled to this file's resolution
                func_img1_filename = complete_filepath(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    try
                        disp(['Currently Processing ' Subj_list(subji).name])

                        now_func_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );
                        func_data = niftiread(now_func_path);
                        all_timecourses = reshape(func_data, [size(func_data,1)*size(func_data,2)*size(func_data,3) size(func_data,4)]); % resampling to 2D - num_GM_voxels X num_timepoints
                        current_voxels_timecourses = double(all_timecourses(GM_voxels,:));  % getting the signal across time from all white-matter voxels
                        clear all_timecourses; clear func_data;

                        % loading the segmentation files of all subjects, for identification of WM voxels in this specific subject
                        current_seg_dir = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.anat_folder_name));
                        current_GM_file = dir(fullfile(current_seg_dir, [GM_file_prefix '*.nii']));
                        current_WM_file = dir(fullfile(current_seg_dir, [WM_file_prefix '*.nii']));
                        current_CSF_file = dir(fullfile(current_seg_dir, [CSF_file_prefix '*.nii']));

                        % resampling the segmentation files to the functional image resolution
                        current_GM_mask = reslice_data(fullfile(current_seg_dir,current_GM_file(1).name), func_img1_filename, 0);
                        current_WM_mask = reslice_data(fullfile(current_seg_dir,current_WM_file(1).name), func_img1_filename, 0);
                        current_CSF_mask = reslice_data(fullfile(current_seg_dir,current_CSF_file(1).name), func_img1_filename, 0);

                        % finding where probability for gray-matter is larger than 0.2 and
                        % larger than probability for grey-matter or CSF
                        current_seg_mask = (current_GM_mask>current_WM_mask) & (current_GM_mask>current_CSF_mask) & (current_GM_mask>0.2);

                        % finding and ignoring irrelevant voxels (e.g. not defined as GM in this specific subject, or have NaN values)
                        GM_voxels_with_data = find(var(current_voxels_timecourses,[],2)~=0 & ~isnan(var(current_voxels_timecourses,[],2)) & current_seg_mask(GM_voxels)==1);
                        GM_voxels_p_with_data = find(var(current_voxels_timecourses(p,:),[],2)~=0 & ~isnan(var(current_voxels_timecourses(p,:),[],2)) & current_seg_mask(GM_voxels(p))==1);

                        % Calculating correlation matrix of each GM voxel to the subsampled voxels
                        current_corr = corr(current_voxels_timecourses(GM_voxels_with_data,:)', current_voxels_timecourses(p(GM_voxels_p_with_data),:)');

                        % adding the current connectivity matrix to the sum of all subjects, for later averaging
                        data_for_clustering_allsubjs(GM_voxels_with_data, GM_voxels_p_with_data) = data_for_clustering_allsubjs(GM_voxels_with_data, GM_voxels_p_with_data) + current_corr;
                        num_subjs_notnan(GM_voxels_with_data, GM_voxels_p_with_data) = num_subjs_notnan(GM_voxels_with_data, GM_voxels_p_with_data) + 1;

                        clear current_voxels_timecourses; clear current_corr; clear current_seg_mask; clear GM_voxels_with_data; clear GM_voxels_p_with_data;
                    catch exception
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')
                        disp(['Preprocessing has encountered errors in Data Initial check (anatomical) for ' Subj_list(subji).name ', I have saved the variables in the subject folder :-) '])
                        disp('xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx')

                        write_error(exception,quality_control_path, Subj_list(subji).name)            % write error to text file and display
                        Subj_list_all(logical(string({Subj_list_all.name}) == Subj_list(subji).name)).error = 1;
                    end
                end
                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                Subj_list = load_subjects(app,output_folder,'Subj_list.csv');

                % calculating mean connectivity matrix from all subjects
                data_for_clustering_allsubjs = data_for_clustering_allsubjs./num_subjs_notnan;
                data_for_clustering_allsubjs(isnan(data_for_clustering_allsubjs))=0;
                missing_voxels = find(std(data_for_clustering_allsubjs,[],2)==0);   % finding voxels with no data
                GM_voxels(missing_voxels) = []; data_for_clustering_allsubjs(missing_voxels,:)=[];

                missing_voxels = std(data_for_clustering_allsubjs,[],1)==0;   % finding voxels with no data
                data_for_clustering_allsubjs(:,missing_voxels)=[];
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %% Selecting the best K on the group-level data, by measuring stability of clustering solutions

                % Here we separate the correlation matrix columns into 4 groups
                % (cross-validation folds), therefore selecting a subset of the features.
                % We perform the clustering on each fold, and then measure the similarity
                % between clustering solutions for all pairs of folds. This is repeated for
                % each K (number of clusters). A "good" K will give the same clustering
                % solution even when using different features, and will therefore have more
                % similarity (stability) between folds (Lange et al., Neural Comput 2004).
                %
                % For a MxN connectivity matrix, we'll get four Mx(N/4) matrices, and check
                % the correspondence of their clustering solutions to one another.


                if K_range_l ~= K_range_h           % If the lower and upper values of Grid search for K are the same, that means the grid search is not necessary skip it and directly calculate the networks
                    disp([num2str(CV_folds) ' Fold Cross validation in process to find out the correct value of K'])
                    % separating the data into subsets of features
                    num_CV_folds = CV_folds;
                    num_replicates = 10;
                    IDX_folds_new = cell(1,num_CV_folds);
                    Dice_coefficient_folds_all = zeros(1,K_range_h - K_range_l + 1);
                    elb = zeros(1,K_range_h - K_range_l + 1);
                    for K= K_range_l:K_range_h          % going over all possible numbers of clusters, to measure each one's stability
                        disp(['K = ' num2str(K)]);
                        IDX_folds = zeros(size(data_for_clustering_allsubjs,1),num_CV_folds); IDX_folds_new{K} = zeros(size(IDX_folds));
                        size_fold = size(data_for_clustering_allsubjs,2)/num_CV_folds;
                        sumD = zeros(K,num_CV_folds);
                        for c=1:num_CV_folds        % going over folds (sub-matrices)
                            disp(c)
                            mat_corr_current = data_for_clustering_allsubjs(:,round((c-1)*size_fold+1):round(c*size_fold));     % the sub-correlation-matrix
                            [IDX_folds(:,c),~,sumD(:,c)] = kmeans(mat_corr_current, K,'distance','correlation','replicates',num_replicates);  % calculating the clustering result for this K
                        end


                        % computing the difference between adjacency matrices for each fold
                        size_chunk = 100;   % need to compute adjacency matrices in parts - otherwise it takes too much memory (~300 million numbers per matrix)
                        num_chunks = floor(size(IDX_folds,1) / size_chunk);
                        sum_diff_adjmats_folds = zeros(num_CV_folds); sum_common_connections_adjmats = zeros(num_CV_folds); sum_all_connections_adjmats = zeros(num_CV_folds);
                        for ch1=1:num_chunks
                            for ch2=ch1:num_chunks    % iterating over all adjacency matrix parts combinations
                                current_clustering_adjmats = zeros(size_chunk,size_chunk,num_CV_folds);
                                current_chunk1 = (ch1-1)*size_chunk+1 : ch1*size_chunk;
                                current_chunk2 = (ch2-1)*size_chunk+1 : ch2*size_chunk;

                                % creating the current adjacency matrix part, for all folds
                                for c=1:num_CV_folds
                                    for i=1:size_chunk
                                        if ch1 == ch2
                                            j_points = i:size_chunk;
                                        else
                                            j_points = 1:size_chunk;
                                        end
                                        for j=j_points
                                            % In the adjacency matrix, cell (i,j) equals 1 if voxels (i,j) belong to the same cluster and 0 otherwise
                                            % This allows comparison of clustering results even if the labels of the same clusters in each result are different
                                            % (e.g. if the occipital cluster in solution 1 is labeled as cluster number 4, and in solution 2 it's labeled as cluster 7)
                                            if IDX_folds(current_chunk1(i),c)==IDX_folds(current_chunk2(j),c)

                                                current_clustering_adjmats(i,j,c)=1;
                                                if ch1 == ch2
                                                    current_clustering_adjmats(j,i,c)=1;
                                                end
                                            end
                                        end
                                    end
                                end
                                %%
                                % Computing the difference between adjacency matrix for different folds, and adding this difference to the sum matrix
                                for c1=1:num_CV_folds
                                    for c2=c1+1:num_CV_folds
                                        sum_diff_adjmats_folds(c1,c2) = sum_diff_adjmats_folds(c1,c2) + (sum(sum(current_clustering_adjmats(:,:,c1)~=current_clustering_adjmats(:,:,c2))));
                                        sum_common_connections_adjmats(c1,c2) = sum_common_connections_adjmats(c1,c2) + (sum(sum(current_clustering_adjmats(:,:,c1) & current_clustering_adjmats(:,:,c2))));
                                        sum_all_connections_adjmats(c1,c2) = sum_all_connections_adjmats(c1,c2) + (sum(sum(current_clustering_adjmats(:,:,c1) + current_clustering_adjmats(:,:,c2))));
                                        sum_diff_adjmats_folds(c2,c1) = sum_diff_adjmats_folds(c1,c2);
                                        sum_common_connections_adjmats(c2,c1) = sum_common_connections_adjmats(c1,c2);
                                        sum_all_connections_adjmats(c2,c1) = sum_all_connections_adjmats(c1,c2);
                                    end
                                end
                            end
                        end

                        % Calculating the average difference between adjacency matrices (across all folds pairs)
                        %                 sum_diff_adjmats_folds_all(K) = mean(sum_diff_adjmats_folds(~eye(num_CV_folds)));   % sum of all differences
                        elb(K) = mean(mean(sumD));
                        Dice_coefficient = sum_common_connections_adjmats * 2 ./ sum_all_connections_adjmats;
                        Dice_coefficient_folds_all(K) = mean(Dice_coefficient(~eye(num_CV_folds)));    % Dice's coef is 1 for perfect match, 0 for no commonalities
                    end

                    % plotting the stability results for all K values, to identify peaks
                    yyaxis left
                    figure; plot(Dice_coefficient_folds_all,'Marker','*'), xlim([K_range_l K_range_h])
                    xlabel('K-values')
                    ylabel('Dice Coefficients')

                    yyaxis right
                    plot(elb,'Marker','+')
                    ylabel('Distortion')
                    saveas(gcf,fullfile(output_folder,'Analysis','GM_Networks','K_grid_search_dice_corficient_GM.png'))
                    K = str2double(cell2mat(inputdlg('Choose the K-value','K-Value')));
                    close gcf
                else
                    K = K_range_l;  % Assign K as the lower K value (doesnt matter as the lower and upper K values are the same)
                end
                %% K-means clustering of subjects' mean connectivity matrix

                for k=K
                    disp(['K = ' num2str(k)]);
                    IDX_allsubjs = kmeans(data_for_clustering_allsubjs, k,'distance','correlation','replicates',10);            % K-means clustering
                    clustering_results_allsubjs = zeros(size(GMmask)); clustering_results_allsubjs(GM_voxels) = IDX_allsubjs;   % putting the clustering results in an image
                    save_mat_to_nifti(func_img1_filename,clustering_results_allsubjs,fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K' num2str(k) '.nii']));    % saving the results to file %% set output path
                    clear clustering_results_allsubjs;
                end
                %                 end

                %% Create brain net viwer images
                if over_write == 1
                    GM_brainnet_image = dir(fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K',num2str(K),'_BrainNet_images'],'GM_1_Anterior.png'));
                    if ~isempty(GM_brainnet_image)
                        delete(fullfile(GM_brainnet_image.folder,GM_brainnet_image.name))
                    end
                    GM_brainnet_image = [];
                else
                    GM_brainnet_image = dir(fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K',num2str(K),'_BrainNet_images'],'GM_1_Anterior.png'));
                end
                if isempty(GM_brainnet_image)
                    mkdir(fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K' num2str(K) '_BrainNet_images']))
                    image_folder = fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K' num2str(K) '_BrainNet_images']);

                    cluster = dir(fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K' num2str(k) '.nii']));

                    mode = 'GM';
                    view_angles = [ 0 , 0 ;
                        90, 0 ;
                        0 , 90;
                        180, 0 ;
                        180,-90;
                        -90, 0 ;];
                    view_names = {'Posterior';
                        'Right'  ;
                        'Dorsal'  ;
                        'Anterior' ;
                        'Ventral' ;
                        'left'   ;};
                    for i = 1:length(view_angles)
                        for j = 1:K
                            BrainNet_MapCfg_WhifuN(fullfile(preproc_code_path,'BrainNetViewer_20191031','Data','SurfTemplate','BrainMesh_Ch2_smoothed.nv'),fullfile(cluster.folder,cluster.name),fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters2.mat'),view_angles(i,:),j,fullfile(image_folder,[mode '_' num2str(j) '_' view_names{i} '.png']));%fullfile(preproc_code_path,'BrainNetViewer_20191031','brainnet_parameters2.mat')  'temp.mat'
                        end
                    end
                end
                %% Obtaining averaged time series of GMFNs
            end
            if over_write == 1
                gmfn_file = dir(fullfile(output_folder,'Analysis','GM_Networks',['network_signals_gm_K' num2str(K) '.mat']));
                if ~isempty(gmfn_file)
                    delete(fullfile(output_folder,'Analysis','GM_Networks',['network_signals_gm_K' num2str(K) '.mat']));
                    gmfn_file = [];
                end
            else
                if exist("K","var")
                    gmfn_file = dir(fullfile(output_folder,'Analysis','GM_Networks',['network_signals_gm_K' num2str(K) '.mat']));
                else
                    gmfn_file = [];
                end
            end

            if isempty(gmfn_file)
                nt = Subj_list(1).nt_dis;       % nt has to be the same for all subjects for corpus callosum networks
                [new_data_mask,~]=y_Read(fullfile(output_folder,'Analysis','GM_Networks',['GM_clustering_K' num2str(K) '.nii'])); %%% input the mask of white-matter functional netwotks
                index=unique(new_data_mask);index(index==0)=[];
                final_result_gm = zeros(nt,numel(index),length(Subj_list) );    % nt has to be same for all subjects
                for subji = 1:length(Subj_list)      % For loop on number of subjects
                    disp(['Currently Processing ' Subj_list(subji).name])
                    now_func_path = complete_filepath(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']) );

                    [data,~]=y_Read(now_func_path);
                    roi1=isnan(data);data(roi1)=0;[x,y,z,t]=size(data);
                    new_data=reshape(data,x*y*z,t);
                    for k=1:length(index)
                        roi=new_data_mask==k;
                        result=mean(new_data(roi,:));
                        final_result_gm(:,k,subji)=result;
                        clear result
                    end
                end

                save(fullfile(output_folder,'Analysis','GM_Networks',['network_signals_gm_K' num2str(K) '.mat']),'final_result_gm','Subj_list'); %% added
            else
                disp('Gray matter Average time series file found, hence skipping this step. ')
            end


        end

        % Value changed function: WMatlasDropDown
        function WMatlasDropDownValueChanged(app, event)
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            value = app.WMatlasDropDown.Value;
            switch value
                case 'JHU Type 1'
                    app.atlas_filefolder = preproc_code_path;
                    app.atlas_filename = 'JHU_MNI_SS_WMPM_Type-I_to_MNI_brain.nii';
                case 'JHU Type 2'
                    app.atlas_filefolder = preproc_code_path;
                    app.atlas_filename = 'JHU_MNI_SS_WMPM_Type-II_to_MNI_brain.nii';
                case 'JHU Type 3'
                    app.atlas_filefolder = preproc_code_path;
                    app.atlas_filename = 'JHU_MNI_SS_WMPM_Type-III_to_MNI_brain.nii';
                case 'Choose Manually'
                    try
                        [app.atlas_filename,app.atlas_filefolder] = uigetfile(fullfile(pwd,'*.nii'));

                    catch
                        return
                    end

            end
            if ~isnumeric(app.atlas_filename)
                app.TextArea.Value = app.atlas_filename(1:end-4);
            end
        end

        % Button pushed function: RunAtlasbasedFCButton
        function RunAtlasbasedFCButtonPushed(app, event)

            atlas_name = char(app.TextArea.Value);
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);


            if isempty(app.SelectedSubjectswithgooddataDropDown.Items)
                msgbox('Please select subjects first')
                return
            end
            %%          These values can be changed but default is recommended
            if double(string(app.NoofVolumestoDiscardTextArea.Value)) ~= 0
                Cut_pre = 'c_';                                                             % Prefix for the Discarding Initial volumes File
            else
                Cut_pre = '';
            end
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            Norm_pre = 'w';                                                             % Prefix for the Normalized File
            smooth_drop = app.SmoothingDropDown.Value;
            Reg_drop = app.NuisanceRegressionDropDown.Value;

            f_check = app.FilterCheckBox.Value;
            if f_check
                f_pre = 'f';   % prefix for filtered file
            else
                f_pre = '';
            end

            switch smooth_drop
                case 'No Smoothing'
                    Smooth_pre = '';
                otherwise
                    Smooth_pre = 's';                                                           % Prefix for the Smoothed File
            end

            switch Reg_drop
                case 'No Regression'
                    Reg_pre = '';
                otherwise
                    Reg_pre = 'REG_';
            end

            if isempty(app.atlas_filefolder)
                app.atlas_filefolder = preproc_code_path;
            end
            output_folder = char(app.DataPathTextArea_2.Value);
            Subj_list = load_subjects(app,output_folder,'Subj_list.csv');
            %             Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv');
            mkdir(fullfile(output_folder,'Analysis','Atlas_Based_FC','Atlas_null_regions',atlas_name))

            disp('##########################################################################################')
            disp(['Computing Average ROI timeseries using Atlas: ' char(atlas_name)])

            now_func_path = dir(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));   % Choosing first subject image just for reslicing the atlas in the same space as that of the subjects
            atlas = reslice_data(fullfile(app.atlas_filefolder,app.atlas_filename),fullfile(now_func_path.folder,now_func_path.name),1,1,fullfile(output_folder,'Analysis','Atlas_Based_FC',[app.atlas_filename]));
            level = unique(sort((atlas(:))));
            level = level(2:end);
            nt = Subj_list(1).nt_dis;
            reg = length(level);    % Number of Brain Regions to be considered

            reg_ts = zeros(nt,reg,length(Subj_list));

            vox_count_in_reg = zeros(length(level),length(Subj_list));
            for subji = 1:length(Subj_list)
                func_file = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                subject = niftiread(fullfile(func_file.folder,func_file.name));
                [x,y,z,nT] = size(subject);
                subMat = reshape(subject,[x*y*z,nT]);
                subMat = double(subMat');
                subMat_mean = mean(subMat,1);

                for r = 1:length(level)
                    vox_id = find(atlas==level(r));
                    disp(r)
                    [vx,vy,vz] = ind2sub(size(atlas),vox_id);
                    pos = [mean(vx);mean(vy);mean(vz)];
                    reg_voxels_ts = subMat(:,vox_id) - subMat_mean(vox_id);
                    vox_count_in_reg(r,subji) = size(reg_voxels_ts,2);

                    voxels_with_data = sum(abs(reg_voxels_ts));
                    if nnz(voxels_with_data) == length(voxels_with_data) && nnz(isnan(voxels_with_data)) == 0
                        good_sub = 1;
                        reg_ts(:,r,subji) = mean(reg_voxels_ts,2);  % mean TS of region 'r'
                    else
                        good_sub = 0;
                    end

                    if good_sub == 0
                        if (nnz(voxels_with_data) < length(voxels_with_data) && nnz(voxels_with_data) > 0) || (nnz(isnan(voxels_with_data)) > 0 && nnz(isnan(voxels_with_data)) < length(voxels_with_data))
                            reg_voxels_ts(:,voxels_with_data==0) = [];
                            reg_voxels_ts(:,isnan(voxels_with_data)) = [];

                            vox_count_in_reg(r,subji) = size(reg_voxels_ts,2);
                            reg_ts(:,r,subji) = mean(reg_voxels_ts,2);
                            out_msg = sprintf(['Atlas: ' atlas_name '\nAtlas region ' num2str(r) '\nROI has partial data \ndata for this subject']);
                        else
                            out_msg = sprintf(['Atlas: ' atlas_name '\nAtlas region ' num2str(r) '\nROI does not have \ndata for this subject']);
                        end
                        %                             spm_image('display',[fullfile(func_file.folder,func_file.name),',1']);
                        new_atlas = zeros(size(atlas));
                        new_atlas(vox_id) = 1;
                        at_info = niftiinfo(fullfile(output_folder,'Analysis','Atlas_Based_FC',[app.atlas_filename]));
                        niftisave(new_atlas,'temp.nii',at_info)
                        spm_check_registration(char([fullfile(func_file.folder,func_file.name),',1'],...
                            'temp.nii'))
                        st1 = spm_vol([fullfile(func_file.folder,func_file.name),',1']);
                        tmp = st1.mat;
                        pos1 = tmp(1:3,:)*[pos ; 1];
                        spm_orthviews('Reposition',pos1);
                        spm_orthviews('Caption', 1, [Subj_list(subji).name ' Atlas region ' num2str(r)]);
                        spm_orthviews('Caption', 2, out_msg);
                        spm_orthviews('contour','display',2,1);
                        saveas(gcf,(fullfile(output_folder,'Analysis','Atlas_Based_FC','Atlas_null_regions',atlas_name,[Subj_list(subji).name '_region_' num2str(r) '.png'])))
                        delete('temp.nii')
                    end
                end


                disp([num2str(subji) ,' out of ',num2str(length(Subj_list)),' done' ])


            end
            save(fullfile(output_folder,'Analysis','Atlas_Based_FC',[atlas_name,'_reg_ts.mat']),'reg_ts','vox_count_in_reg','Subj_list')
            disp(['Extractation of Average Timeseries using Atlas: ' char(atlas_name) ' is done'])
            disp('##########################################################################################')

        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');

                    load_param(app,path,'parameters.mat')

                else
                    path = char(app.DataPathTextArea_2.Value);
                end
            catch
            end
            if path == 0
                return
            end


            Subj_list = load_subjects(app,path,'Subj_list.csv');
            app.DialogApp = ttest_compare_visualize_side_plots4_regstats(app,path,Subj_list,app.OverwriteexistingfilesCheckBox.Value);

        end

        % Button pushed function: CompareFNButton
        function CompareFNButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [file_name1,path1] = uigetfile(pwd,'Select the first Network');
                else
                    [file_name1,path1] = uigetfile(app.DataPathTextArea_2.Value,'Select the first Network');
                end
            catch
            end
            if path1 == 0
                return
            end

            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [file_name2,path2] = uigetfile(pwd,'Select the second network');
                else
                    [file_name2,path2] = uigetfile(app.DataPathTextArea_2.Value,'Select the second network');
                end
            catch
            end
            if path2 == 0
                return
            end
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'whifun_functions'))
            [~,~] = dice_iou(fullfile(path1,file_name1),fullfile(path2,file_name2),0,1);


        end

        % Value changed function: FilterCheckBox
        function FilterCheckBoxValueChanged(app, event)
            value = app.FilterCheckBox.Value;
            if value
                app.TemporalFilteringHzTextArea.Enable = "on";
                app.FilterTextArea_2.Enable = "on";
            else
                app.TemporalFilteringHzTextArea.Enable = "off";
                app.FilterTextArea_2.Enable = "off";
            end
        end

        % Button pushed function: FrequenciesperbandButton
        function FrequenciesperbandButtonPushed(app, event)
            no_f_bands = app.NumberofFreqBandsSpinner.Value;

            app.DialogApp = freq_per_band(app,no_f_bands,app.band1_name,app.band1_lp,app.band1_hp,app.band2_name,app.band2_lp,app.band2_hp,...
                app.band3_name,app.band3_lp,app.band3_hp,app.band4_name,app.band4_lp,app.band4_hp,...
                app.band5_name,app.band5_lp,app.band5_hp,app.band6_name,app.band6_lp,app.band6_hp);


        end

        % Button pushed function: CreateAverageTimeSeriesButton
        function CreateAverageTimeSeriesButtonPushed(app, event)
            AllFreqCheckBox = 0;
            [app.ex_tf_atlas_path,app.ex_tf_atlas,ext] = fileparts(char(app.DataPathTextArea_3.Value));
            if isempty(app.SelectedSubjectswithgooddataDropDown.Items)
                msgbox('Please select subjects first')
                return
            end
            if ~strcmp(ext,'.nii')
                msgbox('must be a Nifti file with extension .nii')
            end

            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    output_folder = uigetdir(pwd,'Select the Output folder');
                    app.DataPathTextArea_2.Value = output_folder;
                else
                    output_folder = char(app.DataPathTextArea_2.Value);
                end
            catch
            end
            Subj_list = load_subjects(app,output_folder,'Subj_list.csv');
            %             Subj_list_all = load_subjects_all(app,output_folder,'Subj_list.csv');
            load_param(app,output_folder,'parameters.mat')
            %%          These values can be changed but default is recommended
            if double(string(app.NoofVolumestoDiscardTextArea.Value)) ~= 0
                Cut_pre = 'c_';                                                             % Prefix for the Discarding Initial volumes File
            else
                Cut_pre = '';
            end
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            Norm_pre = 'w';                                                             % Prefix for the Normalized File
            smooth_drop = app.SmoothingDropDown.Value;
            Reg_drop = app.NuisanceRegressionDropDown.Value;

            f_check = app.FilterCheckBox.Value;
            if f_check
                f_pre = 'f';   % prefix for filtered file
            else
                f_pre = '';
            end

            switch smooth_drop
                case 'No Smoothing'
                    Smooth_pre = '';
                otherwise
                    Smooth_pre = 's';                                                           % Prefix for the Smoothed File
            end

            switch Reg_drop
                case 'No Regression'
                    Reg_pre = '';
                otherwise
                    Reg_pre = 'REG_';
            end
            mkdir(fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,'Filters'))
            mkdir(fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,'Atlas_null_regions'))

            disp('##########################################################################################')
            disp(['Computing Average ROI timeseries using Atlas/Network: ' char(app.ex_tf_atlas)])

            now_func_path = dir(fullfile(Subj_list(1).folder, Subj_list(1).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre  f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));   % Choosing first subject image just for reslicing the atlas in the same space as that of the subjects
            atlas = reslice_data(fullfile(app.ex_tf_atlas_path,app.ex_tf_atlas),fullfile(now_func_path.folder,now_func_path.name),1,1,fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,[app.ex_tf_atlas,'.nii']));
            level = unique(sort((atlas(:))));
            level = level(2:end);
            nt = Subj_list(1).nt_dis;
            tr = Subj_list(1).TR;
            reg = length(level);    % Number of Brain Regions to be considered

            reg_ts = zeros(nt,reg,length(Subj_list));    % nt has to be same for all subjects
            fs = 1/tr;
            disp('Using Butterworth filter of 2nd order for filtering')
            % create filters

            if ~AllFreqCheckBox
                b = zeros(app.NumberofFreqBandsSpinner.Value,5);
                a = zeros(app.NumberofFreqBandsSpinner.Value,5);
                for i = 1:app.NumberofFreqBandsSpinner.Value
                    eval(['filter_lp = app.band',num2str(i),'_lp;']);
                    eval(['filter_hp = app.band',num2str(i),'_hp;']);

                    [b(i,:),a(i,:)] = butter(2,[filter_lp,filter_hp]/(fs/2),'bandpass');
                    figure; freqz(b(i,:),a(i,:),512,fs);          % Plot the frequency responce using 512 points
                    eval(['band_name = app.band',num2str(i),'_name'])
                    saveas(gcf,fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,'Filters',[band_name '_filter_freq_response.png']))
                    close gcf
                end
            end
            for subji = 1:length(Subj_list)
                func_file = dir(fullfile(Subj_list(subji).folder, Subj_list(subji).name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                subject = niftiread(fullfile(func_file.folder,func_file.name));
                [x,y,z,nT] = size(subject);
                subMat = reshape(subject,[x*y*z,nT]);
                subMat = double(subMat');
                subMat_mean = mean(subMat,1);
                vox_count_in_reg = zeros(length(level),length(Subj_list));
                for r = 1:length(level)
                    vox_id = find(atlas==level(r));
                    disp(r)
                    [vx,vy,vz] = ind2sub(size(atlas),vox_id);
                    pos = [mean(vx);mean(vy);mean(vz)];
                    reg_voxels_ts = subMat(:,vox_id) - subMat_mean(vox_id);
                    vox_count_in_reg(r,subji) = size(reg_voxels_ts,2);

                    voxels_with_data = sum(abs(reg_voxels_ts));
                    if nnz(voxels_with_data) == length(voxels_with_data) && nnz(isnan(voxels_with_data)) == 0
                        good_sub = 1;
                    else
                        good_sub = 0;
                    end
                    if app.SaveQCimagesofregionshavingmissingdataCheckBox.Value
                    if good_sub == 0
                        if (nnz(voxels_with_data) < length(voxels_with_data) && nnz(voxels_with_data) > 0) || (nnz(isnan(voxels_with_data)) > 0 && nnz(isnan(voxels_with_data)) < length(voxels_with_data))
                            reg_voxels_ts(:,voxels_with_data==0) = [];
                            reg_voxels_ts(:,isnan(voxels_with_data)) = [];

                            vox_count_in_reg(r,subji) = size(reg_voxels_ts,2);

                            out_msg = sprintf(['Atlas\Network: ' app.ex_tf_atlas '\nAtlas region ' num2str(r) '\nROI has partial data \ndata for this subject']);
                        else
                            out_msg = sprintf(['Atlas\Network: ' app.ex_tf_atlas '\nAtlas region ' num2str(r) '\nROI does not have \ndata for this subject']);
                        end
                        %                             spm_image('display',[fullfile(func_file.folder,func_file.name),',1']);
                        new_atlas = zeros(size(atlas));
                        new_atlas(vox_id) = 1;
                        at_info = niftiinfo(fullfile(func_file.folder,func_file.name));
                        niftisave(new_atlas,'temp.nii',at_info)
                        spm_check_registration(char([fullfile(func_file.folder,func_file.name),',1'],...
                            'temp.nii'))
                        st1 = spm_vol([fullfile(func_file.folder,func_file.name),',1']);
                        tmp = st1.mat;
                        pos1 = tmp(1:3,:)*[pos ; 1];
                        spm_orthviews('Reposition',pos1);
                        spm_orthviews('Caption', 1, [Subj_list(subji).name ' Atlas region ' num2str(r)]);
                        spm_orthviews('Caption', 2, out_msg);
                        spm_orthviews('contour','display',2,1);
                        saveas(gcf,(fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,'Atlas_null_regions',[Subj_list(subji).name '_region_' num2str(r) '.png'])))
                        delete('temp.nii')
                    end
                    end

                    if AllFreqCheckBox
                        %                     reg_ts(:,r,subji) = mean(reg_voxels_ts,2);
                    else
                        for i = 1:app.NumberofFreqBandsSpinner.Value
                            reg_ts(:,r,subji,i) = filtfilt(b(i,:),a(i,:),mean(reg_voxels_ts,2));
                        end
                    end

                end


                disp([num2str(subji) ,' out of ',num2str(length(Subj_list)),' done' ])


            end

            if AllFreqCheckBox
                %                 save(fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,[app.ex_tf_atlas,'_reg_ts.mat']),'reg_ts','vox_count_in_reg','Subj_list')
            else
                for i = 1:app.NumberofFreqBandsSpinner.Value
                    reg_ts_freq = squeeze(reg_ts(:,:,:,i));
                    eval(['lp = app.band',num2str(i),'_lp;']) %#ok<*EVLDOT>
                    eval(['hp = app.band',num2str(i),'_hp;'])
                    eval(['band_name = app.band',num2str(i),'_name;'])
                    save(fullfile(output_folder,'Analysis','Extract_timeseries',app.ex_tf_atlas,[app.ex_tf_atlas,'_',band_name,'_reg_ts.mat']),'reg_ts_freq','vox_count_in_reg','Subj_list','hp','lp') %#ok<USENS>
                end
            end
            disp(['Extractation of Average Timeseries using Atlas: ' char(app.ex_tf_atlas) ' is done'])
            disp('##########################################################################################')


        end

        % Button pushed function: NetworksAtlasButton
        function NetworksAtlasButtonPushed(app, event)
            try
                [atlas_name,atlas_path] = uigetfile(fullfile(pwd,'*.nii'),'Select the Network/Atlas Nifti file');
            catch
            end
            if atlas_path ~= 0
                app.DataPathTextArea_3.Value = fullfile(atlas_path,atlas_name);
            end
        end

        % Button pushed function: SelectSubjectsButton
        function SelectSubjectsButtonPushed(app, event)
            %             if ~isempty(app.DataPathTextArea.Value)
            %                 data_path = app.DataPathTextArea.Value;
            %                 output_folder =
            %             end

            if ~isempty(char(app.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    return
                end
                output_folder = char(app.DataPathTextArea_2.Value);
                data_path = char(app.DataPathTextArea.Value);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))


                %                 if ~exist(fullfile(output_folder,'Subj_list.csv'),"file")
                %%          Get the Anatomical and Functional Image Paths

                if exist(fullfile(output_folder,'Subj_list.csv'),"file")

                    responce_ = questdlg('Subj_list.csv file found, do you want to delete it and start again?','Subj_list.csv file found','Yes','No','No');

                    switch responce_
                        case 'Yes'
                            delete(fullfile(output_folder,'Subj_list.csv'))
                        case 'No'
                            return
                    end

                end

                Subjects = uigetfile_n_dir(data_path,'Select Subjects');

                if isempty(Subjects)
                    msgbox('No subjects selected!')
                    return
                end
                %%          Get the Anatomical and Functional Image Paths

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list_all = dir( app.comm_subj_name );
                if strcmp(Subj_list_all(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                if strcmp(Subj_list_all(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                nt_dir = false(1,length(Subj_list_all));                                        % make a list of other files that are not directories
                for i = 1:length(Subj_list_all)
                    if Subj_list_all(i).isdir ~= 1
                        nt_dir(i) = true;

                    end
                    Subj_list_all(i).error = 0;

                    Subj_list_all(i).manual_ex = 1;
                    Subj_list_all(i).motion_ex = 0;
                end
                Subj_list_all(nt_dir) = [];                                                     % Remove all the files that are not directories (Now Subj_list should have all subject files)
                clear nt_dir                                                                % Clear nt_dir after use


                Subj_names = struct2cell(Subj_list_all);
                Subj_names(2:end,:) = [];
                idx = zeros(1,length(Subjects));
                for i = 1:length(Subjects)


                    [file_path,name] = fileparts(char(Subjects(i)));

                    if strcmp(file_path,char(app.DataPathTextArea.Value))
                        idx(i) = find(string(name)==string(Subj_names));
                        Subj_list_all(idx(i)).manual_ex = 0;
                    else
                        msgbox('Choose subjects from the Subjects Data folder only')
                    end
                end

                if ~isfolder(output_folder)
                    mkdir(output_folder)
                end

                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                [Subj_list,~] = load_subjects(app,output_folder,"Subj_list.csv");
                load_subjects_dropdown(app,Subj_list)

            else
                msgbox('Please select the Subject Data folder first')

            end
        end

        % Value changed function: DataPathTextArea
        function DataPathTextAreaValueChanged(app, event)
            data_path = char(string(app.DataPathTextArea.Value));

            if strcmp(app.comm_subj_name,'') == 1
                app.comm_subj_name = '*';
            end
            if ~isempty(data_path)
                data_path = data_path(1,:);
            end
            try
                cd (data_path);
            catch
                msgbox('Does not seem a valid path, please try again','Invalid Subject Data folder')
                app.DataPathTextArea.Value = '';
                return
            end

            Subj_list = dir( app.comm_subj_name );
            if strcmp(Subj_list(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                Subj_list(1) = [];
            end

            if strcmp(Subj_list(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                Subj_list(1) = [];
            end

            nt_dir = false(1,length(Subj_list));                                        % make a list of other files that are not directories
            for i = 1:length(Subj_list)
                if Subj_list(i).isdir ~= 1
                    nt_dir(i) = true;
                end
            end
            Subj_list(nt_dir) = [];                                                     % Remove all the files that are not directories (Now Subj_list should have all subject files)
            clear nt_dir                                                                % Clear nt_dir after use

            load_subjects_dropdown(app,Subj_list,'Data_path')


        end

        % Value changed function: AllSubjectsaregoodCheckBox
        function AllSubjectsaregoodCheckBoxValueChanged(app, event)
            value = app.AllSubjectsaregoodCheckBox.Value;
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end

            if value
                try
                    Subj_list = load_subjects(app,path,'Subj_list.csv');
                    if isempty(Subj_list)
                        msgbox('No Subjects found, please specify the correct subject folder path and run initial check.')
                    end
                    load_subjects_dropdown2(app,Subj_list)
                    load_param(app,path,'parameters.mat')
                    app.ManuallyexcludesubjectsCheckBox.Value = 0;
                catch
                    msgbox('Error loading parameters or Subjects, make sure the correct output folder is specified. ','Error loading parameters')
                end
            else
                app.ManuallyexcludesubjectsCheckBox.Value = 1;
                if isempty(char(app.DataPathTextArea_2.Value))
                    path = uigetdir(fullfile(pwd),'Select the Output folder');
                else
                    path = char(app.DataPathTextArea_2.Value);
                end
                load_param(app,path,'parameters.mat')

                Subj_list_all = load_subjects_all(app,path,'Subj_list.csv');
                if ~isempty(Subj_list_all)
                    app.AllSubjectsaregoodCheckBox.Value = 0;
                    temp_path = mfilename('fullpath');                % path of the toolbox
                    preproc_code_path = fileparts(temp_path);
                    addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
                    data_path = Subj_list_all(1).folder;
                    Subjects = uigetfile_n_dir(char(data_path),'Select Subjects to exclude');
                    Subj_names = struct2cell(Subj_list_all);
                    Subj_names(2:end,:) = [];
                    %                 idx = zeros(1,length(Subjects));

                    for i = 1:length(Subjects)

                        [file_path,name] = fileparts(char(Subjects(i)));

                        if strcmp(file_path,char(app.DataPathTextArea.Value))

                            if sum(name==string(Subj_names))
                                idx = name==string(Subj_names);


                                if Subj_list_all(idx).manual_ex ~= 1
                                    Subj_list_all(idx).manual_ex = 1;
                                else
                                    msgbox([name ' Subject Already removed'])
                                end
                            end
                        else
                            msgbox('Choose subjects from the Subjects Data folder only')
                        end

                    end

                    rep = questdlg('This Subject list will be saved for any future analysis. Continue?','Subject list save','Yes','No','Yes');

                    switch rep
                        case 'Yes'
                            writetable(struct2table(Subj_list_all), fullfile(path,"Subj_list.csv"))
                            Subj_list = load_subjects(app,path,'Subj_list.csv');
                            load_subjects_dropdown2(app,Subj_list)

                        case 'No'
                            return
                    end

                end
            end
        end

        % Button pushed function: CheckFNSymettryButton
        function CheckFNSymettryButtonPushed(app, event)
            try
                if isempty(char(app.DataPathTextArea_2.Value))
                    [file_name,path] = uigetfile(pwd,'Select the first Network');
                else
                    [file_name,path] = uigetfile(app.DataPathTextArea_2.Value,'Select the first Network');
                end
            catch
            end
            if path == 0
                return
            end

            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);
            addpath(fullfile(preproc_code_path,'whifun_functions'))
            
            clustering_solution = niftiread(fullfile(path,file_name));
            
            Dice_coef_LR = get_symettry(app,clustering_solution);


            Dice_coef_LR_indi = get_symettry_indi(app,clustering_solution);

            figure;
            h = heatmap(round(Dice_coef_LR_indi,2));clim([0 1]);colormap('parula');
            h.NodeChildren(3).Title.Interpreter = 'None';
            h.Title = ['The symmetry dice coefficient for entire network is ', num2str(Dice_coef_LR) ' file: ' file_name];
            h.XLabel = 'Left Hemisphere';
            h.YLabel = 'Right Hemisphere';
            
%             title(gca,file_name,'Interpreter','none')

%             ylabel('Right Hemisphere')
%             xlabel('Left Hemisphere')
        end

        % Button pushed function: manuallycoregistersubjectsButton
        function manuallycoregistersubjectsButtonPushed(app, event)
            skull_pre = 'b';                                                            % Prefix for the Skull Stripped File
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard
            if n_vol_dis == 0
                Cut_pre = '';
            else
                Cut_pre = 'c_';
            end
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')
            output_folder = char(app.DataPathTextArea_2.Value);

            Reg_pre = 'REG_';                                                           % Prefix for the Regressed File
            f_pre = 'f';                                                                % prefix for filtered file
            Norm_pre = 'w';                                                             % Prefix for the Normalized File
            Smooth_pre = 's';                                                           % Prefix for the Smoothed File

            %%          Parameters

            % PCA or Mean for WM and CSF timeseries data that will be used as a
            % regressor in temporal Regression
            % 1 --> Will use PCA for getting the CSF Components
            % 0 --> will use mean WM and CSF components (faster)
            Reg_drop = app.NuisanceRegressionDropDown.Value;
            switch Reg_drop
                case 'No Regression'
                    Reg_pre = '';
            end
            if ~app.FilterCheckBox.Value
                f_pre = '';
            end
            smooth_drop = app.SmoothingDropDown.Value;
            switch smooth_drop
                case 'No Smoothing'
                    Smooth_pre = '';
            end


            Subj_list_all = load_subjects_all(app,path,'Subj_list.csv');


            if ~isempty(Subj_list_all)


                app.AllSubjectsaregoodCheckBox.Value = 0;
                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
                data_path = Subj_list_all(1).folder;
                subject = uigetdir(char(data_path),'Select Subjects to coregister manually');

                if isnumeric(subject)
                    return
                end
                [subject_folder,subject_name] = fileparts(subject);
                Subj_names = struct2cell(Subj_list_all);
                Subj_names(2:end,:) = [];
                %                 idx = zeros(1,length(Subjects));


                if strcmp(subject_folder,char(app.DataPathTextArea.Value))

                    if sum(subject_name==string(Subj_names))
                        idx = subject_name==string(Subj_names);

                        if ~isfield(Subj_list_all(idx),'manual_coreg')
                            Subj_list_all(idx).manual_coreg = 0;
                        elseif isempty(Subj_list_all(idx).manual_coreg)
                            Subj_list_all(idx).manual_coreg = 0;
                        end
                        now_anat_path = fullfile(Subj_list_all(idx).folder,Subj_list_all(idx).name,app.comm_sess_name,app.anat_folder_name,[skull_pre app.anat_data_name '.nii']);

                        now_func_path = (fullfile(Subj_list_all(idx).folder, Subj_list_all(idx).name,app.comm_sess_name,app.func_folder_name,[Realign_pre Cut_pre app.func_data_name '.nii'])) ;

                        if isempty(dir(now_func_path))
                            msgbox(['Realignment file not found in the functional directory. The following was searched for: ' now_func_path])
                        elseif length(dir(now_func_path)) > 1
                            [file,path] = uigetfile(now_func_path,'Multiple functional realigned files found please choose one');

                            now_func_path = dir(fullfile(path,file));
                        else
                            now_func_path = dir(now_func_path);
                        end

                        if isempty(dir(now_anat_path))
                            msgbox(['Skull Stripped file not found in the anatomical directory. The following was searched for: ' now_anat_path])
                        elseif length(dir(now_anat_path)) > 1
                            [file,path] = uigetfile(now_anat_path,'Multiple anatomical skull stripped files found please choose one');

                            now_anat_path = dir(fullfile(path,file));
                        else
                            now_anat_path = dir(now_anat_path);
                        end
                        
                        response_ = questdlg('This will delete all preprocessing files created after realignment step, do you want to proceed?','Delete files created after co-registeration','Yes','No','Yes');

                        switch response_
                            case 'No'
                                return
                        end

                        % Quality Control
                        % dsplay the anatomical and the Realigned func image after co-registeration
                        spm_check_registration(char(fullfile(now_anat_path.folder,now_anat_path.name),[fullfile(now_func_path.folder,now_func_path.name),',1']))

                        % Display the participant's ID
                        spm_orthviews('Caption', 1, Subj_list_all(idx).name);

                        spm_orthviews('reorient','context_init', 2);
                        Subj_list_all(idx).manual_coreg = 1;

                        % deleting any previous preprocessing file
                        coreg_qc = dir(fullfile(output_folder,'Quality_control','Co_registeration',[subject_name '.png']));

                        if length(coreg_qc) == 1
                            delete(fullfile(coreg_qc.folder,coreg_qc.name))
                        end

                        csf_timeseries = dir(fullfile(data_path,subject_name,app.comm_sess_name,app.func_folder_name,'covariance_csf_REST.mat'));

                        if length(csf_timeseries) == 1
                            delete(fullfile(csf_timeseries.folder,csf_timeseries.name))
                        end

                        mask_qc = dir(fullfile(output_folder,'Quality_control','Masks',['CSF_' subject_name '.png']));

                        if length(mask_qc) == 1
                            delete(fullfile(mask_qc.folder,mask_qc.name))
                        end

                        Reg_file = dir(fullfile(data_path,subject_name,app.comm_sess_name,app.func_folder_name,[Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                        if length(Reg_file) == 1
                            delete(fullfile(Reg_file.folder,Reg_file.name))
                        end

                        reg_qc = dir(fullfile(output_folder,'Quality_control','Regression',[subject_name '.png']));

                        if length(reg_qc) == 1
                            delete(fullfile(reg_qc.folder,reg_qc.name))
                        end

                        filter_file = dir(fullfile(data_path,subject_name,app.comm_sess_name,app.func_folder_name,[f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                        if length(filter_file) == 1
                            delete(fullfile(filter_file.folder,filter_file.name))
                        end

                        f_qc = dir(fullfile(output_folder,'Quality_control','Filter',[subject_name '.png']));

                        if length(f_qc) == 1
                            delete(fullfile(f_qc.folder,f_qc.name))
                        end

                        smooth_file = dir(fullfile(data_path,subject_name,app.comm_sess_name,app.func_folder_name,[Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                        if length(smooth_file) == 1
                            delete(fullfile(smooth_file.folder,smooth_file.name))
                        end
                        
                        s_qc = dir(fullfile(output_folder,'Quality_control','Smoothing',[subject_name '.png']));

                        if length(s_qc) == 1
                            delete(fullfile(s_qc.folder,s_qc.name))
                        end


                        norm_file = dir(fullfile(data_path,subject_name,app.comm_sess_name,app.func_folder_name,[Norm_pre Smooth_pre f_pre Reg_pre Realign_pre Cut_pre app.func_data_name '.nii']));

                        if length(norm_file) == 1
                            delete(fullfile(norm_file.folder,norm_file.name))
                        end

                        n_qc = dir(fullfile(output_folder,'Quality_control','Normalization',[subject_name '_func_image' '.png']));

                        if length(n_qc) == 1
                            delete(fullfile(n_qc.folder,n_qc.name))
                        end

                        rest_mask = dir(fullfile(data_path,subject_name,app.comm_sess_name,app.func_folder_name,'rest_mask.nii'));

                        if length(rest_mask) == 1
                            delete(fullfile(rest_mask.folder,rest_mask.name))
                        end
    
                        ts_qc = dir(fullfile(output_folder,'Quality_control','Time_series_check',[subject_name '.png']));

                        if length(ts_qc) == 1
                            delete(fullfile(ts_qc.folder,ts_qc.name))
                        end
                        
                    end
                else
                    msgbox('Choose subjects from the Subjects Data folder only')
                end

            end


%             'spm_orthviews('reorient','context_init', 1);'
        end

        % Button pushed function: 
        % DetermineFramewiseDisplacementthresholdsButton
        function DetermineFramewiseDisplacementthresholdsButtonPushed(app, event)
            
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')
            output_folder = char(app.DataPathTextArea_2.Value);
            
            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard


            Subj_list_all = load_subjects_all(app,path,'Subj_list.csv');
            if ~isempty(Subj_list_all)
                motion_threshold_select(app,Subj_list_all,app.comm_sess_name, app.func_folder_name, app.func_data_name,n_vol_dis,output_folder)
            else
                msgbox('No subjects found. Please run the intial data check and try again.')
            end


        end

        % Button pushed function: 
        % DeletePreprocessingfilesfromaparticularstepButton
        function DeletePreprocessingfilesfromaparticularstepButtonPushed(app, event)
            if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')
            output_folder = char(app.DataPathTextArea_2.Value);
            n_vol_dis = double(string(app.NoofVolumestoDiscardTextArea.Value));                  % No. of volumes to discard
            Subj_list_all = load_subjects_all(app,path,'Subj_list.csv');
            if ~isempty(Subj_list_all)
                rerunpreproc(app,Subj_list_all,app.comm_sess_name, app.func_folder_name, app.func_data_name,app.anat_folder_name,app.anat_data_name,n_vol_dis,output_folder)
            else
                msgbox('No subjects found. Please run the intial data check and try again.')
            end

            
        end

        % Button pushed function: SubjectInfoButton
        function SubjectInfoButtonPushed(app, event)

            if ~isempty(char(app.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    return
                end
                output_folder = char(app.DataPathTextArea_2.Value);
%                 data_path = char(app.DataPathTextArea.Value);

                if exist(fullfile(output_folder,'Subj_list.csv'),"file")
                    Sub_info(app,output_folder,preproc_code_path)

                else
                    msgbox('Please Select Subjects first','Select Subjects')
                end
            else
                msgbox('Please select the Subject Data folder first','Select Subject data folder')

            end
        end

        % Value changed function: AllfoldersareSubjectsCheckBox
        function AllfoldersareSubjectsCheckBoxValueChanged(app, event)
            value = app.AllfoldersareSubjectsCheckBox.Value;
             if ~isempty(char(app.DataPathTextArea.Value))

                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);

                if isempty(char(app.DataPathTextArea_2.Value))
                    msgbox('Please select an output folder first','Select Output Folder')
                    app.AllfoldersareSubjectsCheckBox.Value = 0;
                    return
                end
                output_folder = char(app.DataPathTextArea_2.Value);
                data_path = char(app.DataPathTextArea.Value);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))


                %                 if ~exist(fullfile(output_folder,'Subj_list.csv'),"file")
                %%          Get the Anatomical and Functional Image Paths

                if exist(fullfile(output_folder,'Subj_list.csv'),"file")

                    responce_ = questdlg('Subj_list.csv file found, do you want to delete it and start again?','Subj_list.csv file found','Yes','No','No');

                    switch responce_
                        case 'Yes'
                            delete(fullfile(output_folder,'Subj_list.csv'))
                        case 'No'
                            return
                    end

                end
                if ~value
                    Subjects = uigetfile_n_dir(data_path,'Select Subjects');

                    if isempty(Subjects)
                        msgbox('No subjects selected!')
                        return
                    end
                else
                    Subjects = dir(data_path);
                end
                %%          Get the Anatomical and Functional Image Paths

                if strcmp(app.comm_subj_name,'') == 1
                    app.comm_subj_name = '*';
                end
                cd (data_path);

                Subj_list_all = dir( app.comm_subj_name );
                if strcmp(Subj_list_all(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                if strcmp(Subj_list_all(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                    Subj_list_all(1) = [];
                end

                nt_dir = false(1,length(Subj_list_all));                                        % make a list of other files that are not directories
                for i = 1:length(Subj_list_all)
                    if Subj_list_all(i).isdir ~= 1
                        nt_dir(i) = true;

                    end
                    Subj_list_all(i).error = 0;
                    if ~value
                        Subj_list_all(i).manual_ex = 1;
                    else
                        Subj_list_all(i).manual_ex = 0;
                    end
                    Subj_list_all(i).motion_ex = 0;
                end
                Subj_list_all(nt_dir) = [];                                                     % Remove all the files that are not directories (Now Subj_list should have all subject files)
                clear nt_dir                                                                % Clear nt_dir after use


                if ~value
                    Subj_names = struct2cell(Subj_list_all);
                    Subj_names(2:end,:) = [];
                    idx = zeros(1,length(Subjects));
                    for i = 1:length(Subjects)


                        [file_path,name] = fileparts(char(Subjects(i)));

                        if strcmp(file_path,char(app.DataPathTextArea.Value))
                            idx(i) = find(string(name)==string(Subj_names));
                            Subj_list_all(idx(i)).manual_ex = 0;
                        else
                            msgbox('Choose subjects from the Subjects Data folder only')
                        end
                    end
                    
                end
                if ~isfolder(output_folder)
                    mkdir(output_folder)
                end
                my_writetable(struct2table(Subj_list_all), fullfile(output_folder,"Subj_list.csv"))
                [Subj_list,~] = load_subjects(app,output_folder,"Subj_list.csv");
                load_subjects_dropdown(app,Subj_list)

            else
                msgbox('Please select the Subject Data folder first')
                app.AllfoldersareSubjectsCheckBox.Value = 0;
            end
        end

        % Value changed function: ManuallyexcludesubjectsCheckBox
        function ManuallyexcludesubjectsCheckBoxValueChanged(app, event)
            value = app.ManuallyexcludesubjectsCheckBox.Value;

            if value
             if isempty(char(app.DataPathTextArea_2.Value))
                path = uigetdir(fullfile(pwd),'Select the Output folder');
            else
                path = char(app.DataPathTextArea_2.Value);
            end
            load_param(app,path,'parameters.mat')

            Subj_list_all = load_subjects_all(app,path,'Subj_list.csv');
            if ~isempty(Subj_list_all)
                app.AllSubjectsaregoodCheckBox.Value = 0;
                temp_path = mfilename('fullpath');                % path of the toolbox
                preproc_code_path = fileparts(temp_path);
                addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))
                data_path = Subj_list_all(1).folder;
                Subjects = uigetfile_n_dir(char(data_path),'Select Subjects to exclude');
                Subj_names = struct2cell(Subj_list_all);
                Subj_names(2:end,:) = [];
                %                 idx = zeros(1,length(Subjects));

                for i = 1:length(Subjects)

                    [file_path,name] = fileparts(char(Subjects(i)));

                    if strcmp(file_path,char(app.DataPathTextArea.Value))

                        if sum(name==string(Subj_names))
                            idx = name==string(Subj_names);


                            if Subj_list_all(idx).manual_ex ~= 1
                                Subj_list_all(idx).manual_ex = 1;
                            else
                                msgbox([name ' Subject Already removed'])
                            end
                        end
                    else
                        msgbox('Choose subjects from the Subjects Data folder only')
                    end

                end

                rep = questdlg('This Subject list will be saved for any future analysis. Continue?','Subject list save','Yes','No','Yes');

                switch rep
                    case 'Yes'
                        writetable(struct2table(Subj_list_all), fullfile(path,"Subj_list.csv"))
                        Subj_list = load_subjects(app,path,'Subj_list.csv');
                        load_subjects_dropdown2(app,Subj_list)

                    case 'No'
                        return
                end

            end
            else
                app.AllSubjectsaregoodCheckBox.Value = 1;
            end
        end

        % Value changed function: DataPathTextArea_2
        function DataPathTextArea_2ValueChanged(app, event)
            output_folder = char(string(app.DataPathTextArea_2.Value));
            if ~isfolder(output_folder)
                output_folder_pre = fileparts(output_folder);
                if ~isfolder(output_folder_pre)
                    msgbox('Does not seem a valid path, please try again','Invalid Output folder')
                    app.DataPathTextArea_2.Value = '';
                    return
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WhiFuNv1UIFigure = uifigure('Visible', 'off');
            app.WhiFuNv1UIFigure.Name = 'WhiFuN v1';
            app.WhiFuNv1UIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.WhiFuNv1UIFigure.Tag = 'Hello';
            app.GridLayout = uigridlayout(app.WhiFuNv1UIFigure);
            app.SaveLoadParametersPanel = uipanel(app.GridLayout);
            app.SaveLoadParametersPanel.Title = 'Save/Load Parameters';
            app.LoadParametersButton = uibutton(app.SaveLoadParametersPanel, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.SaveParametersButton = uibutton(app.SaveLoadParametersPanel, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.SetupPanel = uipanel(app.GridLayout);
            app.SetupPanel.Title = 'Setup';
            app.OutputsfolderButton = uibutton(app.SetupPanel, 'push');
            app.OutputsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputsfolderButtonPushed, true);
            app.OutputsfolderButton.Text = 'Outputs folder';
            app.DataPathTextArea_2 = uitextarea(app.SetupPanel);
            app.DataPathTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DataPathTextArea_2ValueChanged, true);
            app.SubjectDatafolderButton = uibutton(app.SetupPanel, 'push');
            app.SubjectDatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectDatafolderButtonPushed, true);
            app.SubjectDatafolderButton.Text = 'Subject Data folder';
            app.DataPathTextArea = uitextarea(app.SetupPanel);
            app.DataPathTextArea.ValueChangedFcn = createCallbackFcn(app, @DataPathTextAreaValueChanged, true);
            app.BIDSCheckBox = uicheckbox(app.SetupPanel);
            app.BIDSCheckBox.ValueChangedFcn = createCallbackFcn(app, @BIDSCheckBoxValueChanged, true);
            app.BIDSCheckBox.Text = 'BIDS';
            app.BIDSCheckBox.Value = true;
            app.SelectSubjectsButton = uibutton(app.SetupPanel, 'push');
            app.SelectSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSubjectsButtonPushed, true);
            app.SelectSubjectsButton.Text = 'Select Subjects';
            app.Label_4 = uilabel(app.SetupPanel);
            app.Label_4.Text = '';
            app.Label_7 = uilabel(app.SetupPanel);
            app.Label_7.Text = '';
            app.AllfoldersareSubjectsCheckBox = uicheckbox(app.SetupPanel);
            app.AllfoldersareSubjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllfoldersareSubjectsCheckBoxValueChanged, true);
            app.AllfoldersareSubjectsCheckBox.Text = 'All folders are Subjects';
            app.ConstructFNandFCPanel = uipanel(app.GridLayout);
            app.ConstructFNandFCPanel.Title = 'Construct FN and FC';
            app.TabGroup = uitabgroup(app.ConstructFNandFCPanel);
            app.WMNetworksTab = uitab(app.TabGroup);
            app.WMNetworksTab.Title = 'WM Networks';
            app.KmeansKRangeTextArea_2 = uitextarea(app.WMNetworksTab);
            app.KmeansKRangeTextArea_2.Value = {'2'};
            app.Label_2 = uilabel(app.WMNetworksTab);
            app.Label_2.Text = '~';
            app.KmeansKRangeLabel = uilabel(app.WMNetworksTab);
            app.KmeansKRangeLabel.Tooltip = {'Search range for K-means K values. The average dice coeficient and the elbow will be calculated for the range of K values specified here with cross validation technique as described in (Peer et. al. 2017)'};
            app.KmeansKRangeLabel.Text = {'K-means (WM)'; '(K-Range)'};
            app.KmeansWMKRangeTextArea = uitextarea(app.WMNetworksTab);
            app.KmeansWMKRangeTextArea.Value = {'22'};
            app.CorpusCallosumNetworksCheckBox = uicheckbox(app.WMNetworksTab);
            app.CorpusCallosumNetworksCheckBox.Text = 'Corpus Callosum Networks';
            app.NumCVfoldsTextAreaLabel = uilabel(app.WMNetworksTab);
            app.NumCVfoldsTextAreaLabel.Text = 'Num CV folds';
            app.NumCVfoldsTextArea = uitextarea(app.WMNetworksTab);
            app.NumCVfoldsTextArea.Value = {'4'};
            app.CreateWMnetworksButton = uibutton(app.WMNetworksTab, 'push');
            app.CreateWMnetworksButton.ButtonPushedFcn = createCallbackFcn(app, @CreateWMnetworksButtonPushed, true);
            app.CreateWMnetworksButton.Text = 'Create WM networks';
            app.GroupWMmaskthresholdLabel = uilabel(app.WMNetworksTab);
            app.GroupWMmaskthresholdLabel.Text = {'Group WM'; 'threshold (%)'};
            app.GroupWMthresholdTextArea = uitextarea(app.WMNetworksTab);
            app.GroupWMthresholdTextArea.Value = {'60'};
            app.IndividualWMProbabilitythresholdLabel = uilabel(app.WMNetworksTab);
            app.IndividualWMProbabilitythresholdLabel.Text = {'Individual WM'; ' Probability threshold'};
            app.IndividualWMProbabilitythresholdTextArea = uitextarea(app.WMNetworksTab);
            app.IndividualWMProbabilitythresholdTextArea.Value = {'0.6'};
            app.SubsamplingtheWMvoxelFCmatrixLabel = uilabel(app.WMNetworksTab);
            app.SubsamplingtheWMvoxelFCmatrixLabel.Text = 'Sub-sampling the WM voxel FC matrix';
            app.SubsamplingtheWMvoxelFCmatrixDropDown = uidropdown(app.WMNetworksTab);
            app.SubsamplingtheWMvoxelFCmatrixDropDown.Items = {'Let toolbox decide', 'Subsample', 'Use entire FC'};
            app.SubsamplingtheWMvoxelFCmatrixDropDown.Value = 'Let toolbox decide';
            app.GMNetworksTab = uitab(app.TabGroup);
            app.GMNetworksTab.Title = 'GM Networks';
            app.KmeansKRangeTextArea_3 = uitextarea(app.GMNetworksTab);
            app.KmeansKRangeTextArea_3.Value = {'2'};
            app.Label_3 = uilabel(app.GMNetworksTab);
            app.Label_3.Text = '~';
            app.NumCVfoldsTextAreaLabel_2 = uilabel(app.GMNetworksTab);
            app.NumCVfoldsTextAreaLabel_2.Text = 'Num CV folds';
            app.NumCVfoldsTextArea_2 = uitextarea(app.GMNetworksTab);
            app.NumCVfoldsTextArea_2.Value = {'4'};
            app.CreateGMnetworksButton = uibutton(app.GMNetworksTab, 'push');
            app.CreateGMnetworksButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGMnetworksButtonPushed, true);
            app.CreateGMnetworksButton.Text = 'Create GM networks';
            app.KmeansGMKRangeTextAreaLabel = uilabel(app.GMNetworksTab);
            app.KmeansGMKRangeTextAreaLabel.Text = {'K-means (GM)'; '(K-Range)'};
            app.KmeansGMKRangeTextArea = uitextarea(app.GMNetworksTab);
            app.KmeansGMKRangeTextArea.Value = {'22'};
            app.GroupGMmaskthresholdLabel = uilabel(app.GMNetworksTab);
            app.GroupGMmaskthresholdLabel.Text = {'  Group GM '; 'threshold (%)'};
            app.GroupGMthresholdTextArea = uitextarea(app.GMNetworksTab);
            app.GroupGMthresholdTextArea.Value = {'20'};
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel = uilabel(app.GMNetworksTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDownLabel.Text = 'Sub-sampling the GM voxel FC matrix';
            app.SubsamplingtheGMvoxelFCmatrixDropDown = uidropdown(app.GMNetworksTab);
            app.SubsamplingtheGMvoxelFCmatrixDropDown.Items = {'Let toolbox decide', 'Subsample', 'Use entire FC'};
            app.SubsamplingtheGMvoxelFCmatrixDropDown.Value = 'Let toolbox decide';
            app.IndividualGMProbabilitythresholdTextAreaLabel = uilabel(app.GMNetworksTab);
            app.IndividualGMProbabilitythresholdTextAreaLabel.Text = {'Individual GM'; ' Probability threshold'};
            app.IndividualGMProbabilitythresholdTextArea = uitextarea(app.GMNetworksTab);
            app.IndividualGMProbabilitythresholdTextArea.Value = {'0.6'};
            app.AtlasBasedFCTab = uitab(app.TabGroup);
            app.AtlasBasedFCTab.Title = 'Atlas Based FC';
            app.StandardWMatlasLabel = uilabel(app.AtlasBasedFCTab);
            app.StandardWMatlasLabel.Text = 'WM atlas';
            app.WMatlasDropDown = uidropdown(app.AtlasBasedFCTab);
            app.WMatlasDropDown.Items = {'JHU Type 1', 'JHU Type 2', 'JHU Type 3', 'Choose Manually'};
            app.WMatlasDropDown.ValueChangedFcn = createCallbackFcn(app, @WMatlasDropDownValueChanged, true);
            app.WMatlasDropDown.Value = 'JHU Type 1';
            app.TextArea = uitextarea(app.AtlasBasedFCTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'JHU_MNI_SS_WMPM_Type-I_to_MNI_brain'};
            app.StandardWMatlasDropDownLabel_2 = uilabel(app.AtlasBasedFCTab);
            app.StandardWMatlasDropDownLabel_2.Text = 'Atlas Choosen:';
            app.RunAtlasbasedFCButton = uibutton(app.AtlasBasedFCTab, 'push');
            app.RunAtlasbasedFCButton.ButtonPushedFcn = createCallbackFcn(app, @RunAtlasbasedFCButtonPushed, true);
            app.RunAtlasbasedFCButton.Text = 'Run Atlas based FC';
            app.ExtractTimeSeriesinfrequencybandTab = uitab(app.TabGroup);
            app.ExtractTimeSeriesinfrequencybandTab.Title = 'Extract Time Series in frequency band';
            app.DataPathTextArea_3 = uitextarea(app.ExtractTimeSeriesinfrequencybandTab);
            app.NetworksAtlasButton = uibutton(app.ExtractTimeSeriesinfrequencybandTab, 'push');
            app.NetworksAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @NetworksAtlasButtonPushed, true);
            app.NetworksAtlasButton.Text = 'Networks/Atlas';
            app.FrequenciesperbandButton = uibutton(app.ExtractTimeSeriesinfrequencybandTab, 'push');
            app.FrequenciesperbandButton.ButtonPushedFcn = createCallbackFcn(app, @FrequenciesperbandButtonPushed, true);
            app.FrequenciesperbandButton.Text = 'Frequencies per band';
            app.NumberofFreqBandsLabel = uilabel(app.ExtractTimeSeriesinfrequencybandTab);
            app.NumberofFreqBandsLabel.Text = {'Number of '; 'Freq. Bands'};
            app.NumberofFreqBandsSpinner = uispinner(app.ExtractTimeSeriesinfrequencybandTab);
            app.NumberofFreqBandsSpinner.Limits = [1 6];
            app.NumberofFreqBandsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofFreqBandsSpinner.Value = 1;
            app.CreateAverageTimeSeriesButton = uibutton(app.ExtractTimeSeriesinfrequencybandTab, 'push');
            app.CreateAverageTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAverageTimeSeriesButtonPushed, true);
            app.CreateAverageTimeSeriesButton.Text = 'Create Average Time Series';
            app.SaveQCimagesofregionshavingmissingdataCheckBox = uicheckbox(app.ExtractTimeSeriesinfrequencybandTab);
            app.SaveQCimagesofregionshavingmissingdataCheckBox.Text = {'Save QC images of regions'; 'having missing data'};
            app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel = uipanel(app.ConstructFNandFCPanel);
            app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel.Title = 'Check the Quality Control Plots and exclude subjects with bad data';
            app.AllSubjectsaregoodCheckBox = uicheckbox(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.AllSubjectsaregoodCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllSubjectsaregoodCheckBoxValueChanged, true);
            app.AllSubjectsaregoodCheckBox.Text = {'All Subjects'; 'are good'};
            app.Label_5 = uilabel(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.Label_5.Text = '';
            app.SelectedSubjectswithgooddataLabel = uilabel(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.SelectedSubjectswithgooddataLabel.Text = {'Selected Subjects'; 'with good data'};
            app.SelectedSubjectswithgooddataDropDown = uidropdown(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.SelectedSubjectswithgooddataDropDown.Items = {};
            app.SelectedSubjectswithgooddataDropDown.Value = {};
            app.ManuallyexcludesubjectsCheckBox = uicheckbox(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.ManuallyexcludesubjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManuallyexcludesubjectsCheckBoxValueChanged, true);
            app.ManuallyexcludesubjectsCheckBox.Text = {'Manually exclude'; 'subjects'};
            app.orLabel = uilabel(app.ChecktheQualityControlPlotsandexcludesubjectswithbaddataPanel);
            app.orLabel.Text = 'or';
            app.WhiFuNLabel = uilabel(app.GridLayout);
            app.WhiFuNLabel.Text = 'WhiFuN';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon_small.png');
            app.AnalyzeFNandFCPanel = uipanel(app.GridLayout);
            app.AnalyzeFNandFCPanel.Title = 'Analyze FN and FC';
            app.StatisticsButton = uibutton(app.AnalyzeFNandFCPanel, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            app.CompareFNButton = uibutton(app.AnalyzeFNandFCPanel, 'push');
            app.CompareFNButton.ButtonPushedFcn = createCallbackFcn(app, @CompareFNButtonPushed, true);
            app.CompareFNButton.Text = 'Compare FN';
            app.CheckFNSymettryButton = uibutton(app.AnalyzeFNandFCPanel, 'push');
            app.CheckFNSymettryButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFNSymettryButtonPushed, true);
            app.CheckFNSymettryButton.Text = 'Check FN Symettry';
            app.VisualizePanel = uipanel(app.GridLayout);
            app.VisualizePanel.Title = 'Visualize';
            app.DisplayFNButton = uibutton(app.VisualizePanel, 'push');
            app.DisplayFNButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFNButtonPushed, true);
            app.DisplayFNButton.Text = 'Display FN';
            app.DisplayFCButton = uibutton(app.VisualizePanel, 'push');
            app.DisplayFCButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFCButtonPushed, true);
            app.DisplayFCButton.Text = 'Display FC';
            app.CheckDataPanel = uipanel(app.GridLayout);
            app.CheckDataPanel.Title = 'Check Data';
            app.RunDataCheckButton = uibutton(app.CheckDataPanel, 'push');
            app.RunDataCheckButton.ButtonPushedFcn = createCallbackFcn(app, @RunDataCheckButtonPushed, true);
            app.RunDataCheckButton.Text = 'Run Data Check';
            app.SubjectInfoButton = uibutton(app.CheckDataPanel, 'push');
            app.SubjectInfoButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectInfoButtonPushed, true);
            app.SubjectInfoButton.Text = 'Subject Info';
            app.DataCheckReportLabel = uilabel(app.CheckDataPanel);
            app.DataCheckReportLabel.Text = 'Data Check Report';
            app.DataCheckReportTextArea = uitextarea(app.CheckDataPanel);
            app.PreprocessPanel = uipanel(app.GridLayout);
            app.PreprocessPanel.Title = 'Preprocess';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.PipelineTab = uitab(app.TabGroup2);
            app.PipelineTab.Title = 'Pipeline';
            app.Label = uilabel(app.PipelineTab);
            app.Label.Text = '~';
            app.FilterTextArea_2 = uitextarea(app.PipelineTab);
            app.FilterTextArea_2.Value = {'0.15'};
            app.OverwriteexistingfilesCheckBox = uicheckbox(app.PipelineTab);
            app.OverwriteexistingfilesCheckBox.Text = {'Overwrite '; 'existing files'};
            app.FilterCheckBox = uicheckbox(app.PipelineTab);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterCheckBoxValueChanged, true);
            app.FilterCheckBox.Text = '';
            app.FilterCheckBox.Value = true;
            app.MotionParametersCheckBox = uicheckbox(app.PipelineTab);
            app.MotionParametersCheckBox.Text = '24 Motion Parameters';
            app.MotionParametersCheckBox.Value = true;
            app.FramewiseDisplacementFDtootipthresholdsformotionmmLabel = uilabel(app.PipelineTab);
            app.FramewiseDisplacementFDtootipthresholdsformotionmmLabel.Text = 'FD thresholds for motion outlier detection (mm)';
            app.NoofVolumestoDiscardTextAreaLabel = uilabel(app.PipelineTab);
            app.NoofVolumestoDiscardTextAreaLabel.Text = 'No. of Volumes to Discard ';
            app.NoofVolumestoDiscardTextArea = uitextarea(app.PipelineTab);
            app.NoofVolumestoDiscardTextArea.Value = {'10'};
            app.NuisanceRegressionLabel = uilabel(app.PipelineTab);
            app.NuisanceRegressionLabel.Text = {'Nuisance'; 'Regression'};
            app.NuisanceRegressionDropDown = uidropdown(app.PipelineTab);
            app.NuisanceRegressionDropDown.Items = {'Mean CSF', 'PCA CSF', 'No Regression'};
            app.NuisanceRegressionDropDown.ValueChangedFcn = createCallbackFcn(app, @NuisanceRegressionDropDownValueChanged, true);
            app.NuisanceRegressionDropDown.Value = 'Mean CSF';
            app.SmoothingDropDownLabel = uilabel(app.PipelineTab);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.PipelineTab);
            app.SmoothingDropDown.Items = {'WM-GM Seperate', 'All Together', 'No Smoothing'};
            app.SmoothingDropDown.Value = 'WM-GM Seperate';
            app.NormalizationvoxeldimmmTextAreaLabel = uilabel(app.PipelineTab);
            app.NormalizationvoxeldimmmTextAreaLabel.Text = 'Normalization (voxel dim (mm))';
            app.NormalizationvoxeldimmmTextArea = uitextarea(app.PipelineTab);
            app.NormalizationvoxeldimmmTextArea.Value = {'3'};
            app.TemporalFilteringHzLabel = uilabel(app.PipelineTab);
            app.TemporalFilteringHzLabel.Text = {'Temporal'; 'Filtering (Hz)'};
            app.TemporalFilteringHzTextArea = uitextarea(app.PipelineTab);
            app.TemporalFilteringHzTextArea.Value = {'0.01'};
            app.FWHMmmTextAreaLabel = uilabel(app.PipelineTab);
            app.FWHMmmTextAreaLabel.Text = 'FWHM (mm)';
            app.FWHMmmTextArea = uitextarea(app.PipelineTab);
            app.FWHMmmTextArea.Value = {'4'};
            app.FramewiseDisplacementLabel = uilabel(app.PipelineTab);
            app.FramewiseDisplacementLabel.Text = 'max';
            app.maxDropDown = uidropdown(app.PipelineTab);
            app.maxDropDown.Items = {'1', '2.5', '5'};
            app.maxDropDown.Value = '5';
            app.meanDropDownLabel = uilabel(app.PipelineTab);
            app.meanDropDownLabel.Text = 'mean';
            app.meanDropDown = uidropdown(app.PipelineTab);
            app.meanDropDown.Items = {'0.1', '0.2', '0.3', '0.4'};
            app.meanDropDown.Value = '0.2';
            app.Greaterthan20DropDownLabel = uilabel(app.PipelineTab);
            app.Greaterthan20DropDownLabel.Text = 'Greater than 20%';
            app.Greaterthan20DropDown = uidropdown(app.PipelineTab);
            app.Greaterthan20DropDown.Items = {'0.1', '0.2', '0.3', '0.4'};
            app.Greaterthan20DropDown.Value = '0.2';
            app.RunPreprocessingButton = uibutton(app.PipelineTab, 'push');
            app.RunPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunPreprocessingButtonPushed, true);
            app.RunPreprocessingButton.Text = 'Run Preprocessing';
            app.ToolsTab = uitab(app.TabGroup2);
            app.ToolsTab.Title = 'Tools';
            app.DetermineFramewiseDisplacementthresholdsButton = uibutton(app.ToolsTab, 'push');
            app.DetermineFramewiseDisplacementthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineFramewiseDisplacementthresholdsButtonPushed, true);
            app.DetermineFramewiseDisplacementthresholdsButton.Text = {'Determine Framewise Displacement'; ' thresholds'};
            app.manuallycoregistersubjectsButton = uibutton(app.ToolsTab, 'push');
            app.manuallycoregistersubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @manuallycoregistersubjectsButtonPushed, true);
            app.manuallycoregistersubjectsButton.Text = 'manually coregister subjects';
            app.DeletePreprocessingfilesfromaparticularstepButton = uibutton(app.ToolsTab, 'push');
            app.DeletePreprocessingfilesfromaparticularstepButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePreprocessingfilesfromaparticularstepButtonPushed, true);
            app.DeletePreprocessingfilesfromaparticularstepButton.Text = {'Delete Preprocessing files'; ' from a particular step'};
            % Show the figure after all components are created
            app.WhiFuNv1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WhiFuNv1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @tooltip)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WhiFuNv1UIFigure)
        end
    end
end