classdef motion_threshold_select < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionthresholdUIFigure     matlab.ui.Figure
        SelectThresholdsButton      matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        maxDropDown                 matlab.ui.control.DropDown
        FramewiseDisplacementLabel  matlab.ui.control.Label
        NoofParticipantsRejectedduetomotionLabel  matlab.ui.control.Label
        FramewiseDisplacementformotionmmLabel  matlab.ui.control.Label
        Greaterthan20DropDown       matlab.ui.control.DropDown
        Greaterthan20DropDownLabel  matlab.ui.control.Label
        meanDropDown                matlab.ui.control.DropDown
        meanDropDownLabel           matlab.ui.control.Label
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        n_vol % Description
        func_fold
        subjlist
        qc_path
        fd
        output_folder
    end

    methods (Access = private)

        function plot_motion_graphs(app,max_fd_thres,mean_fd_thres,great20_fd_thres)
            %% Framewise Displacement
            fd_max = zeros(length(app.fd),1);
            fd_mean = zeros(length(app.fd),1);
            fd_greater_than_02= zeros(length(app.fd),1);
            loc_fd_max_trans = zeros(length(app.fd),1);


            for i = 1:length(app.fd)
                if ~isempty(app.fd{i})
                    [fd_max(i),loc_fd_max_trans(i)] = max(app.fd{i},[],"omitnan");                     % Get the maximum framewize displacement
                    fd_mean(i) = mean(app.fd{i},'omitnan');                                               % Get the mean framewize displacement
                    fd_greater_than_02(i) = nnz(app.fd{i}>str2double(app.Greaterthan20DropDown.Value))/length(app.fd{i})*100;
                end

            end

            stem(app.UIAxes,fd_max,'.')
            yline(app.UIAxes,max_fd_thres,'r')
            title(app.UIAxes,'Maximum FD')
            xlabel(app.UIAxes,'Participants');ylabel(app.UIAxes,'Max FD')
            app.UIAxes.Title.Color = [1,1,1];
            app.UIAxes.XLabel.Color = [1,1,1];
            app.UIAxes.YLabel.Color = [1,1,1];

            %             saveas(gcf,fullfile(quality_control_path,'Head_motion','Q3_head_motion.png'));
            %             close gcf
            stem(app.UIAxes_2,fd_mean,'.')
            yline(app.UIAxes_2,mean_fd_thres,'r')
            title(app.UIAxes_2,'Mean FD')
            xlabel(app.UIAxes_2,'Participants');ylabel(app.UIAxes_2,'Mean FD')
            app.UIAxes_2.Title.Color = [1,1,1];
            app.UIAxes_2.XLabel.Color = [1,1,1];
            app.UIAxes_2.YLabel.Color = [1,1,1];

            stem(app.UIAxes_3,fd_greater_than_02,'.')
            yline(app.UIAxes_3,20,'r')
            title(app.UIAxes_3,['% of FD greater than '  num2str(great20_fd_thres)])
            xlabel(app.UIAxes_3,'Participants');ylabel(app.UIAxes_3,['% of FD greater than '  num2str(great20_fd_thres)])
            app.UIAxes_3.Title.Color = [1,1,1];
            app.UIAxes_3.XLabel.Color = [1,1,1];
            app.UIAxes_3.YLabel.Color = [1,1,1];

            histogram(app.UIAxes_4,fd_max)
            xline(app.UIAxes_4,max_fd_thres,'r')
            xlabel(app.UIAxes_4,'FD')
            ylabel(app.UIAxes_4,'Frequency')
            title(app.UIAxes_4,'Histogram of Max FD')
            app.UIAxes_4.Title.Color = [1,1,1];
            app.UIAxes_4.XLabel.Color = [1,1,1];
            app.UIAxes_4.YLabel.Color = [1,1,1];

            histogram(app.UIAxes_5,fd_mean)
            xline(app.UIAxes_5,mean_fd_thres,'r')
            xlabel(app.UIAxes_5,'FD')
            ylabel(app.UIAxes_5,'Frequency')
            title(app.UIAxes_5,'Histogram of Mean FD')
            app.UIAxes_5.Title.Color = [1,1,1];
            app.UIAxes_5.XLabel.Color = [1,1,1];
            app.UIAxes_5.YLabel.Color = [1,1,1];

            histogram(app.UIAxes_6,fd_greater_than_02)
            xline(app.UIAxes_6,20,'r')
            xlabel(app.UIAxes_6,['% of FD greater than ' num2str(great20_fd_thres)])
            ylabel(app.UIAxes_6,'Frequency')
            title(app.UIAxes_6,'Histogram of Mean FD')
            app.UIAxes_6.Title.Color = [1,1,1];
            app.UIAxes_6.XLabel.Color = [1,1,1];
            app.UIAxes_6.YLabel.Color = [1,1,1];

            % Exclude Participants with excessive motion
            msg = "";
            p = 1;

            ex = zeros(1,length(app.subjlist));
            for subji = 1:length(app.subjlist)
                if ~isempty(app.fd{subji})
                    if ~isnan(app.fd{subji})
                        if fd_max(subji) > str2double(app.maxDropDown.Value)
                            msg(p) = ['Excluded Participant ' app.subjlist(subji).name ' as it had max motion at location ' ...
                                num2str(loc_fd_max_trans(subji)) ' of ' num2str(fd_max(subji)) ' which is greater than ' num2str(max_fd_thres) ' mm max threshold'];
                            p = p+1;
                            ex(subji) = 1;
                        end

                        if fd_mean(subji) > str2double(app.meanDropDown.Value)
                            msg(p) = ['Excluded Participant ' app.subjlist(subji).name ' as it had mean fd motion ',...
                                'of ' num2str(fd_mean(subji)) ' which is greater than ' num2str(app.meanDropDown.Value) ' mm mean threshold'];
                            p = p+1;
                            ex(subji) = 1;
                        end

                        if fd_greater_than_02(subji) > 20
                            msg(p) = ['Excluded Participant ' app.subjlist(subji).name ' as it had ',num2str(fd_greater_than_02(subji)) ' percent of fMRI volumes (timepoints) ',...
                                'greater than ' num2str(app.Greaterthan20DropDown.Value) ' threshold'];
                            p = p+1;
                            ex(subji) = 1;
                        end


                    else
                        msg(p) = ['Realignment file not found for ' app.subjlist(subji).name];
                        p = p+1;
                        ex(subji) = 1;

                    end
                end
            end
            app.NoofParticipantsRejectedduetomotionLabel.Text = ['No. of Participants Rejected due to motion: ' num2str(nnz(ex))];
            app.TextArea.Value = msg;
            app.TextArea.Editable = "off";

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, Subj_list, n_vol_dis, output_folder)
            app.subjlist = Subj_list;
            app.n_vol = n_vol_dis;

            max_fd_thres = str2double(app.maxDropDown.Value);                                                               % Maximum translation allowed for Framewize displacement
            mean_fd_thres = str2double(app.meanDropDown.Value);
            great20_fd_thres = str2double(app.Greaterthan20DropDown.Value);
            app.output_folder = output_folder;
            app.qc_path = fullfile(output_folder,'Quality_control');

            disp('##########################################################################################')
            disp('Framewise Displacement threshold check')
            % check the framewize displacement and exclude any if the criteria is not matched
            Realign_pre = 'r';                                                          % Prefix for the Realignment File
            if n_vol_dis == 0
                Cut_pre = '';
            else
                Cut_pre = 'c_';
            end
            % Initialize variables

            app.fd = cell(length(Subj_list),1);
            app.TextArea.Value = 'Loading Parameters ..';
            for subji = 1:length(Subj_list)                                % For loop on number of Participants
                if Subj_list(subji).error == 0 && Subj_list(subji).manual_ex == 0
                    now_func_path = dir(fullfile(Subj_list(subji).func_folder,[Realign_pre Cut_pre Subj_list(subji).func_name])) ;
                    if ~isempty(now_func_path)
                        cd(now_func_path(1).folder)

                        head_mot_file = dir(fullfile(Subj_list(subji).func_folder,['rp_' Cut_pre '*.txt'])); % Check for a previous head motion qc file

                        if isempty(head_mot_file)
                            app.fd{subji} = nan;
                            warning(['The head motion file not found for subject ', Subj_list(subji).name ,' Trying to open : ',fullfile(Subj_list(subji).func_folder,['rp_' Cut_pre '*.txt'])])
                            continue
                        end
                        func_name_wo_ext = strsplit(Subj_list(subji).func_name,'.');
                        now_func_path = dir(fullfile(Subj_list(subji).func_folder,['rp_' Cut_pre func_name_wo_ext{1} '.txt'])) ;
                        rp_rest = load(fullfile(now_func_path.folder,now_func_path.name));              % input the text file generated at the Realignment stage

                        rp_diff_trans = diff(rp_rest(:,1:3));                      % The first 3 parameters tell the displacement in x,y, and z direction in mm. Here the vector difference operator is used to get the derivative of vector. (framewise difference)
                        %                 rp_diff_rotat = diff(rp_rest(:,4:6)*180/pi);             % % The last  3 parameters tell the rotation values pitch, yaw and roll in radians (here we convert them to degrees)
                        rp_diff_rotat = diff(rp_rest(:,4:6)*50);                   % Converting angles to mm by asuming a 50mm radius circle


                        app.fd{subji} = sum(rp_diff_trans,2) + sum(rp_diff_rotat,2)  ;

                        % plots
                    else
                        app.fd{subji} = nan;
                    end
                end
            end

            plot_motion_graphs(app,max_fd_thres,mean_fd_thres,great20_fd_thres)

        end

        % Value changed function: maxDropDown
        function maxDropDownValueChanged(app, event)
            max_fd_thres = str2double(app.maxDropDown.Value);                                                               % Maximum translation allowed for Framewize displacement
            mean_fd_thres = str2double(app.meanDropDown.Value);
            great20_fd_thres = str2double(app.Greaterthan20DropDown.Value);
            plot_motion_graphs(app,max_fd_thres,mean_fd_thres,great20_fd_thres)
        end

        % Value changed function: meanDropDown
        function meanDropDownValueChanged(app, event)
            max_fd_thres = str2double(app.maxDropDown.Value);                                                               % Maximum translation allowed for Framewize displacement
            mean_fd_thres = str2double(app.meanDropDown.Value);
            great20_fd_thres = str2double(app.Greaterthan20DropDown.Value);
            plot_motion_graphs(app,max_fd_thres,mean_fd_thres,great20_fd_thres)
        end

        % Value changed function: Greaterthan20DropDown
        function Greaterthan20DropDownValueChanged(app, event)
            max_fd_thres = str2double(app.maxDropDown.Value);                                                               % Maximum translation allowed for Framewize displacement
            mean_fd_thres = str2double(app.meanDropDown.Value);
            great20_fd_thres = str2double(app.Greaterthan20DropDown.Value);
            plot_motion_graphs(app,max_fd_thres,mean_fd_thres,great20_fd_thres)
        end

        % Button pushed function: SelectThresholdsButton
        function SelectThresholdsButtonPushed(app, event)
            t1 = struct2table(app.subjlist);
            t1.motion_ex = zeros(height(t1),1);
            my_writetable(t1, fullfile(app.output_folder,"Subj_list.csv"))
            load(fullfile(app.output_folder,'parameters.mat')) %#ok<LOAD> 
            max_fd = app.maxDropDown.Value;
            mean_fd = app.meanDropDown.Value;
            greater_than_20 = app.Greaterthan20DropDown.Value;
            save(fullfile(app.output_folder,'parameters.mat'),"data_path","output_folder",'comm_sess_name','comm_subj_name','bids_check',...
                'func_folder_name','anat_folder_name','func_data_name',"anat_data_name",'n_vol_dis',...
                'max_fd','CSF_thres','Reg_drop','n_pca','motion_reg','filter_lp','filter_hp',"smooth_drop",...
                "smooth_fwhm","vox","grp_gm_thres","grp_wm_thres","K_range_l",'K_range_h',"CV_folds","K_range_l_gm",'K_range_h_gm'...
                ,"CV_folds_gm","Corpus_check",...
                "over_write",'filter_check','mean_fd','greater_than_20') %#ok<USENS> 
            msgbox('Run Preprocessing to apply the changes.','Run Preprocessing now')
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MotionthresholdUIFigure = uifigure('Visible', 'off');
            app.MotionthresholdUIFigure.Name = 'Motion threshold';
            app.MotionthresholdUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.UIAxes = uiaxes(app.MotionthresholdUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.MotionthresholdUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.MotionthresholdUIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.MotionthresholdUIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.MotionthresholdUIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.MotionthresholdUIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.meanDropDownLabel = uilabel(app.MotionthresholdUIFigure);
            app.meanDropDownLabel.Text = 'mean';
            app.meanDropDown = uidropdown(app.MotionthresholdUIFigure);
            app.meanDropDown.Items = {'0.1', '0.2', '0.3', '0.4'};
            app.meanDropDown.ValueChangedFcn = createCallbackFcn(app, @meanDropDownValueChanged, true);
            app.meanDropDown.Value = '0.2';
            app.Greaterthan20DropDownLabel = uilabel(app.MotionthresholdUIFigure);
            app.Greaterthan20DropDownLabel.Text = 'Greater than 20%';
            app.Greaterthan20DropDown = uidropdown(app.MotionthresholdUIFigure);
            app.Greaterthan20DropDown.Items = {'0.1', '0.2', '0.3', '0.4'};
            app.Greaterthan20DropDown.ValueChangedFcn = createCallbackFcn(app, @Greaterthan20DropDownValueChanged, true);
            app.Greaterthan20DropDown.Value = '0.2';
            app.FramewiseDisplacementformotionmmLabel = uilabel(app.MotionthresholdUIFigure);
            app.FramewiseDisplacementformotionmmLabel.Text = 'Framewise Displacement for motion (mm)';
            app.NoofParticipantsRejectedduetomotionLabel = uilabel(app.MotionthresholdUIFigure);
            app.NoofParticipantsRejectedduetomotionLabel.Text = 'No. of Participants Rejected due to motion: ';
            app.FramewiseDisplacementLabel = uilabel(app.MotionthresholdUIFigure);
            app.FramewiseDisplacementLabel.Text = 'max';
            app.maxDropDown = uidropdown(app.MotionthresholdUIFigure);
            app.maxDropDown.Items = {'1', '2.5', '5'};
            app.maxDropDown.ValueChangedFcn = createCallbackFcn(app, @maxDropDownValueChanged, true);
            app.maxDropDown.Value = '5';
            app.TextArea = uitextarea(app.MotionthresholdUIFigure);
            app.SelectThresholdsButton = uibutton(app.MotionthresholdUIFigure, 'push');
            app.SelectThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectThresholdsButtonPushed, true);
            app.SelectThresholdsButton.Text = 'Select Thresholds';
            % Show the figure after all components are created
            app.MotionthresholdUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motion_threshold_select(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionthresholdUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionthresholdUIFigure)
        end
    end
end