classdef rerunpreproc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DetelePreprocessingfilesUIFigure  matlab.ui.Figure
        ParticipantsDropDown            matlab.ui.control.DropDown
        ParticipantsDropDownLabel       matlab.ui.control.Label
        DeletePreprocessingfilesButton  matlab.ui.control.Button
        DeletePreprocessingfilesfromButtonGroup  matlab.ui.container.ButtonGroup
        NormalizationButton             matlab.ui.control.ToggleButton
        SmoothingButton                 matlab.ui.control.ToggleButton
        FilteringButton                 matlab.ui.control.ToggleButton
        RegressionButton                matlab.ui.control.ToggleButton
        CSFMasksButton                  matlab.ui.control.ToggleButton
        SegmentationButton              matlab.ui.control.ToggleButton
        RealignmentButton               matlab.ui.control.ToggleButton
        InitialVolumeDiscardButton      matlab.ui.control.ToggleButton
        NoofParticipantsselectedLabel   matlab.ui.control.Label
        SelectParticipantsButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        Subj_list
        Subj_list_all
        callerapp% Description
        n_vol_dis
        qc_path
        preproc_val = 1;
    end
    
    methods (Access = private)
        
        function load_subjects_dropdown(app,Subj_list)

            if ~isempty(Subj_list)
                Subj_names = struct2cell(Subj_list);
                Subj_names(2:end,:) = [];

                app.ParticipantsDropDown.Items = Subj_names;
                app.NoofParticipantsselectedLabel.Text = ['No. of participants selected: ' num2str(length(Subj_names))];
            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, Subj_list, n_vol_dis, output_folder)
            app.callerapp = caller;
            app.Subj_list_all = Subj_list;
            

            app.n_vol_dis = n_vol_dis;
            app.qc_path = fullfile(output_folder,'Quality_control');


        end

        % Selection changed function: 
        % DeletePreprocessingfilesfromButtonGroup
        function DeletePreprocessingfilesfromButtonGroupSelectionChanged(app, event)
            selectedButton = app.DeletePreprocessingfilesfromButtonGroup.SelectedObject.Text;
            
            preproc_steps = ["Initial Volume Discard","Realignment","Segmentation","CSF Masks","Regression","Filtering","Smoothing","Normalization"];

            app.preproc_val = find(preproc_steps == selectedButton);

        end

        % Button pushed function: DeletePreprocessingfilesButton
        function DeletePreprocessingfilesButtonPushed(app, event)
            
            if isempty(app.Subj_list)
                msgbox('Please select Participants First','Select participants First');
                return
            end
            
            response_ = questdlg(['This step will delete all the preprocessing files created during and after ' ...
                app.DeletePreprocessingfilesfromButtonGroup.SelectedObject.Text ' step. Do you want to proceed? '],'Continue?','Yes','No','No');

            switch response_
                case 'No'
                    return
            end


            filter_check = app.callerapp.FilterCheckBox.Value;
            
            
            %%          These values can be changed but default is recommended
            Cut_pre = 'c_';                                                             % Prefix for the Discarding Initial volumes File
            % Realign_pre = 'r';                                                          % Prefix for the Realignment File
            Reg_pre = 'REG_';                                                           % Prefix for the Regressed File
            f_pre = 'f';                                                                % prefix for filtered file
            Smooth_pre = 's';                                                           % Prefix for the Smoothed File

            %%          Parameters
            if app.n_vol_dis == 0
                Cut_pre = '';
            end

            % PCA or Mean for WM and CSF timeseries data that will be used as a
            % regressor in temporal Regression
            % 1 --> Will use PCA for getting the CSF Components
            % 0 --> will use mean WM and CSF components (faster)
            Reg_drop = app.callerapp.NuisanceRegressionDropDown.Value;
            switch Reg_drop
                case 'No Regression'
                    Reg_pre = '';
            end
            if ~filter_check
                f_pre = '';
            end
            smooth_drop = app.callerapp.SmoothingDropDown.Value;
            switch smooth_drop
                case 'No Smoothing'
                    Smooth_pre = '';
            end

            for i = 1:length(app.Subj_list)
                
                now_anat_path = dir(app.Subj_list(i).nii_anat_native);
                if isempty(now_anat_path)
                    now_anat_path = dir(fullfile(app.Subj_list(i).anat_folder,[app.Subj_list(i).anat_name '*']));
                end
                if length(now_anat_path) > 1
                    msgbox('More than one anatomical files found! Please use the BIDS checkbox to change the Anatomical image name such that only one image is detected','More than one Anat images found')
                    return
                end
                
                now_func_path = dir(app.Subj_list(i).nii_func_native) ;
                if isempty(now_func_path)
                    now_func_path = dir(fullfile(app.Subj_list(i).func_folder,[app.Subj_list(i).func_name '*'])) ;
                end
                if length(now_func_path) > 1
                    msgbox('More than one functional files found! Please use the BIDS checkbox to change the Functional image name such that only one image is detected','More than one Func images found')
                    return
                end
                [~,func_name,~] = fileparts(now_func_path.name);
                [~,func_name,~] = fileparts(func_name);

                if length(now_func_path) <= 1
                    if app.preproc_val <= 1
                        delete(char(app.Subj_list(i).initial_func_native))
                    end

                    if app.preproc_val <= 2
                        
                        delete(fullfile(now_func_path.folder,[Cut_pre func_name '.mat']));
                        delete(fullfile(now_func_path.folder,['mean' Cut_pre now_func_path.name]))
                        delete(char(app.Subj_list(i).realigned_func_native));
                        delete(char(app.Subj_list(i).motion_txt))
                        coreg_qc = dir(fullfile(app.qc_path,'d_Co_registeration','*','*',[app.Subj_list(i).name '*.png']));

                        if length(coreg_qc)>=1
                            for ii = 1:length(coreg_qc)
                                delete(fullfile(coreg_qc(ii).folder,coreg_qc(ii).name))
                            end
                        end

                        head_qc = dir(fullfile(app.qc_path,'b_Head_motion',[app.Subj_list(i).name '.png']));

                        if isscalar(head_qc)
                            delete(fullfile(head_qc.folder,head_qc.name))
                        end
                    end

                    
                end
                if length(now_anat_path) <= 1

                    if app.preproc_val <= 3

                        [~,anat_name,ext] = fileparts(now_anat_path.name);
                        delete(fullfile(now_anat_path.folder,['y_' now_anat_path.name]))
                        delete(char(app.Subj_list(i).skull_stripped_anat_native))
                        delete(char(app.Subj_list(i).anat_mask_native));
                        delete(char(app.Subj_list(i).anat_mask_MNI));
                        delete(char(app.Subj_list(i).anat_MNI))
                        delete(char(app.Subj_list(i).GM_native))
                        delete(char(app.Subj_list(i).WM_native))
                        delete(char(app.Subj_list(i).CSF_native))
                        delete(fullfile(now_anat_path.folder,['m' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,['mwc1' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,['mwc2' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,['mwc3' now_anat_path.name]))
                        delete(char(app.Subj_list(i).GM_MNI))
                        delete(char(app.Subj_list(i).WM_MNI))
                        delete(char(app.Subj_list(i).CSF_MNI))

                        delete(fullfile(now_anat_path.folder,['iy_' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,['rc1' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,['u_rc1' anat_name '_Template' ext]))
                        delete(fullfile(now_anat_path.folder,['rc2' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,['rc3' now_anat_path.name]))
                        delete(fullfile(now_anat_path.folder,'Template_0.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_1.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_2.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_3.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_4.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_5.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_6.nii'))
                        delete(fullfile(now_anat_path.folder,'Template_6_2mni.mat'))
                        delete(fullfile(now_anat_path.folder,[anat_name '_seg8.mat']))

                        seg_qc = dir(fullfile(app.qc_path,'c_Segmentation','*','*',[app.Subj_list(i).name '*.png']));

                        if length(seg_qc)>= 1
                            for ii = 1:length(seg_qc)
                                delete(fullfile(seg_qc(ii).folder,seg_qc(ii).name))
                            end
                        end

                    end
                end
                if length(now_func_path) <= 1
                    if app.preproc_val <= 4
                        delete(char(app.Subj_list(i).CSF_mask_func));
                        delete(char(app.Subj_list(i).nuisance_regression_csf_covariates))

                        mask_qc = dir(fullfile(app.qc_path,'e_CSF_Masks_for_Regression','*','*',[app.Subj_list(i).name '*.png']));

                        if length(mask_qc)>=1
                            for ii = 1:length(mask_qc)
                                delete(fullfile(mask_qc(ii).folder,mask_qc(ii).name))
                            end
                        end
                    end
                    if ~isempty(Reg_pre)
                        if app.preproc_val <= 5
                            delete(char(app.Subj_list(i).nuisance_regressed_func_native))
                            delete(char(app.Subj_list(i).func_mask_native))
                            reg_qc = dir(fullfile(app.qc_path,'f_Nuisance_Regression','*','*',[app.Subj_list(i).name '.png']));

                            if length(reg_qc)>= 1
                                for ii = 1:length(reg_qc)
                                    delete(fullfile(reg_qc(ii).folder,reg_qc(ii).name))
                                end
                            end

                            reg_qc = dir(fullfile(app.qc_path,'f_Nuisance_Regression','*','*','*',[app.Subj_list(i).name '*.png']));

                            if length(reg_qc)>= 1
                                for ii = 1:length(reg_qc)
                                    delete(fullfile(reg_qc(ii).folder,reg_qc(ii).name))
                                end
                            end

                            reg_qc = dir(fullfile(app.qc_path,'f_Nuisance_Regression','*','*','*','*',[app.Subj_list(i).name '*.png']));

                            if length(reg_qc)>= 1
                                for ii = 1:length(reg_qc)
                                    delete(fullfile(reg_qc(ii).folder,reg_qc(ii).name))
                                end
                            end
                        end
                    else
                        delete(char(app.Subj_list(i).func_mask_native))
                        disp(['Regression Step was not performed in Preprocessing for Participant: '  char(app.Subj_list(i).name)])
                    end
                    
                    if ~isempty(f_pre)
                        if app.preproc_val <= 6
                            delete(char(app.Subj_list(i).filtered_func_native))

                            f_qc = dir(fullfile(app.qc_path,'g_Filtering','*','*',[app.Subj_list(i).name '*.png']));

                            if length(f_qc)>= 1
                                for ii = 1:length(f_qc)
                                    delete(fullfile(f_qc(ii).folder,f_qc(ii).name))
                                end
                            end

                        end
                    else
                        disp(['Filtering Step was not performed in Preprocessing for Participant: ' char(app.Subj_list(i).name)])
                    end
                    if ~isempty(Smooth_pre)
                    if app.preproc_val <= 7
                        delete(char(app.Subj_list(i).smoothed_func))

                        s_qc = dir(fullfile(app.qc_path,'h_Smoothing','*','*',[app.Subj_list(i).name '*.png']));

                        if length(s_qc)>= 1
                            for ii = 1:length(s_qc)
                                delete(fullfile(s_qc(ii).folder,s_qc(ii).name))
                            end
                        end
                    end
                    else
                        disp(['Smoothing Step was not performed in Preprocessing for Participant: ' char(app.Subj_list(i).name)])
                    end

                    if app.preproc_val <= 8
                        delete(char(app.Subj_list(i).func_MNI))
                        delete(char(app.Subj_list(i).func_mask_MNI))
                        n_qc = dir(fullfile(app.qc_path,'i_Final_func_MNI','*','*',[app.Subj_list(i).name '*.png']));

                        if length(n_qc)>= 1
                            for ii = 1:length(n_qc)
                                delete(fullfile(n_qc(ii).folder,n_qc(ii).name))
                            end
                        end
                        
                        n_qc = dir(fullfile(app.qc_path,'i_Final_func_MNI','*','*','*',[app.Subj_list(i).name '*.png']));

                        if length(n_qc)>= 1
                            for ii = 1:length(n_qc)
                                delete(fullfile(n_qc(ii).folder,n_qc(ii).name))
                            end
                        end

                        ts_qc = dir(fullfile(app.qc_path,'j_Time_series_check',[app.Subj_list(i).name '.png']));

                        if isscalar(ts_qc)
                            delete(fullfile(ts_qc.folder,ts_qc.name))
                        end

                        deep_wm_mask = dir(fullfile(app.Subj_list(i).anat_folder,['deep_num_er-4_*c2',now_anat_path.name]));

                        if length(deep_wm_mask)>=1
                            for ii = 1:length(deep_wm_mask)
                                delete(fullfile(deep_wm_mask(ii).folder,deep_wm_mask(ii).name))
                            end
                        end
                        [~,anat_name,~] = fileparts(now_anat_path.name);
                        [~,anat_name,~] = fileparts(anat_name);

                        [~,wfunc_name,ext] = fileparts(app.Subj_list(i).func_MNI);
                        delete(fullfile(app.Subj_list(i).func_folder,['wc2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['deep_wc2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['wc1',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['wc3',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['seed_corr_map_Seed_lh_cort_aud_64_-12_2',wfunc_name,ext]))
                        delete(fullfile(app.Subj_list(i).func_folder,['seed_corr_map_Seed_lh_pcc_5_-49_40_',wfunc_name,ext]))
                        delete(fullfile(app.Subj_list(i).func_folder,['seed_corr_map_Seed_rh_cort_vis_-4_-91_-3',wfunc_name,ext]))
                        delete(fullfile(app.Subj_list(i).func_folder,['c2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['deep_c2',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['c1',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        delete(fullfile(app.Subj_list(i).func_folder,['c3',anat_name,'_for_vox_ts_qc_0.5.nii']))
                        
                    end
                end
                

            end

            msgbox(['All files created during and after ' app.DeletePreprocessingfilesfromButtonGroup.SelectedObject.Text ' step were deleted for the selected participants. ' ...
                ' You may now run preprocessing and the files will be again created for the selected participants.'],'Files Deleted')
        end

        % Button pushed function: SelectParticipantsButton
        function SelectParticipantsButtonPushed(app, event)
            data_path = char(app.callerapp.DataPathTextArea.Value);
            temp_path = mfilename('fullpath');                % path of the toolbox
            preproc_code_path = fileparts(temp_path);

            addpath(fullfile(preproc_code_path,'uigetfile_n_dir'))


            Subjects = uigetfile_n_dir(data_path,'Select Participants');

            if isempty(Subjects)
                msgbox('No Participants selected!')
                return
            end
            app.Subj_list = app.Subj_list_all;
            Subj_names = struct2cell(app.Subj_list_all);
            Subj_names(2:end,:) = [];
            idx = zeros(1,length(Subjects));
            for i = 1:length(Subjects)


                [file_path,name] = fileparts(char(Subjects(i)));

                if strcmp(file_path,data_path)
                    temmpp = find(string(name)==string(Subj_names));
                    if ~isempty(temmpp)
                        idx(i) = temmpp;
                    else
                        disp(['Files not found for participant ' char(name) ])
                    end
                else
                    msgbox('Choose participants from the Participants Data folder only')
                end
            end
            idx(idx==0) = [];
            app.Subj_list = app.Subj_list_all(idx);

            load_subjects_dropdown(app,app.Subj_list)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DetelePreprocessingfilesUIFigure = uifigure('Visible', 'off');
            app.DetelePreprocessingfilesUIFigure.Name = 'Detele Preprocessing files';
            app.DetelePreprocessingfilesUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.SelectParticipantsButton = uibutton(app.DetelePreprocessingfilesUIFigure, 'push');
            app.SelectParticipantsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParticipantsButtonPushed, true);
            app.SelectParticipantsButton.Text = 'Select Participants';
            app.NoofParticipantsselectedLabel = uilabel(app.DetelePreprocessingfilesUIFigure);
            app.NoofParticipantsselectedLabel.Text = 'No. of Participants selected: ';
            app.DeletePreprocessingfilesfromButtonGroup = uibuttongroup(app.DetelePreprocessingfilesUIFigure);
            app.DeletePreprocessingfilesfromButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DeletePreprocessingfilesfromButtonGroupSelectionChanged, true);
            app.DeletePreprocessingfilesfromButtonGroup.Title = 'Delete Preprocessing files from';
            app.InitialVolumeDiscardButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.InitialVolumeDiscardButton.Text = 'Initial Volume Discard';
            app.InitialVolumeDiscardButton.Value = true;
            app.RealignmentButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.RealignmentButton.Text = 'Realignment';
            app.SegmentationButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.SegmentationButton.Text = 'Segmentation';
            app.CSFMasksButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.CSFMasksButton.Text = 'CSF Masks';
            app.RegressionButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.RegressionButton.Text = 'Regression';
            app.FilteringButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.FilteringButton.Text = 'Filtering';
            app.SmoothingButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.SmoothingButton.Text = 'Smoothing';
            app.NormalizationButton = uitogglebutton(app.DeletePreprocessingfilesfromButtonGroup);
            app.NormalizationButton.Text = 'Normalization';
            app.DeletePreprocessingfilesButton = uibutton(app.DetelePreprocessingfilesUIFigure, 'push');
            app.DeletePreprocessingfilesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePreprocessingfilesButtonPushed, true);
            app.DeletePreprocessingfilesButton.Text = 'Delete Preprocessing files';
            app.ParticipantsDropDownLabel = uilabel(app.DetelePreprocessingfilesUIFigure);
            app.ParticipantsDropDownLabel.Text = 'Participants';
            app.ParticipantsDropDown = uidropdown(app.DetelePreprocessingfilesUIFigure);
            app.ParticipantsDropDown.Items = {};
            app.ParticipantsDropDown.Value = {};
            % Show the figure after all components are created
            app.DetelePreprocessingfilesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rerunpreproc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DetelePreprocessingfilesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DetelePreprocessingfilesUIFigure)
        end
    end
end