classdef whifun_display_FC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayFCUIFigure               matlab.ui.Figure
        SaveplotasimageButton           matlab.ui.control.Button
        TotalnumberofParticipantsinGroup2Label  matlab.ui.control.Label
        TotalnumberofParticipantsinGroup1Label  matlab.ui.control.Label
        Group2NameEditField             matlab.ui.control.EditField
        Group2ParticipantsSelectButton  matlab.ui.control.Button
        SubjectlistDropDown_3           matlab.ui.control.DropDown
        SubjectlistDropDownLabel_3      matlab.ui.control.Label
        Group1NameEditField             matlab.ui.control.EditField
        Group1ParticipantsSelectButton  matlab.ui.control.Button
        SubjectlistDropDown_2           matlab.ui.control.DropDown
        SubjectlistDropDownLabel_2      matlab.ui.control.Label
        UploadCSVforParticipantGroupsButton  matlab.ui.control.Button
        DivideingroupsCheckBox          matlab.ui.control.CheckBox
        DataTipsCheckBox                matlab.ui.control.CheckBox
        TotalnumberofParticipantsLabel  matlab.ui.control.Label
        ParticipantlistDropDown         matlab.ui.control.DropDown
        ParticipantlistDropDownLabel    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AllfrequenciesTab               matlab.ui.container.Tab
        WMfileSelectDropDown            matlab.ui.control.DropDown
        WMfileSelectLabel               matlab.ui.control.Label
        WMFCButton_2                    matlab.ui.control.Button
        GMfileSelectDropDown            matlab.ui.control.DropDown
        GMfileSelectLabel               matlab.ui.control.Label
        WMGMFCButton_2                  matlab.ui.control.Button
        CCfileSelectDropDown            matlab.ui.control.DropDown
        CCfileSelectDropDownLabel       matlab.ui.control.Label
        WMCCFCButton_2                  matlab.ui.control.Button
        AtlasFCButton_2                 matlab.ui.control.Button
        AtlasfileSelectDropDown         matlab.ui.control.DropDown
        AtlasfileSelectLabel            matlab.ui.control.Label
        FrequencyBandsTab               matlab.ui.container.Tab
        Band_selectDropDown             matlab.ui.control.DropDown
        FrequencyBandSelectLabel        matlab.ui.control.Label
        WMfileSelectDropDown_2          matlab.ui.control.DropDown
        WMfileSelectLabel_2             matlab.ui.control.Label
        WMFCButton                      matlab.ui.control.Button
        GMfileSelectDropDown_2          matlab.ui.control.DropDown
        GMfileSelectLabel_2             matlab.ui.control.Label
        WMGMFCButton                    matlab.ui.control.Button
        CCfileSelectDropDown_2          matlab.ui.control.DropDown
        CCfileSelectDropDownLabel_2     matlab.ui.control.Label
        WMCCFCButton                    matlab.ui.control.Button
        AtlasFCButton                   matlab.ui.control.Button
        AtlasfileSelectDropDown_2       matlab.ui.control.DropDown
        AtlasfileSelectLabel_2          matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main App
        final_result
        final_result_cc
        Subj_list
        slide_select = 0
        final_result_gm
        output_folder
        final_result_atlas
        atlas_file
        atlas_folders
        bands_info
        folders_info
        Tabposition
        initial_UIAxes_pos
        initial_UIAxes2_pos
        initial_UIAxes2_2_pos
        Subj_list1  
        Subj_list2 
        all_sub_names
        data_path

        dropselect = 1; % Description
        hp
        lp% Description
    end
    
    
    methods (Access = private)
        
        function load_sub_dropdown(app,Subj_list)
            
            app.Subj_list = Subj_list;  
            Subj_names = struct2cell(app.Subj_list);
            Subj_names(2:end,:) = [];
            Subj_names = ['Mean','STD',Subj_names];
            app.ParticipantlistDropDown.Items = Subj_names;

            num_sub = length(Subj_names)-2;

            app.TotalnumberofParticipantsLabel.Text = ['Total number of participants: ' num2str(num_sub)];
        end

        function initialise_freq_buttons(app,current_band)
            
            current_band_atlas_idx = string(app.bands_info(:,1)) == current_band;
%             current_band_atlas = app.bands_info(current_band_atlas_idx,2);
            current_band_atlas_number = app.bands_info(current_band_atlas_idx,3);
            
            WM_reg_files_idx = intersect(find(app.folders_info(:,1)),cell2mat(current_band_atlas_number));
            
            if isscalar(WM_reg_files_idx)
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(WM_reg_files_idx).folder,app.atlas_folders(WM_reg_files_idx).name,['Participant_' app.atlas_folders(WM_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
                app.WMfileSelectDropDown_2.Enable = 'off';
               
                app.WMFCButton.Enable = 'on';
            elseif length(WM_reg_files_idx) > 1
                app.WMfileSelectDropDown_2.Enable = 'on';
                wm_reg_file = struct2cell(app.atlas_folders(WM_reg_files_idx));
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown_2.Items = wm_reg_file;
                app.WMFCButton.Enable = 'on';
            else
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.WMFCButton.Enable = "off";
                app.WMFCButton.Tooltip = 'White Matter(WM) Network average timeseries file not found: After creating the WM Networks, Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the WM_clustering_K*.nii file from <output folder>/Analysis/WM_FN as Network/Atlas file and try again';
            end
            CC_reg_files_idx = intersect(find(app.folders_info(:,2)),cell2mat(current_band_atlas_number));

            if isscalar(CC_reg_files_idx)
                app.final_result_cc = load_in_cell_freq(app,fullfile(app.atlas_folders(CC_reg_files_idx).folder,app.atlas_folders(CC_reg_files_idx).name,['Participant_' app.atlas_folders(CC_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_cc.hp;
                app.lp = app.final_result_cc.lp;
                app.CCfileSelectDropDown_2.Enable  = 'off';
                app.WMCCFCButton.Enable = 'on';
            elseif length(CC_reg_files_idx) > 1
                app.CCfileSelectDropDown_2.Enable = 'on';
                app.WMCCFCButton.Enable = 'on';
                CC_reg_files = struct2cell(app.atlas_folders(CC_reg_files_idx));
                CC_reg_files(2:end,:) = [];
                app.CCfileSelectDropDown_2.Items = CC_reg_files;

            else
                app.WMCCFCButton.Enable = "off";
                app.WMCCFCButton.Tooltip = 'Corpus Callosum (CC) average timeseries file not found: After creating the CC Networks, Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the CC_network_from_WM_K*.nii file from <output folder>/Analysis/Corpus_Callosum_FN as Network/Atlas file and try again';
                app.CCfileSelectDropDown_2.Enable = 'off';
            end

            GM_reg_files_idx = intersect(find(app.folders_info(:,3)),cell2mat(current_band_atlas_number));

            if isscalar(GM_reg_files_idx)              
                app.final_result_gm = load_in_cell_freq(app,fullfile(app.atlas_folders(GM_reg_files_idx).folder,app.atlas_folders(GM_reg_files_idx).name,['Participant_' app.atlas_folders(GM_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_gm.hp;
                app.lp = app.final_result_gm.lp;
                app.GMfileSelectDropDown_2.Enable  = 'off';
                app.WMGMFCButton.Enable = "on";
            elseif length(GM_reg_files_idx) > 1
                app.GMfileSelectDropDown_2.Enable = 'on';
                gm_reg_file = struct2cell(app.atlas_folders(GM_reg_files_idx));
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown_2.Items = gm_reg_file;
                app.WMGMFCButton.Enable = "on";

            else
                app.WMGMFCButton.Enable = "off";
                app.GMfileSelectDropDown_2.Enable = 'off';
                app.WMGMFCButton.Tooltip = 'Gray Matter(GM) Network average timeseries file not found: After creating the GM networks, Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the GM_clustering_K*.nii file from <output folder>/Analysis/GM_FN as Network/Atlas file and try again';

            end

            Atlas_reg_files_idx = intersect(find(app.folders_info(:,4)),cell2mat(current_band_atlas_number));

            if isscalar(Atlas_reg_files_idx)
                
                app.final_result_atlas = load_in_cell_freq(app,fullfile(app.atlas_folders(Atlas_reg_files_idx).folder,app.atlas_folders(Atlas_reg_files_idx).name,['Participant_' app.atlas_folders(Atlas_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_atlas.hp;
                app.lp = app.final_result_atlas.lp;
                app.AtlasfileSelectDropDown_2.Enable  = 'off';
                app.AtlasFCButton.Enable = "on";
                app.atlas_file = app.atlas_folders(Atlas_reg_files_idx).name;
            elseif length(Atlas_reg_files_idx) > 1
                app.AtlasfileSelectDropDown_2.Enable = 'on';
                atlas_reg_file = struct2cell(app.atlas_folders(Atlas_reg_files_idx));
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown_2.Items = atlas_reg_file;
                app.AtlasFCButton.Enable = "on";

            else
                app.AtlasFCButton.Enable = "off";
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.AtlasFCButton.Tooltip = 'Atlas average timeseries file not found: Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the preffered Atlas as Network/Atlas file and try again';

            end
        end

        function WMFC(app,final_result,Subj_list)
            d = uiprogressdlg(app.DisplayFCUIFigure,'Title','Please wait','Message','Computing WM-FC',...
                'Indeterminate','on');
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            

            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list)
                subid = find(app.ParticipantlistDropDown.Value == string(app.ParticipantlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list1));
                final_result = final_result(ia);
                Subj_list = Subj_list(ia);
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list2));
                final_result = final_result(ib);
                Subj_list = Subj_list(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 1;

            
            WM_net_no = size(final_result{1},2); % number of WM networks (taking from the first subject as it is same for every subject)
            if subid == 1
                mean_corr = zeros(WM_net_no,WM_net_no,length(Subj_list));
                for i = 1:length(Subj_list)
                    mean_corr(:,:,i) = corr(final_result{i});
                end
                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = "WM FC Mean";
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = ['WM FC Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(WM_net_no,WM_net_no,length(Subj_list));
                for i = 1:length(Subj_list)
                    std_corr(:,:,i) = corr(final_result{i});
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = "WM FC STD";
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = ['WM FC STD , Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group1NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM FC Group: ' char(app.Group2NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(final_result{subid-2}))
                
                if app.Tabposition
                    app.UIAxes.Title.String = string([ 'WM FC for participant: ' Subj_list(subid-2).name]); 
                else
                    app.UIAxes.Title.String = string([ 'WM FC for participant: ' Subj_list(subid-2).name ', Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ]); 
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            set(app.UIAxes,'YLim',[0.5 WM_net_no + 0.5])
            set(app.UIAxes,'XLim',[0.5 WM_net_no + 0.5])
            xlabel(app.UIAxes,'WM Networks')
            ylabel(app.UIAxes,'WM Networks')
            
            
            %% side maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K' ,num2str(WM_net_no)],['WM_FN_K',num2str(WM_net_no),'_BrainNet_images']);


            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_FN_K' ,num2str(WM_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);
            
            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])

            a = imtile(WM_im_filename,'GridSize',[1,WM_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'WM Networks')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')

            x_tick_pos = round((m_size(2)/WM_net_no)/2:(m_size(2)/WM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:WM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            axis(app.UIAxes,'off')
            axis(app.UIAxes,'off')

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
            
            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
            close(d)
        end
        
        function WM_CC_FC(app,final_result,final_result_ccn,Subj_list)
            d = uiprogressdlg(app.DisplayFCUIFigure,'Title','Please wait','Message','Computing WM-CC-FC',...
                'Indeterminate','on');
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            
            
            WM_net_no = size(final_result{1},2); %Taking the number of WM networks from 1st subject as it is the same for every subject 
            CC_net_no = size(final_result_ccn{1},2); %Taking the number of CC networks from 1st subject as it is the same for every subject 

            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list)
                subid = find(app.ParticipantlistDropDown.Value == string(app.ParticipantlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list1));
                final_result = final_result(ia);
                final_result_ccn = final_result_ccn(ia);
                Subj_list = Subj_list(ia);
                
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list2));
                final_result = final_result(ib);
                final_result_ccn = final_result_ccn(ib);
                Subj_list = Subj_list(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 2;

            if subid == 1
                mean_corr = zeros(WM_net_no,CC_net_no,length(Subj_list));
                for i = 1:length(Subj_list)
                    mean_corr(:,:,i) = corr(final_result{i},final_result_ccn{i});
                end


                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = "WM-CC-FC Mean";
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = ['WM-CC-FC Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group1NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group2NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(WM_net_no,CC_net_no,length(Subj_list));
                for i = 1:length(Subj_list)
                    std_corr(:,:,i) = corr(final_result{i},final_result_ccn{i});
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = "WM-CC-FC STD";
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = ['WM-CC-FC STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group1NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-CC-FC Group: ' char(app.Group2NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(final_result{subid-2},final_result_ccn{subid-2}))
                if app.Tabposition
                    app.UIAxes.Title.String = string([ 'WM-CC FC for participant: ' Subj_list(subid-2).name]); 
                else
                    app.UIAxes.Title.String = string([ 'WM-CC FC for participant: ' Subj_list(subid-2).name ', Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ]); 
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            set(app.UIAxes,'YLim',[0.5 WM_net_no + 0.5])
            set(app.UIAxes,'XLim',[0.5 CC_net_no + 0.5])
            
            xlabel(app.UIAxes,'Corpus calosum Regions')
            ylabel(app.UIAxes,'WM Networks')

            
            %% side maps
            
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K' ,num2str(WM_net_no)],['WM_FN_K',num2str(WM_net_no),'_BrainNet_images']);


            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_FN_K' ,num2str(WM_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
            
            CC_image_folder = fullfile(app.output_folder,'Analysis','Original_corpus_callosum',['Original_corpus_callosum_FN_from_WM_K' num2str(CC_net_no)],['Original_corpus_callosum_FN_from_WM_K' num2str(CC_net_no) '__BrainNet_images']);%'Corpus_Callosum_FN',['CC_network_from_WM_K',num2str(CC_net_no),'_BrainNet_images']);

            CC_im_filename = cell(CC_net_no,1);
            for i = 1:CC_net_no
                CC_im_filename{i} = fullfile(CC_image_folder,['Original_corpus_callosum_FN_from_WM_K' num2str(CC_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(CC_im_filename,'GridSize',[1,CC_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'Corpus Callosum Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/CC_net_no)/2:(m_size(2)/CC_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:CC_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
%             map_pos2_in = app.UIAxes2_2.InnerPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), map_pos2(3), map_pos2(4)])
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), map_pos2(3), map_pos(4)])

            xlabel(app.UIAxes,'Corpus calosum Regions')
            ylabel(app.UIAxes,'WM Networks')
            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
            close(d)
        end
        
        function WM_GM_FC(app,final_result,final_result_gm,Subj_list_wm,Subj_list_gm)
            d = uiprogressdlg(app.DisplayFCUIFigure,'Title','Please wait','Message','Computing WM-GM-FC',...
                'Indeterminate','on');
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            WM_net_no = size(final_result{1},2);
            GM_net_no = size(final_result_gm{1},2);                  %Taking the number of CC networks from 1st subject as it is the same for every subject 
            Sub_names_wm = string(struct2cell(Subj_list_wm)); 
            Sub_names_wm(2:end,:) = [] ; 

            Sub_names_gm = string(struct2cell(Subj_list_gm));
            Sub_names_gm(2:end,:) = [] ; 
            if length(Sub_names_gm) ~= length(Sub_names_wm)
                msgbox('Number of participants used to compute the WM networks is different from that used to compute the GM networks. Please use the same participants to compute WM and GM and try again.')
                return
            end
            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list_gm)
                subid = find(app.ParticipantlistDropDown.Value == string(app.ParticipantlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list_gm),substruc2string(app,app.Subj_list1));
                final_result = final_result(ia);
                final_result_gm = final_result_gm(ia);
                Subj_list_gm = Subj_list_gm(ia);
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list_gm),substruc2string(app,app.Subj_list2));
                final_result = final_result(ib);
                final_result_gm = final_result_gm(ib);
                Subj_list_gm = Subj_list_gm(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 3;

            if subid == 1
                mean_corr = zeros(WM_net_no,GM_net_no,length(Subj_list_gm));
                for i = 1:length(Subj_list_gm)
                    mean_corr(:,:,i) = corr(final_result{i},final_result_gm{i});
                end
                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = "WM-GM-FC Mean";
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group1NameEditField.Value) ' Mean'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group2NameEditField.Value) ' Mean'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = ['WM-GM-FC Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group1NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group2NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(WM_net_no,GM_net_no,length(Subj_list_gm));
                for i = 1:length(Subj_list_gm)
                    std_corr(:,:,i) = corr(final_result{i},final_result_gm{i});
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
               if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = "WM-GM-FC STD";
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group1NameEditField.Value) ' STD'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group2NameEditField.Value) ' STD'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = ['WM-GM-FC STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group1NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = ['WM-GM-FC Group: ' char(app.Group2NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(final_result{subid-2},final_result_gm{subid-2}))
                if app.Tabposition
                    app.UIAxes.Title.String = string([ 'WM-GM FC for participant: ' Subj_list_gm(subid-2).name ]); 
                else
                    app.UIAxes.Title.String = string([ 'WM-GM FC for participant: ' Subj_list_gm(subid-2).name ', Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ]); 
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            set(app.UIAxes,'YLim',[0.5 WM_net_no + 0.5])
            set(app.UIAxes,'XLim',[0.5 GM_net_no + 0.5])
            xlabel(app.UIAxes,'GM Networks')
            ylabel(app.UIAxes,'WM Networks')
            
%% Side Maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";

            
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K' ,num2str(WM_net_no)],['WM_FN_K',num2str(WM_net_no),'_BrainNet_images']);


            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_FN_K' ,num2str(WM_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
            GM_im_filename = cell(GM_net_no,1);
            GM_image_folder = fullfile(app.output_folder,'Analysis','GM_FN',['GM_FN_K' ,num2str(GM_net_no)],['GM_FN_K',num2str(GM_net_no),'_BrainNet_images']);
            for i = 1:GM_net_no
                GM_im_filename{i} = fullfile(GM_image_folder,['GM_FN_K' ,num2str(GM_net_no) '_' num2str(i) '_left.png']);
                
            end
            a = imtile(GM_im_filename,'GridSize',[1,GM_net_no]);
            m_size = size(a);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            xlabel(app.UIAxes2_2,'GM Networks')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/GM_net_no)/2:(m_size(2)/GM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:GM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
            close(d)
        end
        
        function Atlas_FC(app,reg_ts,Subj_list)

            d = uiprogressdlg(app.DisplayFCUIFigure,'Title','Please wait','Message','Computing Atlas-FC',...
                'Indeterminate','on');
            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')

            if app.dropselect == 1
                load_sub_dropdown(app,Subj_list)
                subid = find(app.ParticipantlistDropDown.Value == string(app.ParticipantlistDropDown.Items));
            elseif app.dropselect == 2
                [~,ia] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list1));
                reg_ts = reg_ts(ia);
                Subj_list = Subj_list(ia);
                subid = find(app.SubjectlistDropDown_2.Value == string(app.SubjectlistDropDown_2.Items));
            elseif app.dropselect == 3
                [~,ib] = intersect(substruc2string(app,Subj_list),substruc2string(app,app.Subj_list2));
                reg_ts = reg_ts(ib);
                Subj_list = Subj_list(ib);
                subid = find(app.SubjectlistDropDown_3.Value == string(app.SubjectlistDropDown_3.Items));
            end
            app.slide_select = 4;
            axis(app.UIAxes,'on')
            nreg = size(reg_ts{1},2); % Taking the size of regions from the first subject. It should be the same for every subject

            if subid == 1
                mean_corr = zeros(nreg,nreg,length(Subj_list));
                for i = 1:length(Subj_list)
                    mean_corr(:,:,i) = corr(reg_ts{i});
                end
                mean_corr = mean(mean_corr,3);
                imagesc(app.UIAxes,mean_corr);
                clim(app.UIAxes,[-0.6,0.6])
                
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = [char(app.atlas_file) ' FC Mean'];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = [char(app.atlas_file) ' ' char(app.Group1NameEditField.Value) ' Mean'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = [char(app.atlas_file) ' ' char(app.Group2NameEditField.Value) ' Mean'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = [char(app.atlas_file) ' FC Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = [char(app.atlas_file) ' ' char(app.Group1NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = [char(app.atlas_file) ' ' char(app.Group2NameEditField.Value) ' Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
            elseif subid == 2
                std_corr = zeros(nreg,nreg,length(Subj_list));
                for i = 1:length(Subj_list)
                    std_corr(:,:,i) = corr(reg_ts{i});
                end
                std_corr = std(std_corr,[],3);
                imagesc(app.UIAxes,std_corr);
                
                if app.Tabposition
                    if app.dropselect == 1
                        app.UIAxes.Title.String = [char(app.atlas_file(1:end-11)) ' FC STD'];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = [char(app.atlas_file(1:end-11)) ' ' char(app.Group1NameEditField.Value) ' STD'];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = [char(app.atlas_file(1:end-11)) ' ' char(app.Group2NameEditField.Value) ' STD'];
                    end
                else
                    if app.dropselect == 1
                        app.UIAxes.Title.String = [char(app.atlas_file(1:end-11)) ' FC Mean, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 2
                        app.UIAxes.Title.String = [char(app.atlas_file(1:end-11)) ' ' char(app.Group1NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    elseif app.dropselect == 3
                        app.UIAxes.Title.String = [char(app.atlas_file(1:end-11)) ' ' char(app.Group2NameEditField.Value) ' STD, Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ];
                    end
                end
                app.UIAxes.Title.Color = [1,1,1];
                
                clim(app.UIAxes,[min(std_corr,[],'all'),max(std_corr,[],'all')])
            else
                imagesc(app.UIAxes,corr(reg_ts{subid-2}))
                
                if app.Tabposition
                    app.UIAxes.Title.String = string([ char(app.atlas_file) ' FC for participant: ' Subj_list(subid-2).name]); 
                else
                    app.UIAxes.Title.String = string([ char(app.atlas_file) ' FC for participant: ' Subj_list(subid-2).name , ' Freq. Range ' num2str(app.lp) '~' num2str(app.hp) ]); 
                end
                app.UIAxes.Title.Color = [1,1,1];
                clim(app.UIAxes,[-0.6,0.6])
            end
            app.UIAxes.Title.Interpreter = "none";
            set(app.UIAxes,'YLim',[0.5 nreg + 0.5])
            set(app.UIAxes,'XLim',[0.5 nreg + 0.5])

            if nreg > 10
                xticks(app.UIAxes,round(linspace(1,nreg,10)))
                yticks(app.UIAxes,round(linspace(1,nreg,10)))
            else
                xticks(app.UIAxes,round(1:nreg))
                yticks(app.UIAxes,round(1:nreg))
            end
            xlabel(app.UIAxes,[num2str(nreg) ' ROI'])
            ylabel(app.UIAxes,[num2str(nreg) ' ROI'])
            c = colorbar(app.UIAxes);
            c.Color = [1 1 1];
            close(d)
        end
        
        function sub_string = substruc2string(~,Subj_list)
            if ~isempty(Subj_list)
                sub_string = struct2cell(Subj_list);
                sub_string(2:end,:) = [];
                sub_string = string(sub_string);
            else
                sub_string= "";
            end
        end
        
        function subset_group(app)

            if app.DivideingroupsCheckBox.Value    % Do the following only if Divide groups checkbox is on
                if isempty(app.Subj_list1)
                    msgbox('Select participants in Group 1 or upload CSV file')
                    return
                end

                if isempty(app.Subj_list2)
                    msgbox('Select participants in Group 2 or upload CSV file')
                    return
                end
                app.all_sub_names = app.ParticipantlistDropDown.Items;
                app.all_sub_names = app.all_sub_names(2:end);

                [Subj_names,~] = intersect(string(app.all_sub_names),cellfun(@(x) string(x),squeeze(struct2cell(app.Subj_list1))));
                
                app.SubjectlistDropDown_2.Items =[ string(['Mean ' app.Group1NameEditField.Value]); string(['STD ' app.Group1NameEditField.Value]); Subj_names];
                app.TotalnumberofParticipantsinGroup1Label.Text = ['Total number of participants in Group 1: ' num2str(length(app.SubjectlistDropDown_2.Items)-2)];

                [Subj_names,~] = intersect(string(app.all_sub_names),cellfun(@(x) string(x),squeeze(struct2cell(app.Subj_list2))));
                app.SubjectlistDropDown_3.Items = [ string(['Mean ' app.Group2NameEditField.Value]); string(['STD ' app.Group2NameEditField.Value]); Subj_names];
                app.TotalnumberofParticipantsinGroup2Label.Text = ['Total number of participants in Group 2: ' num2str(length(app.SubjectlistDropDown_3.Items)-2)];
            end
        end
        
        function out = load_in_cell_wm(~,path_,freq)
            if ~exist('freq','var')
                freq = 0;
            end
            subj_list = dir(path_);
            n = length(subj_list);
            out.final_result = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));

            if ~freq
                out.final_result{i} = temp.avg_ts;
            else
                out.final_result{i} = temp.avg_ts_freq;
                out.hp = temp.hp;
                out.lp = temp.lp;
            end
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                if ~freq
                    out.final_result{i} = temp.avg_ts;
                else
                    out.final_result{i} = temp.avg_ts_freq;
                end
                out.Subj_list(i) = temp.Subj;
            end
        end

        function out = load_in_cell_cc(~,path_,freq)
            if ~exist('freq','var')
                freq = 0;
            end
            subj_list = dir(path_);
            n = length(subj_list);
            out.final_result_ccn = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            if ~freq
                out.final_result_ccn{i} = temp.avg_ts;
            else
                out.final_result_ccn{i} = temp.avg_ts_freq;
                out.hp = temp.hp;
                out.lp = temp.lp;
            end
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                if ~freq
                    out.final_result_ccn{i} = temp.avg_ts;
                else
                    out.final_result_ccn{i} = temp.avg_ts_freq;
                end
                out.Subj_list(i) = temp.Subj;
            end
        end

        function out = load_in_cell_atlas(~,path_,freq)
            if ~exist('freq','var')
                freq = 0;
            end
            subj_list = dir(path_);
            n = length(subj_list);
            out.reg_ts = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.reg_ts{i} = temp.avg_ts;
            if ~freq
                out.reg_ts{i} = temp.avg_ts;
            else
                out.reg_ts{i} = temp.avg_ts_freq;
                out.hp = temp.hp;
                out.lp = temp.lp;
            end
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                if ~freq
                    out.reg_ts{i} = temp.avg_ts;
                else
                    out.reg_ts{i} = temp.avg_ts_freq;
                end
                out.Subj_list(i) = temp.Subj;
            end
        end

        function out = load_in_cell_gm(~,path_,freq)
            if ~exist('freq','var')
                freq = 0;
            end
            subj_list = dir(path_);
            n = length(subj_list);
            out.final_result_gm = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.final_result_gm{i} = temp.avg_ts;
            if ~freq
                out.final_result_gm{i} = temp.avg_ts;
            else
                out.final_result_gm{i} = temp.avg_ts_freq;
                out.hp = temp.hp;
                out.lp = temp.lp;
            end
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                if ~freq
                    out.final_result_gm{i} = temp.avg_ts;
                else
                    out.final_result_gm{i} = temp.avg_ts_freq;
                end
                out.Subj_list(i) = temp.Subj;
            end
        end

        function get_atlas_regts(app,Atlas_reg_files)

            app.final_result_atlas = load_in_cell_atlas(app,fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,'Subject_Atlas_avg_ts','*_atlas_avgts.mat'));
                
                app.atlas_name = Atlas_reg_files.name;
                nRegions = cellfun(@(x) size(x,2), app.final_result_atlas.reg_ts);
                if numel(unique(nRegions)) ~= 1
                    subj_names = {app.final_result_atlas.Subj_list.name};
                    % Use the first subject as reference
                    ref_nRegions = nRegions(1);

                    % Find subjects that differ
                    diff_idx = find(nRegions ~= ref_nRegions,1);  % only first mismatch

                    if ~isempty(diff_idx)
                        bad_subj = subj_names{diff_idx};
                        % fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,'Subject_Atlas_avg_ts','<subject_name>_atlas_avgts.mat')
                        % Build path to check
                        check_path = fullfile(Atlas_reg_files.folder, ...
                            Atlas_reg_files.name, ...
                            'Subject_Atlas_avg_ts', ...
                            [bad_subj '_' Atlas_reg_files.name '_atlas_avgts.mat']);

                        error(['Mismatch in regions! Expected %d, but subject %s has %d.\n' ...
                            'Please check the file: %s'], ...
                            ref_nRegions, bad_subj, nRegions(diff_idx), check_path);
                    end
                end
                if isempty(app.RegionsEditField.Value)
                    app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts{1},2))];
                end
            
        end
        
        function out = load_in_cell_freq(~,path_)
            subj_list = dir(path_);
            n = length(subj_list);
            out.reg_ts_freq = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.reg_ts_freq{i} = temp.avg_ts_freq;
            out.Subj_list = temp.Subj;
            out.hp = temp(i).hp;   % Should be same for every subject
            out.lp = temp(i).lp;   % Should be same for every subject
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                out.reg_ts_freq{i} = temp.avg_ts_freq;
                out.Subj_list(i) = temp.Subj;
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path, data_path)
            app.CallingApp = caller;
            app.output_folder = path;
            app.Tabposition = 1;
            app.data_path = data_path;
            
            app.WMFCButton_2.Tooltip = 'White Matter Functional Connectivity (WM-FC): Pearson correlation will be computed between the average timeseries extracted from every WM network and this WM-FC Matrix will be plotted above.';

            app.WMCCFCButton_2.Tooltip = 'White Matter - Corpus Callosum Functional Connectivity (WM-CC-FC): Pearson correlation is computed between the average timeseries extracted from every WM network with the average timseries extracted from every CC network to make the WM-CC-FC Matrix.';
            
            app.WMGMFCButton_2.Tooltip = 'White Matter - Gray Matter Functional Connectivity (WM-GM-FC): Pearson correlation is computed between the average timeseries extracted from every WM network with the average timseries extracted from every GM network to make the WM-GM-FC Matrix.';

            app.AtlasFCButton_2.Tooltip = 'Atlas-Functional Connectivity: Pearson correlation is computed between the average timeseries extracted from every region of interest specified in the Atlas to make the Atlas-FC Matrix.';
            app.UploadCSVforParticipantGroupsButton.Enable = "off";
            app.UploadCSVforParticipantGroupsButton.Visible = "off";
            app.Group1NameEditField.Visible = "off";
            app.Group1ParticipantsSelectButton.Visible = "off";
            app.SubjectlistDropDown_2.Visible = "off";
            app.SubjectlistDropDownLabel_2.Visible = "off";
            app.Group2NameEditField.Visible = "off";
            app.Group2ParticipantsSelectButton.Visible = "off";
            app.SubjectlistDropDownLabel_3.Visible = "off";
            app.SubjectlistDropDown_3.Visible = "off";
            app.TotalnumberofParticipantsinGroup1Label.Visible = 'off';
            app.TotalnumberofParticipantsinGroup2Label.Visible = 'off';


            WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_FN','WM_FN_K*'));
            if isscalar(WM_reg_files)
                app.final_result = load_in_cell_wm(app,fullfile(WM_reg_files.folder,WM_reg_files.name,'Participant_WM_FN_K*_avg_ts','*_WM_FN_K*_avg_ts.mat'));
                app.WMfileSelectDropDown.Enable = 'off';
                % app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFCButton_2.Enable = "off";
                app.WMFCButton_2.Tooltip = 'White Matter Network average timeseries file not found: Please create the WM networks from the Create FN and FC module and try again';
            end
            cc_reg_files = dir(fullfile(app.output_folder,'Analysis','Original_corpus_callosum','Original_corpus_callosum_FN_from_WM_K*'));

            if isscalar(cc_reg_files)
                app.final_result_cc = load_in_cell_cc(app,fullfile(cc_reg_files.folder,cc_reg_files.name,'Participant_Original_corpus_callosum_FN_from_WM_K*_avg_ts','*_Original_corpus_callosum_FN_from_WM_K*_avg_ts.mat'));
                app.CCfileSelectDropDown.Enable  = 'off';
            elseif length(cc_reg_files) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_files);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCCFCButton_2.Enable = "off";
                app.CCfileSelectDropDown.Enable = 'off';
                app.WMCCFCButton_2.Tooltip = 'Corpus Callosum average timeseries file not found: Please create the  Corpus callosum networks by checking the Corpus callosum check box under the WM networks tab from the Create FN and FC module and try again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','ROI_Timeseries'));
            Atlas_reg_files = Atlas_reg_files(~ismember({Atlas_reg_files.name}, {'.','..','.DS_Store'}));  % remove unwanted


            if isscalar(Atlas_reg_files)
                
                app.AtlasfileSelectDropDown.Enable  = 'off';
                app.atlas_file = Atlas_reg_files.name;
                app.final_result_atlas = load_in_cell_atlas(app,fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,'Participant_*_avg_ts','*_avg_ts.mat'));
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasFCButton_2.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasFCButton_2.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end


            GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_FN','GM_FN_K*'));

            if isscalar(GM_reg_files)
                app.final_result_gm = load_in_cell_gm(app,fullfile(GM_reg_files.folder,GM_reg_files.name,'Participant_GM_FN_K*_avg_ts','*_GM_FN_K*.mat'));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMFCButton_2.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMFCButton_2.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end


            app.initial_UIAxes_pos = app.UIAxes.Position;
            app.initial_UIAxes2_pos = app.UIAxes2.Position;
            app.initial_UIAxes2_2_pos = app.UIAxes2_2.Position;
            c = colorbar(app.UIAxes,'eastoutside','AxisLocation','out');
            c.Color = [1 1 1];
            axis(app.UIAxes,'off')

        end

        % Button pushed function: WMFCButton_2
        function WMFCButton_2Pushed(app, event)
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','WM_FN',wm_file,['Participant_' wm_file '_avg_ts'],['*_' wm_file '_avg_ts.mat']));
            end
            subset_group(app)
            WMFC(app,app.final_result.final_result,app.final_result.Subj_list)
            
        end

        % Button pushed function: WMCCFCButton_2
        function WMCCFCButton_2Pushed(app, event)
            if strcmp(app.CCfileSelectDropDown.Enable,'on')
                cc_file = app.CCfileSelectDropDown.Value;
                app.final_result_cc = load_in_cell_cc(app,fullfile(app.output_folder,'Analysis','Original_corpus_callosum',cc_file,['Participant_' cc_file '_avg_ts'],'*_Original_corpus_callosum_FN_from_WM_K*_avg_ts.mat'));

            end
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                 app.final_result = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','WM_FN',wm_file,['Participant_' wm_file '_avg_ts'],['*_' wm_file '_avg_ts.mat']));
            end
            subset_group(app)
            WM_CC_FC(app,app.final_result.final_result,app.final_result_cc.final_result_ccn,app.final_result_cc.Subj_list)

        end

        % Button pushed function: WMGMFCButton_2
        function WMGMFCButton_2Pushed(app, event)
            if strcmp(app.GMfileSelectDropDown.Enable,'on')
                gm_file = app.GMfileSelectDropDown.Value;
                app.final_result_gm = load_in_cell_gm(app,fullfile(app.output_folder,'Analysis','GM_FN',gm_file,['Participant_' gm_file '_avg_ts'],['*_' gm_file '_avg_ts.mat']));
            end

            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','WM_FN',wm_file,['Participant_' wm_file '_avg_ts'],['*_' wm_file '_avg_ts.mat']));
            end
            subset_group(app)
            WM_GM_FC(app,app.final_result.final_result,app.final_result_gm.final_result_gm,app.final_result.Subj_list,app.final_result_gm.Subj_list)
            

        end

        % Button pushed function: AtlasFCButton_2
        function AtlasFCButton_2Pushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown.Value;
                Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','ROI_Timeseries'));
                Atlas_reg_files = Atlas_reg_files(ismember({Atlas_reg_files.name}, {app.atlas_file}));
                app.final_result_atlas = load_in_cell_atlas(app,fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,['Participant_' Atlas_reg_files.name '_avg_ts'],'*_avg_ts.mat'));
            end
            subset_group(app)
            Atlas_FC(app,app.final_result_atlas.reg_ts,app.final_result_atlas.Subj_list)
            
        end

        % Value changed function: AtlasfileSelectDropDown
        function AtlasfileSelectDropDownValueChanged(app, event)
            AtlasFCButton_2Pushed(app, event)
        end

        % Value changed function: GMfileSelectDropDown
        function GMfileSelectDropDownValueChanged(app, event)
            WMGMFCButton_2Pushed(app, event)
        end

        % Value changed function: WMfileSelectDropDown
        function WMfileSelectDropDownValueChanged(app, event)
            switch app.slide_select
                case 1
                    WMFCButton_2Pushed(app, event)
                    
                case 2

                    WMCCFCButton_2Pushed(app, event)
                    
                case 3
                    WMGMFCButton_2Pushed(app, event)
            end
        end

        % Button down function: AllfrequenciesTab
        function AllfrequenciesTabButtonDown(app, event)
            app.Tabposition = 1;
            WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_FN','WM_FN_K*'));
            if isscalar(WM_reg_files)
                app.final_result = load_in_cell_wm(app,fullfile(WM_reg_files.folder,WM_reg_files.name,'Participant_WM_FN_K*_avg_ts','*_WM_FN_K*_avg_ts.mat'));
                app.WMfileSelectDropDown.Enable = 'off';
                % app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFCButton_2.Enable = "off";
                app.WMFCButton_2.Tooltip = 'White Matter Network average timeseries file not found: Please create the WM networks from the Create FN and FC module and try again';
            end
            cc_reg_files = dir(fullfile(app.output_folder,'Analysis','Original_corpus_callosum','Original_corpus_callosum_FN_from_WM_K*'));

            if isscalar(cc_reg_files)
                app.final_result_cc = load_in_cell_cc(app,fullfile(cc_reg_files.folder,cc_reg_files.name,'Participant_Original_corpus_callosum_FN_from_WM_K*_avg_ts','*_Original_corpus_callosum_FN_from_WM_K*_avg_ts.mat'));
                app.CCfileSelectDropDown.Enable  = 'off';
            elseif length(cc_reg_files) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_files);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCCFCButton_2.Enable = "off";
                app.CCfileSelectDropDown.Enable = 'off';
                app.WMCCFCButton_2.Tooltip = 'Corpus Callosum average timeseries file not found: Please create the  Corpus callosum networks by checking the Corpus callosum check box under the WM networks tab from the Create FN and FC module and try again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','ROI_Timeseries'));
            Atlas_reg_files = Atlas_reg_files(~ismember({Atlas_reg_files.name}, {'.','..','.DS_Store'}));  % remove unwanted


            if isscalar(Atlas_reg_files)
                
                app.AtlasfileSelectDropDown.Enable  = 'off';
                app.atlas_file = Atlas_reg_files.name;
                app.final_result_atlas = load_in_cell_atlas(app,fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,'Subject_Atlas_avg_ts','*_atlas_avgts.mat'));
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasFCButton_2.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasFCButton_2.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end


            GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_FN','GM_FN_K*'));

            if isscalar(GM_reg_files)
                app.final_result_gm = load_in_cell_gm(app,fullfile(GM_reg_files.folder,GM_reg_files.name,'Participant_GM_FN_K*_avg_ts','*_GM_FN_K*.mat'));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMFCButton_2.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMFCButton_2.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end
        end

        % Button down function: FrequencyBandsTab
        function FrequencyBandsTabButtonDown(app, event)
            app.Tabposition = 0;
            ex_time_folder = fullfile(app.output_folder,'Analysis','Freq_Specific_ROI_Timeseries');

            app.atlas_folders = dir(ex_time_folder);
            
            subset_group(app)
            if isempty(app.atlas_folders)
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.WMFCButton.Enable = "off";
                app.WMCCFCButton.Enable = "off";
                app.CCfileSelectDropDown_2.Enable = "off";
                app.WMGMFCButton.Enable = "off";
                app.GMfileSelectDropDown_2.Enable = "off";
                app.AtlasFCButton.Enable = 'off';
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.Band_selectDropDown.Enable = "off";
                return
            end
            app.atlas_folders = app.atlas_folders(~ismember({app.atlas_folders.name},{'.','..','.DS_Store'}));
            
            nt_dir = false(1,length(app.atlas_folders));                                        % make a list of other files that are not directories
            app.folders_info = false(length(app.atlas_folders),4);                                  %no. of folders x 4, col 1 --> WM networks, col 2 = CC networks, col 3 GM, col 4--> others 
            app.bands_info = cell(length(app.atlas_folders),3);
            p = 1;
            for i = 1:length(app.atlas_folders)
                if app.atlas_folders(i).isdir ~= 1
                    nt_dir(i) = true;
                end
            end
            app.atlas_folders(nt_dir) = [];
            for i = 1:length(app.atlas_folders)
                if length(app.atlas_folders(i).name) > 5 && length(app.atlas_folders(i).name) <= 37
                    if strcmp(app.atlas_folders(i).name(1:5),'WM_FN')
                        app.folders_info(i,1) = true;                    
                    elseif strcmp(app.atlas_folders(i).name(1:5),'GM_FN')
                        app.folders_info(i,3) = true;
                    else
                        app.folders_info(i,4) = true;
                    end
                elseif  length(app.atlas_folders(i).name) > 37
                    if strcmp(app.atlas_folders(i).name(1:37),'Original_corpus_callosum_FN_from_WM_K')
                        app.folders_info(i,2) = true;
                    else
                        app.folders_info(i,4) = true;
                    end
                else
                    app.folders_info(i,4) = true;
                end


                temp = dir(fullfile(app.atlas_folders(i).folder,app.atlas_folders(i).name,['Participant_' app.atlas_folders(i).name '_avg_ts']));
                temp = temp(~ismember({temp.name},{'.','..','.DS_Store'}));
                for i1 = 1:length(temp)
                    avg_ts_dir = dir(fullfile(temp(i1).folder,temp(i1).name,'*avg_ts.mat'));
                    temp_avg_ts = load(fullfile(avg_ts_dir(1).folder,avg_ts_dir(1).name));
                    app.bands_info(p,1) = {temp_avg_ts.band_name};  % band_name
                    app.bands_info(p,2) = {app.atlas_folders(i).name};                                  % atlas_name
                    app.bands_info(p,3) = {i};                                                        % atlas_number
                    p = p+1;
                end

                
            end
            
            
            bands_found = unique(app.bands_info(:,1));
            app.Band_selectDropDown.Items = string(bands_found);
            current_band = app.Band_selectDropDown.Value;
            
            initialise_freq_buttons(app,current_band)
        end

        % Value changed function: Band_selectDropDown
        function Band_selectDropDownValueChanged(app, event)
            current_band = app.Band_selectDropDown.Value;
            initialise_freq_buttons(app,current_band)
%             cla(app.UIAxes)
             switch app.slide_select
                case 1
                    if strcmp(app.WMFCButton.Enable,'on')
                        WMFCButtonPushed(app, event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the WM networks file from: ',fullfile(app.output_folder,'Analysis','WM_FN','WM_clustering_K*.nii'),', with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end

                case 2
                    if strcmp(app.WMCCFCButton.Enable,'on')
                        WMCCFCButtonPushed(app, event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the CC networks file from: ',fullfile(app.output_folder,'Analysis','Corpus_Callosum_FN','CC_network_from_WM_K*.nii'),', with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end
                case 3
                    if strcmp(app.WMGMFCButton.Enable,'on')
                        WMGMFCButtonPushed(app, event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the GM networks file from: ',fullfile(app.output_folder,'Analysis','GM_FN','GM_clustering_K*.nii'),', with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end
                case 4
                    if strcmp(app.AtlasFCButton.Enable,'on')
                        AtlasFCButtonPushed(app,event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the desired atlas with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end
            end
        end

        % Button pushed function: WMFCButton
        function WMFCButtonPushed(app, event)
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;

                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,wm_file,['Participant_' wm_file '_avg_ts'],[char(app.Band_selectDropDown.Value) '*'],'*avg_ts.mat'));
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
            end
            subset_group(app)
            WMFC(app,app.final_result.reg_ts_freq,app.final_result.Subj_list)
        end

        % Button pushed function: WMCCFCButton
        function WMCCFCButtonPushed(app, event)
            if strcmp(app.CCfileSelectDropDown_2.Enable,'on')
                cc_file = app.CCfileSelectDropDown_2.Value;
                app.final_result_cc = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,cc_file,['Participant_' cc_file '_avg_ts'],[char(app.Band_selectDropDown.Value) '*'],'*avg_ts.mat'));
            end
            hp1 = app.final_result_cc.hp;
            lp1 = app.final_result_cc.lp;
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,wm_file,['Participant_' wm_file '_avg_ts'],[char(app.Band_selectDropDown.Value) '*'],'*avg_ts.mat'));
            end
            hp2 = app.final_result.hp;
            lp2 = app.final_result.lp;

            if ~(hp1 == hp2 && lp1 == lp2)
                msgbox('The Frequency range for WM and CC are different, although the band name is the same','Band Name same but Frequency Range different.')
            else
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
            end
            

            if strcmp(app.WMFCButton.Enable, 'on')
                subset_group(app)
                WM_CC_FC(app,app.final_result.reg_ts_freq,app.final_result_cc.reg_ts_freq,app.final_result.Subj_list)
            else
                msgbox('White Matter Network average timeseries file not found: Please extract frequency specific timeseries from the Extract Time series tab under Create FN and FC module and choose the WM networks file <WM_clustering_K*.nii> and try again')
            end
        end

        % Button pushed function: WMGMFCButton
        function WMGMFCButtonPushed(app, event)
            if strcmp(app.GMfileSelectDropDown_2.Enable,'on')
                gm_file = app.GMfileSelectDropDown_2.Value;
                
                app.final_result_gm = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,gm_file,['Participant_' gm_file '_avg_ts'],[char(app.Band_selectDropDown.Value) '*'],'*avg_ts.mat'));
            end
            hp1 = app.final_result_gm.hp;
            lp1 = app.final_result_gm.lp;
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,wm_file,['Participant_' wm_file '_avg_ts'],[char(app.Band_selectDropDown.Value) '*'],'*avg_ts.mat'));
            end
            hp2 = app.final_result.hp;
            lp2 = app.final_result.lp;
            if ~(hp1 == hp2 && lp1 == lp2)
                msgbox('The Frequency range for WM and GM are different, although the band name is the same','Band Name same but Frequency Range different.')
            else
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
            end
            
            
            if strcmp(app.WMFCButton.Enable, 'on')
                subset_group(app)
            WM_GM_FC(app,app.final_result.reg_ts_freq,app.final_result_gm.reg_ts_freq,app.final_result.Subj_list,app.final_result_gm.Subj_list)
            else
                msgbox('White Matter Network average timeseries file not found: Please extract frequency specific timeseries from the Extract Time series tab under Create FN and FC module and choose the WM networks file <WM_clustering_K*.nii> and try again')
            end
        end

        % Button pushed function: AtlasFCButton
        function AtlasFCButtonPushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown_2.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown_2.Value;
                
                app.final_result_atlas = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,app.atlas_file,['Participant_' app.atlas_file '_avg_ts'],[char(app.Band_selectDropDown.Value) '*'],'*avg_ts.mat'));
                app.hp = app.final_result_atlas.hp;
                app.lp = app.final_result_atlas.lp;
            end
            subset_group(app)
            Atlas_FC(app,app.final_result_atlas.reg_ts_freq,app.final_result_atlas.Subj_list)

        end

        % Value changed function: DataTipsCheckBox
        function DataTipsCheckBoxValueChanged(app, event)
            value = app.DataTipsCheckBox.Value;
            dcm_obj = datacursormode(app.DisplayFCUIFigure);

            if value == 1
                dcm_obj.Enable = 'on';  
               
            else
                dcm_obj.Enable = 'off';
               
            end
        end

        % Value changed function: DivideingroupsCheckBox
        function DivideingroupsCheckBoxValueChanged(app, event)
            value = app.DivideingroupsCheckBox.Value;
            
            if value
                app.UploadCSVforParticipantGroupsButton.Enable = "on";
                app.UploadCSVforParticipantGroupsButton.Visible = "on";
                app.Group1NameEditField.Visible = "on";
                app.Group1ParticipantsSelectButton.Visible = "on";
                app.SubjectlistDropDown_2.Visible = "on";
                app.SubjectlistDropDownLabel_2.Visible = "on";
                app.Group2NameEditField.Visible = "on";
                app.Group2ParticipantsSelectButton.Visible = "on";
                app.SubjectlistDropDownLabel_3.Visible = "on";
                app.SubjectlistDropDown_3.Visible = "on";
                app.TotalnumberofParticipantsinGroup1Label.Visible = 'on';
                app.TotalnumberofParticipantsinGroup2Label.Visible = 'on';
                
            else
                app.UploadCSVforParticipantGroupsButton.Enable = "off";
                app.UploadCSVforParticipantGroupsButton.Visible = "off";
                app.Group1NameEditField.Visible = "off";
                app.Group1ParticipantsSelectButton.Visible = "off";
                app.SubjectlistDropDown_2.Visible = "off";
                app.SubjectlistDropDownLabel_2.Visible = "off";
                app.Group2NameEditField.Visible = "off";
                app.Group2ParticipantsSelectButton.Visible = "off";
                app.SubjectlistDropDownLabel_3.Visible = "off";
                app.SubjectlistDropDown_3.Visible = "off";
                app.TotalnumberofParticipantsinGroup1Label.Visible = 'off';
                app.TotalnumberofParticipantsinGroup2Label.Visible = 'off';
            end
        end

        % Button pushed function: UploadCSVforParticipantGroupsButton
        function UploadCSVforParticipantGroupsButtonPushed(app, event)
            try
                
                [csvfile,csvfolderpath] = uigetfile('.csv','Select CSV file');

            catch
            end
            if all(csvfolderpath == 0) || isempty(csvfolderpath)
                return
            end
            CSV = readmatrix(fullfile(csvfolderpath,csvfile), 'OutputType','char');

%             if app.CSVhasHeadersCheckBox.Value
%                 CSV(1,:) = [];
%             end

            grps = unique(CSV(:,2));
                    
            app.Subj_list1 = struct;
            app.Subj_list2 = struct;

            if length(grps) ~= 2
                msgbox('Only 2 Groups allowed')
            end

            app.Group1NameEditField.Value = grps{1};
            app.Group1ParticipantsSelectButton.Text = grps{1};
            app.Group2NameEditField.Value = grps{2};
            app.Group2ParticipantsSelectButton.Text = grps{2};
            p1 = 1;
            p2 = 1;
            for i = 1:length(CSV)
                if strcmp(CSV(i,2),grps{1})
                    app.Subj_list1(p1).name = CSV{i,1};
                    
                    p1 = p1 + 1;
                else
                    app.Subj_list2(p2).name = CSV{i,1};
                    
                    p2 = p2 + 1;
                end
            end
            %             if app.MydatahasheadersCheckBox.Value == 1
            %                 app.Group1NameEditField.Value = CSV{1,1};
            %                 app.Group2NameEditField.Value = CSV{1,2};
            %                 CSV(1,:) = [];
            %             end
            %
            
            app.all_sub_names = app.ParticipantlistDropDown.Items;
            app.all_sub_names = app.all_sub_names(2:end);
            
            [Subj_names,~] = intersect(string(app.all_sub_names),squeeze(struct2cell(app.Subj_list1)));
            app.SubjectlistDropDown_2.Items =[ string(['Mean ' app.Group1NameEditField.Value]); string(['STD ' app.Group1NameEditField.Value]); Subj_names];
            app.TotalnumberofParticipantsinGroup1Label.Text = ['Total number of participants in Group 1: ' num2str(length(app.SubjectlistDropDown_2.Items)-2)];

            [Subj_names,~] = intersect(string(app.all_sub_names),squeeze(struct2cell(app.Subj_list2)));
            app.SubjectlistDropDown_3.Items = [ string(['Mean ' app.Group2NameEditField.Value]); string(['STD ' app.Group2NameEditField.Value]); Subj_names];
            app.TotalnumberofParticipantsinGroup2Label.Text = ['Total number of participants in Group 2: ' num2str(length(app.SubjectlistDropDown_3.Items)-2)];
        end

        % Button pushed function: Group1ParticipantsSelectButton
        function Group1ParticipantsSelectButtonPushed(app, event)
            try
                while 1
                    Group1_subjects = uigetfile_n_dir(app.data_path, ['Choose participants for' app.Group1NameEditField.Value]);
                    [filepath, ~] = fileparts(Group1_subjects(1));
                    if strcmp(filepath,app.data_path)
                        break
                    end
                    if isempty(Group1_subjects)
                        return
                    end
                    f = questdlg('Select participant folders, which are in the Participant Data Folder only.','Wrong folder selected','Try again','Cancel','Try again');
                    switch f
                        case "Cancel"
                            return
                    end
                end
                
            catch
                return
            end
            app.Subj_list1 = struct;
            for i = 1:length(Group1_subjects)
                [filepath, name] = fileparts(Group1_subjects(i));


                app.Subj_list1(i).name = name;
                app.Subj_list1(i).folder = filepath;


            end

            Subj_names1 = struct2cell(app.Subj_list1);

            Subj_names1(2:end,:) = [];
            app.SubjectlistDropDown_2.Items = [ string(['Mean ' app.Group1NameEditField.Value]) string(['STD ' app.Group1NameEditField.Value]) Subj_names1];
            app.TotalnumberofParticipantsinGroup1Label.Text = ['Total number of participants in Group 1: ' num2str(length(app.SubjectlistDropDown_2.Items)-2)];
        end

        % Button pushed function: Group2ParticipantsSelectButton
        function Group2ParticipantsSelectButtonPushed(app, event)
            try
                while 1
                    Group2_subjects = uigetfile_n_dir(app.data_path, ['Choose participants for ' app.Group2NameEditField.Value]);
                    [filepath, ~] = fileparts(Group2_subjects(1));
                    if strcmp(filepath,app.data_path)
                        break
                    end
                    if isempty(Group2_subjects)
                        return
                    end
                    f = questdlg('Select participant folders, which are in the Participant Data Folder only.','Wrong folder selected','Try again','Cancel','Try again');
                    switch f
                        case "Cancel"
                            return
                    end
                end
            catch
                return
            end
            app.Subj_list2 = struct;
            for i = 1:length(Group2_subjects)
                [filepath, name] = fileparts(Group2_subjects(i));
                app.Subj_list2(i).name = name;
                app.Subj_list2(i).folder = filepath;

            end

            Subj_names = struct2cell(app.Subj_list2);
            Subj_names(2:end,:) = [];
            app.SubjectlistDropDown_3.Items = [ string(['Mean ' app.Group2NameEditField.Value]) string(['STD ' app.Group2NameEditField.Value]) Subj_names];
            app.TotalnumberofParticipantsinGroup2Label.Text = ['Total number of participants in Group 2: ' num2str(length(app.SubjectlistDropDown_3.Items)-2)];
        end

        % Value changed function: Group1NameEditField
        function Group1NameEditFieldValueChanged(app, event)
            value = app.Group1NameEditField.Value;
            app.Group1ParticipantsSelectButton.Text = value;
        end

        % Value changed function: Group2NameEditField
        function Group2NameEditFieldValueChanged(app, event)
            value = app.Group2NameEditField.Value;
            app.Group2ParticipantsSelectButton.Text = value;
        end

        % Clicked callback: ParticipantlistDropDown
        function ParticipantlistDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

            if ~isempty(item)
                app.dropselect = 1;
                switch app.slide_select

                    case 1
                        if app.Tabposition
                            WMFCButton_2Pushed(app, event)
                        else
                            WMFCButtonPushed(app, event)
                        end

                    case 2
                        if app.Tabposition
                            WMCCFCButton_2Pushed(app, event)
                        else
                            WMCCFCButtonPushed(app, event)
                        end

                    case 3
                        if app.Tabposition
                            WMGMFCButton_2Pushed(app, event)
                        else
                            WMGMFCButtonPushed(app, event)
                        end
                    case 4
                        if app.Tabposition
                            AtlasFCButton_2Pushed(app, event)
                        else
                            AtlasFCButtonPushed(app, event)
                        end

                end

            end
        end

        % Clicked callback: SubjectlistDropDown_2
        function SubjectlistDropDown_2Clicked(app, event)
            item = event.InteractionInformation.Item;

            if ~isempty(item)
                app.dropselect = 2;
                switch app.slide_select

                    case 1
                        if app.Tabposition
                            WMFCButton_2Pushed(app, event)
                        else
                            WMFCButtonPushed(app, event)
                        end

                    case 2
                        if app.Tabposition
                            WMCCFCButton_2Pushed(app, event)
                        else
                            WMCCFCButtonPushed(app, event)
                        end

                    case 3
                        if app.Tabposition
                            WMGMFCButton_2Pushed(app, event)
                        else
                            WMGMFCButtonPushed(app, event)
                        end
                    case 4
                        if app.Tabposition
                            AtlasFCButton_2Pushed(app, event)
                        else
                            AtlasFCButtonPushed(app, event)
                        end

                end

            end
        end

        % Clicked callback: SubjectlistDropDown_3
        function SubjectlistDropDown_3Clicked(app, event)
            item = event.InteractionInformation.Item;

            if ~isempty(item)
                app.dropselect = 3;
                switch app.slide_select

                    case 1
                        if app.Tabposition
                            WMFCButton_2Pushed(app, event)
                        else
                            WMFCButtonPushed(app, event)
                        end

                    case 2
                        if app.Tabposition
                            WMCCFCButton_2Pushed(app, event)
                        else
                            WMCCFCButtonPushed(app, event)
                        end

                    case 3
                        if app.Tabposition
                            WMGMFCButton_2Pushed(app, event)
                        else
                            WMGMFCButtonPushed(app, event)
                        end
                    case 4
                        if app.Tabposition
                            AtlasFCButton_2Pushed(app, event)
                        else
                            AtlasFCButtonPushed(app, event)
                        end

                end

            end
        end

        % Button pushed function: SaveplotasimageButton
        function SaveplotasimageButtonPushed(app, event)
            try
                
                [imgfile,imgfolderpath] = uiputfile('WhiFuN_FC_visualise_figure.png','Save FC visualize figure');

            catch
            end
            if all(imgfolderpath == 0) || isempty(imgfolderpath)
                return
            end

            c = app.UIAxes.Colorbar;
            c.Color = [0 0 0];
            app.UIAxes2_2.XColor = [0,0,0];
            set(app.UIAxes2,'FontSize',18)
            app.UIAxes2.YColor = [0,0,0];
            set(app.UIAxes2_2,'FontSize',18)
            exportgraphics(app.DisplayFCUIFigure,fullfile(imgfolderpath,imgfile),"BackgroundColor",[1,1,1])
            c.Color = [1 1 1];
            app.UIAxes2_2.XColor = [1 1 1];
            app.UIAxes2.YColor = [1 1 1];
            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
        end

        % Value changed function: CCfileSelectDropDown
        function CCfileSelectDropDownValueChanged(app, event)
            % value = app.CCfileSelectDropDown.Value;
            WMCCFCButton_2Pushed(app, event)
        end

        % Value changed function: WMfileSelectDropDown_2
        function WMfileSelectDropDown_2ValueChanged(app, event)
            switch app.slide_select
                case 1
                    WMFCButtonPushed(app, event)
                    
                case 2

                    WMCCFCButtonPushed(app, event)
                    
                case 3
                    WMGMFCButtonPushed(app, event)
            end
        end

        % Value changed function: CCfileSelectDropDown_2
        function CCfileSelectDropDown_2ValueChanged(app, event)
            WMCCFCButtonPushed(app, event)
            
        end

        % Value changed function: GMfileSelectDropDown_2
        function GMfileSelectDropDown_2ValueChanged(app, event)
            switch app.slide_select
                case 1
                    WMFCButtonPushed(app, event)
                    
                case 2

                    WMCCFCButtonPushed(app, event)
                    
                case 3
                    WMGMFCButtonPushed(app, event)
            end
            
        end

        % Value changed function: AtlasfileSelectDropDown_2
        function AtlasfileSelectDropDown_2ValueChanged(app, event)
            AtlasFCButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DisplayFCUIFigure = uifigure('Visible', 'off');
            app.DisplayFCUIFigure.Name = 'Display FC';
            app.DisplayFCUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.UIAxes = uiaxes(app.DisplayFCUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'parula')
            app.UIAxes2 = uiaxes(app.DisplayFCUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.Visible = 'off';
            colormap(app.UIAxes2, 'parula')
            app.UIAxes2_2 = uiaxes(app.DisplayFCUIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.ZTick = [];
            app.UIAxes2_2.Visible = 'off';
            colormap(app.UIAxes2_2, 'parula')
            app.TabGroup = uitabgroup(app.DisplayFCUIFigure);
            app.AllfrequenciesTab = uitab(app.TabGroup);
            app.AllfrequenciesTab.Title = 'All frequencies';
            app.AllfrequenciesTab.ButtonDownFcn = createCallbackFcn(app, @AllfrequenciesTabButtonDown, true);
            app.AtlasfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.AtlasfileSelectLabel.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.AtlasfileSelectDropDown.Items = {};
            app.AtlasfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDownValueChanged, true);
            app.AtlasfileSelectDropDown.Value = {};
            app.AtlasFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.AtlasFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @AtlasFCButton_2Pushed, true);
            app.AtlasFCButton_2.Text = 'Atlas-FC';
            app.WMCCFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.WMCCFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMCCFCButton_2Pushed, true);
            app.WMCCFCButton_2.Text = 'WM-CC-FC';
            app.CCfileSelectDropDownLabel = uilabel(app.AllfrequenciesTab);
            app.CCfileSelectDropDownLabel.Text = 'CC file Select';
            app.CCfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.CCfileSelectDropDown.Items = {};
            app.CCfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @CCfileSelectDropDownValueChanged, true);
            app.CCfileSelectDropDown.Value = {};
            app.WMGMFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.WMGMFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMGMFCButton_2Pushed, true);
            app.WMGMFCButton_2.Text = 'WM-GM-FC';
            app.GMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.GMfileSelectLabel.Text = 'GM file Select';
            app.GMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.GMfileSelectDropDown.Items = {};
            app.GMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDownValueChanged, true);
            app.GMfileSelectDropDown.Value = {};
            app.WMFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.WMFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMFCButton_2Pushed, true);
            app.WMFCButton_2.Text = 'WM-FC';
            app.WMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.WMfileSelectLabel.Text = 'WM file Select';
            app.WMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.WMfileSelectDropDown.Items = {};
            app.WMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDownValueChanged, true);
            app.WMfileSelectDropDown.Value = {};
            app.FrequencyBandsTab = uitab(app.TabGroup);
            app.FrequencyBandsTab.Title = 'Frequency Bands';
            app.FrequencyBandsTab.ButtonDownFcn = createCallbackFcn(app, @FrequencyBandsTabButtonDown, true);
            app.AtlasfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.AtlasfileSelectLabel_2.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.AtlasfileSelectDropDown_2.Items = {};
            app.AtlasfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDown_2ValueChanged, true);
            app.AtlasfileSelectDropDown_2.Value = {};
            app.AtlasFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.AtlasFCButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasFCButtonPushed, true);
            app.AtlasFCButton.Text = 'Atlas-FC';
            app.WMCCFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.WMCCFCButton.ButtonPushedFcn = createCallbackFcn(app, @WMCCFCButtonPushed, true);
            app.WMCCFCButton.Text = 'WM-CC-FC';
            app.CCfileSelectDropDownLabel_2 = uilabel(app.FrequencyBandsTab);
            app.CCfileSelectDropDownLabel_2.Text = 'CC file Select';
            app.CCfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.CCfileSelectDropDown_2.Items = {};
            app.CCfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CCfileSelectDropDown_2ValueChanged, true);
            app.CCfileSelectDropDown_2.Value = {};
            app.WMGMFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.WMGMFCButton.ButtonPushedFcn = createCallbackFcn(app, @WMGMFCButtonPushed, true);
            app.WMGMFCButton.Text = 'WM-GM-FC';
            app.GMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.GMfileSelectLabel_2.Text = 'GM file Select';
            app.GMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.GMfileSelectDropDown_2.Items = {};
            app.GMfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDown_2ValueChanged, true);
            app.GMfileSelectDropDown_2.Value = {};
            app.WMFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.WMFCButton.ButtonPushedFcn = createCallbackFcn(app, @WMFCButtonPushed, true);
            app.WMFCButton.Text = 'WM-FC';
            app.WMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.WMfileSelectLabel_2.Text = 'WM file Select';
            app.WMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.WMfileSelectDropDown_2.Items = {};
            app.WMfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDown_2ValueChanged, true);
            app.WMfileSelectDropDown_2.Value = {};
            app.FrequencyBandSelectLabel = uilabel(app.FrequencyBandsTab);
            app.FrequencyBandSelectLabel.Text = 'Frequency Band Select';
            app.Band_selectDropDown = uidropdown(app.FrequencyBandsTab);
            app.Band_selectDropDown.Items = {};
            app.Band_selectDropDown.ValueChangedFcn = createCallbackFcn(app, @Band_selectDropDownValueChanged, true);
            app.Band_selectDropDown.Value = {};
            app.ParticipantlistDropDownLabel = uilabel(app.DisplayFCUIFigure);
            app.ParticipantlistDropDownLabel.Text = 'Participant list';
            app.ParticipantlistDropDown = uidropdown(app.DisplayFCUIFigure);
            app.ParticipantlistDropDown.Items = {};
            app.ParticipantlistDropDown.ClickedFcn = createCallbackFcn(app, @ParticipantlistDropDownClicked, true);
            app.ParticipantlistDropDown.Value = {};
            app.TotalnumberofParticipantsLabel = uilabel(app.DisplayFCUIFigure);
            app.TotalnumberofParticipantsLabel.Text = 'Total number of Participants:';
            app.DataTipsCheckBox = uicheckbox(app.DisplayFCUIFigure);
            app.DataTipsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataTipsCheckBoxValueChanged, true);
            app.DataTipsCheckBox.Text = 'Data Tips';
            app.DivideingroupsCheckBox = uicheckbox(app.DisplayFCUIFigure);
            app.DivideingroupsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DivideingroupsCheckBoxValueChanged, true);
            app.DivideingroupsCheckBox.Text = 'Divide in groups';
            app.UploadCSVforParticipantGroupsButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.UploadCSVforParticipantGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCSVforParticipantGroupsButtonPushed, true);
            app.UploadCSVforParticipantGroupsButton.Text = 'Upload CSV for Participant Groups';
            app.SubjectlistDropDownLabel_2 = uilabel(app.DisplayFCUIFigure);
            app.SubjectlistDropDownLabel_2.Text = 'Participants list';
            app.SubjectlistDropDown_2 = uidropdown(app.DisplayFCUIFigure);
            app.SubjectlistDropDown_2.Items = {};
            app.SubjectlistDropDown_2.ClickedFcn = createCallbackFcn(app, @SubjectlistDropDown_2Clicked, true);
            app.SubjectlistDropDown_2.Value = {};
            app.Group1ParticipantsSelectButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.Group1ParticipantsSelectButton.ButtonPushedFcn = createCallbackFcn(app, @Group1ParticipantsSelectButtonPushed, true);
            app.Group1ParticipantsSelectButton.Text = {'Group 1 Participants'; ' Select'};
            app.Group1NameEditField = uieditfield(app.DisplayFCUIFigure, 'text');
            app.Group1NameEditField.ValueChangedFcn = createCallbackFcn(app, @Group1NameEditFieldValueChanged, true);
            app.Group1NameEditField.Value = 'Group 1 name here';
            app.SubjectlistDropDownLabel_3 = uilabel(app.DisplayFCUIFigure);
            app.SubjectlistDropDownLabel_3.Text = 'Participants list';
            app.SubjectlistDropDown_3 = uidropdown(app.DisplayFCUIFigure);
            app.SubjectlistDropDown_3.Items = {};
            app.SubjectlistDropDown_3.ClickedFcn = createCallbackFcn(app, @SubjectlistDropDown_3Clicked, true);
            app.SubjectlistDropDown_3.Value = {};
            app.Group2ParticipantsSelectButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.Group2ParticipantsSelectButton.ButtonPushedFcn = createCallbackFcn(app, @Group2ParticipantsSelectButtonPushed, true);
            app.Group2ParticipantsSelectButton.Text = {'Group 2 Participants'; ' Select'};
            app.Group2NameEditField = uieditfield(app.DisplayFCUIFigure, 'text');
            app.Group2NameEditField.ValueChangedFcn = createCallbackFcn(app, @Group2NameEditFieldValueChanged, true);
            app.Group2NameEditField.Value = 'Group 2 name here';
            app.TotalnumberofParticipantsinGroup1Label = uilabel(app.DisplayFCUIFigure);
            app.TotalnumberofParticipantsinGroup1Label.Text = 'Total number of Participants in Group 1';
            app.TotalnumberofParticipantsinGroup2Label = uilabel(app.DisplayFCUIFigure);
            app.TotalnumberofParticipantsinGroup2Label.Text = 'Total number of Participants in Group 2';
            app.SaveplotasimageButton = uibutton(app.DisplayFCUIFigure, 'push');
            app.SaveplotasimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotasimageButtonPushed, true);
            app.SaveplotasimageButton.Text = 'Save plot as image';
            % Show the figure after all components are created
            app.DisplayFCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whifun_display_FC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayFCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayFCUIFigure)
        end
    end
end