classdef whifun_qcviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThemeMenu                    matlab.ui.container.Menu
        DarkMenu                     matlab.ui.container.Menu
        LightMenu                    matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        manualMenu                   matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        PreviousparticipantButton    matlab.ui.control.Button
        NextparticipantButton        matlab.ui.control.Button
        AcceptanceInformationPanel   matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        exclusionDropDown            matlab.ui.control.DropDown
        DropDownLabel                matlab.ui.control.Label
        RejectButton                 matlab.ui.control.Button
        AcceptButton                 matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        ParticipantoutofPanel        matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        ParticipantDropDown          matlab.ui.control.DropDown
        GridLayout8                  matlab.ui.container.GridLayout
        ViewSettingsPanel            matlab.ui.container.Panel
        GridLayout4_2                matlab.ui.container.GridLayout
        MNIorNATIVEsegmentation      matlab.ui.control.Switch
        ANATorFUNCinitialcheck       matlab.ui.control.Switch
        voxButtonfinalFunc           matlab.ui.control.StateButton
        masksButtonfinalFunc         matlab.ui.control.StateButton
        mnispaceButtonfinalFunc      matlab.ui.control.StateButton
        regressionDropdown           matlab.ui.control.DropDown
        voxButtonregression          matlab.ui.control.StateButton
        globalButtonregression       matlab.ui.control.StateButton
        SPLITButton                  matlab.ui.control.StateButton
        VisualSeedButtonSeedbased    matlab.ui.control.StateButton
        DefaultModeButtonSeedbased   matlab.ui.control.StateButton
        AuditorySeedButtonSeedbased  matlab.ui.control.StateButton
        ViewModesPanel               matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        TimesereriesplotsButton      matlab.ui.control.Button
        SeedbasedCorrButton          matlab.ui.control.Button
        FinalFunccheckButton         matlab.ui.control.Button
        SmoothingButton              matlab.ui.control.Button
        NuisanceRegressionButton     matlab.ui.control.Button
        CSFmaskButton                matlab.ui.control.Button
        CoregisterationButton        matlab.ui.control.Button
        SegmentationButton           matlab.ui.control.Button
        HeadMotionButton             matlab.ui.control.Button
        InitialCheckButton           matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        whifun_viewButton            matlab.ui.control.Button
        splitPanel                   matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        sagittalButton               matlab.ui.control.StateButton
        coronalButton                matlab.ui.control.StateButton
        OrthosliceButton             matlab.ui.control.StateButton
        axialButton                  matlab.ui.control.StateButton
        errorInformation             matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        fileDirectory;
        % dont change past here
        spreadsheetDirectory;
        currentType="none";
        qcFolder;
        tableData;
        participants = [];
        acceptanceData = [];
        currentAcceptance;
        acceptanceIndex;

        
        %used to check if axis needs to be changed
        storedXData;
        storedYData;

        wantSplit = 0;

        initialCheckFolder = 'a_Initial_check';
        headMotionFolder = 'b_Head_motion';
        segmentationFolder = 'c_Segmentation';
        coregistrationFolder = 'd_Co_registeration';
        csfmaskFolder = 'e_CSF_Masks_for_Regression';
        nuisanceRegressionFolder = 'f_Nuisance_Regression';
        smoothingFolder = 'h_Smoothing';
        finalFuncFolder = 'i_Final_func_MNI';
        seedbasedFolder = 'k_Seed_Based_Corr';
        timeseries = 'j_Time_series_check';
        activeView = "axial";

        p1;p2;p3;p4;


        ThemeName = "light"   % "light" or "dark"
        Themes

    end
    
    properties (Access = private)
        whifun_view_path = ''; % Description
        whifun_view_cmap = gray;
    end
    
    methods (Access = public)
        
        function updateActiveParticipant(app)
            if app.currentType == "Segmentation"
                app.SegmentationButtonPushed
            elseif app.currentType == "Head Motion"
                app.HeadMotionButtonPushed
            elseif app.currentType == "Co-Registration"
                app.CoregisterationButtonPushed
            elseif app.currentType == "CSF Mask"
                app.CSFmaskButtonPushed
            elseif app.currentType == "Smoothing"
                app.SmoothingButtonPushed
            elseif app.currentType == "Initial Check"
                app.InitialCheckButtonPushed
            elseif app.currentType == "Seed-Based Corr"
                app.SeedbasedCorrButtonPushed
            elseif app.currentType == "Nuisance Regression"
                app.NuisanceRegressionButtonPushed
            elseif app.currentType == "Final Func"
                app.FinalFunccheckButtonPushed
            elseif app.currentType == "Timeseries Plots"
                app.TimesereriesplotsButtonPushed
            end
            updateAcceptance(app);
        end

        function UIinteraction(app, type)
            if type == "Seed-Based Corr"
                app.AuditorySeedButtonSeedbased.Visible = "on";
                app.DefaultModeButtonSeedbased.Visible = "on";
                app.VisualSeedButtonSeedbased.Visible = "on";
                app.SPLITButton.Visible = "on";
            else
                app.AuditorySeedButtonSeedbased.Visible = "off";
                app.DefaultModeButtonSeedbased.Visible = "off";
                app.VisualSeedButtonSeedbased.Visible = "off";
                app.SPLITButton.Visible = "off";
            end

            if type == "Nuisance Regression"
                app.globalButtonregression.Visible = "on";
                app.voxButtonregression.Visible = "on";
                if app.voxButtonregression.Value == 1
                    app.regressionDropdown.Visible = "on";
                end
            else
                app.globalButtonregression.Visible = "off";
                app.voxButtonregression.Visible = "off";
                app.regressionDropdown.Visible = "off";
            end
            
            if type == "Final Func"
                app.masksButtonfinalFunc.Visible = "on";
                app.mnispaceButtonfinalFunc.Visible = "on";
                app.voxButtonfinalFunc.Visible = "on";
            else
                app.masksButtonfinalFunc.Visible = "off";
                app.mnispaceButtonfinalFunc.Visible = "off";
                app.voxButtonfinalFunc.Visible = "off";
            end

            if type == "Initial Check"
                app.ANATorFUNCinitialcheck.Visible = "on";
            else
                app.ANATorFUNCinitialcheck.Visible = "off";
            end

            if type == "Segmentation"
                app.MNIorNATIVEsegmentation.Visible = "on";
            else
                app.MNIorNATIVEsegmentation.Visible = "off";
            end
        end
    end
    
    methods (Access = private)
        
        function updateAcceptance(app)
            index = find(string(app.participants)==app.ParticipantDropDown.Value);
            app.ParticipantoutofPanel.Title = ['Participant ' num2str(index) ' out of ' num2str(length(app.participants))];
            app.acceptanceIndex = index;
            app.currentAcceptance = app.acceptanceData(index, :);

            currentComments = app.currentAcceptance(:, 'comments');
            currentManualEx = app.currentAcceptance(:, 'manual_ex');
            currentMotionEx = app.currentAcceptance(:, 'motion_ex');
            currentError = app.currentAcceptance(:, 'error');
            green = [0.40 0.70 0.50];
            red = [0.85 0.40 0.40];
            if currentManualEx.manual_ex == 1
                app.AcceptButton.BackgroundColor = [0.50,0.50,0.50];
                app.RejectButton.BackgroundColor = red;
                app.RejectButton.Text = "Rejected: Manual";
                app.AcceptButton.Text = "Accept";
            elseif currentMotionEx.motion_ex == 1
                app.AcceptButton.BackgroundColor = [0.50,0.50,0.50];
                app.RejectButton.BackgroundColor = red;
                app.RejectButton.Text = "Rejected: Motion";
                app.AcceptButton.Text = "Accept";
            elseif currentError.error == 1
                app.AcceptButton.BackgroundColor = [0.50,0.50,0.50];
                app.RejectButton.BackgroundColor = red;
                app.RejectButton.Text = "Rejected: Error";
                app.AcceptButton.Text = "Accept";
            else
                app.AcceptButton.BackgroundColor = green;
                app.RejectButton.BackgroundColor = [0.50,0.50,0.50];
                app.RejectButton.Text = "Reject";
                app.AcceptButton.Text = "Accepted";
            end

            app.TextArea.Value = currentComments.comments{1};
        end
        
        function updateImage(app, path)
            if path == "split"
                app.splitPanel.Visible="on";
                cla(app.UIAxes);
                seedbasedStack = {app.p1,app.p2,app.p3,app.p4};
                cla(app.UIAxes_2);cla(app.UIAxes_3);cla(app.UIAxes_4);cla(app.UIAxes_5);
                for i=1:4
                    picturePath = seedbasedStack{i};
                   
                    if ~any(isfile(picturePath), 'all')
                        %not a picture
                    else
                       
                        img = imread(picturePath);
                        if i==1
                            display = imshow(img, 'Parent', app.UIAxes_2,'InitialMagnification','fit');
                        elseif i==2
                            display = imshow(img, 'Parent', app.UIAxes_3,'InitialMagnification','fit');
                        elseif i==3
                            display = imshow(img, 'Parent', app.UIAxes_4,'InitialMagnification','fit');
                        else
                            display = imshow(img, 'Parent', app.UIAxes_5,'InitialMagnification','fit');
                        end
                        [h,w,~] = size(img);
                        display.XData = [-(w-1)/2,  (w-1)/2];
                        display.YData = [-(h-1)/2,  (h-1)/2];

                        if i==1
                            set(app.UIAxes_2, 'XLim', display.XData, 'YLim', display.YData);
                        elseif i==2
                            set(app.UIAxes_3, 'XLim', display.XData, 'YLim', display.YData);
                        elseif i == 3
                            set(app.UIAxes_4, 'XLim', display.XData, 'YLim', display.YData);
                        else
                            set(app.UIAxes_5, 'XLim', display.XData, 'YLim', display.YData);
                        end

                    end
                end
            elseif isfile(path)
                app.splitPanel.Visible="off";
                app.errorInformation.Visible = "off";

                img = imread(path);
                display = imshow(img, 'Parent', app.UIAxes,'InitialMagnification','fit');
    
                %center the image
                [h,w,~] = size(img);
                display.XData = [-(w-1)/2,  (w-1)/2];
                display.YData = [-(h-1)/2,  (h-1)/2];

                if ~isequal(app.storedXData, display.XData) || ~isequal(app.storedYData, display.YData)
                    set(app.UIAxes, 'XLim', display.XData, 'YLim', display.YData);
                end

                app.storedXData = display.XData;
                app.storedYData = display.YData;
            else
                %warning that file does not exist
                cla(app.UIAxes)
                app.errorInformation.Visible = "on";
            end
        end
        
        function writeSpreadsheet(app)
            spreadsheetPath = fullfile(app.fileDirectory, 'Subj_list.csv');
             app.tableData(:,{'manual_ex','error','motion_ex','comments'}) = app.acceptanceData(:,:);
             my_writetable(app.tableData, spreadsheetPath);
        end
        
        function disableOnStart(app)
            folders = {app.initialCheckFolder,app.headMotionFolder,app.segmentationFolder, ...
                       app.coregistrationFolder,app.csfmaskFolder,app.nuisanceRegressionFolder, ...
                       app.smoothingFolder,app.finalFuncFolder,app.seedbasedFolder};
        
            exists = cellfun(@(f) isfolder(fullfile(app.qcFolder,f)), folders);
        
            btns = [app.InitialCheckButton, app.HeadMotionButton, app.SegmentationButton, ...
                    app.CoregisterationButton, app.CSFmaskButton, app.NuisanceRegressionButton, ...
                    app.SmoothingButton, app.FinalFunccheckButton, app.SeedbasedCorrButton];
        
            set(btns(~exists), 'Enable','off');
            set(btns( exists), 'Enable','on');

        end
        
        function disableViews(app, checkPath)
            orthoCheck = dir(fullfile(checkPath, '**', '*axial*'));
            if isempty(orthoCheck)
                app.axialButton.Enable = "off";
            else
                app.axialButton.Enable = "on";
            end

            orthoCheck = dir(fullfile(checkPath, '**', '*orthoslice*'));
            if isempty(orthoCheck)
                app.OrthosliceButton.Enable = "off";
            else
                app.OrthosliceButton.Enable = "on";
            end

            coronalCheck = dir(fullfile(checkPath, '**', '*coronal*'));
            if isempty(coronalCheck)
                app.coronalButton.Enable = "off";
            else
                app.coronalButton.Enable = "on";
            end

            sagittalCheck = dir(fullfile(checkPath, '**', '*sagittal*'));
            if isempty(sagittalCheck)
                app.sagittalButton.Enable = "off";
            else
                app.sagittalButton.Enable = "on";
            end
        end


function applyTheme(app)
    T = app.Themes.(app.ThemeName);

    % Figure background
    if isprop(app.UIFigure,'Color'), app.UIFigure.Color = T.bg; end

    % Recurse through all components
    app.themeChildren(app.UIFigure.Children, T);
end

function themeChildren(app, kids, T)
    for k = 1:numel(kids)
        c = kids(k);

        % ===== Containers =====
        if isa(c,'matlab.ui.container.Panel') || ...
           isa(c,'matlab.ui.container.ButtonGroup') || ...
           isa(c,'matlab.ui.container.GridLayout')

            if isprop(c,'BackgroundColor'), c.BackgroundColor = T.bg2; end
            % Set title/foreground if present (Panels, ButtonGroups)
            if isprop(c,'FontColor'),      c.FontColor      = T.fg;  end
            if isprop(c,'ForegroundColor'),c.ForegroundColor= T.fg;  end
            app.themeChildren(c.Children, T);

        elseif isa(c,'matlab.ui.container.TabGroup')
            % TabGroup itself has no text color, but its Tabs do
            app.themeChildren(c.Children, T);

        elseif isa(c,'matlab.ui.container.Tab')
            % Tab title color
            if isprop(c,'FontColor'),       c.FontColor       = T.fg;  end
            if isprop(c,'ForegroundColor'), c.ForegroundColor = T.fg;  end
            if isprop(c,'BackgroundColor'), c.BackgroundColor = T.bg2; end
            app.themeChildren(c.Children, T);

        elseif isa(c,'matlab.ui.container.Tree')
            % Tree & nodes
            if isprop(c,'BackgroundColor'), c.BackgroundColor = T.bg3; end
            if isprop(c,'FontColor'),       c.FontColor       = T.fg;  end
            app.themeChildren(c.Children, T); % nodes

        % ===== Text-bearing controls =====
        elseif isa(c,'matlab.ui.control.Label') || ...
               isa(c,'matlab.ui.control.Button') || ...
               isa(c,'matlab.ui.control.StateButton') || ...
               isa(c,'matlab.ui.control.CheckBox') || ...
               isa(c,'matlab.ui.control.Switch') || ...
               isa(c,'matlab.ui.control.DropDown') || ...
               isa(c,'matlab.ui.control.ListBox') || ...
               isa(c,'matlab.ui.control.EditField') || ...
               isa(c,'matlab.ui.control.NumericEditField') || ...
               isa(c,'matlab.ui.control.TextArea') || ...
               isa(c,'matlab.ui.control.Spinner')
            if isprop(c,'BackgroundColor'), c.BackgroundColor = T.bg3; end
            if isprop(c,'FontColor'),       c.FontColor       = T.fg;  end
            if isprop(c,'ForegroundColor'), c.ForegroundColor = T.fg;  end

        % ===== Tables =====
        elseif isa(c,'matlab.ui.control.Table')
            c.BackgroundColor = [T.bg3; T.bg3];
            try
                s = uistyle('FontColor', T.fg, 'BackgroundColor', T.bg3);
                addStyle(c, s);
            catch
            end
            if isprop(c,'GridColor'), c.GridColor = T.grid; end

        % ===== Axes =====
        elseif isa(c,'matlab.ui.control.UIAxes')
            c.Color     = T.axesBg;
            c.XColor    = T.axesFg;
            c.YColor    = T.axesFg;
            c.ZColor    = T.axesFg;
            c.GridColor = T.grid;

            % Axes title & labels to match foreground
            if ~isempty(c.Title),           c.Title.Color    = T.axesFg; end
            if ~isempty(c.XLabel),          c.XLabel.Color   = T.axesFg; end
            if ~isempty(c.YLabel),          c.YLabel.Color   = T.axesFg; end
            if ~isempty(c.ZLabel),          c.ZLabel.Color   = T.axesFg; end

            % Legends & colorbars (if present)
            lgd = findobj(c,'Type','Legend');
            if ~isempty(lgd)
                set(lgd,'TextColor',T.axesFg,'Color',T.axesBg,'EdgeColor',T.grid);
            end
            cbs = findobj(c,'Type','ColorBar');
            if ~isempty(cbs)
                set(cbs,'Color',T.axesFg,'BackgroundColor',T.axesBg);
            end

        % ===== Anything else — recurse if it has children =====
        else
            if isprop(c,'Children') && ~isempty(c.Children)
                app.themeChildren(c.Children, T);
            end
        end
    end
end

function setTheme(app, name)
    if ~isfield(app.Themes, char(name)), return; end
    app.ThemeName = string(name);
    setpref("MyApp","ThemeName", app.ThemeName);
    app.applyTheme();
end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, output_folder)
            % app.CallingApp = caller;
            app.UIFigure.Name = 'QC Viewer';

            app.fileDirectory = output_folder;

            app.spreadsheetDirectory = fullfile(app.fileDirectory, 'Subj_list.csv');
            app.qcFolder = fullfile(app.fileDirectory, 'Quality_control');

            %Read spreadsheet and extract participant IDs 
            opts = detectImportOptions(app.spreadsheetDirectory,'Delimiter',',');
            opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
            T1 = readtable(app.spreadsheetDirectory,opts);

            T = readtable(app.spreadsheetDirectory,'Delimiter',',');

            T.name = T1.name;
            T.comments = T1.comments;
            app.tableData = T;
            app.participants = app.tableData(:,'name');
            app.participants = table2cell(app.participants);
            app.participants = app.participants(1:end);
            app.ParticipantDropDown.Items = string(app.participants);

            app.acceptanceData = app.tableData(:, {'manual_ex','error','motion_ex','comments'});
            updateAcceptance(app)

            app.disableOnStart()

            app.Themes.light.bg   = [1 1 1];
            app.Themes.light.fg   = [0 0 0];
            app.Themes.light.bg2  = [0.97 0.97 0.97];
            app.Themes.light.bg3  = [1 1 1];
            app.Themes.light.axesBg = [1 1 1];
            app.Themes.light.axesFg = [0 0 0];
            app.Themes.light.grid = [0.85 0.85 0.85];
            app.Themes.light.accent = [0.00 0.45 0.74];
    
            app.Themes.dark.bg      = [0.09 0.09 0.11];  % base window / panels
            app.Themes.dark.fg      = [0.92 0.94 0.97];  % primary text/icons
            app.Themes.dark.bg2     = [0.12 0.12 0.14];  % inputs/cards/secondary panels
            app.Themes.dark.bg3     = [0.14 0.15 0.17];  % raised elements/hover states
            app.Themes.dark.axesBg  = [0.11 0.12 0.14];  % plots (slightly lifted from bg)
            app.Themes.dark.axesFg  = [0.88 0.90 0.94];  % axis labels/ticks
            app.Themes.dark.grid    = [0.28 0.30 0.34];  % subdued grid; visible, not loud
            app.Themes.dark.accent  = [0.35 0.68 0.99];  % action/selection (azure)
            app.applyTheme();

            app.updateAcceptance();
        end

        % Button pushed function: NextparticipantButton
        function NextparticipantButtonPushed(app, event)
            currentParticipant = app.ParticipantDropDown.Value;
            allParticipants = app.ParticipantDropDown.Items;
            index=find(ismember(allParticipants, currentParticipant));
            nextIndex = index + 1;

            if nextIndex <= numel(allParticipants)
                app.ParticipantDropDown.Value = allParticipants{nextIndex};
            end
            focus(app.UIFigure)

            updateActiveParticipant(app);
        end

        % Button pushed function: PreviousparticipantButton
        function PreviousparticipantButtonPushed(app, event)
            currentParticipant = app.ParticipantDropDown.Value;
            allParticipants = app.ParticipantDropDown.Items;
            index=find(ismember(allParticipants, currentParticipant));
            nextIndex = index - 1;

            if nextIndex >= 1
                app.ParticipantDropDown.Value = allParticipants{nextIndex};
            end
            focus(app.UIFigure)

            updateActiveParticipant(app);
        end

        % Value changed function: ParticipantDropDown
        function ParticipantDropDownValueChanged(app, event)
            updateActiveParticipant(app);
        end

        % Button pushed function: HeadMotionButton
        function HeadMotionButtonPushed(app, event)
            focus(app.UIFigure)

            headMotionPath = fullfile(app.qcFolder, app.headMotionFolder);
            disableViews(app, headMotionPath);

            currentParticipant = app.ParticipantDropDown.Value;
            headMotionPath = dir(fullfile(headMotionPath, append('*',currentParticipant,'*.png')));
            headMotionPath = string(fullfile({headMotionPath.folder},{headMotionPath.name}));
            app.whifun_view_path = 'na';
            updateImage(app, headMotionPath)
            app.UIAxes.Title.String = "Head Motion";
            app.currentType = "Head Motion";
            UIinteraction(app, app.currentType);
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;

            segmentationPath = fullfile(app.qcFolder, app.segmentationFolder);
            disableViews(app, segmentationPath);

            if app.MNIorNATIVEsegmentation.Value == "MNI"
                segmentationPath = fullfile(segmentationPath,'MNI_Space');
                app.whifun_view_path = 'seg_MNI';
            elseif app.MNIorNATIVEsegmentation.Value == "Native"
                segmentationPath = fullfile(segmentationPath,'Native_Space');
                app.whifun_view_path = 'seg_native';
            end

            viewTypeFolder = dir(fullfile(segmentationPath, append(app.activeView,'*')));

            segmentationPath = fullfile(segmentationPath, string(viewTypeFolder.name));
            segmentationPath = dir(fullfile(segmentationPath, append('*',currentParticipant, '*.png')));

            segmentationPath = string(fullfile({segmentationPath.folder},{segmentationPath.name}));
            updateImage(app, segmentationPath)

            app.UIAxes.Title.String = "Segmentation";
            app.currentType = "Segmentation";
            UIinteraction(app, app.currentType);
        end

        % Value changed function: MNIorNATIVEsegmentation
        function MNIorNATIVEsegmentationValueChanged(app, event)
            app.SegmentationButtonPushed
        end

        % Button pushed function: CoregisterationButton
        function CoregisterationButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;

            coregistrationPath = fullfile(app.qcFolder, app.coregistrationFolder,'Native_Space');
            disableViews(app, coregistrationPath);


            viewTypeFolder = dir(fullfile(coregistrationPath, append(app.activeView,'*')));

            coregistrationPath = dir(fullfile(coregistrationPath,string(viewTypeFolder.name),append('*',currentParticipant,'*.png')));

            coregistrationPath = string(fullfile({coregistrationPath.folder},{coregistrationPath.name}));
            app.whifun_view_path = 'coreg';
            updateImage(app, coregistrationPath)
            %set a title
            app.UIAxes.Title.String = "Co-Registration";
            app.currentType = "Co-Registration";
            UIinteraction(app, app.currentType);
        end

        % Button pushed function: CSFmaskButton
        function CSFmaskButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;

            csfmaskPath = fullfile(app.qcFolder, app.csfmaskFolder,'Native_Space');

            viewTypeFolder = dir(fullfile(csfmaskPath, append(app.activeView,'*')));

            csfmaskPath = dir(fullfile(csfmaskPath,string(viewTypeFolder.name),append('*',currentParticipant,'*.png')));

            csfmaskPath = string(fullfile({csfmaskPath.folder},{csfmaskPath.name}));

            updateImage(app, csfmaskPath)
            app.whifun_view_path = 'csf_mask';
            %set a title
            app.UIAxes.Title.String = "CSF Mask";
            app.currentType = "CSF Mask";
            UIinteraction(app, app.currentType);
        end

        % Button pushed function: SmoothingButton
        function SmoothingButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;
            viewTypeFolder = append(app.activeView,'_View');

            smoothingPath = fullfile(app.qcFolder, app.smoothingFolder,'Native_Space');

            smoothingPath = dir(fullfile(smoothingPath, viewTypeFolder,append('*',currentParticipant,'*.png')));

            smoothingPath = string(fullfile({smoothingPath.folder},{smoothingPath.name}));

            updateImage(app, smoothingPath)
            app.whifun_view_path = app.tableData.smoothed_func;
            app.whifun_view_cmap = gray;
            %set a title
            app.UIAxes.Title.String = "Smoothing";
            app.currentType = "Smoothing";
            UIinteraction(app, app.currentType);
        end

        % Button pushed function: InitialCheckButton
        function InitialCheckButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;

            initialCheckPath = fullfile(app.qcFolder, app.initialCheckFolder, 'Native_Space');
            disableViews(app, initialCheckPath);
            viewTypeFolder = append(app.activeView,'_View');

            if app.ANATorFUNCinitialcheck.Value == "Func"
                initialCheckPath = fullfile(initialCheckPath,'func');
                app.whifun_view_path = app.tableData.nii_func_native;
                app.whifun_view_cmap = gray;
            elseif app.ANATorFUNCinitialcheck.Value == "Anat"
                initialCheckPath = fullfile(initialCheckPath,'anat');
                app.whifun_view_path = app.tableData.nii_anat_native;
                app.whifun_view_cmap = gray;
            end
    

            initialCheckPath = dir(fullfile(initialCheckPath,viewTypeFolder,append('*',currentParticipant,'*.png')));
            
            initialCheckPath = string(fullfile({initialCheckPath.folder},{initialCheckPath.name}));

            updateImage(app, initialCheckPath);
            app.UIAxes.Title.String = "Initial Check";
            app.currentType = "Initial Check";
            UIinteraction(app, app.currentType);
        end

        % Value changed function: ANATorFUNCinitialcheck
        function ANATorFUNCinitialcheckValueChanged(app, event)
            app.InitialCheckButtonPushed
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    app.PreviousparticipantButtonPushed
                case 'rightarrow'
                    app.NextparticipantButtonPushed
            end
        end

        % Button pushed function: SeedbasedCorrButton
        function SeedbasedCorrButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;
            seedbasedPath = fullfile(app.qcFolder, app.seedbasedFolder);
            finalFuncPath = fullfile(app.qcFolder, app.finalFuncFolder, 'MNI_Space');

            if app.wantSplit == 1
                disableViews(app, seedbasedPath);
                p1 = fullfile(seedbasedPath, dir(fullfile(seedbasedPath,'Default*')).name); %#ok<ADPROPLC>
                viewTypeFolder = dir(fullfile(p1, append(app.activeView,'*'))); %#ok<ADPROPLC>
                p1 =  dir(fullfile(p1, string(viewTypeFolder.name), append('*',currentParticipant,'*.png'))); %#ok<ADPROPLC>

                p2 = fullfile(seedbasedPath, dir(fullfile(seedbasedPath,'Visual*')).name); %#ok<ADPROPLC>
                p2 =  dir(fullfile(p2, string(viewTypeFolder.name), append('*',currentParticipant,'*.png'))); %#ok<ADPROPLC>

                p3 = fullfile(seedbasedPath, dir(fullfile(seedbasedPath,'Auditory*')).name); %#ok<ADPROPLC>
                p3 =  dir(fullfile(p3, string(viewTypeFolder.name), append('*',currentParticipant,'*.png'))); %#ok<ADPROPLC>
                
                finalFuncPath = fullfile(finalFuncPath,'Vox_ts');
                p4 = dir(fullfile(finalFuncPath,append('*',currentParticipant,'*.png'))); %#ok<ADPROPLC>

                app.p1 = string(fullfile({p1.folder},{p1.name})); %#ok<ADPROPLC>
                app.p2 = string(fullfile({p2.folder},{p2.name})); %#ok<ADPROPLC>
                app.p3 = string(fullfile({p3.folder},{p3.name})); %#ok<ADPROPLC>
                app.p4 = string(fullfile({p4.folder},{p4.name})); %#ok<ADPROPLC>

                updateImage(app, "split");
                app.UIAxes.Title.String = "Seed-Based Corr";
                app.currentType = "Seed-Based Corr";
                UIinteraction(app, app.currentType);
                app.whifun_view_path = 'na';
            else

                disableViews(app, seedbasedPath);

                if app.DefaultModeButtonSeedbased.Value == 1
                    seedbasedPath = fullfile(seedbasedPath, dir(fullfile(seedbasedPath,'Default*')).name);
                    viewTypeFolder = dir(fullfile(seedbasedPath, append(app.activeView,'*')));
                    seedbasedPath =  dir(fullfile(seedbasedPath, string(viewTypeFolder.name), append('*',currentParticipant,'*.png')));
                    app.whifun_view_path = 'sd_def';
                elseif app.VisualSeedButtonSeedbased.Value == 1
                    seedbasedPath = fullfile(seedbasedPath, dir(fullfile(seedbasedPath,'Visual*')).name);
                    viewTypeFolder = dir(fullfile(seedbasedPath, append(app.activeView,'*')));
                    seedbasedPath =  dir(fullfile(seedbasedPath, string(viewTypeFolder.name), append('*',currentParticipant,'*.png')));
                    app.whifun_view_path = 'sd_viz';
                elseif app.AuditorySeedButtonSeedbased.Value == 1
                    seedbasedPath = fullfile(seedbasedPath, dir(fullfile(seedbasedPath,'Auditory*')).name);
                    viewTypeFolder = dir(fullfile(seedbasedPath, append(app.activeView,'*')));
                    seedbasedPath =  dir(fullfile(seedbasedPath, string(viewTypeFolder.name), append('*',currentParticipant,'*.png')));
                    app.whifun_view_path = 'sd_aud';
                end

                seedbasedPath = string(fullfile({seedbasedPath.folder},{seedbasedPath.name}));

                updateImage(app,seedbasedPath);
                app.UIAxes.Title.String = "Seed-Based Corr";
                app.currentType = "Seed-Based Corr";
                UIinteraction(app, app.currentType);
            end
        end

        % Button pushed function: NuisanceRegressionButton
        function NuisanceRegressionButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;

            regressionPath = fullfile(app.qcFolder, app.nuisanceRegressionFolder, 'Native_Space');
            disableViews(app, regressionPath);


            if app.globalButtonregression.Value == 1
                regressionPath = dir(fullfile(regressionPath,'Global_ts',append('*',currentParticipant,'*.png')));
            elseif app.voxButtonregression.Value == 1
                regressionPath = fullfile(regressionPath,'Vox_ts');
                if app.regressionDropdown.Value == "Before"
                    regressionPath = dir(fullfile(regressionPath,'Before_Nuisance_regression_vox_ts',append('*',currentParticipant,'*.png')));
                elseif  app.regressionDropdown.Value == "After"
                    regressionPath = dir(fullfile(regressionPath,'After_Nuisance_regression_vox_ts',append('*',currentParticipant,'*.png')));
                elseif  app.regressionDropdown.Value == "Masks"
                    regressionPath = fullfile(regressionPath, 'Masks_for_Vox_ts');
                    viewTypeFolder = dir(fullfile(regressionPath, append(app.activeView,'*')));
                    regressionPath = dir(fullfile(regressionPath,string(viewTypeFolder.name),append('*',currentParticipant,'*.png')));
                end
            end
            app.whifun_view_path = app.tableData.nuisance_regressed_func_native;
            app.whifun_view_cmap = gray;
            regressionPath = string(fullfile({regressionPath.folder},{regressionPath.name}));
            updateImage(app, regressionPath);
            app.UIAxes.Title.String = "Nuisance Regression";
            app.currentType = "Nuisance Regression";
            UIinteraction(app, app.currentType);
        end

        % Value changed function: regressionDropdown
        function regressionDropdownValueChanged(app, event)
            focus(app.UIFigure)
            app.NuisanceRegressionButtonPushed;
        end

        % Button pushed function: FinalFunccheckButton
        function FinalFunccheckButtonPushed(app, event)
            focus(app.UIFigure)
            currentParticipant = app.ParticipantDropDown.Value;

            finalFuncPath = fullfile(app.qcFolder, app.finalFuncFolder, 'MNI_Space');
            disableViews(app, finalFuncPath);
            viewTypeFolder = dir(fullfile(finalFuncPath, append(app.activeView,'*')));


            if app.mnispaceButtonfinalFunc.Value == 1
                finalFuncPath = dir(fullfile(finalFuncPath,string(viewTypeFolder.name),append('*',currentParticipant,'*.png')));
                app.whifun_view_path = 'func_MNI';
            elseif app.voxButtonfinalFunc.Value == 1
                finalFuncPath = fullfile(finalFuncPath,'Vox_ts');
                finalFuncPath = dir(fullfile(finalFuncPath,append('*',currentParticipant,'*.png')));
                app.whifun_view_path = 'func_MNI';
            elseif app.masksButtonfinalFunc.Value == 1
                finalFuncPath = fullfile(finalFuncPath, 'Masks_for_Vox_ts');
                finalFuncPath = dir(fullfile(finalFuncPath,string(viewTypeFolder.name),append('*',currentParticipant,'*.png')));
                app.whifun_view_path = 'func_MNI';
            end
            finalFuncPath = string(fullfile({finalFuncPath.folder},{finalFuncPath.name}));

            updateImage(app, finalFuncPath);
            app.UIAxes.Title.String = "Final Func";
            app.currentType = "Final Func";
            UIinteraction(app, app.currentType);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {696, 696};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {353, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            focus(app.UIFigure)
            %1=error, 2=manualex, 3=comments, 4=motion
            app.currentAcceptance.manual_ex = 0;
            app.currentAcceptance.motion_ex = 0;
            app.currentAcceptance.error = 0;
            app.currentAcceptance.comments = {app.TextArea.Value};
            app.acceptanceData(app.acceptanceIndex, :) = app.currentAcceptance(:,:);
            writeSpreadsheet(app)
            updateAcceptance(app)

        end

        % Value changed function: OrthosliceButton, axialButton, 
        % ...and 2 other components
        function OrientationButtonValueChanged(app, event)
            focus(app.UIFigure)
            src  = event.Source;
            btns = [app.axialButton, app.OrthosliceButton, app.coronalButton, app.sagittalButton];
            if ~src.Value
                src.Value = true;
            else
                for b = btns
                    if b ~= src
                        b.Value = false;
                    end
                end
            end
            app.activeView = src.Text;
            updateActiveParticipant(app)
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            focus(app.UIFigure)

            %1=error, 2=manualex, 3=comments, 4=motion
            if app.exclusionDropDown.Value == "Manual Exclusion"
                app.currentAcceptance.manual_ex = 1;
                app.currentAcceptance.motion_ex = 0;
                app.currentAcceptance.error = 0;
            elseif app.exclusionDropDown.Value == "Motion Exclusion"
                app.currentAcceptance.manual_ex = 0;
                app.currentAcceptance.motion_ex = 1;
                app.currentAcceptance.error = 0;
            elseif app.exclusionDropDown.Value == "Error"
                app.currentAcceptance.manual_ex = 0;
                app.currentAcceptance.motion_ex = 0;
                app.currentAcceptance.error = 1;
            end
            app.currentAcceptance.comments = {app.TextArea.Value};
            app.acceptanceData(app.acceptanceIndex, :) = app.currentAcceptance(:,:);
            writeSpreadsheet(app)
            updateAcceptance(app)

        end

        % Value changed function: globalButtonregression, 
        % ...and 1 other component
        function nuisanceRegressionViewOptions(app, event)
            focus(app.UIFigure)
            src  = event.Source;
            btns = [app.globalButtonregression, app.voxButtonregression];
            if ~src.Value
                src.Value = 1;
            else
                for b = btns
                    if b ~= src
                        b.Value = 0;
                    end
                end
            end

            if app.voxButtonregression.Value == 1
                app.regressionDropdown.Visible = "on";
            else
                app.regressionDropdown.Visible = "off";
            end

            app.NuisanceRegressionButtonPushed;
        end

        % Value changed function: masksButtonfinalFunc, 
        % ...and 2 other components
        function finalFuncViewOptions(app, event)
            focus(app.UIFigure)
            src  = event.Source;
            btns = [app.mnispaceButtonfinalFunc, app.voxButtonfinalFunc, app.masksButtonfinalFunc];
            if ~src.Value
                src.Value = 1;
            else
                for b = btns
                    if b ~= src
                        b.Value = 0;
                    end
                end
            end
            app.FinalFunccheckButtonPushed;
        end

        % Value changed function: AuditorySeedButtonSeedbased, 
        % ...and 3 other components
        function seedBasedViewOptions(app, event)
            focus(app.UIFigure)
            src  = event.Source;
            btns = [app.SPLITButton, app.VisualSeedButtonSeedbased, app.DefaultModeButtonSeedbased, app.AuditorySeedButtonSeedbased];
            if ~src.Value
                src.Value = 1;
            else
                for b = btns
                    if b ~= src
                        b.Value = 0;
                    end
                end
            end

            if app.SPLITButton.Value == 1
                app.wantSplit = 1;
            else
                app.wantSplit = 0;
            end
            app.SeedbasedCorrButtonPushed;
        end

        % Menu selected function: DarkMenu
        function DarkMenuSelected(app, event)
            focus(app.UIFigure)
            app.setTheme("dark");
        end

        % Menu selected function: LightMenu
        function LightMenuSelected(app, event)
            focus(app.UIFigure)
            app.setTheme("light");
        end

        % Button pushed function: TimesereriesplotsButton
        function TimesereriesplotsButtonPushed(app, event)
            focus(app.UIFigure)
            folderPath = fullfile(app.qcFolder, app.timeseries);

            disableViews(app, folderPath);
            currentParticipant = app.ParticipantDropDown.Value;

            timeseriesPath = fullfile(folderPath, [currentParticipant '.png']);
            app.whifun_view_path = 'na';
            updateImage(app, timeseriesPath)
            app.UIAxes.Title.String = "Time Series Plots";
            app.currentType = "Timeseries Plots";
            UIinteraction(app, app.currentType);
            
        end

        % Menu selected function: manualMenu
        function manualMenuSelected(app, event)
            url = 'https://whifun.readthedocs.io/en/latest/quality_control.html';
            web(url)
        end

        % Button pushed function: whifun_viewButton
        function whifun_viewButtonPushed(app, event)
            if ~isempty(app.whifun_view_path)

                if strcmp(app.whifun_view_path,'na')
                    msgbox('Whifun View not available for this mode')
                elseif strcmp(app.whifun_view_path,'seg_MNI')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    [gm,info]  = whifun_niftiread(char(subj_list_1.GM_MNI));
                    wm  = whifun_niftiread(char(subj_list_1.WM_MNI));
                    csf = whifun_niftiread(char(subj_list_1.CSF_MNI));
                    
                    whifun_view(1.*(gm>0.5) + 2.*(wm>0.5) + 3.*(csf>0.5),info.Transform.T);
                    colormap([ 0 0 0; 1 0 0; 0 1 0; 0 0 1])
                elseif strcmp(app.whifun_view_path,'seg_native')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    [gm,info]  = whifun_niftiread(char(subj_list_1.GM_native));
                    wm  = whifun_niftiread(char(subj_list_1.WM_native));
                    csf = whifun_niftiread(char(subj_list_1.CSF_native));

                    whifun_view(1.*(gm>0.5) + 2.*(wm>0.5) + 3.*(csf>0.5),info.Transform.T);
                    colormap([ 0 0 0; 1 0 0; 0 1 0; 0 0 1])
                elseif strcmp(app.whifun_view_path,'coreg')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    fg = spm_figure('GetWin','Graphics');
                    temp = spm('WinSize','Graphics');
                    set(fg,'Position',[temp(1),temp(2),temp(4),temp(3)])
                    set(fg,'PaperPosition',[temp(1),temp(2),temp(4),temp(3)])

                    spm_check_registration(char(subj_list_1.skull_stripped_anat_native),[char(subj_list_1.coregistered_func_native) ',1'])
                    disp(['Func File: ' char(subj_list_1.coregistered_func_native)])
                    disp(['Anat File: ' char(subj_list_1.skull_stripped_anat_native)])
                elseif strcmp(app.whifun_view_path,'csf_mask')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    fg = spm_figure('GetWin','Graphics');
                    temp = spm('WinSize','Graphics');
                    set(fg,'Position',[temp(1),temp(2),temp(4),temp(3)])
                    set(fg,'PaperPosition',[temp(1),temp(2),temp(4),temp(3)])

                    spm_check_registration(char(subj_list_1.CSF_mask_func),[char(subj_list_1.coregistered_func_native) ',1'])
                    disp(['Func File: ' char(subj_list_1.coregistered_func_native)])
                    disp(['CSF Mask File: ' char(subj_list_1.CSF_mask_func)])

                elseif strcmp(app.whifun_view_path,'func_MNI')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    fg = spm_figure('GetWin','Graphics');
                    temp = spm('WinSize','Graphics');
                    set(fg,'Position',[temp(1),temp(2),temp(4),temp(3)])
                    set(fg,'PaperPosition',[temp(1),temp(2),temp(4),temp(3)])

                    spm_check_registration([char(subj_list_1.final_func_MNI) ',1'],char(subj_list_1.MNI_template))
                    disp(['Func File: ' char(subj_list_1.final_func_MNI)])
                    disp(['MNI File: ' char(subj_list_1.MNI_template)])
                elseif strcmp(app.whifun_view_path,'sd_def')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    [final_func_folder,final_func_image,ext] = fileparts(char(subj_list_1.final_func_MNI));
                    disp(['Func File: ' char(subj_list_1.final_func_MNI)])
                    disp(['Default Seed Corr File: ' fullfile(final_func_folder,['seed_corr_map_Seed_lh_pcc_5_-49_40_' final_func_image ext])])
                    whifun_view(char(subj_list_1.final_func_MNI),fullfile(final_func_folder,['seed_corr_map_Seed_lh_pcc_5_-49_40_' final_func_image ext]))
                elseif strcmp(app.whifun_view_path,'sd_viz')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    [final_func_folder,final_func_image,ext] = fileparts(char(subj_list_1.final_func_MNI));
                    disp(['Func File: ' char(subj_list_1.final_func_MNI)])
                    disp(['Default Seed Corr File: ' fullfile(final_func_folder,['seed_corr_map_Seed_rh_cort_vis_-4_-91_-3' final_func_image ext])])
                    whifun_view(char(subj_list_1.final_func_MNI),fullfile(final_func_folder,['seed_corr_map_Seed_rh_cort_vis_-4_-91_-3' final_func_image ext]))
                elseif strcmp(app.whifun_view_path,'sd_aud')
                    subj_list_1 = app.tableData(logical(string(app.tableData.name) == app.ParticipantDropDown.Value),:);
                    [final_func_folder,final_func_image,ext] = fileparts(char(subj_list_1.final_func_MNI));
                    disp(['Func File: ' char(subj_list_1.final_func_MNI)])
                    disp(['Default Seed Corr File: ' fullfile(final_func_folder,['seed_corr_map_Seed_lh_cort_aud_64_-12_2' final_func_image ext])])
                    whifun_view(char(subj_list_1.final_func_MNI),fullfile(final_func_folder,['seed_corr_map_Seed_lh_cort_aud_64_-12_2' final_func_image ext]))

                else
                    final_path = char(app.whifun_view_path(logical(string(app.tableData.name) == app.ParticipantDropDown.Value)));
                    disp(char(['Viewing Image at path: ' final_path]))
                    whifun_view(final_path)
                    colormap(app.whifun_view_cmap)
                end
            else
                msgbox('Select a QC view mode')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.ThemeMenu = uimenu(app.UIFigure);
            app.ThemeMenu.Text = 'Theme';
            app.DarkMenu = uimenu(app.ThemeMenu);
            app.DarkMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkMenuSelected, true);
            app.DarkMenu.Text = 'Dark';
            app.LightMenu = uimenu(app.ThemeMenu);
            app.LightMenu.MenuSelectedFcn = createCallbackFcn(app, @LightMenuSelected, true);
            app.LightMenu.Text = 'Light';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.manualMenu = uimenu(app.HelpMenu);
            app.manualMenu.MenuSelectedFcn = createCallbackFcn(app, @manualMenuSelected, true);
            app.manualMenu.Text = 'manual';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.ViewModesPanel = uipanel(app.GridLayout3);
            app.ViewModesPanel.Title = 'View Modes';
            app.GridLayout4 = uigridlayout(app.ViewModesPanel);
            app.InitialCheckButton = uibutton(app.GridLayout4, 'push');
            app.InitialCheckButton.ButtonPushedFcn = createCallbackFcn(app, @InitialCheckButtonPushed, true);
            app.InitialCheckButton.Text = 'Initial Check';
            app.HeadMotionButton = uibutton(app.GridLayout4, 'push');
            app.HeadMotionButton.ButtonPushedFcn = createCallbackFcn(app, @HeadMotionButtonPushed, true);
            app.HeadMotionButton.Text = 'Head Motion';
            app.SegmentationButton = uibutton(app.GridLayout4, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.CoregisterationButton = uibutton(app.GridLayout4, 'push');
            app.CoregisterationButton.ButtonPushedFcn = createCallbackFcn(app, @CoregisterationButtonPushed, true);
            app.CoregisterationButton.Text = 'Co-registeration';
            app.CSFmaskButton = uibutton(app.GridLayout4, 'push');
            app.CSFmaskButton.ButtonPushedFcn = createCallbackFcn(app, @CSFmaskButtonPushed, true);
            app.CSFmaskButton.Text = 'CSF mask';
            app.NuisanceRegressionButton = uibutton(app.GridLayout4, 'push');
            app.NuisanceRegressionButton.ButtonPushedFcn = createCallbackFcn(app, @NuisanceRegressionButtonPushed, true);
            app.NuisanceRegressionButton.Text = 'Nuisance Regression';
            app.SmoothingButton = uibutton(app.GridLayout4, 'push');
            app.SmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothingButtonPushed, true);
            app.SmoothingButton.Text = 'Smoothing';
            app.FinalFunccheckButton = uibutton(app.GridLayout4, 'push');
            app.FinalFunccheckButton.ButtonPushedFcn = createCallbackFcn(app, @FinalFunccheckButtonPushed, true);
            app.FinalFunccheckButton.Text = 'Final Func check';
            app.SeedbasedCorrButton = uibutton(app.GridLayout4, 'push');
            app.SeedbasedCorrButton.ButtonPushedFcn = createCallbackFcn(app, @SeedbasedCorrButtonPushed, true);
            app.SeedbasedCorrButton.Text = 'Seed based Corr';
            app.TimesereriesplotsButton = uibutton(app.GridLayout4, 'push');
            app.TimesereriesplotsButton.ButtonPushedFcn = createCallbackFcn(app, @TimesereriesplotsButtonPushed, true);
            app.TimesereriesplotsButton.Text = 'Timesereries plots';
            app.ViewSettingsPanel = uipanel(app.GridLayout3);
            app.ViewSettingsPanel.Title = 'View Settings';
            app.GridLayout4_2 = uigridlayout(app.ViewSettingsPanel);
            app.AuditorySeedButtonSeedbased = uibutton(app.GridLayout4_2, 'state');
            app.AuditorySeedButtonSeedbased.ValueChangedFcn = createCallbackFcn(app, @seedBasedViewOptions, true);
            app.AuditorySeedButtonSeedbased.Visible = 'off';
            app.AuditorySeedButtonSeedbased.Text = 'Auditory';
            app.AuditorySeedButtonSeedbased.Value = true;
            app.DefaultModeButtonSeedbased = uibutton(app.GridLayout4_2, 'state');
            app.DefaultModeButtonSeedbased.ValueChangedFcn = createCallbackFcn(app, @seedBasedViewOptions, true);
            app.DefaultModeButtonSeedbased.Visible = 'off';
            app.DefaultModeButtonSeedbased.Text = 'Default';
            app.VisualSeedButtonSeedbased = uibutton(app.GridLayout4_2, 'state');
            app.VisualSeedButtonSeedbased.ValueChangedFcn = createCallbackFcn(app, @seedBasedViewOptions, true);
            app.VisualSeedButtonSeedbased.Visible = 'off';
            app.VisualSeedButtonSeedbased.Text = 'Visual';
            app.SPLITButton = uibutton(app.GridLayout4_2, 'state');
            app.SPLITButton.ValueChangedFcn = createCallbackFcn(app, @seedBasedViewOptions, true);
            app.SPLITButton.Visible = 'off';
            app.SPLITButton.Text = {'S'; 'P'; 'L'; 'I'; 'T'};
            app.globalButtonregression = uibutton(app.GridLayout4_2, 'state');
            app.globalButtonregression.ValueChangedFcn = createCallbackFcn(app, @nuisanceRegressionViewOptions, true);
            app.globalButtonregression.Visible = 'off';
            app.globalButtonregression.Text = 'Global';
            app.globalButtonregression.Value = true;
            app.voxButtonregression = uibutton(app.GridLayout4_2, 'state');
            app.voxButtonregression.ValueChangedFcn = createCallbackFcn(app, @nuisanceRegressionViewOptions, true);
            app.voxButtonregression.Visible = 'off';
            app.voxButtonregression.Text = 'Vox';
            app.regressionDropdown = uidropdown(app.GridLayout4_2);
            app.regressionDropdown.Items = {'Before', 'After', 'Masks'};
            app.regressionDropdown.ValueChangedFcn = createCallbackFcn(app, @regressionDropdownValueChanged, true);
            app.regressionDropdown.Visible = 'off';
            app.regressionDropdown.Value = 'Before';
            app.mnispaceButtonfinalFunc = uibutton(app.GridLayout4_2, 'state');
            app.mnispaceButtonfinalFunc.ValueChangedFcn = createCallbackFcn(app, @finalFuncViewOptions, true);
            app.mnispaceButtonfinalFunc.Visible = 'off';
            app.mnispaceButtonfinalFunc.Text = 'MNI Space';
            app.mnispaceButtonfinalFunc.Value = true;
            app.masksButtonfinalFunc = uibutton(app.GridLayout4_2, 'state');
            app.masksButtonfinalFunc.ValueChangedFcn = createCallbackFcn(app, @finalFuncViewOptions, true);
            app.masksButtonfinalFunc.Visible = 'off';
            app.masksButtonfinalFunc.Text = 'Masks for Vox_ts';
            app.voxButtonfinalFunc = uibutton(app.GridLayout4_2, 'state');
            app.voxButtonfinalFunc.ValueChangedFcn = createCallbackFcn(app, @finalFuncViewOptions, true);
            app.voxButtonfinalFunc.Visible = 'off';
            app.voxButtonfinalFunc.Text = 'Vox_ts';
            app.ANATorFUNCinitialcheck = uiswitch(app.GridLayout4_2, 'slider');
            app.ANATorFUNCinitialcheck.Items = {'Anat', 'Func'};
            app.ANATorFUNCinitialcheck.ValueChangedFcn = createCallbackFcn(app, @ANATorFUNCinitialcheckValueChanged, true);
            app.ANATorFUNCinitialcheck.Visible = 'off';
            app.ANATorFUNCinitialcheck.Value = 'Anat';
            app.MNIorNATIVEsegmentation = uiswitch(app.GridLayout4_2, 'slider');
            app.MNIorNATIVEsegmentation.Items = {'MNI', 'Native'};
            app.MNIorNATIVEsegmentation.ValueChangedFcn = createCallbackFcn(app, @MNIorNATIVEsegmentationValueChanged, true);
            app.MNIorNATIVEsegmentation.Visible = 'off';
            app.MNIorNATIVEsegmentation.Value = 'MNI';
            app.ParticipantoutofPanel = uipanel(app.GridLayout3);
            app.ParticipantoutofPanel.Title = 'Participant : out of ';
            app.GridLayout6 = uigridlayout(app.ParticipantoutofPanel);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.ParticipantDropDown = uidropdown(app.GridLayout6);
            app.ParticipantDropDown.Items = {''};
            app.ParticipantDropDown.Editable = 'on';
            app.ParticipantDropDown.ValueChangedFcn = createCallbackFcn(app, @ParticipantDropDownValueChanged, true);
            app.ParticipantDropDown.Value = '';
            app.AcceptanceInformationPanel = uipanel(app.GridLayout3);
            app.AcceptanceInformationPanel.Title = 'Acceptance Information';
            app.GridLayout5 = uigridlayout(app.AcceptanceInformationPanel);
            app.TextAreaLabel = uilabel(app.GridLayout5);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Placeholder = 'Comments';
            app.AcceptButton = uibutton(app.GridLayout5, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.GridLayout5, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.DropDownLabel = uilabel(app.GridLayout5);
            app.DropDownLabel.Text = 'Drop Down';
            app.exclusionDropDown = uidropdown(app.GridLayout5);
            app.exclusionDropDown.Items = {'Manual Exclusion', 'Motion Exclusion', 'Error'};
            app.exclusionDropDown.Value = 'Manual Exclusion';
            app.NextparticipantButton = uibutton(app.GridLayout3, 'push');
            app.NextparticipantButton.ButtonPushedFcn = createCallbackFcn(app, @NextparticipantButtonPushed, true);
            app.NextparticipantButton.Text = 'Next participant';
            app.PreviousparticipantButton = uibutton(app.GridLayout3, 'push');
            app.PreviousparticipantButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousparticipantButtonPushed, true);
            app.PreviousparticipantButton.Text = 'Previous participant';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Waiting for Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.errorInformation = uitextarea(app.GridLayout7);
            app.errorInformation.Editable = 'off';
            app.errorInformation.Visible = 'off';
            app.errorInformation.Placeholder = 'Error Occured';
            app.errorInformation.Value = {'Image is unavailable'; 'File not found'};
            app.Panel = uipanel(app.GridLayout7);
            app.GridLayout9 = uigridlayout(app.Panel);
            app.axialButton = uibutton(app.GridLayout9, 'state');
            app.axialButton.ValueChangedFcn = createCallbackFcn(app, @OrientationButtonValueChanged, true);
            app.axialButton.Text = 'axial';
            app.axialButton.Value = true;
            app.OrthosliceButton = uibutton(app.GridLayout9, 'state');
            app.OrthosliceButton.ValueChangedFcn = createCallbackFcn(app, @OrientationButtonValueChanged, true);
            app.OrthosliceButton.Text = 'Orthoslice';
            app.coronalButton = uibutton(app.GridLayout9, 'state');
            app.coronalButton.ValueChangedFcn = createCallbackFcn(app, @OrientationButtonValueChanged, true);
            app.coronalButton.Text = 'coronal';
            app.sagittalButton = uibutton(app.GridLayout9, 'state');
            app.sagittalButton.ValueChangedFcn = createCallbackFcn(app, @OrientationButtonValueChanged, true);
            app.sagittalButton.Text = 'sagittal';
            app.splitPanel = uipanel(app.GridLayout7);
            app.splitPanel.Visible = 'off';
            app.GridLayout10 = uigridlayout(app.splitPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout10);
            title(app.UIAxes_2, 'Default')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.GridLayout10);
            title(app.UIAxes_3, 'Visual')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.GridLayout10);
            title(app.UIAxes_4, 'Auditory')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.GridLayout10);
            title(app.UIAxes_5, 'vox ts')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.whifun_viewButton = uibutton(app.GridLayout7, 'push');
            app.whifun_viewButton.ButtonPushedFcn = createCallbackFcn(app, @whifun_viewButtonPushed, true);
            app.whifun_viewButton.Text = 'whifun_view';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whifun_qcviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end