classdef whifun_statistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticsUIFigure             matlab.ui.Figure
        fitlineCheckBox                matlab.ui.control.CheckBox
        SaveplotsofallsignificantpairsButton  matlab.ui.control.Button
        ParticipantslistDropDown       matlab.ui.control.DropDown
        ParticipantslistDropDownLabel  matlab.ui.control.Label
        NoofParticipantsLabel          matlab.ui.control.Label
        SaveplotasimageButton          matlab.ui.control.Button
        VariablesDropDown              matlab.ui.control.DropDown
        VariablesDropDownLabel         matlab.ui.control.Label
        HasHeaderCheckBox              matlab.ui.control.CheckBox
        AlphaEditFieldLabel_2          matlab.ui.control.Label
        plotDropDown                   matlab.ui.control.DropDown
        plotDropDownLabel              matlab.ui.control.Label
        RegionsEditField               matlab.ui.control.EditField
        RegionsEditFieldLabel          matlab.ui.control.Label
        Net2XaxisEditField             matlab.ui.control.NumericEditField
        Net2XaxisEditFieldLabel        matlab.ui.control.Label
        Net1YaxisEditField             matlab.ui.control.NumericEditField
        Net1YaxisEditFieldLabel        matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        Group2label                    matlab.ui.control.Label
        UploadCSVwithbehaviourmetadataforParticipantsButton  matlab.ui.control.Button
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        CorrectionDropDown             matlab.ui.control.DropDown
        CorrectionDropDownLabel        matlab.ui.control.Label
        DataTipsCheckBox               matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        AllfrequenciesTab              matlab.ui.container.Tab
        WMGMFCButton_2                 matlab.ui.control.Button
        CCfileSelectDropDown           matlab.ui.control.DropDown
        CCfileSelectDropDownLabel      matlab.ui.control.Label
        AtlasfileSelectDropDown        matlab.ui.control.DropDown
        AtlasfileSelectLabel           matlab.ui.control.Label
        AtlasFCButton_2                matlab.ui.control.Button
        GMfileSelectDropDown           matlab.ui.control.DropDown
        GMfileSelectLabel              matlab.ui.control.Label
        WMCCFCButton_2                 matlab.ui.control.Button
        WMfileSelectDropDown           matlab.ui.control.DropDown
        WMfileSelectLabel              matlab.ui.control.Label
        WMFCButton_2                   matlab.ui.control.Button
        FrequencyBandsTab              matlab.ui.container.Tab
        WMGMFCButton                   matlab.ui.control.Button
        Band_selectDropDown            matlab.ui.control.DropDown
        FrequencyBandSelectLabel       matlab.ui.control.Label
        CCfileSelectDropDown_2         matlab.ui.control.DropDown
        CCfileSelectDropDownLabel_2    matlab.ui.control.Label
        AtlasfileSelectDropDown_2      matlab.ui.control.DropDown
        AtlasfileSelectLabel_2         matlab.ui.control.Label
        AtlasFCButton                  matlab.ui.control.Button
        GMfileSelectDropDown_2         matlab.ui.control.DropDown
        GMfileSelectLabel_2            matlab.ui.control.Label
        WMCCFCButton                   matlab.ui.control.Button
        WMfileSelectDropDown_2         matlab.ui.control.DropDown
        WMfileSelectLabel_2            matlab.ui.control.Label
        WMFCButton                     matlab.ui.control.Button
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main App
        final_result
        final_result_cc
        Subj_list
        slide_select = 0
        final_result_gm
        output_folder
        final_result_atlas
        atlas_file
        data_path
        Subj_list1
        Subj_list2
        all_sub_names
        current_fig = [];
        bands_info
        folders_info
        atlas_folders
        Tabposition % Description
        over_write
        preproc_code_path
        atlas_name
        initial_UIAxes_pos
        initial_UIAxes2_pos
        initial_UIAxes2_2_pos
        spm_path
        atlas_regions
        vec1
        X
        sub_names_csv
        CSV_head % Description
        stats % Description

        hp
        lp % Description
        WM_net_no
        GM_net_no
        pos1
        pos2
        
    end
   
    
    methods (Access = private)
        
        function sub_string = substruc2string(~,Subj_list)
            if ~isempty(Subj_list)
                sub_string = struct2cell(Subj_list);
                sub_string(2:end,:) = [];
                sub_string = string(sub_string);
            else
                sub_string= "";
            end
        end
        
        function load_sub_dropdown1(app,Subj_names)
            app.ParticipantslistDropDown.Items = Subj_names;
            app.Group1label.Text = [app.Group1NameEditField.Value ' participants no.: ' num2str(length(Subj_names))];

        end
%         function load_sub_dropdown2(app,Subj_names)
%             app.SubjectlistDropDown_2.Items = Subj_names;
%             app.Group2label.Text = [app.Group2NameEditField.Value ' participants no.: ' num2str(length(Subj_names))];
%         end
        
        function WM_ttest(app,final_result,Subj_list)
            app.RegionsEditField.Visible = "off";
            app.RegionsEditFieldLabel.Visible = 'off';
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the participant Ids in CSV file (Column 1 of CSV file) has different participant names from the Participant folder. Please use the same names from Participant folder and try again.' )
                return
            end

            d = uiprogressdlg(app.StatisticsUIFigure,'Title','Please wait','Message','Computing WM-FC associations',...
                'Indeterminate','on');
            
            app.ParticipantslistDropDown.Items = Sub_names;
            app.NoofParticipantsLabel.Text = ['Number of Participants : ' num2str(length(Sub_names))];

            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')
            axis(app.UIAxes,'on')
            cla(app.UIAxes)
 
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            app.slide_select = 2;
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            app.slide_select = 1;
            app.WM_net_no = size(final_result{1},2); % number of WM networks (taking from the first subject as it is same for every subject)
            map = zeros(app.WM_net_no,app.WM_net_no,length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(final_result{i}));
                p = p+1;

            end
            
            
             val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = corrvec(map);
%             if nnz(isnan(app.vec1))
%                 msgbox('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.','NaN values in FC')
%                 return
%             end
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                if nnz(isnan(app.vec1(i,:)))
                    disp('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.')
                    t(i) = nan;
                    p(i) = nan;
                else
                    stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
                    app.stats{i} = stat;
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
                end
%                 stat = regstats(app.vec1(i,:),app.X(iia,:));
%                 app.stats{i} = stat;
% 
% %                 if val_idx == 1
% %                     t(i) = stat.fstat.f;
% %                     p(i) = stat.fstat.pval;
% %                 else
%                     t(i) = stat.tstat.t(val_idx+1);
%                     p(i) = stat.tstat.pval(val_idx+1);
% %                 end
            end
            t(isnan(t)) = 0;
            [app.pos1,app.pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,app.WM_net_no);
                                  
             %% side maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K' ,num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no),'_BrainNet_images']);         
            
            WM_im_filename = cell(app.WM_net_no,1);
            for i = 1:app.WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_FN_K' ,num2str(app.WM_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[app.WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);
            
            ylabel(app.UIAxes2,'WM-FN')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/app.WM_net_no)/2:(m_size(1)/app.WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:app.WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])

            a = imtile(WM_im_filename,'GridSize',[1,app.WM_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'WM-FN')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')

            x_tick_pos = round((m_size(2)/app.WM_net_no)/2:(m_size(2)/app.WM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:app.WM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

%             app.UIAxes.Title.String = ["ttest between " app.Group1NameEditField.Value ' and ' app.Group2NameEditField.Value];
            app.UIAxes.Title.Color = [1,1,1];
%             clim(app.UIAxes,[0,1])         
            
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            axis(app.UIAxes,'off')
            axis(app.UIAxes,'off')

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

            switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];
                    text(app.UIAxes,app.pos1,app.pos2,"*","Color",'w','FontSize',40)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
            end

           
            my_datatip_wm(app,s,p)

            close(d)

        end


        function WM_CC_ttest(app,final_result,final_result_ccn,Subj_list)
            app.RegionsEditField.Visible = "off";
            app.RegionsEditFieldLabel.Visible = 'off';
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the Participant Ids in CSV file (Column 1 of CSV file) has different participant names from the Participant folder. Please use the same names from Participant folder and try again.' )
                return
            end
            d = uiprogressdlg(app.StatisticsUIFigure,'Title','Please wait','Message','Computing WM-CC-FC associations',...
                'Indeterminate','on');
            

            app.ParticipantslistDropDown.Items = Sub_names;
            app.NoofParticipantsLabel.Text = ['Number of Participants : ' num2str(length(Sub_names))];
            
            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')
            axis(app.UIAxes,'on')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);

            cla(app.UIAxes)
 
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            app.slide_select = 2;
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            CC_net_no = size(final_result_ccn{1},2); %Taking the number of CC networks from 1st subject as it is the same for every subject 
            app.WM_net_no = size(final_result{1},2); %Taking the number of WM networks from 1st subject as it is the same for every subject 
            map = zeros(app.WM_net_no,CC_net_no,length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(final_result{i},final_result_ccn{i})); %
                p = p+1;

            end
           
            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = reshape(map,[],length(ia));

%             if nnz(isnan(app.vec1))
%                 msgbox('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.','NaN values in FC')
%                 return
%             end
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                if nnz(isnan(app.vec1(i,:)))
                    disp('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.')
                    t(i) = nan;
                    p(i) = nan;
                else
                    stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
                    app.stats{i} = stat;
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
                end
            end
            [app.pos1,app.pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,app.WM_net_no);
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";

%% side maps
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K' ,num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no),'_BrainNet_images']);
               
            WM_im_filename = cell(app.WM_net_no,1);
            for i = 1:app.WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_FN_K' ,num2str(app.WM_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[app.WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM-FN')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/app.WM_net_no)/2:(m_size(1)/app.WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:app.WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
                
            CC_image_folder = fullfile(app.output_folder,'Analysis','Original_corpus_callosum',['Original_corpus_callosum_FN_from_WM_K' num2str(CC_net_no)],['Original_corpus_callosum_FN_from_WM_K' num2str(CC_net_no) '__BrainNet_images']);%'Corpus_Callosum_FN',['CC_network_from_WM_K',num2str(CC_net_no),'_BrainNet_images']);

            CC_im_filename = cell(CC_net_no,1);
            for i = 1:CC_net_no
                CC_im_filename{i} = fullfile(CC_image_folder,['Original_corpus_callosum_FN_from_WM_K' num2str(CC_net_no) '_' num2str(i) '_left.png']);
            end
            a = imtile(CC_im_filename,'GridSize',[1,CC_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'Corpus Callosum Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/CC_net_no)/2:(m_size(2)/CC_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:CC_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
%             map_pos2_in = app.UIAxes2_2.InnerPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), map_pos2(3), map_pos2(4)])
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), map_pos2(3), map_pos(4)])

            xlabel(app.UIAxes,'Corpus Callosum Networks')
            ylabel(app.UIAxes,'WM-FN')
           switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];
                    text(app.UIAxes,app.pos2,app.pos1,"*","Color",'w','FontSize',40)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
           end

           
            my_datatip_cc(app,s,p)
            % close the dialog box
            close(d)
        end
        
        function WMGM_ttest(app,final_result,final_result_gm,Subj_list)
            
            app.RegionsEditField.Visible = "off";
            app.RegionsEditFieldLabel.Visible = 'off';
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the Participant Ids in CSV file (Column 1 of CSV file) has different participant names from the Participant folder. Please use the same names from Participant folder and try again.' )
                return
            end
            d = uiprogressdlg(app.StatisticsUIFigure,'Title','Please wait','Message','Computing WM-GM-FC associations',...
                'Indeterminate','on');
            app.ParticipantslistDropDown.Items = Sub_names;
            app.NoofParticipantsLabel.Text = ['Number of participants : ' num2str(length(Sub_names))];

            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')

            app.slide_select = 3;
            app.WM_net_no = size(final_result{1},2);
            app.GM_net_no = size(final_result_gm{1},2); %Taking the number of CC networks from 1st subject as it is the same for every subject 
            map = zeros(app.WM_net_no,app.GM_net_no,length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(final_result{i},final_result_gm{i})); %
                p = p+1;

            end
            
            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = reshape(map,[],length(ia));

%             if nnz(isnan(app.vec1))
%                 msgbox('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.','NaN values in FC')
%                 return
%             end
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                if nnz(isnan(app.vec1(i,:)))
                    disp('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.')
                    t(i) = nan;
                    p(i) = nan;
                else
                    stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
                    app.stats{i} = stat;
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
                end
%                 stat = regstats(app.vec1(i,:),app.X(iia,:));
%                 app.stats{i} = stat;
% 
% %                 if val_idx == 1
% %                     t(i) = stat.fstat.f;
% %                     p(i) = stat.fstat.pval;
% %                 else
%                     t(i) = stat.tstat.t(val_idx+1);
%                     p(i) = stat.tstat.pval(val_idx+1);
% %                 end
            end
            [app.pos1,app.pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,app.WM_net_no);
           
            %% Side Maps
            try
                app.UIAxes2_2.Visible = "on";
                app.UIAxes2.Visible = "on";

                WM_image_folder = fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K' ,num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no),'_BrainNet_images']);

                WM_im_filename = cell(app.WM_net_no,1);
                for i = 1:app.WM_net_no
                    WM_im_filename{i} = fullfile(WM_image_folder,['WM_FN_K' ,num2str(app.WM_net_no) '_' num2str(i) '_left.png']);
                end
                a = imtile(WM_im_filename,'GridSize',[app.WM_net_no,1]);
                imagesc(app.UIAxes2,a)
                axis(app.UIAxes2,'image')
                m_size = size(a);

                ylabel(app.UIAxes2,'WM-FN')
                app.UIAxes2.PositionConstraint = 'innerposition';
                axis(app.UIAxes2,'on')

                y_tick_pos = round((m_size(1)/app.WM_net_no)/2:(m_size(1)/app.WM_net_no):m_size(1));
                yticks(app.UIAxes2,y_tick_pos);
                yticklabels(app.UIAxes2,1:app.WM_net_no)
                set(app.UIAxes2,'YLim',[0 m_size(1)])
                GM_im_filename = cell(app.GM_net_no,1);
                GM_image_folder = fullfile(app.output_folder,'Analysis','GM_FN',['GM_FN_K' ,num2str(app.GM_net_no)],['GM_FN_K',num2str(app.GM_net_no),'_BrainNet_images']);
                for i = 1:app.GM_net_no
                    GM_im_filename{i} = fullfile(GM_image_folder,['GM_FN_K' ,num2str(app.GM_net_no) '_' num2str(i) '_left.png']);

                end

                a = imtile(GM_im_filename,'GridSize',[1,app.GM_net_no]);
                m_size = size(a);
                imagesc(app.UIAxes2_2,a)
                axis(app.UIAxes2_2,'image')
                xlabel(app.UIAxes2_2,'GM Networks')
                app.UIAxes2_2.PositionConstraint = 'innerposition';
                axis(app.UIAxes2_2,'on')
                x_tick_pos = round((m_size(2)/app.GM_net_no)/2:(m_size(2)/app.GM_net_no):m_size(2));
                xticks(app.UIAxes2_2,x_tick_pos);
                xticklabels(app.UIAxes2_2,1:app.GM_net_no)
                set(app.UIAxes2_2,'XLim',[0 m_size(2)])

                map_pos = app.UIAxes2.tightPosition;
                map_pos2 = app.UIAxes2_2.tightPosition;
                set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
                set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
            catch
            end
            switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];
                    text(app.UIAxes,app.pos2,app.pos1,"*","Color",'w','FontSize',40)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
            end
            
            my_datatip_gm(app,s,p)
            close(d)

        end
        
        function Atlas_based_ttest(app,reg_ts,Subj_list)
            app.RegionsEditField.Visible = "on";
            app.RegionsEditFieldLabel.Visible = 'on';
            
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the participant Ids in CSV file (Column 1 of CSV file) has different Participant names from the participant folder. Please use the same names from Participant folder and try again.' )
                return
            end

            d = uiprogressdlg(app.StatisticsUIFigure,'Title','Please wait','Message','Computing Atlas-FC associations',...
                'Indeterminate','on');
            app.ParticipantslistDropDown.Items = Sub_names;
            app.NoofParticipantsLabel.Text = ['Number of participants : ' num2str(length(Sub_names))];
            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')
            axis(app.UIAxes,'on')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
%             app.UIAxes2_2.Toolb = "off";
%             app.UIAxes2.Visible = "off";

            app.slide_select = 4;
            eval('reg_ts = cellfun(@(x) x(:,eval(app.atlas_regions)), reg_ts, ''UniformOutput'', false);') %#ok<EVLCS> 
            atlas_regions = eval(app.atlas_regions); %#ok<ADPROPLC> 
            nreg = size(reg_ts{1},2); % Taking the size of regions from the first subject. It should be the same for every subject
            map = zeros(nreg,nreg,length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(reg_ts{i})); %
                p = p+1;

            end
            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = corrvec(map);
%             if nnz(isnan(app.vec1))
% %                 sub_nan = logical(sum(isnan(app.vec1)));
%                 msgbox('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.','NaN values in FC')
%                 return
%             end
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                if nnz(isnan(app.vec1(i,:)))
                    warning('The FC matrix has NaN values, one reason for this could be that no data was found in some of the Atlas regions, making the avearge time series from those regions zero.')
                    t(i) = nan;
                    p(i) = nan;
                else
                    stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
                    app.stats{i} = stat;
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);

                    
                end
%                 stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
%                 app.stats{i} = stat;
% 
% %                 if val_idx == 1
% %                     t(i) = stat.fstat.f;
% %                     p(i) = stat.fstat.pval;
% %                 else
%                     t(i) = stat.tstat.t(val_idx+1);
%                     p(i) = stat.tstat.pval(val_idx+1);
% %                 end
            end
            t(isnan(t)) = 0;
            [app.pos1,app.pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,nreg);
            if nreg > 10
                div = 10;
            else
                div = nreg;
            end

            xticks(app.UIAxes,round(linspace(1,nreg,div)))
            yticks(app.UIAxes,round(linspace(1,nreg,div)))

            xticklabels(app.UIAxes,atlas_regions(round(linspace(1,nreg,div)))) %#ok<ADPROPLC>
            yticklabels(app.UIAxes,atlas_regions(round(linspace(1,nreg,div)))) %#ok<ADPROPLC>

            xlabel(app.UIAxes,[num2str(nreg) ' ROI'])
            ylabel(app.UIAxes,[num2str(nreg) ' ROI'])
            switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];

                    text(app.UIAxes,app.pos2,app.pos1,"*","Color",'w','FontSize',10)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
            end
            my_datatip_atlas(app,s,p)
            close(d)

        end
        
        function initialise_freq_buttons(app,current_band)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            app.vec1 = [];
            current_band_atlas_idx = string(app.bands_info(:,1)) == current_band;
%             current_band_atlas = app.bands_info(current_band_atlas_idx,2);
            current_band_atlas_number = app.bands_info(current_band_atlas_idx,3);
            
            WM_reg_files_idx = intersect(find(app.folders_info(:,1)),cell2mat(current_band_atlas_number));
            
            if isscalar(WM_reg_files_idx)
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(WM_reg_files_idx).folder,app.atlas_folders(WM_reg_files_idx).name,['Participant_' app.atlas_folders(WM_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
                app.WMfileSelectDropDown_2.Enable = 'off';
               
                app.WMFCButton.Enable = 'on';
            elseif length(WM_reg_files_idx) > 1
                app.WMfileSelectDropDown_2.Enable = 'on';
                wm_reg_file = struct2cell(app.atlas_folders(WM_reg_files_idx));
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown_2.Items = wm_reg_file;
                app.WMFCButton.Enable = 'on';
            else
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.WMFCButton.Enable = "off";
                app.WMFCButton.Tooltip = 'White Matter(WM) Network average timeseries file not found: After creating the WM-FN, Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the WM_clustering_K*.nii file from <output folder>/Analysis/WM_FN as Network/Atlas file and try again';
            end
            CC_reg_files_idx = intersect(find(app.folders_info(:,2)),cell2mat(current_band_atlas_number));

            if isscalar(CC_reg_files_idx)
                app.final_result_cc = load_in_cell_freq(app,fullfile(app.atlas_folders(CC_reg_files_idx).folder,app.atlas_folders(CC_reg_files_idx).name,['Participant_' app.atlas_folders(CC_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_cc.hp;
                app.lp = app.final_result_cc.lp;
                app.CCfileSelectDropDown_2.Enable  = 'off';
                app.WMCCFCButton.Enable = 'on';
            elseif length(CC_reg_files_idx) > 1
                app.CCfileSelectDropDown_2.Enable = 'on';
                app.WMCCFCButton.Enable = 'on';
                CC_reg_files = struct2cell(app.atlas_folders(CC_reg_files_idx));
                CC_reg_files(2:end,:) = [];
                app.CCfileSelectDropDown_2.Items = CC_reg_files;

            else
                app.WMCCFCButton.Enable = "off";
                app.WMCCFCButton.Tooltip = 'Corpus Callosum (CC) average timeseries file not found: After creating the CC Networks, Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the CC_network_from_WM_K*.nii file from <output folder>/Analysis/Corpus_Callosum_FN as Network/Atlas file and try again';
                app.CCfileSelectDropDown_2.Enable = 'off';
            end

            GM_reg_files_idx = intersect(find(app.folders_info(:,3)),cell2mat(current_band_atlas_number));

            if isscalar(GM_reg_files_idx)              
                app.final_result_gm = load_in_cell_freq(app,fullfile(app.atlas_folders(GM_reg_files_idx).folder,app.atlas_folders(GM_reg_files_idx).name,['Participant_' app.atlas_folders(GM_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_gm.hp;
                app.lp = app.final_result_gm.lp;
                app.GMfileSelectDropDown_2.Enable  = 'off';
                app.WMGMFCButton.Enable = "on";
            elseif length(GM_reg_files_idx) > 1
                app.GMfileSelectDropDown_2.Enable = 'on';
                gm_reg_file = struct2cell(app.atlas_folders(GM_reg_files_idx));
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown_2.Items = gm_reg_file;
                app.WMGMFCButton.Enable = "on";

            else
                app.WMGMFCButton.Enable = "off";
                app.GMfileSelectDropDown_2.Enable = 'off';
                app.WMGMFCButton.Tooltip = 'Gray Matter(GM) Network average timeseries file not found: After creating the GM networks, Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the GM_clustering_K*.nii file from <output folder>/Analysis/GM_FN as Network/Atlas file and try again';

            end

            Atlas_reg_files_idx = intersect(find(app.folders_info(:,4)),cell2mat(current_band_atlas_number));

            if isscalar(Atlas_reg_files_idx)
                
                app.final_result_atlas = load_in_cell_freq(app,fullfile(app.atlas_folders(Atlas_reg_files_idx).folder,app.atlas_folders(Atlas_reg_files_idx).name,['Participant_' app.atlas_folders(Atlas_reg_files_idx).name '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_atlas.hp;
                app.lp = app.final_result_atlas.lp;
                app.AtlasfileSelectDropDown_2.Enable  = 'off';
                app.AtlasFCButton.Enable = "on";
                app.atlas_file = app.atlas_folders(Atlas_reg_files_idx).name;
            elseif length(Atlas_reg_files_idx) > 1
                app.AtlasfileSelectDropDown_2.Enable = 'on';
                atlas_reg_file = struct2cell(app.atlas_folders(Atlas_reg_files_idx));
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown_2.Items = atlas_reg_file;
                app.AtlasFCButton.Enable = "on";

            else
                app.AtlasFCButton.Enable = "off";
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.AtlasFCButton.Tooltip = 'Atlas average timeseries file not found: Please extract frequency specific timseries from the Extract timeseries tab from the Create FN and FC module and choose the preffered Atlas as Network/Atlas file and try again';

            end
        end
        
        function select_slide(app,event)
%             disp([' tab position' num2str(app.Tabposition)])
%             disp([' Slide select' num2str(app.slide_select)])
            switch app.slide_select
                
                case 1
                    if app.Tabposition
                        WMFCButton_2Pushed(app, event)
                    else
                        WMFCButtonPushed(app, event)
                    end
                    
                case 2
                    if app.Tabposition
                        WMCCFCButton_2Pushed(app, event)
                    else
                        WMCCFCButtonPushed(app, event)
                    end
                    
                case 3
                    if app.Tabposition
                        WMGMFCButton_2Pushed(app, event)
                    else
                        WMGMFCButtonPushed(app, event)
                    end
                case 4
                    if app.Tabposition
                        AtlasFCButton_2Pushed(app, event)
                    else
                        AtlasFCButtonPushed(app, event)
                    end

            end
        end
        
        function [my_col,pos_clim,neg_clim] = my_colormap(~,t)
            t(isnan(t)) = 0;
            if nnz(t)
                my_col = zeros(256,3);
                cval = round((t - min(t,[],'all'))./(max(t,[],'all') - min(t,[],'all')) .* (256-1))+1;
                t_0_idx = find(t==0);
                t_pos = t(t>0);
                t_neg = t(t<0);

                t_min_pos = min(t_pos);
                t_min_neg = -1*min(abs(t_neg));

                t_max_pos = max(t_pos);
                t_max_neg = -1*max(abs(t_neg));

                if isempty(t_max_neg)
                    t_max_neg = 0;
                end
                if isempty(t_max_pos)
                    t_max_pos = 0;
                end
                if ~isempty(t_0_idx)
                    cval_0 = cval(t_0_idx(1));
                    my_col(cval_0,:) = [0,0,0];
                    if ~isempty(t_pos)
                        
                        cval_min_pos_idx = find(t == t_min_pos);
                        cval_min_pos = cval(cval_min_pos_idx(1));
                        my_col(cval_min_pos:256,:)  = flip(autumn(256-cval_min_pos+1),1);

                        if t_min_pos~=t_max_pos
                            pos_clim = [t_min_pos,t_max_pos];
                        else
                            pos_clim = [0,t_max_pos];
                        end
                    else
                        pos_clim = [0 1];
                    end

                    if ~isempty(t_neg)
                        cval_min_neg_idx = find(t == t_min_neg);
                        cval_min_neg = cval(cval_min_neg_idx(1));
                        
                        my_col(1:cval_min_neg,:) = winter(cval_min_neg);

                        if t_max_neg~=t_min_neg
                            neg_clim = [t_max_neg,t_min_neg];
                        else
                            neg_clim = [t_max_neg,0];
                        end
                    else
                        neg_clim = [-1 0];
                        
                    end

%                     if t_min_pos~=t_max_pos
%                         pos_clim = [t_min_pos,t_max_pos];
%                     else
%                         pos_clim = [t_min_pos,1];
%                     end
                else
                    my_col = jet(256);
                    pos_clim = [0 1];
                    neg_clim = [-1 0];
                end
            else
                my_col = zeros(256,3);
                pos_clim = [0 1];
                neg_clim = [-1 0];
            end
            
        end


        function my_datatip_gm(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(GM netid, WM netid) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end

        function my_datatip_cc(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(CC netid, WM netid) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end

        function my_datatip_wm(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(WM netid1, WM netid2) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end

        function my_datatip_atlas(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(ROI1, ROI2) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            % if sum(size(p) ~= size(s.CData))
            %     warning()
            % end
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end+1) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end
        
        function [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size_t)
            app.UIAxes.Title.Color = [1,1,1];
            clim(app.UIAxes,[0,1])
            % p(isnan(p)) = [];
            if strcmp(app.CorrectionDropDown.Value,'False Discovery Rate ')
%                 [h,~,~,p] = fdr_bh(p,app.AlphaEditField.Value,'dep');

                p = mafdr(p,'BHFDR','true');
%                 
%                 p = reshape(p,size_t,[]);
%                 t = reshape(t,size_t,[]);
            elseif strcmp(app.CorrectionDropDown.Value,'Bonferroni')
                
%                 t(p<(app.AlphaEditField.Value/numel(p))) = 1;
                bon_comp = numel(p);
%                 p = reshape(p,size_t,[]);
%                 t = reshape(t,size_t,[]);
            end
            if app.slide_select == 1 || app.slide_select == 4
                t = veccorr(t);
                p = veccorr(p);
            else
                p = reshape(p,size_t,[]);
                t = reshape(t,size_t,[]);
            end

            s = imagesc(app.UIAxes,t);
            if strcmp(app.CorrectionDropDown.Value,'Bonferroni')
                [pos1,pos2] = find(p<(app.AlphaEditField.Value/bon_comp));
                app.AlphaEditFieldLabel_2.Text = ['Bonferroni alpha = ' num2str(app.AlphaEditField.Value) '/' num2str(bon_comp) '=' num2str(app.AlphaEditField.Value/bon_comp)];
            else
                [pos1,pos2] = find(p<app.AlphaEditField.Value);
            end
            
            slide_names = {'WM-FC','WM-CC-FC','WM-GM-FC','Atlas-FC'};
            if app.slide_select <= 3
                if app.Tabposition
                    title(app.UIAxes,[char(slide_names(app.slide_select)) ' associations with ' char(app.VariablesDropDown.Value) ])
                else
                    title(app.UIAxes,[char(slide_names(app.slide_select)) ' associations with ' char(app.VariablesDropDown.Value) ', Freq. Range: ' num2str(app.lp) '~' num2str(app.hp) ])
                end
            else
                if app.Tabposition
                    title(app.UIAxes,[char(app.AtlasfileSelectDropDown.Value(1:end-11)) ' Atlas associations with ' char(app.VariablesDropDown.Value) ],"Interpreter","none")
                else
                    title(app.UIAxes,[char(app.AtlasfileSelectDropDown.Value(1:end-11)) ' Atlas associations with ' char(app.VariablesDropDown.Value) ', Freq. Range: ' num2str(app.lp) '~' num2str(app.hp) ],"Interpreter","none")
                end
            end
            app.current_fig = t;
            set(app.UIAxes,'YLim',[0.5 size(t,1) + 0.5])
            set(app.UIAxes,'XLim',[0.5 size(t,2) + 0.5])
            if nnz(t(~isnan(t)))
                clim(app.UIAxes,[min(t,[],'all'),max(t,[],'all')])
            else
                warning('All T values are zero')
            end

            switch app.plotDropDown.Value
            
                case 'All values'
                    colormap(app.UIAxes,'jet')
                    pos_clim = [];
                    neg_clim = []; 
                case 'Significant only'
                    if strcmp(app.CorrectionDropDown.Value,'Bonferroni')
                        t(p>(app.AlphaEditField.Value/bon_comp)) = 0;
                    else
                        t(p>app.AlphaEditField.Value) = 0;
                    end
                    [my_col,pos_clim,neg_clim ] = my_colormap(app,t);
                    colormap(app.UIAxes,my_col)
            end

            
        end
        
        function out = load_in_cell_wm(~,path_)
            subj_list = dir(path_);
            n = length(subj_list);
            out.final_result = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.final_result{i} = temp.avg_ts;
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                out.final_result{i} = temp.avg_ts;
                out.Subj_list(i) = temp.Subj;
            end
        end

        function out = load_in_cell_cc(~,path_)
            subj_list = dir(path_);
            n = length(subj_list);
            out.final_result_ccn = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.final_result_ccn{i} = temp.avg_ts;
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                out.final_result_ccn{i} = temp.avg_ts;
                out.Subj_list(i) = temp.Subj;
            end
        end

        function out = load_in_cell_atlas(~,path_)
            subj_list = dir(path_);
            n = length(subj_list);
            out.reg_ts = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.reg_ts{i} = temp.avg_ts;
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                out.reg_ts{i} = temp.avg_ts;
                out.Subj_list(i) = temp.Subj;
            end
        end

        function out = load_in_cell_gm(~,path_)
            subj_list = dir(path_);
            n = length(subj_list);
            out.final_result_gm = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.final_result_gm{i} = temp.avg_ts;
            out.Subj_list = temp.Subj;
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                out.final_result_gm{i} = temp.avg_ts;
                out.Subj_list(i) = temp.Subj;
            end
        end
        
        function get_atlas_regts(app,Atlas_reg_files)

            app.final_result_atlas = load_in_cell_atlas(app,fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,['Participant_' Atlas_reg_files.name '_avg_ts'],'*_avg_ts.mat'));
                
                app.atlas_name = Atlas_reg_files.name;
                nRegions = cellfun(@(x) size(x,2), app.final_result_atlas.reg_ts);
                if numel(unique(nRegions)) ~= 1
                    subj_names = {app.final_result_atlas.Subj_list.name};
                    % Use the first subject as reference
                    ref_nRegions = nRegions(1);

                    % Find subjects that differ
                    diff_idx = find(nRegions ~= ref_nRegions,1);  % only first mismatch

                    if ~isempty(diff_idx)
                        bad_subj = subj_names{diff_idx};
                        % fullfile(Atlas_reg_files.folder,Atlas_reg_files.name,'Subject_Atlas_avg_ts','<subject_name>_atlas_avgts.mat')
                        % Build path to check
                        check_path = fullfile(Atlas_reg_files.folder, ...
                            Atlas_reg_files.name, ...
                            'Subject_Atlas_avg_ts', ...
                            [bad_subj '_' Atlas_reg_files.name '_atlas_avgts.mat']);

                        error(['Mismatch in regions! Expected %d, but subject %s has %d.\n' ...
                            'Please check the file: %s'], ...
                            ref_nRegions, bad_subj, nRegions(diff_idx), check_path);
                    end
                end
                if isempty(app.RegionsEditField.Value)
                    app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts{1},2))];
                end
            
        end
        
        function out = load_in_cell_freq(~,path_)
            subj_list = dir(path_);
            n = length(subj_list);
            out.reg_ts_freq = cell(n,1);
            i = 1;
            temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
            out.reg_ts_freq{i} = temp.avg_ts_freq;
            out.Subj_list = temp.Subj;
            out.hp = temp(i).hp;   % Should be same for every subject
            out.lp = temp(i).lp;   % Should be same for every subject
            for i = 2:n
                temp = load(fullfile(subj_list(i).folder,subj_list(i).name));
                out.reg_ts_freq{i} = temp.avg_ts_freq;
                out.Subj_list(i) = temp.Subj;
            end
            
        end
        
        function plots_for_pairs(app,net1,net2)
            
            
            if app.slide_select == 1 || app.slide_select == 4
                if net1 == net2
                msgbox('Diagnoal Values are all ones and hence not interesting','Diagonals are Trivial')
                return
                end
            end
            if strcmp(app.UIAxes2_2.XLabel.String,'Corpus Callosum Networks') || strcmp(app.UIAxes2_2.XLabel.String,'GM Networks')
                 p = sub2ind([app.UIAxes.YLim(2) - 0.5,app.UIAxes.XLim(2) - 0.5], net1,net2);
            else
                b = size(app.vec1);
                temp = veccorr((1:b(1))');
                p = temp(net1,net2);
            end
            
            switch app.slide_select
                case 1
                    titY = 'WM';
                    titX = 'WM';
                    imag_2 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_Dorsal.png']));
                    imag_1 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_left.png']));
                    imag_3 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_Posterior.png']));
                    final_imag1 = [imag_1, imag_2, imag_3];
                    imag_2 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net2) '_Dorsal.png']));
                    imag_1 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net2) '_left.png']));
                    imag_3 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net2) '_Posterior.png']));
                    final_imag2 = [imag_1, imag_2, imag_3];
                    title_y = ['WM ' num2str(net1)];
                    title_x = ['WM ' num2str(net2)];
                case 2
                    titY = 'WM';
                    titX = 'CC';
                    imag_2 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_Dorsal.png']));
                    imag_1 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_left.png']));
                    imag_3 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_Posterior.png']));
                    final_imag1 = [imag_1, imag_2, imag_3];
                    imag_2 = imread(fullfile(app.output_folder,'Analysis','Original_corpus_callosum',['Original_corpus_callosum_FN_from_WM_K' num2str(app.WM_net_no)],['Original_corpus_callosum_FN_from_WM_K',num2str(app.WM_net_no),'__BrainNet_images'],['Original_corpus_callosum_FN_from_WM_K',num2str(app.WM_net_no),'_',num2str(net2),'_Dorsal.png']));
                    imag_1 = imread(fullfile(app.output_folder,'Analysis','Original_corpus_callosum',['Original_corpus_callosum_FN_from_WM_K' num2str(app.WM_net_no)],['Original_corpus_callosum_FN_from_WM_K',num2str(app.WM_net_no),'__BrainNet_images'],['Original_corpus_callosum_FN_from_WM_K',num2str(app.WM_net_no),'_',num2str(net2),'_left.png']));
                    imag_3 = imread(fullfile(app.output_folder,'Analysis','Original_corpus_callosum',['Original_corpus_callosum_FN_from_WM_K' num2str(app.WM_net_no)],['Original_corpus_callosum_FN_from_WM_K',num2str(app.WM_net_no),'__BrainNet_images'],['Original_corpus_callosum_FN_from_WM_K',num2str(app.WM_net_no),'_',num2str(net2),'_Posterior.png']));

                    final_imag2 = [imag_1, imag_2, imag_3];
                    title_y = ['WM ' num2str(net1)];
                    title_x = ['CC ' num2str(net2)];
                    
                case 3
                    titY = 'WM';
                    titX = 'GM';
                    imag_2 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_Dorsal.png']));
                    imag_1 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_left.png']));
                    imag_3 = imread(fullfile(app.output_folder,'Analysis','WM_FN',['WM_FN_K',num2str(app.WM_net_no)],['WM_FN_K',num2str(app.WM_net_no) '_BrainNet_images'],['WM_FN_K',num2str(app.WM_net_no) '_' num2str(net1) '_Posterior.png']));
                    final_imag1 = [imag_1, imag_2, imag_3];
                    imag_2 = imread(fullfile(app.output_folder,'Analysis','GM_FN',['GM_FN_K',num2str(app.GM_net_no)],['GM_FN_K',num2str(app.GM_net_no) '_BrainNet_images'],['GM_FN_K',num2str(app.GM_net_no) '_' num2str(net2) '_Dorsal.png']));
                    imag_1 = imread(fullfile(app.output_folder,'Analysis','GM_FN',['GM_FN_K',num2str(app.GM_net_no)],['GM_FN_K',num2str(app.GM_net_no) '_BrainNet_images'],['GM_FN_K',num2str(app.GM_net_no) '_' num2str(net2) '_left.png']));
                    imag_3 = imread(fullfile(app.output_folder,'Analysis','GM_FN',['GM_FN_K',num2str(app.GM_net_no)],['GM_FN_K',num2str(app.GM_net_no) '_BrainNet_images'],['GM_FN_K',num2str(app.GM_net_no) '_' num2str(net2) '_Posterior.png']));
                    final_imag2 = [imag_1, imag_2, imag_3];
                    title_y = ['WM ' num2str(net1)];
                    title_x = ['GM ' num2str(net2)];
                case 4
                    titY = 'ROI';
                    titX = 'ROI';
            end
          
            
            [Sub_names,ia,iia] = intersect(app.ParticipantslistDropDown.Items,string(app.sub_names_csv)');
            % app.ParticipantslistDropDown.Items = Sub_names;
            val_var = app.VariablesDropDown.Value;
            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));
            vec_now = app.vec1(p,ia);
            X_now = app.X(iia,val_idx);
            var_values = unique(X_now);
            var_type_iden = length(var_values);
            
            if var_type_iden <= 4
                plot_vars = cell(1,var_type_iden);
                for i = 1:var_type_iden
                    plot_vars{i} = vec_now(X_now == var_values(i));
                end
                % figure;
                if exist("final_imag1","var")
                    % subplot(2,1,1)
                    tiledlayout(3,2,'TileSpacing','none','Padding','none');
                    nexttile([2 2]) % span across top row
                end
                [~] = barplot_with_errorbars(plot_vars,{var_values});
                set(gca,'FontSize',15)
                
            else
                % figure;
                if exist("final_imag1","var")
                    % subplot(2,1,1)
                    tiledlayout(3,2,'TileSpacing','none','Padding','none');
                    nexttile([2 2]) % span across top row
                end
                scatter(X_now,vec_now)
                set(gca,'FontSize',15)
                % xlim(gca,[min(X_now)-0.1*(max(X_now) - min(X_now)) max(X_now)+0.1*(max(X_now) - min(X_now)) ])
                % ylim(gca,[min(vec_now)-0.1*(max(vec_now)-min(vec_now)) max(vec_now)+0.1*(max(X_now) - min(X_now)) ])

                if app.fitlineCheckBox.Value
                    y_line = app.stats{p}.tstat.beta(1) + X_now*app.stats{p}.tstat.beta(val_idx+1);
                    hold on
                    plot(X_now,y_line)
                    txt = ['FC = ' num2str(app.stats{p}.tstat.beta(1)) ' + ' num2str(app.stats{p}.tstat.beta(val_idx+1)) '*' val_var];
                    % text(min(X_now)-0.05*min(X_now),max(vec_now)+0.05*max(vec_now),txt,'FontSize',14,'Interpreter','none')
                    legend({'data',txt},'Interpreter','none')
                end
                
            end
            ylabel('Fisher Z transformed FC')
            % ylim([-1 1])
            xlabel(val_var,'Interpreter','none')

            if var_type_iden <= 4
                title([titY num2str(net1) ' - ' titX num2str(net2) ', t-value = ' num2str(app.stats{p}.tstat.t(val_idx+1)) , ' n(' num2str(var_values') ') = (' num2str(cellfun(@length, plot_vars)) ')' ])
            else
                title([titY num2str(net1) ' - ' titX num2str(net2) ', t-value = ' num2str(app.stats{p}.tstat.t(val_idx+1))])
            end
           

            if exist("final_imag1","var")
                % subplot(2,2,3)
                nexttile
                imshow(final_imag1)
                title(title_y)
                set(gca,'FontSize',15)
                % subplot(2,2,4)  
                nexttile
                imshow(final_imag2);
                title(title_x)
                set(gca,'FontSize',15)
                % xlabel('Dorsal Left Posterior')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path, Subj_list, over_write)
            app.over_write = over_write;
            app.spm_path = which('spm');
            if isempty(app.spm_path)
                msgbox("Please set the SPM path by using the 'addpath' MATLAB function",'SPM path not set')
                return
            end
            temp_path = mfilename('fullpath');                % path of the toolbox
            app.preproc_code_path = fileparts(temp_path);
            addpath(fullfile(app.preproc_code_path))
            addpath(fullfile(app.preproc_code_path,'whifun_functions'))
            addpath(fullfile(app.preproc_code_path,'uigetfile_n_dir'))
            app.CallingApp = caller;
            app.output_folder = path;
%             load(fullfile(path,'parameters.mat'),'Subj_list')
            app.Subj_list = Subj_list;  
            app.data_path = app.Subj_list(1).folder;

            app.Tabposition = 1;    
            app.all_sub_names = struct2cell(app.Subj_list);
            app.all_sub_names(2:end,:) = [];
            
            app.WMFCButton_2.Enable = "off";
            app.WMFCButton_2.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
            app.WMfileSelectDropDown.Enable = "off";

            app.WMCCFCButton_2.Enable = 'off';
            app.WMCCFCButton_2.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
            app.CCfileSelectDropDown.Enable = "off";
            
            app.WMGMFCButton_2.Enable = 'off';
            app.WMGMFCButton_2.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
            app.GMfileSelectDropDown.Enable = "off";

            app.AtlasFCButton_2.Enable = "off";
            app.AtlasFCButton_2.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
            app.AtlasfileSelectDropDown.Enable = "off";
            app.AlphaEditFieldLabel_2.Visible = "off";
            
        end

        % Button pushed function: WMFCButton_2
        function WMFCButton_2Pushed(app, event)
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','WM_FN',wm_file,['Participant_' wm_file '_avg_ts'],['*_' wm_file '_avg_ts.mat']));
            end
            
            
            WM_ttest(app,app.final_result.final_result,app.final_result.Subj_list)
                           
        end

        % Button pushed function: WMCCFCButton_2
        function WMCCFCButton_2Pushed(app, event)
            if strcmp(app.CCfileSelectDropDown.Enable,'on')
                cc_file = app.CCfileSelectDropDown.Value;
                app.final_result_cc = load_in_cell_cc(app,fullfile(app.output_folder,'Analysis','Original_corpus_callosum',cc_file,['Participant_' cc_file '_avg_ts'],'*_Original_corpus_callosum_FN_from_WM_K*_avg_ts.mat'));

            end
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                 app.final_result = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','WM_FN',wm_file,['Participant_' wm_file '_avg_ts'],['*_' wm_file '_avg_ts.mat']));
            end

            WM_CC_ttest(app,app.final_result.final_result,app.final_result_cc.final_result_ccn,app.final_result.Subj_list)
            
            
        end

        % Button pushed function: WMGMFCButton_2
        function WMGMFCButton_2Pushed(app, event)
            if strcmp(app.GMfileSelectDropDown.Enable,'on')
                gm_file = app.GMfileSelectDropDown.Value;
                app.final_result_gm = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','GM_FN',gm_file,['Participant_' gm_file '_avg_ts'],['*_' gm_file '_avg_ts.mat']));
            end

            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load_in_cell_wm(app,fullfile(app.output_folder,'Analysis','WM_FN',wm_file,['Participant_' wm_file '_avg_ts'],['*_' wm_file '_avg_ts.mat']));
            end
            
            WMGM_ttest(app,app.final_result.final_result,app.final_result_gm.final_result_gm,app.final_result_gm.Subj_list)
           
        end

        % Button pushed function: AtlasFCButton_2
        function AtlasFCButton_2Pushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown.Enable,'on')
                app.atlas_name = app.AtlasfileSelectDropDown.Value;
                Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','ROI_Timeseries'));
                Atlas_reg_files = Atlas_reg_files(ismember({Atlas_reg_files.name}, {app.atlas_name}));
                get_atlas_regts(app,Atlas_reg_files); 
            end
            Atlas_based_ttest(app,app.final_result_atlas.reg_ts,app.final_result_atlas.Subj_list)
        end

        % Value changed function: AtlasfileSelectDropDown
        function AtlasfileSelectDropDownValueChanged(app, event)
            app.RegionsEditField.Value = '';
%             app.atlas_regions  = '';  
            AtlasFCButton_2Pushed(app, event)
                  
        end

        % Value changed function: GMfileSelectDropDown
        function GMfileSelectDropDownValueChanged(app, event)
            WMGMFCButton_2Pushed(app, event)
        end

        % Value changed function: WMfileSelectDropDown
        function WMfileSelectDropDownValueChanged(app, event)
           select_slide(app,event)
        end

        % Value changed function: DataTipsCheckBox
        function DataTipsCheckBoxValueChanged(app, event)
            value = app.DataTipsCheckBox.Value;
            dcm_obj = datacursormode(app.StatisticsUIFigure);

            if value == 1
                dcm_obj.Enable = 'on';  
                if app.Tabposition
                    app.WMCCFCButton.Enable = "off";
                    app.WMFCButton.Enable = "off";
                    app.WMGMFCButton.Enable = "off";
                    app.AtlasFCButton.Enable = "off"; 
                else
                    app.WMCCFCButton_2.Enable = "off";
                    app.WMFCButton_2.Enable = "off";
                    app.WMGMFCButton_2.Enable = "off";
                    app.AtlasFCButton_2.Enable = "off"; 
                end
            else
                dcm_obj.Enable = 'off';
                if app.Tabposition
                    app.WMCCFCButton.Enable = "on";
                    app.WMFCButton.Enable = "on";
                    app.WMGMFCButton.Enable = "on";
                    app.AtlasFCButton.Enable = "on"; 
                else
                    app.WMCCFCButton_2.Enable = "on";
                    app.WMFCButton_2.Enable = "on";
                    app.WMGMFCButton_2.Enable = "on";
                    app.AtlasFCButton_2.Enable = "on"; 
                end

            end
        end

        % Value changed function: CorrectionDropDown
        function CorrectionDropDownValueChanged(app, event)
           switch app.CorrectionDropDown.Value
               case 'Bonferroni'
                   app.AlphaEditFieldLabel_2.Visible = "on";
               case 'False Discovery Rate '
                   app.AlphaEditFieldLabel_2.Visible = "off";
           end
            select_slide(app,event)
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
           select_slide(app,event)
        end

        % Button pushed function: 
        % UploadCSVwithbehaviourmetadataforParticipantsButton
        function UploadCSVwithbehaviourmetadataforParticipantsButtonPushed(app, event)
            try
                [file,path] = uigetfile('.csv','Select CSV file');
            catch
            end
            if path == 0
                return
            end
            opts = detectImportOptions(fullfile(path,file));
            opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
            CSV = table2cell(readtable(fullfile(path,file),opts));
           
            if app.HasHeaderCheckBox.Value
                CSVhead = readcell(fullfile(path,file));
                CSVhead(2:end,:) = [];
                for i = 1:size(CSV,2)-1
                    app.CSV_head{i} = CSVhead{i+1};
                end
            else
                app.CSV_head = cell(1,size(CSV,2));
                for i = 1:size(CSV,2)
                    app.CSV_head{i+2} = ['Var_' num2str(i)];
                end
            end

            d = uiprogressdlg(app.StatisticsUIFigure,'Title','Please wait','Message','Loading ... ',...
                'Indeterminate','on');

            app.sub_names_csv = CSV(:,1);
            CSV = CSV(:,2:end);
            app.X = cellfun(@(x) str2double(x), CSV);

            app.VariablesDropDown.Items = app.CSV_head;

            app.WMFCButton_2.Enable = "on";
            app.WMFCButton_2.Tooltip = 'White Matter(WM) Functional Connectivity. A GLM model is fit to the WM FC elements and covariates provided by the CSV file.';
            app.WMfileSelectDropDown.Enable = "on";

            app.WMCCFCButton_2.Enable = 'on';
            app.WMCCFCButton_2.Tooltip = 'White Matter(WM) - Corpus Callosum(CC) Functional Connectivity.  A GLM model is fit to the WM-CC FC elements and covariates provided by the CSV file.';
            app.CCfileSelectDropDown.Enable = "on";
            
            app.WMGMFCButton_2.Enable = 'on';
            app.WMGMFCButton_2.Tooltip = 'White Matter(WM) - Gray Matter(GM) Functional Connectivity. A GLM model is fit to the WM-GM FC elements and covariates provided by the CSV file.';
            app.GMfileSelectDropDown.Enable = "on";

            app.AtlasFCButton_2.Enable = "on";
            app.AtlasFCButton_2.Tooltip = 'Atlas-Functional Connectivity : A GLM model is fit to the Atlas-FC elements and covariates provided by the CSV file.';
            app.AtlasfileSelectDropDown.Enable = "on";

            app.WMFCButton.Enable = "on";
            app.WMFCButton.Tooltip = 'White Matter(WM) Functional Connectivity. A GLM model is fit to the WM FC elements and covariates provided by the CSV file.';
            app.WMfileSelectDropDown_2.Enable = "on";

            app.WMCCFCButton.Enable = 'on';
            app.WMCCFCButton.Tooltip = 'White Matter(WM) - Corpus Callosum(CC) Functional Connectivity.  A GLM model is fit to the WM-CC FC elements and covariates provided by the CSV file.';
            app.CCfileSelectDropDown_2.Enable = "on";
            
            app.WMGMFCButton.Enable = 'on';
            app.WMGMFCButton.Tooltip = 'White Matter(WM) - Gray Matter(GM) Functional Connectivity. A GLM model is fit to the WM-GM FC elements and covariates provided by the CSV file.';
            app.GMfileSelectDropDown_2.Enable = "on";

            app.AtlasFCButton.Enable = "on";
            app.AtlasFCButton.Tooltip = 'Atlas-Functional Connectivity : A GLM model is fit to the Atlas-FC elements and covariates provided by the CSV file.';
            app.AtlasfileSelectDropDown_2.Enable = "on";

            app.initial_UIAxes_pos = app.UIAxes.Position;
            app.initial_UIAxes2_pos = app.UIAxes2.Position;
            app.initial_UIAxes2_2_pos = app.UIAxes2_2.Position;

            if app.Tabposition
            WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_FN','WM_FN_K*'));

            if isscalar(WM_reg_files)
                app.WMfileSelectDropDown.Enable = 'off';

                app.final_result = load_in_cell_wm(app,fullfile(WM_reg_files.folder,WM_reg_files.name,'Participant_WM_FN_K*_avg_ts','*_WM_FN_K*.mat'));
                
                app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFCButton_2.Enable = "off";
                app.WMFCButton_2.Tooltip = 'White Matter Network average timeseries file not found: Please create the WM-FN from the Create FN and FC module and try again';
            end
            cc_reg_file = dir(fullfile(app.output_folder,'Analysis','Original_corpus_callosum','Original_corpus_callosum_FN_from_WM_K*'));


            if isscalar(cc_reg_file)
                app.CCfileSelectDropDown.Enable  = 'off';
                app.final_result_cc = load_in_cell_cc(app,fullfile(cc_reg_file.folder,cc_reg_file.name,'Participant_Original_corpus_callosum_FN_from_WM_K*_avg_ts','*_Original_corpus_callosum_FN_from_WM_K*.mat'));
                
            elseif length(cc_reg_file) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_file);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCCFCButton_2.Enable = "off";
                app.WMCCFCButton_2.Tooltip = 'Corpus Callosum average timeseries file not found: Please create the Corpus callosum networks by checking the Corpus callosum check box under the WM-FN tab from the Create FN and FC module and try again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','ROI_Timeseries'));
            Atlas_reg_files = Atlas_reg_files(~ismember({Atlas_reg_files.name}, {'.','..','.DS_Store'}));  % remove unwanted

            if isscalar(Atlas_reg_files)
                app.AtlasfileSelectDropDown.Enable  = 'off';
                get_atlas_regts(app,Atlas_reg_files)
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasFCButton_2.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasFCButton_2.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end


             GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_FN','GM_FN_K*'));

            if isscalar(GM_reg_files)
                app.final_result_gm = load_in_cell_gm(app,fullfile(GM_reg_files.folder,GM_reg_files.name,'Participant_GM_FN_K*_avg_ts','*_GM_FN_K*.mat'));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMFCButton_2.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMFCButton_2.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end
            
            switch app.plotDropDown.Value
                case 'All values'
                
                case 'Significant only'
                    
                c = colorbar(app.UIAxes,'eastoutside','AxisLocation','out');
                c.Color = [1 1 1];
                c_pos = c.Position;
                c.Position = [c.Position(1) + 2*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 4*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                c1.Color = [1 1 1];
    %             c = colorbar(app.UIAxes,'Position',[c_pos(1),c_pos(2)+c_pos(4)/2, c_pos(3),c_pos(4)/2]);
    %             c.Color = [1 1 1];
                axis(app.UIAxes,'off')
            end

            else
                FrequencyBandsTabButtonDown(app, event)
            end

            
            app.FrequencyBandsTab.HandleVisibility = "on";
            close(d)
        end

        % Button down function: FrequencyBandsTab
        function FrequencyBandsTabButtonDown(app, event)
            app.Tabposition = 0;
            if isempty(app.X)
                msgbox('Upload CSV File with the first column containing participant names and the other columns conating the age, sex, or behavioral data.','Upload CSV file first');
                app.TabGroup.SelectedTab = app.AllfrequenciesTab;
                app.WMFCButton.Enable = "off";
                app.WMFCButton.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
                app.WMfileSelectDropDown_2.Enable = "off";

                app.WMCCFCButton.Enable = 'off';
                app.WMCCFCButton.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
                app.CCfileSelectDropDown_2.Enable = "off";

                app.WMGMFCButton.Enable = 'off';
                app.WMGMFCButton.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
                app.GMfileSelectDropDown_2.Enable = "off";

                app.AtlasFCButton.Enable = "off";
                app.AtlasFCButton.Tooltip = 'Upload CSV File with the first column containing participant names and the other columns containing the age, sex, or behavioral data.';
                app.AtlasfileSelectDropDown_2.Enable = "off";
                return
            end
            
            app.Tabposition = 0;
            ex_time_folder = fullfile(app.output_folder,'Analysis','Freq_Specific_ROI_Timeseries');


            app.atlas_folders = dir(ex_time_folder);
            if isempty(app.atlas_folders)
                msgbox('No Freq_Specific_ts folder found. Please run extract timeseries from FC and FN section and try again.')
                app.WMFCButton.Enable = 'off';
                app.WMCCFCButton.Enable = 'off';
                app.WMGMFCButton.Enable = 'off';
                app.AtlasFCButton.Enable = 'off';
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.CCfileSelectDropDown_2.Enable = 'off';
                app.GMfileSelectDropDown_2.Enable = 'off';
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.Band_selectDropDown.Enable = 'off';
                return
            end

            app.atlas_folders = app.atlas_folders(~ismember({app.atlas_folders.name},{'.','..','.DS_Store'}));

            nt_dir = false(1,length(app.atlas_folders));                                        % make a list of other files that are not directories
            app.folders_info = false(length(app.atlas_folders),4);                                  %no. of folders x 4, col 1 --> WM networks, col 2 = CC networks, col 3 GM, col 4--> others 
            app.bands_info = cell(length(app.atlas_folders),3);
            p = 1;
            for i = 1:length(app.atlas_folders)
                if app.atlas_folders(i).isdir ~= 1
                    nt_dir(i) = true;
                end
            end
            app.atlas_folders(nt_dir) = [];
            for i = 1:length(app.atlas_folders)
                if length(app.atlas_folders(i).name) > 5 && length(app.atlas_folders(i).name) <= 37
                    if strcmp(app.atlas_folders(i).name(1:5),'WM_FN')
                        app.folders_info(i,1) = true;                    
                    elseif strcmp(app.atlas_folders(i).name(1:5),'GM_FN')
                        app.folders_info(i,3) = true;
                    else
                        app.folders_info(i,4) = true;
                    end
                elseif  length(app.atlas_folders(i).name) > 37
                    if strcmp(app.atlas_folders(i).name(1:37),'Original_corpus_callosum_FN_from_WM_K')
                        app.folders_info(i,2) = true;
                    else
                        app.folders_info(i,4) = true;
                    end
                else
                    app.folders_info(i,4) = true;
                end


                temp = dir(fullfile(app.atlas_folders(i).folder,app.atlas_folders(i).name,['Participant_' app.atlas_folders(i).name '_avg_ts']));
                temp = temp(~ismember({temp.name},{'.','..','.DS_Store'}));
                for i1 = 1:length(temp)
                    avg_ts_dir = dir(fullfile(temp(i1).folder,temp(i1).name,'*avg_ts.mat'));
                    temp_avg_ts = load(fullfile(avg_ts_dir(1).folder,avg_ts_dir(1).name));
                    app.bands_info(p,1) = {temp_avg_ts.band_name};  % band_name
                    app.bands_info(p,2) = {app.atlas_folders(i).name};                                  % atlas_name
                    app.bands_info(p,3) = {i};                                                        % atlas_number
                    p = p+1;
                end

                
            end
            
            
            bands_found = unique(app.bands_info(:,1));
            app.Band_selectDropDown.Items = string(bands_found);
            current_band = app.Band_selectDropDown.Value;
            
            initialise_freq_buttons(app,current_band)


        end

        % Button down function: AllfrequenciesTab
        function AllfrequenciesTabButtonDown(app, event)
            if isempty(app.X)
                return
            end
            pause(0.5)
            d = uiprogressdlg(app.StatisticsUIFigure,'Title','Please wait','Message','Loading ... ',...
                'Indeterminate','on');
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            app.vec1 = [];
           
             WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_FN','WM_FN_K*'));

            if isscalar(WM_reg_files)
                app.WMfileSelectDropDown.Enable = 'off';

                app.final_result = load_in_cell_wm(app,fullfile(WM_reg_files.folder,WM_reg_files.name,'Participant_WM_FN_K*_avg_ts','*_WM_FN_K*.mat'));
                
                app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFCButton_2.Enable = "off";
                app.WMFCButton_2.Tooltip = 'White Matter Network average timeseries file not found: Please create the WM-FN from the Create FN and FC module and try again';
            end
            cc_reg_file = dir(fullfile(app.output_folder,'Analysis','Original_corpus_callosum','Original_corpus_callosum_FN_from_WM_K*'));


            if isscalar(cc_reg_file)
                app.CCfileSelectDropDown.Enable  = 'off';
                app.final_result_cc = load_in_cell_cc(app,fullfile(cc_reg_files.folder,cc_reg_files.name,'Participant_Original_corpus_callosum_FN_from_WM_K*_avg_ts','*_Original_corpus_callosum_FN_from_WM_K*.mat'));
                
            elseif length(cc_reg_file) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_file);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCCFCButton_2.Enable = "off";
                app.WMCCFCButton_2.Tooltip = 'Corpus Callosum average timeseries file not found: Please create the Corpus callosum networks by checking the Corpus callosum check box under the WM-FN tab from the Create FN and FC module and try again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','ROI_Timeseries'));
            Atlas_reg_files = Atlas_reg_files(~ismember({Atlas_reg_files.name}, {'.','..','.DS_Store'}));  % remove unwanted

            if isscalar(Atlas_reg_files)
                app.AtlasfileSelectDropDown.Enable  = 'off';
                get_atlas_regts(app,Atlas_reg_files)
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasFCButton_2.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasFCButton_2.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end


             GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_FN','GM_FN_K*'));

            if isscalar(GM_reg_files)
                app.final_result_gm = load_in_cell_gm(app,fullfile(GM_reg_files.folder,GM_reg_files.name,'Participant_GM_FN_K*_avg_ts','*_GM_FN_K*.mat'));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMFCButton_2.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMFCButton_2.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end

            
            close(d)
            app.Tabposition = 1;
        end

        % Value changed function: Band_selectDropDown
        function Band_selectDropDownValueChanged(app, event)
            current_band = app.Band_selectDropDown.Value;
            initialise_freq_buttons(app,current_band)
            %             cla(app.UIAxes)
            switch app.slide_select
                case 1
                    if strcmp(app.WMFCButton.Enable,'on')
                        WMFCButtonPushed(app, event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the WM-FN file from: ',fullfile(app.output_folder,'Analysis','WM_FN','WM_clustering_K*.nii'),', with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end

                case 2
                    if strcmp(app.WMCCFCButton.Enable,'on')
                        WMCCFCButtonPushed(app, event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the CC networks file from: ',fullfile(app.output_folder,'Analysis','Corpus_Callosum_FN','CC_network_from_WM_K*.nii'),', with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end
                case 3
                    if strcmp(app.WMGMFCButton.Enable,'on')
                        WMGMFCButtonPushed(app, event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the GM networks file from: ',fullfile(app.output_folder,'Analysis','GM_FN','GM_clustering_K*.nii'),', with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end
                case 4
                    if strcmp(app.AtlasFCButton.Enable,'on')
                        AtlasFCButtonPushed(app,event)
                    else
                        msgbox(['Frequency Band: ' char(app.Band_selectDropDown.Value) ' Not found. Please extract frequency specific timeseries from the Extract Time series under Create FN and FC module, choose the desired atlas with Frequency specific band name ',char(app.Band_selectDropDown.Value), ' and try again'],'Frequency Specific Timeseries not found')
                    end
            end
        end

        % Button pushed function: WMFCButton
        function WMFCButtonPushed(app, event)
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                current_band = app.Band_selectDropDown.Value;
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,wm_file,['Participant_' wm_file '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
            end
            
            
            WM_ttest(app,app.final_result.reg_ts_freq,app.final_result.Subj_list)

            
        end

        % Button pushed function: WMCCFCButton
        function WMCCFCButtonPushed(app, event)
            current_band = app.Band_selectDropDown.Value;

            if strcmp(app.CCfileSelectDropDown_2.Enable,'on')
                cc_file = app.CCfileSelectDropDown_2.Value;
                app.final_result_cc = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,cc_file,['Participant_' cc_file '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
            end
            hp1 = app.final_result_cc.hp;
            lp1 = app.final_result_cc.lp;
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,wm_file,['Participant_' wm_file '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
            end
            hp2 = app.final_result.hp;
            lp2 = app.final_result.lp;
            if ~(hp1 == hp2 && lp1 == lp2)
                msgbox('The Frequency range for WM and CC are different, although the band name is the same','Band Name same but Frequency Range different.')
            else
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
            end
            [~,ia,ib] = intersect(substruc2string(app,app.final_result.Subj_list),substruc2string(app,app.final_result_cc.Subj_list));
            if strcmp(app.WMFCButton.Enable, 'on')
                WM_CC_ttest(app,app.final_result.reg_ts_freq(ia),app.final_result_cc.reg_ts_freq(ib),app.final_result_cc.Subj_list(ib))
            else
                msgbox('White Matter Network average timeseries file not found: Please extract frequency specific timeseries from the Extract Time series tab under Create FN and FC module and choose the WM-FN file <WM_clustering_K*.nii> and try again')
            end

        end

        % Button pushed function: WMGMFCButton
        function WMGMFCButtonPushed(app, event)
             current_band = app.Band_selectDropDown.Value;
            if strcmp(app.GMfileSelectDropDown_2.Enable,'on')
                gm_file = app.GMfileSelectDropDown_2.Value;
                app.final_result_gm = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,gm_file,['Participant_' gm_file '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
            end
            hp1 = app.final_result_gm.hp;
            lp1 = app.final_result_gm.lp;
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,wm_file,['Participant_' wm_file '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
            end
            hp2 = app.final_result.hp;
            lp2 = app.final_result.lp;
            if ~(hp1 == hp2 && lp1 == lp2)
                msgbox('The Frequency range for WM and GM are different, although the band name is the same','Band Name same but Frequency Range different.')
            else
                app.hp = app.final_result.hp;
                app.lp = app.final_result.lp;
            end
            [~,ia,ib] = intersect(substruc2string(app,app.final_result.Subj_list),substruc2string(app,app.final_result_gm.Subj_list));
            if strcmp(app.WMFCButton.Enable, 'on')
                WMGM_ttest(app,app.final_result.reg_ts_freq(ia),app.final_result_gm.reg_ts_freq(ib),app.final_result_gm.Subj_list(ib))
            else
                msgbox('White Matter Network average timeseries file not found: Please extract frequency specific timeseries from the Extract Time series tab under Create FN and FC module and choose the WM-FN file <WM_clustering_K*.nii> and try again')
            end
        end

        % Button pushed function: AtlasFCButton
        function AtlasFCButtonPushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown_2.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown_2.Value;
                app.atlas_name = app.AtlasfileSelectDropDown_2.Value;
                current_band = app.Band_selectDropDown.Value;
                app.final_result_atlas = load_in_cell_freq(app,fullfile(app.atlas_folders(1).folder,app.atlas_file,['Participant_' app.atlas_file '_avg_ts'],[current_band '*'],'*avg_ts.mat'));
                app.hp = app.final_result_atlas.hp;
                app.lp = app.final_result_atlas.lp;
                if isempty(app.RegionsEditField.Value)
                    app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts_freq{1},2))];
                end
            end
            Atlas_based_ttest(app,app.final_result_atlas.reg_ts_freq,app.final_result_atlas.Subj_list)
        end

        % Value changed function: CCfileSelectDropDown
        function CCfileSelectDropDownValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: CCfileSelectDropDown_2
        function CCfileSelectDropDown_2ValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: WMfileSelectDropDown_2
        function WMfileSelectDropDown_2ValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: GMfileSelectDropDown_2
        function GMfileSelectDropDown_2ValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: AtlasfileSelectDropDown_2
        function AtlasfileSelectDropDown_2ValueChanged(app, event)
            app.RegionsEditField.Value = '';
            select_slide(app,event)
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            figure;
            if app.slide_select == 0
                msgbox('Select a FC first','Select FC')
                return
            end
            
            net1 = round(app.Net1YaxisEditField.Value);
            % edge cases
            switch app.slide_select
                
                case 1
                    net1_low = str2double(app.UIAxes2.YTickLabel{1});
                    net1_high = str2double(app.UIAxes2.YTickLabel{end});

                    net2_low = str2double(app.UIAxes2_2.XTickLabel{1});
                    net2_high = str2double(app.UIAxes2_2.XTickLabel{end});
                case 2
                    net1_low = str2double(app.UIAxes2.YTickLabel{1});
                    net1_high = str2double(app.UIAxes2.YTickLabel{end});

                    net2_low = str2double(app.UIAxes2_2.XTickLabel{1});
                    net2_high = str2double(app.UIAxes2_2.XTickLabel{end});
                case 3
                    net1_low = str2double(app.UIAxes2.YTickLabel{1});
                    net1_high = str2double(app.UIAxes2.YTickLabel{end});

                    net2_low = str2double(app.UIAxes2_2.XTickLabel{1});
                    net2_high = str2double(app.UIAxes2_2.XTickLabel{end});
                case 4
                    net1_low = app.UIAxes.YTick(1);
                    net1_high = app.UIAxes.YTick(end);

                    net2_low = app.UIAxes.XTick(1);
                    net2_high = app.UIAxes.XTick(end);

            end


            if net1 < net1_low
                net1 = net1_low;
                app.Net1YaxisEditField.Value = net1;
            elseif net1 > net1_high
                net1 = net1_high;
                app.Net1YaxisEditField.Value = net1;
            end
            net2 = round(app.Net2XaxisEditField.Value);
            if net2 < net2_low
                net2 = net2_low;
                app.Net2XaxisEditField.Value = net2;
            elseif net2 > net2_high
                net2 = net2_high;
                app.Net2XaxisEditField.Value = net2;
            end
            plots_for_pairs(app,net1,net2)
            

        end

        % Value changed function: RegionsEditField
        function RegionsEditFieldValueChanged(app, event)
            value = app.RegionsEditField.Value;
            app.atlas_regions = value;
            if app.Tabposition
                AtlasFCButton_2Pushed(app, event)
            else
                AtlasFCButtonPushed(app, event)
            end
        end

        % Button pushed function: SaveplotasimageButton
        function SaveplotasimageButtonPushed(app, event)
            try
                
                [imgfile,imgfolderpath] = uiputfile('WhiFuN_stats_figure.png','Save FC visualize figure');

            catch
            end
            if all(imgfolderpath == 0) || isempty(imgfolderpath)
                return
            end

            switch app.plotDropDown.Value
                case 'All values'
                    app.UIAxes.XColor = [0,0,0];
                    app.UIAxes.YColor = [0,0,0];
                    c = app.UIAxes.Colorbar;
                    c.Color = [0 0 0];
                    app.UIAxes2_2.XColor = [0,0,0];
                    set(app.UIAxes2_2,'FontSize',18)
                    app.UIAxes2.YColor = [0,0,0];
                    set(app.UIAxes2,'FontSize',18)
                    exportgraphics(app.StatisticsUIFigure,fullfile(imgfolderpath,imgfile),"BackgroundColor",[1,1,1])
                    app.UIAxes.XColor = [1,1,1];
                    app.UIAxes.YColor = [1,1,1];
                    c.Color = [1 1 1];
                    app.UIAxes2_2.XColor = [1 1 1];
                    app.UIAxes2.YColor = [1 1 1];
                    map_pos = app.UIAxes2.tightPosition;
                    map_pos2 = app.UIAxes2_2.tightPosition;
                    set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
                    set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
                case 'Significant only'
%                     app.UIAxes.XColor = [0,0,0];
%                     app.UIAxes.YColor = [0,0,0];
%                     
%                     app.colbar1.Color = [0 0 0];
%                     app.colbar2.Color = [0 0 0];
%                     app.colbar1.Title.Color= [0 0 0];
%                     app.colbar2.Title.Color= [0 0 0];
% 
%                     app.UIAxes2_2.XColor = [0,0,0];
%                     set(app.UIAxes2_2,'FontSize',18)
%                     app.UIAxes2.YColor = [0,0,0];
%                     set(app.UIAxes2,'FontSize',18)
                    exportgraphics(app.StatisticsUIFigure,fullfile(imgfolderpath,imgfile),"BackgroundColor",[0,0,0])
%                     app.UIAxes.XColor = [1,1,1];
%                     app.UIAxes.YColor = [1,1,1];
%                     app.UIAxes2_2.XColor = [1 1 1];
%                     app.UIAxes2.YColor = [1 1 1];
                    map_pos = app.UIAxes2.tightPosition;
                    map_pos2 = app.UIAxes2_2.tightPosition;
                    set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
                    set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

            end
        end

        % Value changed function: VariablesDropDown
        function VariablesDropDownValueChanged(app, event)
            val_var = app.VariablesDropDown.Value;
            if ~isempty(app.vec1)
                [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));
                t = zeros(size(app.vec1,1),1);
                p = zeros(size(app.vec1,1),1);
                for i = 1:size(app.vec1,1)
                    %                 if val_idx == 1
                    %                     t(i) = app.stats{i}.fstat.f;
                    %                     p(i) = app.stats{i}.fstat.pval;
                    %                 else
                    t(i) = app.stats{i}.tstat.t(val_idx+1);
                    p(i) = app.stats{i}.tstat.pval(val_idx+1);
                    %                 end
                end

                if app.slide_select == 4
                    [app.pos1,app.pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,str2double(app.UIAxes.XLabel.String(1:end-4)));
                else
                    colorbar(app.UIAxes,'off')
                    [app.pos1,app.pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size(app.UIAxes2.YTick,2));
                end

                switch app.plotDropDown.Value

                    case 'All values'
                        c = colorbar(app.UIAxes);
                        c.Color = [1 1 1];
                        c_pos = c.Position;
                        c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                        c1.Color = [1 1 1];
                        c1.Title.String = 'T-Values';
                        c1.Title.Color = [1,1,1];
                        text(app.UIAxes,app.pos2,app.pos1,"*","Color",'w','FontSize',40)
                    case 'Significant only'
                        c = colorbar(app.UIAxes);
                        c.Color = [1 1 1];
                        c_pos = c.Position;
                        c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                        c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                        c1.Color = [1 1 1];

                        c1.Limits = neg_clim;
                        c.Limits = pos_clim;
                        c.Title.String = 'Pos(+)';
                        c.Title.Color = [1,1,1];

                        c1.Title.String = 'Neg(-)';
                        c1.Title.Color = [1,1,1];
                end
                my_datatip_cc(app,s,p)
            end
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            select_slide(app,event)
        end

        % Button pushed function: SaveplotsofallsignificantpairsButton
        function SaveplotsofallsignificantpairsButtonPushed(app, event)
            if app.slide_select == 0
                msgbox('Select a FC first','Select FC')
                return
            end
            switch app.slide_select
                
                case 1
                    pre1 = 'WM';
                    pre2 = 'WM';
                    
                case 2
                    pre1 = 'WM';
                    pre2 = 'CC';
                    
                case 3
                    pre1 = 'WM';
                    pre2 = 'GM';
                case 4
                   pre1 = [app.atlas_name '_ROI1'];
                   pre2 = 'ROI2';

            end
            f = figure(Visible="off");

            out_image_folder = uigetdir(fullfile(app.output_folder,'Analysis'));


            for i = 1:length(app.pos1)
                    
                out_image_path = fullfile(out_image_folder,[pre1 '-' num2str(app.pos1(i)) '_' pre2 '-' num2str(app.pos2(i)) '.png']);

                    net1 = app.pos1(i);
                    if net1 < app.UIAxes.YLim(1)
                        net1 = app.UIAxes.YLim(1) + 0.5;
                        app.Net1YaxisEditField.Value = net1;
                    elseif net1 > app.UIAxes.YLim(2)
                        net1 = app.UIAxes.YLim(2) - 0.5;
                        app.Net1YaxisEditField.Value = net1;
                    end
                    net2 = app.pos2(i);
                    if net2 < app.UIAxes.XLim(1)
                        net2 = app.UIAxes.XLim(1) + 0.5;
                        app.Net2XaxisEditField.Value = net2;
                    elseif net2 > app.UIAxes.XLim(2)
                        net2 = app.UIAxes.XLim(2) - 0.5;
                        app.Net2XaxisEditField.Value = net2;
                    end
                    plots_for_pairs(app,net1,net2)
                    exportgraphics(f,out_image_path)
         
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.StatisticsUIFigure = uifigure('Visible', 'off');
            app.StatisticsUIFigure.Name = 'Statistics';
            app.StatisticsUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.UIAxes = uiaxes(app.StatisticsUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'parula')
            app.UIAxes2 = uiaxes(app.StatisticsUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            colormap(app.UIAxes2, 'parula')
            app.UIAxes2_2 = uiaxes(app.StatisticsUIFigure);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            colormap(app.UIAxes2_2, 'parula')
            app.TabGroup = uitabgroup(app.StatisticsUIFigure);
            app.AllfrequenciesTab = uitab(app.TabGroup);
            app.AllfrequenciesTab.Title = 'All frequencies';
            app.AllfrequenciesTab.ButtonDownFcn = createCallbackFcn(app, @AllfrequenciesTabButtonDown, true);
            app.WMFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.WMFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMFCButton_2Pushed, true);
            app.WMFCButton_2.Text = 'WM-FC';
            app.WMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.WMfileSelectLabel.Text = 'WM file Select';
            app.WMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.WMfileSelectDropDown.Items = {};
            app.WMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDownValueChanged, true);
            app.WMfileSelectDropDown.Value = {};
            app.WMCCFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.WMCCFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMCCFCButton_2Pushed, true);
            app.WMCCFCButton_2.Text = 'WM-CC-FC';
            app.GMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.GMfileSelectLabel.Text = 'GM file Select';
            app.GMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.GMfileSelectDropDown.Items = {};
            app.GMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDownValueChanged, true);
            app.GMfileSelectDropDown.Value = {};
            app.AtlasFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.AtlasFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @AtlasFCButton_2Pushed, true);
            app.AtlasFCButton_2.Text = 'Atlas-FC';
            app.AtlasfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.AtlasfileSelectLabel.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.AtlasfileSelectDropDown.Items = {};
            app.AtlasfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDownValueChanged, true);
            app.AtlasfileSelectDropDown.Value = {};
            app.CCfileSelectDropDownLabel = uilabel(app.AllfrequenciesTab);
            app.CCfileSelectDropDownLabel.Text = 'CC file Select';
            app.CCfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.CCfileSelectDropDown.Items = {};
            app.CCfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @CCfileSelectDropDownValueChanged, true);
            app.CCfileSelectDropDown.Value = {};
            app.WMGMFCButton_2 = uibutton(app.AllfrequenciesTab, 'push');
            app.WMGMFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMGMFCButton_2Pushed, true);
            app.WMGMFCButton_2.Text = 'WM-GM-FC';
            app.FrequencyBandsTab = uitab(app.TabGroup);
            app.FrequencyBandsTab.Title = 'Frequency Bands';
            app.FrequencyBandsTab.ButtonDownFcn = createCallbackFcn(app, @FrequencyBandsTabButtonDown, true);
            app.WMFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.WMFCButton.ButtonPushedFcn = createCallbackFcn(app, @WMFCButtonPushed, true);
            app.WMFCButton.Text = 'WM-FC';
            app.WMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.WMfileSelectLabel_2.Text = 'WM file Select';
            app.WMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.WMfileSelectDropDown_2.Items = {};
            app.WMfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDown_2ValueChanged, true);
            app.WMfileSelectDropDown_2.Value = {};
            app.WMCCFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.WMCCFCButton.ButtonPushedFcn = createCallbackFcn(app, @WMCCFCButtonPushed, true);
            app.WMCCFCButton.Text = 'WM-CC-FC';
            app.GMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.GMfileSelectLabel_2.Text = 'GM file Select';
            app.GMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.GMfileSelectDropDown_2.Items = {};
            app.GMfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDown_2ValueChanged, true);
            app.GMfileSelectDropDown_2.Value = {};
            app.AtlasFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.AtlasFCButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasFCButtonPushed, true);
            app.AtlasFCButton.Text = 'Atlas-FC';
            app.AtlasfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.AtlasfileSelectLabel_2.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.AtlasfileSelectDropDown_2.Items = {};
            app.AtlasfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDown_2ValueChanged, true);
            app.AtlasfileSelectDropDown_2.Value = {};
            app.CCfileSelectDropDownLabel_2 = uilabel(app.FrequencyBandsTab);
            app.CCfileSelectDropDownLabel_2.Text = 'CC file Select';
            app.CCfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.CCfileSelectDropDown_2.Items = {};
            app.CCfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CCfileSelectDropDown_2ValueChanged, true);
            app.CCfileSelectDropDown_2.Value = {};
            app.FrequencyBandSelectLabel = uilabel(app.FrequencyBandsTab);
            app.FrequencyBandSelectLabel.Text = {'Frequency Band'; ' Select'};
            app.Band_selectDropDown = uidropdown(app.FrequencyBandsTab);
            app.Band_selectDropDown.Items = {};
            app.Band_selectDropDown.ValueChangedFcn = createCallbackFcn(app, @Band_selectDropDownValueChanged, true);
            app.Band_selectDropDown.Value = {};
            app.WMGMFCButton = uibutton(app.FrequencyBandsTab, 'push');
            app.WMGMFCButton.ButtonPushedFcn = createCallbackFcn(app, @WMGMFCButtonPushed, true);
            app.WMGMFCButton.Text = 'WM-GM-FC';
            app.DataTipsCheckBox = uicheckbox(app.StatisticsUIFigure);
            app.DataTipsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataTipsCheckBoxValueChanged, true);
            app.DataTipsCheckBox.Text = 'Data Tips';
            app.CorrectionDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.StatisticsUIFigure);
            app.CorrectionDropDown.Items = {'False Discovery Rate ', 'Bonferroni'};
            app.CorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionDropDownValueChanged, true);
            app.CorrectionDropDown.Value = 'False Discovery Rate ';
            app.AlphaEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.StatisticsUIFigure, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.05;
            app.UploadCSVwithbehaviourmetadataforParticipantsButton = uibutton(app.StatisticsUIFigure, 'push');
            app.UploadCSVwithbehaviourmetadataforParticipantsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCSVwithbehaviourmetadataforParticipantsButtonPushed, true);
            app.UploadCSVwithbehaviourmetadataforParticipantsButton.Text = 'Upload CSV with behaviour meta data for  Participants';
            app.Group2label = uilabel(app.StatisticsUIFigure);
            app.Group2label.Text = '';
            app.PlotButton = uibutton(app.StatisticsUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Net1YaxisEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.Net1YaxisEditFieldLabel.Text = {'Net 1'; '(Y-axis)'};
            app.Net1YaxisEditField = uieditfield(app.StatisticsUIFigure, 'numeric');
            app.Net2XaxisEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.Net2XaxisEditFieldLabel.Text = {'Net 2'; '(X-axis)'};
            app.Net2XaxisEditField = uieditfield(app.StatisticsUIFigure, 'numeric');
            app.RegionsEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.RegionsEditFieldLabel.Text = 'Regions';
            app.RegionsEditField = uieditfield(app.StatisticsUIFigure, 'text');
            app.RegionsEditField.ValueChangedFcn = createCallbackFcn(app, @RegionsEditFieldValueChanged, true);
            app.plotDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.plotDropDownLabel.Text = 'plot';
            app.plotDropDown = uidropdown(app.StatisticsUIFigure);
            app.plotDropDown.Items = {'All values', 'Significant only'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Value = 'All values';
            app.AlphaEditFieldLabel_2 = uilabel(app.StatisticsUIFigure);
            app.AlphaEditFieldLabel_2.Text = 'Bonferonni corrected alpha :';
            app.HasHeaderCheckBox = uicheckbox(app.StatisticsUIFigure);
            app.HasHeaderCheckBox.Text = 'Has Header';
            app.HasHeaderCheckBox.Value = true;
            app.VariablesDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.VariablesDropDownLabel.Text = 'Variables';
            app.VariablesDropDown = uidropdown(app.StatisticsUIFigure);
            app.VariablesDropDown.Items = {};
            app.VariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDownValueChanged, true);
            app.VariablesDropDown.Value = {};
            app.SaveplotasimageButton = uibutton(app.StatisticsUIFigure, 'push');
            app.SaveplotasimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotasimageButtonPushed, true);
            app.SaveplotasimageButton.Text = 'Save plot as image';
            app.NoofParticipantsLabel = uilabel(app.StatisticsUIFigure);
            app.NoofParticipantsLabel.Text = 'No. of Participants:';
            app.ParticipantslistDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.ParticipantslistDropDownLabel.Text = 'Participants list';
            app.ParticipantslistDropDown = uidropdown(app.StatisticsUIFigure);
            app.ParticipantslistDropDown.Items = {};
            app.ParticipantslistDropDown.Value = {};
            app.SaveplotsofallsignificantpairsButton = uibutton(app.StatisticsUIFigure, 'push');
            app.SaveplotsofallsignificantpairsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotsofallsignificantpairsButtonPushed, true);
            app.SaveplotsofallsignificantpairsButton.Text = {'Save plots of all '; 'significant pairs'};
            app.fitlineCheckBox = uicheckbox(app.StatisticsUIFigure);
            app.fitlineCheckBox.Text = 'fit line';
            % Show the figure after all components are created
            app.StatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whifun_statistics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatisticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticsUIFigure)
        end
    end
end