classdef whifun_statistics_old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticsUIFigure              matlab.ui.Figure
        SubjectlistDropDown             matlab.ui.control.DropDown
        SubjectlistDropDownLabel        matlab.ui.control.Label
        NoofSubjectsLabel               matlab.ui.control.Label
        SaveplotasimageButton           matlab.ui.control.Button
        VariablesDropDown               matlab.ui.control.DropDown
        VariablesDropDownLabel          matlab.ui.control.Label
        HasHeaderCheckBox               matlab.ui.control.CheckBox
        AlphaEditFieldLabel_2           matlab.ui.control.Label
        plotDropDown                    matlab.ui.control.DropDown
        plotDropDownLabel               matlab.ui.control.Label
        RegionsEditField                matlab.ui.control.EditField
        RegionsEditFieldLabel           matlab.ui.control.Label
        Net2XaxisEditField              matlab.ui.control.NumericEditField
        Net2XaxisEditFieldLabel         matlab.ui.control.Label
        Net1YaxisEditField              matlab.ui.control.NumericEditField
        Net1YaxisEditFieldLabel         matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        Group2label                     matlab.ui.control.Label
        UploadCSVforSubjectGroupsButton  matlab.ui.control.Button
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        CorrectionDropDown              matlab.ui.control.DropDown
        CorrectionDropDownLabel         matlab.ui.control.Label
        DataTipsCheckBox                matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        AllfrequenciesTab               matlab.ui.container.Tab
        CCfileSelectDropDown            matlab.ui.control.DropDown
        CCfileSelectDropDownLabel       matlab.ui.control.Label
        AtlasfileSelectDropDown         matlab.ui.control.DropDown
        AtlasfileSelectLabel            matlab.ui.control.Label
        AtlasbasedFCButton              matlab.ui.control.Button
        GMfileSelectDropDown            matlab.ui.control.DropDown
        GMfileSelectLabel               matlab.ui.control.Label
        WMGMNetworkConnectivityButton   matlab.ui.control.Button
        WMCorpusCallosumNetworksButton  matlab.ui.control.Button
        WMfileSelectDropDown            matlab.ui.control.DropDown
        WMfileSelectLabel               matlab.ui.control.Label
        WMFunctionalNetworkconnectvityButton  matlab.ui.control.Button
        FrequencyBandsTab               matlab.ui.container.Tab
        Band_selectDropDown             matlab.ui.control.DropDown
        FrequencyBandSelectLabel        matlab.ui.control.Label
        CCfileSelectDropDown_2          matlab.ui.control.DropDown
        CCfileSelectDropDownLabel_2     matlab.ui.control.Label
        AtlasfileSelectDropDown_2       matlab.ui.control.DropDown
        AtlasfileSelectLabel_2          matlab.ui.control.Label
        AtlasbasedFCButton_2            matlab.ui.control.Button
        GMfileSelectDropDown_2          matlab.ui.control.DropDown
        GMfileSelectLabel_2             matlab.ui.control.Label
        WMGMNetworkConnectivityButton_2  matlab.ui.control.Button
        WMCorpusCallosumNetworksButton_2  matlab.ui.control.Button
        WMfileSelectDropDown_2          matlab.ui.control.DropDown
        WMfileSelectLabel_2             matlab.ui.control.Label
        WMFunctionalNetworkconnectvityButton_2  matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main App
        final_result
        final_result_cc
        Subj_list
        slide_select = 0
        final_result_gm
        output_folder
        final_result_atlas
        atlas_file
        data_path
        Subj_list1
        Subj_list2
        all_sub_names
        current_fig = [];
        bands_info
        folders_info
        atlas_folders
        Tabposition % Description
        over_write
        preproc_code_path
        atlas_name
        initial_UIAxes_pos
        initial_UIAxes2_pos
        initial_UIAxes2_2_pos
        spm_path
        atlas_regions
        vec1
        X
        sub_names_csv
        CSV_head % Description
        stats % Description

    end
    
    
    methods (Access = private)
        
        function sub_string = substruc2string(~,Subj_list)
            if ~isempty(Subj_list)
                sub_string = struct2cell(Subj_list);
                sub_string(2:end,:) = [];
                sub_string = string(sub_string);
            else
                sub_string= "";
            end
        end
        
        function load_sub_dropdown1(app,Subj_names)
            app.SubjectlistDropDown.Items = Subj_names;
            app.Group1label.Text = [app.Group1NameEditField.Value ' subjects no.: ' num2str(length(Subj_names))];

        end
        function load_sub_dropdown2(app,Subj_names)
            app.SubjectlistDropDown_2.Items = Subj_names;
            app.Group2label.Text = [app.Group2NameEditField.Value ' subjects no.: ' num2str(length(Subj_names))];
        end
        
        function WM_ttest(app,final_result,Subj_list)
            app.RegionsEditField.Visible = "off";
            app.RegionsEditFieldLabel.Visible = 'off';
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the Subject Ids in CSV file (Column 1 of CSV file) has different subject names from the subject folder. Please use the same names from Subject folder and try again.' )
                return
            end
            app.SubjectlistDropDown.Items = Sub_names;
            app.NoofSubjectsLabel.Text = ['Number of Subjects : ' num2str(length(Sub_names))];

            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')
            axis(app.UIAxes,'on')
            cla(app.UIAxes)
 
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            app.slide_select = 2;
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            app.slide_select = 1;
            
            map = zeros(size(final_result,2),size(final_result,2),length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(final_result(:,:,i)));
                p = p+1;

            end
            
            
             val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = corrvec(map);
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                stat = regstats(app.vec1(i,:),app.X(iia,:));
                app.stats{i} = stat;

%                 if val_idx == 1
%                     t(i) = stat.fstat.f;
%                     p(i) = stat.fstat.pval;
%                 else
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
%                 end
            end
            t(isnan(t)) = 0;
            [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size(final_result,2));
                                  
             %% side maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_networks',['WM_clustering_K',num2str(size(final_result,2)),'_BrainNet_Images']);%'F:\WhiFuN_results_HCP_100_motion_reject\Analysis\WM_Networks\WM_network_BrainNet_Images';

            WM_net_no = size(final_result,2);
            
            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);
            
            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])

            a = imtile(WM_im_filename,'GridSize',[1,WM_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'WM Networks')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')

            x_tick_pos = round((m_size(2)/WM_net_no)/2:(m_size(2)/WM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:WM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

%             app.UIAxes.Title.String = ["ttest between " app.Group1NameEditField.Value ' and ' app.Group2NameEditField.Value];
            app.UIAxes.Title.Color = [1,1,1];
%             clim(app.UIAxes,[0,1])         
            
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            axis(app.UIAxes,'off')
            axis(app.UIAxes,'off')

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

            switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];
                    text(app.UIAxes,pos1,pos2,"*","Color",'w','FontSize',40)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
            end

           
            my_datatip_wm(app,s,p)
        end


        function WM_CC_ttest(app,final_result,final_result_ccn,Subj_list)
            app.RegionsEditField.Visible = "off";
            app.RegionsEditFieldLabel.Visible = 'off';
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the Subject Ids in CSV file (Column 1 of CSV file) has different subject names from the subject folder. Please use the same names from Subject folder and try again.' )
                return
            end

            app.SubjectlistDropDown.Items = Sub_names;
            app.NoofSubjectsLabel.Text = ['Number of Subjects : ' num2str(length(Sub_names))];
            
            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')
            axis(app.UIAxes,'on')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);

            cla(app.UIAxes)
 
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')
            app.slide_select = 2;
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";
            map = zeros(size(final_result,2),size(final_result_ccn,2),length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(final_result(:,:,i),final_result_ccn(:,:,i))); %
                p = p+1;

            end
           
            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = reshape(map,[],length(ia));
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
                app.stats{i} = stat;

%                 if val_idx == 1
%                     t(i) = stat.fstat.f;
%                     p(i) = stat.fstat.pval;
%                 else
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
%                 end
            end
            [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size(final_result,2));
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";

%% side maps
            CC_image_folder = fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks',['CC_network_from_WM_K',num2str(size(final_result_ccn,2)),'_BrainNet_Images']);
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_networks',['WM_clustering_K',num2str(size(final_result,2)),'_BrainNet_Images']);

            WM_net_no = size(final_result,2);
            CC_net_no = size(final_result_ccn,2);
            
            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
                
            CC_im_filename = cell(CC_net_no,1);
            for i = 1:CC_net_no
                CC_im_filename{i} = fullfile(CC_image_folder,['CC_' num2str(i) '_left.png']);
            end
            a = imtile(CC_im_filename,'GridSize',[1,CC_net_no]);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            m_size = size(a);
            xlabel(app.UIAxes2_2,'Corpus Callosum Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/CC_net_no)/2:(m_size(2)/CC_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:CC_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
%             map_pos2_in = app.UIAxes2_2.InnerPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), map_pos2(3), map_pos2(4)])
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), map_pos2(3), map_pos(4)])

            xlabel(app.UIAxes,'Corpus calosum Regions')
            ylabel(app.UIAxes,'WM Networks')
           switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];
                    text(app.UIAxes,pos2,pos1,"*","Color",'w','FontSize',40)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
           end

           
            my_datatip_cc(app,s,p)
        end
        
        function WMGM_ttest(app,final_result,final_result_gm,Subj_list)
            
            app.RegionsEditField.Visible = "off";
            app.RegionsEditFieldLabel.Visible = 'off';
            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the Subject Ids in CSV file (Column 1 of CSV file) has different subject names from the subject folder. Please use the same names from Subject folder and try again.' )
                return
            end
            app.SubjectlistDropDown.Items = Sub_names;
            app.NoofSubjectsLabel.Text = ['Number of Subjects : ' num2str(length(Sub_names))];

            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            colorbar(app.UIAxes,'off')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
            axis(app.UIAxes,'off')

            app.slide_select = 3;
            
            map = zeros(size(final_result,2),size(final_result_gm,2),length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(final_result(:,:,i),final_result_gm(:,:,i))); %
                p = p+1;

            end
            
            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = reshape(map,[],length(ia));
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                stat = regstats(app.vec1(i,:),app.X(iia,:));
                app.stats{i} = stat;

%                 if val_idx == 1
%                     t(i) = stat.fstat.f;
%                     p(i) = stat.fstat.pval;
%                 else
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
%                 end
            end
            [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size(final_result,2));
           
%% Side Maps
            app.UIAxes2_2.Visible = "on";
            app.UIAxes2.Visible = "on";

            GM_image_folder = fullfile(app.output_folder,'Analysis','GM_networks',['GM_clustering_K',num2str(size(final_result_gm,2)),'_BrainNet_Images']);%'F:\WhiFuN_results_HCP_100_motion_reject\Analysis\GM_Networks\GM_network_BrainNet_Images';
            WM_image_folder = fullfile(app.output_folder,'Analysis','WM_networks',['WM_clustering_K',num2str(size(final_result,2)),'_BrainNet_Images']);%'F:\WhiFuN_results_HCP_100_motion_reject\Analysis\WM_Networks\WM_network_BrainNet_Images';

            WM_net_no = size(final_result,2);
            GM_net_no = size(final_result_gm,2);
            
            WM_im_filename = cell(WM_net_no,1);
            for i = 1:WM_net_no
                WM_im_filename{i} = fullfile(WM_image_folder,['WM_' num2str(i) '_left.png']);
            end
            a = imtile(WM_im_filename,'GridSize',[WM_net_no,1]);
            imagesc(app.UIAxes2,a)
            axis(app.UIAxes2,'image')
            m_size = size(a);

            ylabel(app.UIAxes2,'WM Networks')
            app.UIAxes2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2,'on')

            y_tick_pos = round((m_size(1)/WM_net_no)/2:(m_size(1)/WM_net_no):m_size(1));
            yticks(app.UIAxes2,y_tick_pos);
            yticklabels(app.UIAxes2,1:WM_net_no)
            set(app.UIAxes2,'YLim',[0 m_size(1)])
            GM_im_filename = cell(GM_net_no,1);
            for i = 1:GM_net_no
                GM_im_filename{i} = fullfile(GM_image_folder,['GM_' num2str(i) '_left.png']);
            end
            a = imtile(GM_im_filename,'GridSize',[1,GM_net_no]);
            m_size = size(a);
            imagesc(app.UIAxes2_2,a)
            axis(app.UIAxes2_2,'image')
            xlabel(app.UIAxes2_2,'GM Networks')
            app.UIAxes2_2.PositionConstraint = 'innerposition';
            axis(app.UIAxes2_2,'on')
            x_tick_pos = round((m_size(2)/GM_net_no)/2:(m_size(2)/GM_net_no):m_size(2));
            xticks(app.UIAxes2_2,x_tick_pos);
            xticklabels(app.UIAxes2_2,1:GM_net_no)
            set(app.UIAxes2_2,'XLim',[0 m_size(2)])

            map_pos = app.UIAxes2.tightPosition;
            map_pos2 = app.UIAxes2_2.tightPosition;
            set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
            set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

           switch app.plotDropDown.Value

               case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];
                    text(app.UIAxes,pos2,pos1,"*","Color",'w','FontSize',40)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
            end
            
            my_datatip_gm(app,s,p)
        end
        
        function Atlas_based_ttest(app,reg_ts,Subj_list)
            app.RegionsEditField.Visible = "on";
            app.RegionsEditFieldLabel.Visible = 'on';

            [Sub_names,ia,iia] = intersect(substruc2string(app,Subj_list),string(app.sub_names_csv)');

            if isempty(Sub_names)
                msgbox('It seems that the Subject Ids in CSV file (Column 1 of CSV file) has different subject names from the subject folder. Please use the same names from Subject folder and try again.' )
                return
            end
            app.SubjectlistDropDown.Items = Sub_names;
            app.NoofSubjectsLabel.Text = ['Number of Subjects : ' num2str(length(Sub_names))];
            app.UIAxes2_2.Visible = "off";
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'')
            app.UIAxes2.Visible = "off";
            cla(app.UIAxes2_2)
            xlabel(app.UIAxes2_2,'')
            colorbar(app.UIAxes,'off')
            axis(app.UIAxes,'on')
            set(app.UIAxes,'Position',app.initial_UIAxes_pos);
            set(app.UIAxes2,'Position',app.initial_UIAxes2_pos);
            set(app.UIAxes2_2,'Position',app.initial_UIAxes2_2_pos);
%             app.UIAxes2_2.Toolb = "off";
%             app.UIAxes2.Visible = "off";

            app.slide_select = 4;
            eval('reg_ts = reg_ts(:,eval(app.atlas_regions),:);') %#ok<EVLCS> 
            atlas_regions = eval(app.atlas_regions); %#ok<ADPROPLC> 
            map = zeros(size(reg_ts,2),size(reg_ts,2),length(ia));
            p = 1;
            for i = ia'
                map(:,:,p) = atanh(corr(reg_ts(:,:,i))); %
                p = p+1;

            end
            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));

            app.vec1 = corrvec(map);
            t = zeros(size(app.vec1,1),1);
            p = zeros(size(app.vec1,1),1);
            app.stats = cell(1,10);
            for i = 1:size(app.vec1,1)
                stat = regstats(app.vec1(i,:),app.X(iia,:),'linear','tstat');
                app.stats{i} = stat;

%                 if val_idx == 1
%                     t(i) = stat.fstat.f;
%                     p(i) = stat.fstat.pval;
%                 else
                    t(i) = stat.tstat.t(val_idx+1);
                    p(i) = stat.tstat.pval(val_idx+1);
%                 end
            end
            t(isnan(t)) = 0;
            [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size(reg_ts,2));
            if size(reg_ts,2) > 10
                div = 10;
            else
                div = size(reg_ts,2);
            end

            xticks(app.UIAxes,round(linspace(1,size(reg_ts,2),div)))
            yticks(app.UIAxes,round(linspace(1,size(reg_ts,2),div)))

            xticklabels(app.UIAxes,atlas_regions(round(linspace(1,size(reg_ts,2),div)))) %#ok<ADPROPLC>
            yticklabels(app.UIAxes,atlas_regions(round(linspace(1,size(reg_ts,2),div)))) %#ok<ADPROPLC>

            xlabel(app.UIAxes,[num2str(size(reg_ts,2)) ' ROI'])
            ylabel(app.UIAxes,[num2str(size(reg_ts,2)) ' ROI'])
            switch app.plotDropDown.Value

                case 'All values'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];
                    c1.Title.String = 'T-Values';
                    c1.Title.Color = [1,1,1];

                    text(app.UIAxes,pos2,pos1,"*","Color",'w','FontSize',10)
                case 'Significant only'
                    c = colorbar(app.UIAxes);
                    c.Color = [1 1 1];
                    c_pos = c.Position;
                    c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                    c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                    c1.Color = [1 1 1];

                    c1.Limits = neg_clim;
                    c.Limits = pos_clim;
                    c.Title.String = 'Pos(+)';
                    c.Title.Color = [1,1,1];

                    c1.Title.String = 'Neg(-)';
                    c1.Title.Color = [1,1,1];
            end
            my_datatip_wm(app,s,p)
        end
        
        function initialise_freq_buttons(app,current_band)
            
            current_band_atlas_idx = string(app.bands_info(:,1)) == current_band;
%             current_band_atlas = app.bands_info(current_band_atlas_idx,2);
            current_band_atlas_number = app.bands_info(current_band_atlas_idx,3);
            
            WM_reg_files_idx = intersect(find(app.folders_info(:,1)),cell2mat(current_band_atlas_number));
            
            if length(WM_reg_files_idx) == 1
                app.final_result = load(fullfile(app.atlas_folders(WM_reg_files_idx).folder,app.atlas_folders(WM_reg_files_idx).name,[app.atlas_folders(WM_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.atlas_file = app.atlas_folders(WM_reg_files_idx).name;
                app.WMFunctionalNetworkconnectvityButton_2.Enable = 'on';
            elseif length(WM_reg_files_idx) > 1
                app.WMfileSelectDropDown_2.Enable = 'on';
                wm_reg_file = struct2cell(app.atlas_folders(WM_reg_files_idx));
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown_2.Items = wm_reg_file;
                app.WMFunctionalNetworkconnectvityButton_2.Enable = 'on';
            else
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton_2.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton_2.Tooltip = 'White Matter Network average timeseries file not found: Please extract frequency specific timeseries from the Extract Time series tab under Create FN and FC module and choose the WM networks file <WM_clustering_K*.nii> and try again';
            end
            CC_reg_files_idx = intersect(find(app.folders_info(:,2)),cell2mat(current_band_atlas_number));

            if length(CC_reg_files_idx) == 1
                app.final_result_cc = load(fullfile(app.atlas_folders(CC_reg_files_idx).folder,app.atlas_folders(CC_reg_files_idx).name,[app.atlas_folders(CC_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.CCfileSelectDropDown_2.Enable  = 'off';
                app.WMCorpusCallosumNetworksButton_2.Enable = 'on';
            elseif length(CC_reg_files_idx) > 1
                app.CCfileSelectDropDown_2.Enable = 'on';
                app.WMCorpusCallosumNetworksButton_2.Enable = 'on';
                CC_reg_files = struct2cell(app.atlas_folders(CC_reg_files_idx));
                CC_reg_files(2:end,:) = [];
                app.CCfileSelectDropDown_2.Items = CC_reg_files;

            else
                app.WMCorpusCallosumNetworksButton_2.Enable = "off";
                app.WMCorpusCallosumNetworksButton_2.Tooltip = 'Corpus Callosum average timeseries file not found: Please extract frequency specific timeseries from the Extract Time series tab under Create FN and FC module and choose the WM networks file <CC_network_from_WM_K*.nii> and try again';

            end

            GM_reg_files_idx = intersect(find(app.folders_info(:,3)),cell2mat(current_band_atlas_number));

            if length(GM_reg_files_idx) == 1
                app.final_result_gm = load(fullfile(app.atlas_folders(GM_reg_files_idx).folder,app.atlas_folders(GM_reg_files_idx).name,[app.atlas_folders(GM_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.GMfileSelectDropDown_2.Enable  = 'off';
                app.WMGMNetworkConnectivityButton_2.Enable = "on";
            elseif length(GM_reg_files_idx) > 1
                app.GMfileSelectDropDown_2.Enable = 'on';
                gm_reg_file = struct2cell(app.atlas_folders(GM_reg_files_idx));
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown_2.Items = gm_reg_file;
                app.WMGMNetworkConnectivityButton_2.Enable = "on";

            else
                app.WMGMNetworkConnectivityButton_2.Enable = "off";
                app.GMfileSelectDropDown_2.Enable = 'off';
                app.WMGMNetworkConnectivityButton_2.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end

            Atlas_reg_files_idx = intersect(find(app.folders_info(:,4)),cell2mat(current_band_atlas_number));

            if length(Atlas_reg_files_idx) == 1
                app.final_result_atlas = load(fullfile(app.atlas_folders(Atlas_reg_files_idx).folder,app.atlas_folders(Atlas_reg_files_idx).name,[app.atlas_folders(Atlas_reg_files_idx).name ,'_', current_band '_reg_ts.mat']));
                app.AtlasfileSelectDropDown_2.Enable  = 'off';
                app.AtlasbasedFCButton_2.Enable = "on";
                app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts_freq,2))];
            elseif length(Atlas_reg_files_idx) > 1
                app.AtlasfileSelectDropDown_2.Enable = 'on';
                atlas_reg_file = struct2cell(app.atlas_folders(Atlas_reg_files_idx));
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown_2.Items = atlas_reg_file;
                app.AtlasbasedFCButton_2.Enable = "on";

            else
                app.AtlasbasedFCButton_2.Enable = "off";
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.AtlasbasedFCButton_2.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end
        end
        
        function select_slide(app,event)
%             disp([' tab position' num2str(app.Tabposition)])
%             disp([' Slide select' num2str(app.slide_select)])
            switch app.slide_select
                
                case 1
                    if app.Tabposition
                        WMFunctionalNetworkconnectvityButtonPushed(app, event)
                    else
                        WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
                    end
                    
                case 2
                    if app.Tabposition
                        WMCorpusCallosumNetworksButtonPushed(app, event)
                    else
                        WMCorpusCallosumNetworksButton_2Pushed(app, event)
                    end
                    
                case 3
                    if app.Tabposition
                        WMGMNetworkConnectivityButtonPushed(app, event)
                    else
                        WMGMNetworkConnectivityButton_2Pushed(app, event)
                    end
                case 4
                    if app.Tabposition
                        AtlasbasedFCButtonPushed(app, event)
                    else
                        AtlasbasedFCButton_2Pushed(app, event)
                    end

            end
        end
        
        function [my_col,pos_clim,neg_clim] = my_colormap(~,t)
            t(isnan(t)) = 0;
            if nnz(t)
                my_col = zeros(256,3);
                cval = round((t - min(t,[],'all'))./(max(t,[],'all') - min(t,[],'all')) .* (256-1))+1;
                t_0_idx = find(t==0);
                t_pos = t(t>0);
                t_neg = t(t<0);

                t_min_pos = min(t_pos);
                t_min_neg = -1*min(abs(t_neg));

                t_max_pos = max(t_pos);
                t_max_neg = -1*max(abs(t_neg));

                if isempty(t_max_neg)
                    t_max_neg = 0;
                end
                if isempty(t_max_pos)
                    t_max_pos = 0;
                end
                if ~isempty(t_0_idx)
                    cval_0 = cval(t_0_idx(1));
                    my_col(cval_0,:) = [0,0,0];
                    if ~isempty(t_pos)
                        
                        cval_min_pos_idx = find(t == t_min_pos);
                        cval_min_pos = cval(cval_min_pos_idx(1));
                        my_col(cval_min_pos:256,:)  = flip(autumn(256-cval_min_pos+1),1);

                        if t_min_pos~=t_max_pos
                            pos_clim = [t_min_pos,t_max_pos];
                        else
                            pos_clim = [0,t_max_pos];
                        end
                    else
                        pos_clim = [0 1];
                    end

                    if ~isempty(t_neg)
                        cval_min_neg_idx = find(t == t_min_neg);
                        cval_min_neg = cval(cval_min_neg_idx(1));
                        
                        my_col(1:cval_min_neg,:) = winter(cval_min_neg);

                        if t_max_neg~=t_min_neg
                            neg_clim = [t_max_neg,t_min_neg];
                        else
                            neg_clim = [t_max_neg,0];
                        end
                    else
                        neg_clim = [-1 0];
                        
                    end

%                     if t_min_pos~=t_max_pos
%                         pos_clim = [t_min_pos,t_max_pos];
%                     else
%                         pos_clim = [t_min_pos,1];
%                     end
                else
                    my_col = jet(256);
                    pos_clim = [0 1];
                    neg_clim = [-1 0];
                end
            else
                my_col = zeros(256,3);
                pos_clim = [0 1];
                neg_clim = [-1 0];
            end
            
        end


        function my_datatip_gm(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(GM netid, WM netid) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end

        function my_datatip_cc(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(CC netid, WM netid) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end

        function my_datatip_wm(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(WM netid1, WM netid2) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end

        function my_datatip_atlas(app,s,p)
            datatip(s,1,1);
            s.DataTipTemplate.DataTipRows(1).Label = "(ROI1, ROI2) ";
            s.DataTipTemplate.DataTipRows(2).Label = "t-value (2 sample t-test)"; 
            dtRows = [dataTipTextRow("p-value",p)];
            s.DataTipTemplate.DataTipRows(end) = dtRows;
            delete(findall(app.StatisticsUIFigure,'Type','hggroup'))
        end
        
        function [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size_t)
            app.UIAxes.Title.Color = [1,1,1];
            clim(app.UIAxes,[0,1])

            if strcmp(app.CorrectionDropDown.Value,'false Rate Discovery')
%                 [h,~,~,p] = fdr_bh(p,app.AlphaEditField.Value,'dep');

                p = mafdr(p,'BHFDR','true');
%                 
%                 p = reshape(p,size_t,[]);
%                 t = reshape(t,size_t,[]);
            elseif strcmp(app.CorrectionDropDown.Value,'Bonferroni')
                
%                 t(p<(app.AlphaEditField.Value/numel(p))) = 1;
                bon_comp = numel(p);
%                 p = reshape(p,size_t,[]);
%                 t = reshape(t,size_t,[]);
            end
            if app.slide_select == 1 || app.slide_select == 4
                t = veccorr(t);
                p = veccorr(p);
            else
                p = reshape(p,size_t,[]);
                t = reshape(t,size_t,[]);
            end

            s = imagesc(app.UIAxes,t);
            if strcmp(app.CorrectionDropDown.Value,'Bonferroni')
                [pos1,pos2] = find(p<(app.AlphaEditField.Value/bon_comp));
                app.AlphaEditFieldLabel_2.Text = ['Bonferroni alpha = ' num2str(app.AlphaEditField.Value) '/' num2str(bon_comp) '=' num2str(app.AlphaEditField.Value/bon_comp)];
            else
                [pos1,pos2] = find(p<app.AlphaEditField.Value);
            end
            
            app.current_fig = t;
            set(app.UIAxes,'YLim',[0.5 size(t,1) + 0.5])
            set(app.UIAxes,'XLim',[0.5 size(t,2) + 0.5])
            if nnz(t)
                clim(app.UIAxes,[min(t,[],'all'),max(t,[],'all')])
            end

            switch app.plotDropDown.Value
            
                case 'All values'
                    colormap(app.UIAxes,'jet')
                    pos_clim = [];
                    neg_clim = []; 
                case 'Significant only'
                    if strcmp(app.CorrectionDropDown.Value,'Bonferroni')
                        t(p>(app.AlphaEditField.Value/bon_comp)) = 0;
                    else
                        t(p>app.AlphaEditField.Value) = 0;
                    end
                    [my_col,pos_clim,neg_clim ] = my_colormap(app,t);
                    colormap(app.UIAxes,my_col)
            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path, Subj_list, over_write)
            app.over_write = over_write;
            app.spm_path = which('spm');
            if isempty(app.spm_path)
                msgbox("Please set the SPM path by using the 'addpath' MATLAB function",'SPM path not set')
                return
            end
            temp_path = mfilename('fullpath');                % path of the toolbox
            app.preproc_code_path = fileparts(temp_path);
            addpath(fullfile(app.preproc_code_path))
            addpath(fullfile(app.preproc_code_path,'whifun_functions'))
            addpath(fullfile(app.preproc_code_path,'uigetfile_n_dir'))
            app.CallingApp = caller;
            app.output_folder = path;
%             load(fullfile(path,'parameters.mat'),'Subj_list')
            app.Subj_list = Subj_list;  
            app.data_path = app.Subj_list(1).folder;

            app.Tabposition = 1;    
            app.all_sub_names = struct2cell(app.Subj_list);
            app.all_sub_names(2:end,:) = [];
            
            app.WMFunctionalNetworkconnectvityButton.Enable = "off";
            app.WMFunctionalNetworkconnectvityButton.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
            app.WMfileSelectDropDown.Enable = "off";

            app.WMCorpusCallosumNetworksButton.Enable = 'off';
            app.WMCorpusCallosumNetworksButton.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
            app.CCfileSelectDropDown.Enable = "off";
            
            app.WMGMNetworkConnectivityButton.Enable = 'off';
            app.WMGMNetworkConnectivityButton.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
            app.GMfileSelectDropDown.Enable = "off";

            app.AtlasbasedFCButton.Enable = "off";
            app.AtlasbasedFCButton.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
            app.AtlasfileSelectDropDown.Enable = "off";
            
            
        end

        % Button pushed function: WMFunctionalNetworkconnectvityButton
        function WMFunctionalNetworkconnectvityButtonPushed(app, event)
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load(fullfile(app.output_folder,'Analysis','WM_Networks',wm_file));
            end
            
            
            WM_ttest(app,app.final_result.final_result,app.final_result.Subj_list)
                           
        end

        % Button pushed function: WMCorpusCallosumNetworksButton
        function WMCorpusCallosumNetworksButtonPushed(app, event)
            if strcmp(app.CCfileSelectDropDown.Enable,'on')
                cc_file = app.CCfileSelectDropDown.Value;
                app.final_result_cc = load(fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks',cc_file));
            end
            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load(fullfile(app.output_folder,'Analysis','WM_Networks',wm_file));
            end

            WM_CC_ttest(app,app.final_result.final_result,app.final_result_cc.final_result_ccn,app.final_result.Subj_list)
            
            
        end

        % Button pushed function: WMGMNetworkConnectivityButton
        function WMGMNetworkConnectivityButtonPushed(app, event)
            if strcmp(app.GMfileSelectDropDown.Enable,'on')
                gm_file = app.GMfileSelectDropDown.Value;
                app.final_result_gm = load(fullfile(app.output_folder,'Analysis','GM_Networks',gm_file));
            end

            if strcmp(app.WMfileSelectDropDown.Enable,'on')
                wm_file = app.WMfileSelectDropDown.Value;
                app.final_result = load(fullfile(app.output_folder,'Analysis','WM_Networks',wm_file));
            end
            
            WMGM_ttest(app,app.final_result.final_result,app.final_result_gm.final_result_gm,app.final_result_gm.Subj_list)
           
        end

        % Button pushed function: AtlasbasedFCButton
        function AtlasbasedFCButtonPushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown.Value;
                app.atlas_name = app.AtlasfileSelectDropDown_2.Value;
                app.final_result_atlas = load(fullfile(app.output_folder,'Analysis','Atlas_FC',app.atlas_file));

                if isempty(app.RegionsEditField.Value)
                    app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts,2))];
                end
            end
            Atlas_based_ttest(app,app.final_result_atlas.reg_ts,app.final_result_atlas.Subj_list)
        end

        % Value changed function: AtlasfileSelectDropDown
        function AtlasfileSelectDropDownValueChanged(app, event)
            app.RegionsEditField.Value = '';
%             app.atlas_regions  = '';  
            AtlasbasedFCButtonPushed(app, event)
                  
        end

        % Value changed function: GMfileSelectDropDown
        function GMfileSelectDropDownValueChanged(app, event)
            WMGMNetworkConnectivityButtonPushed(app, event)
        end

        % Value changed function: WMfileSelectDropDown
        function WMfileSelectDropDownValueChanged(app, event)
           select_slide(app,event)
        end

        % Value changed function: DataTipsCheckBox
        function DataTipsCheckBoxValueChanged(app, event)
            value = app.DataTipsCheckBox.Value;
            dcm_obj = datacursormode(app.StatisticsUIFigure);

            if value == 1
                dcm_obj.Enable = 'on';  
                if app.Tabposition
                    app.WMCorpusCallosumNetworksButton_2.Enable = "off";
                    app.WMFunctionalNetworkconnectvityButton_2.Enable = "off";
                    app.WMGMNetworkConnectivityButton_2.Enable = "off";
                    app.AtlasbasedFCButton_2.Enable = "off"; 
                else
                    app.WMCorpusCallosumNetworksButton.Enable = "off";
                    app.WMFunctionalNetworkconnectvityButton.Enable = "off";
                    app.WMGMNetworkConnectivityButton.Enable = "off";
                    app.AtlasbasedFCButton.Enable = "off"; 
                end
            else
                dcm_obj.Enable = 'off';
                if app.Tabposition
                    app.WMCorpusCallosumNetworksButton_2.Enable = "on";
                    app.WMFunctionalNetworkconnectvityButton_2.Enable = "on";
                    app.WMGMNetworkConnectivityButton_2.Enable = "on";
                    app.AtlasbasedFCButton_2.Enable = "on"; 
                else
                    app.WMCorpusCallosumNetworksButton.Enable = "on";
                    app.WMFunctionalNetworkconnectvityButton.Enable = "on";
                    app.WMGMNetworkConnectivityButton.Enable = "on";
                    app.AtlasbasedFCButton.Enable = "on"; 
                end

            end
        end

        % Value changed function: CorrectionDropDown
        function CorrectionDropDownValueChanged(app, event)
           switch app.CorrectionDropDown.Value
               case 'Bonferroni'
                   app.AlphaEditFieldLabel_2.Visible = "on";
               case 'false Rate Discovery'
                   app.AlphaEditFieldLabel_2.Visible = "off";
           end
            select_slide(app,event)
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
           select_slide(app,event)
        end

        % Button pushed function: UploadCSVforSubjectGroupsButton
        function UploadCSVforSubjectGroupsButtonPushed(app, event)
            try
                [file,path] = uigetfile('.csv','Select CSV file');
            catch
            end
            if path == 0
                return
            end
            opts = detectImportOptions(fullfile(path,file));
            opts = setvartype(opts, 'char'); % or 'string', depending on your MATLAB version
            CSV = table2cell(readtable(fullfile(path,file),opts));
           
            if app.HasHeaderCheckBox.Value
                CSVhead = readcell(fullfile(path,file));
                CSVhead(2:end,:) = [];
                for i = 1:size(CSV,2)-1
                    app.CSV_head{i} = CSVhead{i+1};
                end
            else
                app.CSV_head = cell(1,size(CSV,2));
                for i = 1:size(CSV,2)
                    app.CSV_head{i+2} = ['Var_' num2str(i)];
                end
            end

            app.sub_names_csv = CSV(:,1);
            CSV = CSV(:,2:end);
            app.X = cellfun(@(x) str2double(x), CSV);

            app.VariablesDropDown.Items = app.CSV_head;

            app.WMFunctionalNetworkconnectvityButton.Enable = "on";
            app.WMFunctionalNetworkconnectvityButton.Tooltip = 'White Matter(WM) Functional Connectivity. A GLM model is fit to the WM FC elements and covariates provided by the CSV file.';
            app.WMfileSelectDropDown.Enable = "on";

            app.WMCorpusCallosumNetworksButton.Enable = 'on';
            app.WMCorpusCallosumNetworksButton.Tooltip = 'White Matter(WM) - Corpus Callosum(CC) Functional Connectivity.  A GLM model is fit to the WM-CC FC elements and covariates provided by the CSV file.';
            app.CCfileSelectDropDown.Enable = "on";
            
            app.WMGMNetworkConnectivityButton.Enable = 'on';
            app.WMGMNetworkConnectivityButton.Tooltip = 'White Matter(WM) - Gray Matter(GM) Functional Connectivity. A GLM model is fit to the WM-GM FC elements and covariates provided by the CSV file.';
            app.GMfileSelectDropDown.Enable = "on";

            app.AtlasbasedFCButton.Enable = "on";
            app.AtlasbasedFCButton.Tooltip = 'Atlas-Functional Connectivity : A GLM model is fit to the Atlas-FC elements and covariates provided by the CSV file.';
            app.AtlasfileSelectDropDown.Enable = "on";
            WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_Networks','network_avgts_wm*.mat'));

            if length(WM_reg_files) == 1
                app.final_result = load(fullfile(WM_reg_files.folder,WM_reg_files.name));
                app.WMfileSelectDropDown.Enable = 'off';
                app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton.Tooltip = 'White Matter Network average timeseries file not found: Please create the WM networks from the Create FN and FC module and try again';
            end
            cc_reg_file = dir(fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks','network_avgts_cc_K*.mat'));

            if length(cc_reg_file) == 1
                app.final_result_cc = load(fullfile(cc_reg_file.folder,cc_reg_file.name));
                app.CCfileSelectDropDown.Enable  = 'off';
            elseif length(cc_reg_file) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_file);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCorpusCallosumNetworksButton.Enable = "off";
                app.WMCorpusCallosumNetworksButton.Tooltip = 'Corpus Callosum average timeseries file not found: Please create the  Corpus callosum networks by checking the Corpus callosum check box under the WM networks tab from the Create FN and FC module and try again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','Atlas_FC','*reg_ts.mat'));

            if length(Atlas_reg_files) == 1
                app.final_result_atlas = load(fullfile(Atlas_reg_files.folder,Atlas_reg_files.name));
                app.AtlasfileSelectDropDown.Enable  = 'off';
                app.atlas_name = Atlas_reg_files.name;
                app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts,2))];
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasbasedFCButton.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasbasedFCButton.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end


            GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_Networks','network_avgts_gm*.mat'));

            if length(GM_reg_files) == 1
                app.final_result_gm = load(fullfile(GM_reg_files.folder,GM_reg_files.name));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMNetworkConnectivityButton.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMNetworkConnectivityButton.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end
            app.initial_UIAxes_pos = app.UIAxes.Position;
                app.initial_UIAxes2_pos = app.UIAxes2.Position;
                app.initial_UIAxes2_2_pos = app.UIAxes2_2.Position;
            switch app.plotDropDown.Value
                case 'All values'
                
                case 'Significant only'
                    
                c = colorbar(app.UIAxes,'eastoutside','AxisLocation','out');
                c.Color = [1 1 1];
                c_pos = c.Position;
                c.Position = [c.Position(1) + 2*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 4*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                c1.Color = [1 1 1];
    %             c = colorbar(app.UIAxes,'Position',[c_pos(1),c_pos(2)+c_pos(4)/2, c_pos(3),c_pos(4)/2]);
    %             c.Color = [1 1 1];
                axis(app.UIAxes,'off')
            end
            app.AlphaEditFieldLabel_2.Visible = "off";
            app.FrequencyBandsTab.HandleVisibility = "on";
        end

        % Button down function: FrequencyBandsTab
        function FrequencyBandsTabButtonDown(app, event)
            if isempty(app.X)
                msgbox('Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.','Upload CSV file first');
                app.TabGroup.SelectedTab = app.AllfrequenciesTab;
                app.WMFunctionalNetworkconnectvityButton_2.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton_2.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
                app.WMfileSelectDropDown_2.Enable = "off";

                app.WMCorpusCallosumNetworksButton_2.Enable = 'off';
                app.WMCorpusCallosumNetworksButton_2.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
                app.CCfileSelectDropDown_2.Enable = "off";

                app.WMGMNetworkConnectivityButton_2.Enable = 'off';
                app.WMGMNetworkConnectivityButton_2.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
                app.GMfileSelectDropDown_2.Enable = "off";

                app.AtlasbasedFCButton_2.Enable = "off";
                app.AtlasbasedFCButton_2.Tooltip = 'Upload CSV File with the first column containing Subject names and the other columns conating the age, sex, or behavioral data.';
                app.AtlasfileSelectDropDown_2.Enable = "off";
                return
            end
            
            app.Tabposition = 0;
            ex_time_folder = fullfile(app.output_folder,'Analysis','Extract_Timeseries');


            app.atlas_folders = dir(ex_time_folder);
            if isempty(app.atlas_folders)
                msgbox('No Extract timeseries folder found. Please run extract timeseries from FC and FN section and try again.')
                app.WMFunctionalNetworkconnectvityButton_2.Enable = 'off';
                app.WMCorpusCallosumNetworksButton_2.Enable = 'off';
                app.WMGMNetworkConnectivityButton_2.Enable = 'off';
                app.AtlasbasedFCButton_2.Enable = 'off';
                app.WMfileSelectDropDown_2.Enable = 'off';
                app.CCfileSelectDropDown_2.Enable = 'off';
                app.GMfileSelectDropDown_2.Enable = 'off';
                app.AtlasfileSelectDropDown_2.Enable = 'off';
                app.Band_selectDropDown.Enable = 'off';
                return
            end

            if strcmp(app.atlas_folders(1).name, '.')                                           % If the first file is '.', then remove it as it is not a subject directory
                app.atlas_folders(1) = [];
            end

            if strcmp(app.atlas_folders(1).name, '..')                                          % If the first file is '..', then remove it as it is not a subject directory
                app.atlas_folders(1) = [];
            end

            nt_dir = false(1,length(app.atlas_folders));                                        % make a list of other files that are not directories
            app.folders_info = false(length(app.atlas_folders),4);                                  %no. of folders x 4, col 1 --> WM networks, col 2 = CC networks, col 3 GM, col 4--> others 
            app.bands_info = cell(length(app.atlas_folders),3);
            p = 1;
            for i = 1:length(app.atlas_folders)
                if app.atlas_folders(i).isdir ~= 1
                    nt_dir(i) = true;
                end
            end
            app.atlas_folders(nt_dir) = [];
            for i = 1:length(app.atlas_folders)
               
                if strcmp(app.atlas_folders(i).name(1:13),'WM_clustering')
                    app.folders_info(i,1) = true;
                elseif strcmp(app.atlas_folders(i).name(1:20),'CC_network_from_WM_K')
                    app.folders_info(i,2) = true;
                elseif strcmp(app.atlas_folders(i).name(1:13),'GM_clustering')
                    app.folders_info(i,3) = true;
                else
                    app.folders_info(i,4) = true;
                end
                

                temp = dir(fullfile(app.atlas_folders(i).folder,app.atlas_folders(i).name,[app.atlas_folders(i).name '*_reg_ts.mat']));
                for i1 = 1:length(temp)
                    app.bands_info(p,1) = {temp(i1).name(length(app.atlas_folders(i).name)+2:end-11)};  % band_name
                    app.bands_info(p,2) = {app.atlas_folders(i).name};                                  % atlas_name
                    
                    app.bands_info(p,3) = {i};                                                        % atlas_number
                    p = p+1;
                end

                
            end
            
            
            bands_found = unique(app.bands_info(:,1));
            app.Band_selectDropDown.Items = string(bands_found);
            current_band = app.Band_selectDropDown.Value;
            
            initialise_freq_buttons(app,current_band)


        end

        % Button down function: AllfrequenciesTab
        function AllfrequenciesTabButtonDown(app, event)
            if isempty(app.X)
                return
            end

            
            WM_reg_files = dir(fullfile(app.output_folder,'Analysis','WM_Networks','network_avgts_wm*.mat'));
            
            app.Tabposition = 1;


            if length(WM_reg_files) == 1
                app.final_result = load(fullfile(WM_reg_files.folder,WM_reg_files.name));
                app.WMfileSelectDropDown.Enable = 'off';
                app.atlas_file = WM_reg_files.name;
            elseif length(WM_reg_files) > 1
                app.WMfileSelectDropDown.Enable = 'on';
                wm_reg_file = struct2cell(WM_reg_files);
                wm_reg_file(2:end,:) = [];
                app.WMfileSelectDropDown.Items = wm_reg_file;
            else
                app.WMfileSelectDropDown.Enable = 'off';
                app.WMFunctionalNetworkconnectvityButton.Enable = "off";
                app.WMFunctionalNetworkconnectvityButton.Tooltip = 'White Matter Network average timeseries file not found: Please create the WM networks from the Create FN and FC module and try again';
            end
            cc_reg_file = dir(fullfile(app.output_folder,'Analysis','Corpus_Callosum_Networks','network_avgts_cc_K*.mat'));

            if length(cc_reg_file) == 1
                app.final_result_cc = load(fullfile(cc_reg_file.folder,cc_reg_file.name));
                app.CCfileSelectDropDown.Enable  = 'off';
            elseif length(cc_reg_file) > 1
                app.CCfileSelectDropDown.Enable = 'on';
                cc_reg_file = struct2cell(cc_reg_file);
                cc_reg_file(2:end,:) = [];
                app.CCfileSelectDropDown.Items = cc_reg_file;

            else
                app.WMCorpusCallosumNetworksButton.Enable = "off";
                app.WMCorpusCallosumNetworksButton.Tooltip = 'Corpus Callosum average timeseries file not found: Please create the  Corpus callosum networks by checking the Corpus callosum check box under the WM networks tab from the Create FN and FC module and try again';

            end

            Atlas_reg_files = dir(fullfile(app.output_folder,'Analysis','Atlas_FC','*reg_ts.mat'));

            if length(Atlas_reg_files) == 1
                app.final_result_atlas = load(fullfile(Atlas_reg_files.folder,Atlas_reg_files.name));
                app.AtlasfileSelectDropDown.Enable  = 'off';
                app.atlas_name = Atlas_reg_files.name;
                app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts,2))];
            elseif length(Atlas_reg_files) > 1
                app.AtlasfileSelectDropDown.Enable = 'on';
                atlas_reg_file = struct2cell(Atlas_reg_files);
                atlas_reg_file(2:end,:) = [];
                app.AtlasfileSelectDropDown.Items = atlas_reg_file;

            else
                app.AtlasbasedFCButton.Enable = "off";
                app.AtlasfileSelectDropDown.Enable = 'off';
                app.AtlasbasedFCButton.Tooltip = 'Atlas average timeseries file not found: Please create the Atlas FC timeseries from Atlas-FC tab in the Create FN and FC module and try again';

            end


            GM_reg_files = dir(fullfile(app.output_folder,'Analysis','GM_Networks','network_avgts_gm*.mat'));

            if length(GM_reg_files) == 1
                app.final_result_gm = load(fullfile(GM_reg_files.folder,GM_reg_files.name));
                app.GMfileSelectDropDown.Enable  = 'off';
            elseif length(GM_reg_files) > 1
                app.GMfileSelectDropDown.Enable = 'on';
                gm_reg_file = struct2cell(GM_reg_files);
                gm_reg_file(2:end,:) = [];
                app.GMfileSelectDropDown.Items = gm_reg_file;

            else
                app.WMGMNetworkConnectivityButton.Enable = "off";
                app.GMfileSelectDropDown.Enable = 'off';
                app.WMGMNetworkConnectivityButton.Tooltip = 'Gray Matter Network average timeseries file not found: Please create the GM networks from the Create FN and FC module and try again';

            end

        end

        % Value changed function: Band_selectDropDown
        function Band_selectDropDownValueChanged(app, event)
            current_band = app.Band_selectDropDown.Value;
            initialise_freq_buttons(app,current_band)
            %             cla(app.UIAxes)
            switch app.slide_select
                case 1
                    WMFunctionalNetworkconnectvityButton_2Pushed(app, event)

                case 2

                    WMCorpusCallosumNetworksButton_2Pushed(app, event)

                case 3
                    WMGMNetworkConnectivityButton_2Pushed(app, event)

                case 4
                    AtlasbasedFCButton_2Pushed(app,event)
            end
        end

        % Button pushed function: WMFunctionalNetworkconnectvityButton_2
        function WMFunctionalNetworkconnectvityButton_2Pushed(app, event)
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load(fullfile(app.atlas_folders(1).folder,wm_file,[wm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            
            
            WM_ttest(app,app.final_result.reg_ts_freq,app.final_result.Subj_list)

            
        end

        % Button pushed function: WMCorpusCallosumNetworksButton_2
        function WMCorpusCallosumNetworksButton_2Pushed(app, event)
             if strcmp(app.CCfileSelectDropDown_2.Enable,'on')
                cc_file = app.CCfileSelectDropDown_2.Value;
                app.final_result_cc = load(fullfile(app.atlas_folders(1).folder,cc_file,[cc_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load(fullfile(app.atlas_folders(1).folder,wm_file,[wm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end

            WM_CC_ttest(app,app.final_result.reg_ts_freq,app.final_result_cc.reg_ts_freq,app.final_result.Subj_list)
            

        end

        % Button pushed function: WMGMNetworkConnectivityButton_2
        function WMGMNetworkConnectivityButton_2Pushed(app, event)
            if strcmp(app.GMfileSelectDropDown_2.Enable,'on')
                gm_file = app.GMfileSelectDropDown_2.Value;
                app.final_result_gm = load(fullfile(app.atlas_folders(1).folder,gm_file,[gm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end

            if strcmp(app.WMfileSelectDropDown_2.Enable,'on')
                wm_file = app.WMfileSelectDropDown_2.Value;
                app.final_result = load(fullfile(app.atlas_folders(1).folder,wm_file,[wm_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
            end
            
            WMGM_ttest(app,app.final_result.reg_ts_freq,app.final_result_gm.reg_ts_freq,app.final_result_gm.Subj_list)
            
        end

        % Button pushed function: AtlasbasedFCButton_2
        function AtlasbasedFCButton_2Pushed(app, event)
            if strcmp(app.AtlasfileSelectDropDown_2.Enable,'on')
                app.atlas_file = app.AtlasfileSelectDropDown_2.Value;
                app.atlas_name = app.AtlasfileSelectDropDown_2.Value;
                app.final_result_atlas = load(fullfile(app.atlas_folders(1).folder,app.atlas_file,[app.atlas_file ,'_', char(app.Band_selectDropDown.Value) '_reg_ts.mat']));
                if isempty(app.RegionsEditField.Value)
                    app.atlas_regions = ['1:' num2str(size(app.final_result_atlas.reg_ts,2))];
                end
            end
            Atlas_based_ttest(app,app.final_result_atlas.reg_ts_freq,app.final_result_atlas.Subj_list)
        end

        % Value changed function: CCfileSelectDropDown
        function CCfileSelectDropDownValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: CCfileSelectDropDown_2
        function CCfileSelectDropDown_2ValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: WMfileSelectDropDown_2
        function WMfileSelectDropDown_2ValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: GMfileSelectDropDown_2
        function GMfileSelectDropDown_2ValueChanged(app, event)
            select_slide(app,event)
        end

        % Value changed function: AtlasfileSelectDropDown_2
        function AtlasfileSelectDropDown_2ValueChanged(app, event)
            app.RegionsEditField.Value = '';
            select_slide(app,event)
            

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            if app.slide_select == 0
                msgbox('Select a FC first','Select FC')
                return
            end

            %% Check if the reference MNI template is present
            
            net1 = round(app.Net1YaxisEditField.Value);

            if net1 < app.UIAxes.YLim(1)
                net1 = app.UIAxes.YLim(1) + 0.5;
                app.Net1YaxisEditField.Value = net1;
            elseif net1 > app.UIAxes.YLim(2)
                net1 = app.UIAxes.YLim(2) - 0.5;
                app.Net1YaxisEditField.Value = net1;
            end

            net2 = round(app.Net2XaxisEditField.Value);

            if net2 < app.UIAxes.XLim(1)
                net2 = app.UIAxes.XLim(1) + 0.5;
                app.Net2XaxisEditField.Value = net2;
            elseif net2 > app.UIAxes.XLim(2)
                net2 = app.UIAxes.XLim(2) - 0.5;
                app.Net2XaxisEditField.Value = net2;
            end
            
            if app.slide_select == 1 || app.slide_select == 4
                if net1 == net2
                msgbox('Diagnoal Values are all ones and hence not interesting','Diagonals are Trivial')
                return
                end
            end
            if strcmp(app.UIAxes2_2.XLabel.String,'Corpus calosum Regions') || strcmp(app.UIAxes2_2.XLabel.String,'GM Networks')
                 p = sub2ind([app.UIAxes.YLim(2) - 0.5,app.UIAxes.XLim(2) - 0.5], net1,net2);
            else

                b = size(app.vec1);

                temp = veccorr((1:b(1))');

                p = temp(net1,net2);

            end
            
            switch app.slide_select
                case 1
                    titY = 'WM';
                    titX = 'WM';
                case 2
                    titY = 'WM';
                    titX = 'CC';
                case 3
                    titY = 'WM';
                    titX = 'GM';
                case 4
                    titY = 'ROI';
                    titX = 'ROI';
            end


            [Sub_names,ia,iia] = intersect(app.SubjectlistDropDown.Items,string(app.sub_names_csv)');
            app.SubjectlistDropDown.Items = Sub_names;

            val_var = app.VariablesDropDown.Value;

            [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));
            vec_now = app.vec1(p,ia);
            X_now = app.X(iia,val_idx);
            var_values = unique(X_now);
            var_type_iden = length(var_values);
            
            if var_type_iden <= 4
                plot_vars = cell(1,var_type_iden);
                for i = 1:var_type_iden
                    plot_vars{i} = vec_now(X_now == var_values(i));
                end
                figure;
                barplot_with_errorbars(plot_vars,{var_values})
                
            else
                figure;
                scatter(X_now,vec_now)
            end
            ylabel('Mean Fisher Z transformed FC \pm standard deviation')
            ylim([-1 1])
            xlabel(val_var)
            
            
            title([titY num2str(net1) ' - ' titX num2str(net2) ', t-value = ' num2str(app.stats{p}.tstat.t(val_idx+1))])


        end

        % Value changed function: RegionsEditField
        function RegionsEditFieldValueChanged(app, event)
            value = app.RegionsEditField.Value;
            app.atlas_regions = value;
            if app.Tabposition
                AtlasbasedFCButtonPushed(app, event)
            else
                AtlasbasedFCButton_2Pushed(app, event)
            end
        end

        % Button pushed function: SaveplotasimageButton
        function SaveplotasimageButtonPushed(app, event)
            try
                
                [imgfile,imgfolderpath] = uiputfile('WhiFuN_stats_figure.png','Save FC visualize figure');

            catch
            end
            if prod(imgfolderpath == 0) || isempty(imgfolderpath)
                return
            end

            switch app.plotDropDown.Value
                case 'All values'
                    app.UIAxes.XColor = [0,0,0];
                    app.UIAxes.YColor = [0,0,0];
                    c = app.UIAxes.Colorbar;
                    c.Color = [0 0 0];
                    app.UIAxes2_2.XColor = [0,0,0];
                    set(app.UIAxes2_2,'FontSize',18)
                    app.UIAxes2.YColor = [0,0,0];
                    set(app.UIAxes2,'FontSize',18)
                    exportgraphics(app.StatisticsUIFigure,fullfile(imgfolderpath,imgfile),"BackgroundColor",[1,1,1])
                    app.UIAxes.XColor = [1,1,1];
                    app.UIAxes.YColor = [1,1,1];
                    c.Color = [1 1 1];
                    app.UIAxes2_2.XColor = [1 1 1];
                    app.UIAxes2.YColor = [1 1 1];
                    map_pos = app.UIAxes2.tightPosition;
                    map_pos2 = app.UIAxes2_2.tightPosition;
                    set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
                    set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])
                case 'Significant only'
%                     app.UIAxes.XColor = [0,0,0];
%                     app.UIAxes.YColor = [0,0,0];
%                     
%                     app.colbar1.Color = [0 0 0];
%                     app.colbar2.Color = [0 0 0];
%                     app.colbar1.Title.Color= [0 0 0];
%                     app.colbar2.Title.Color= [0 0 0];
% 
%                     app.UIAxes2_2.XColor = [0,0,0];
%                     set(app.UIAxes2_2,'FontSize',18)
%                     app.UIAxes2.YColor = [0,0,0];
%                     set(app.UIAxes2,'FontSize',18)
                    exportgraphics(app.StatisticsUIFigure,fullfile(imgfolderpath,imgfile),"BackgroundColor",[0,0,0])
%                     app.UIAxes.XColor = [1,1,1];
%                     app.UIAxes.YColor = [1,1,1];
%                     app.UIAxes2_2.XColor = [1 1 1];
%                     app.UIAxes2.YColor = [1 1 1];
                    map_pos = app.UIAxes2.tightPosition;
                    map_pos2 = app.UIAxes2_2.tightPosition;
                    set(app.UIAxes2_2,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2) - map_pos2(4), app.UIAxes2_2.InnerPosition(3), map_pos2(4)])
                    set(app.UIAxes,'InnerPosition',[map_pos(1) + map_pos(3), map_pos(2), app.UIAxes2_2.InnerPosition(3), map_pos(4)])

            end
        end

        % Value changed function: VariablesDropDown
        function VariablesDropDownValueChanged(app, event)
            val_var = app.VariablesDropDown.Value;
            if ~isempty(app.vec1)
                [~,val_idx] = intersect(string(app.VariablesDropDown.Items),string(val_var));
                t = zeros(size(app.vec1,1),1);
                p = zeros(size(app.vec1,1),1);
                for i = 1:size(app.vec1,1)
                    %                 if val_idx == 1
                    %                     t(i) = app.stats{i}.fstat.f;
                    %                     p(i) = app.stats{i}.fstat.pval;
                    %                 else
                    t(i) = app.stats{i}.tstat.t(val_idx+1);
                    p(i) = app.stats{i}.tstat.pval(val_idx+1);
                    %                 end
                end

                if app.slide_select == 4
                    [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,str2double(app.UIAxes.XLabel.String(1:end-4)));
                else
                    colorbar(app.UIAxes,'off')
                    [pos1,pos2,pos_clim,neg_clim,s,p] = plot_t(app,t,p,size(app.UIAxes2.YTick,2));
                end

                switch app.plotDropDown.Value

                    case 'All values'
                        c = colorbar(app.UIAxes);
                        c.Color = [1 1 1];
                        c_pos = c.Position;
                        c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                        c1.Color = [1 1 1];
                        c1.Title.String = 'T-Values';
                        c1.Title.Color = [1,1,1];
                        text(app.UIAxes,pos2,pos1,"*","Color",'w','FontSize',40)
                    case 'Significant only'
                        c = colorbar(app.UIAxes);
                        c.Color = [1 1 1];
                        c_pos = c.Position;
                        c.Position = [c.Position(1) + 3*c.Position(3),c.Position(2),c.Position(3),c.Position(4)];
                        c1 = colorbar(app.UIAxes,'Position',[c_pos(1) + 5*c_pos(3),c_pos(2), c_pos(3),c_pos(4)]);
                        c1.Color = [1 1 1];

                        c1.Limits = neg_clim;
                        c.Limits = pos_clim;
                        c.Title.String = 'Pos(+)';
                        c.Title.Color = [1,1,1];

                        c1.Title.String = 'Neg(-)';
                        c1.Title.Color = [1,1,1];
                end
                my_datatip_cc(app,s,p)
            end
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            select_slide(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.StatisticsUIFigure = uifigure('Visible', 'off');
            app.StatisticsUIFigure.Name = 'Statistics';
            app.StatisticsUIFigure.Icon = fullfile(pathToMLAPP, 'icon_small.png');
            app.UIAxes = uiaxes(app.StatisticsUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'parula')
            app.UIAxes2 = uiaxes(app.StatisticsUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            colormap(app.UIAxes2, 'parula')
            app.UIAxes2_2 = uiaxes(app.StatisticsUIFigure);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            colormap(app.UIAxes2_2, 'parula')
            app.TabGroup = uitabgroup(app.StatisticsUIFigure);
            app.AllfrequenciesTab = uitab(app.TabGroup);
            app.AllfrequenciesTab.Title = 'All frequencies';
            app.AllfrequenciesTab.ButtonDownFcn = createCallbackFcn(app, @AllfrequenciesTabButtonDown, true);
            app.WMFunctionalNetworkconnectvityButton = uibutton(app.AllfrequenciesTab, 'push');
            app.WMFunctionalNetworkconnectvityButton.ButtonPushedFcn = createCallbackFcn(app, @WMFunctionalNetworkconnectvityButtonPushed, true);
            app.WMFunctionalNetworkconnectvityButton.Text = {'WM Functional Network'; 'connectvity'};
            app.WMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.WMfileSelectLabel.Text = 'WM file Select';
            app.WMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.WMfileSelectDropDown.Items = {};
            app.WMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDownValueChanged, true);
            app.WMfileSelectDropDown.Value = {};
            app.WMCorpusCallosumNetworksButton = uibutton(app.AllfrequenciesTab, 'push');
            app.WMCorpusCallosumNetworksButton.ButtonPushedFcn = createCallbackFcn(app, @WMCorpusCallosumNetworksButtonPushed, true);
            app.WMCorpusCallosumNetworksButton.Text = {'WM Corpus Callosum'; 'Networks'};
            app.WMGMNetworkConnectivityButton = uibutton(app.AllfrequenciesTab, 'push');
            app.WMGMNetworkConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @WMGMNetworkConnectivityButtonPushed, true);
            app.WMGMNetworkConnectivityButton.Text = 'WM GM Network Connectivity';
            app.GMfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.GMfileSelectLabel.Text = 'GM file Select';
            app.GMfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.GMfileSelectDropDown.Items = {};
            app.GMfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDownValueChanged, true);
            app.GMfileSelectDropDown.Value = {};
            app.AtlasbasedFCButton = uibutton(app.AllfrequenciesTab, 'push');
            app.AtlasbasedFCButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasbasedFCButtonPushed, true);
            app.AtlasbasedFCButton.Text = 'Atlas based FC';
            app.AtlasfileSelectLabel = uilabel(app.AllfrequenciesTab);
            app.AtlasfileSelectLabel.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.AtlasfileSelectDropDown.Items = {};
            app.AtlasfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDownValueChanged, true);
            app.AtlasfileSelectDropDown.Value = {};
            app.CCfileSelectDropDownLabel = uilabel(app.AllfrequenciesTab);
            app.CCfileSelectDropDownLabel.Text = 'CC file Select';
            app.CCfileSelectDropDown = uidropdown(app.AllfrequenciesTab);
            app.CCfileSelectDropDown.Items = {};
            app.CCfileSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @CCfileSelectDropDownValueChanged, true);
            app.CCfileSelectDropDown.Value = {};
            app.FrequencyBandsTab = uitab(app.TabGroup);
            app.FrequencyBandsTab.Title = 'Frequency Bands';
            app.FrequencyBandsTab.ButtonDownFcn = createCallbackFcn(app, @FrequencyBandsTabButtonDown, true);
            app.WMFunctionalNetworkconnectvityButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.WMFunctionalNetworkconnectvityButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMFunctionalNetworkconnectvityButton_2Pushed, true);
            app.WMFunctionalNetworkconnectvityButton_2.Text = {'WM Functional Network'; 'connectvity'};
            app.WMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.WMfileSelectLabel_2.Text = 'WM file Select';
            app.WMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.WMfileSelectDropDown_2.Items = {};
            app.WMfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WMfileSelectDropDown_2ValueChanged, true);
            app.WMfileSelectDropDown_2.Value = {};
            app.WMCorpusCallosumNetworksButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.WMCorpusCallosumNetworksButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMCorpusCallosumNetworksButton_2Pushed, true);
            app.WMCorpusCallosumNetworksButton_2.Text = {'WM Corpus Callosum'; 'Networks'};
            app.WMGMNetworkConnectivityButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.WMGMNetworkConnectivityButton_2.ButtonPushedFcn = createCallbackFcn(app, @WMGMNetworkConnectivityButton_2Pushed, true);
            app.WMGMNetworkConnectivityButton_2.Text = 'WM GM Network Connectivity';
            app.GMfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.GMfileSelectLabel_2.Text = 'GM file Select';
            app.GMfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.GMfileSelectDropDown_2.Items = {};
            app.GMfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GMfileSelectDropDown_2ValueChanged, true);
            app.GMfileSelectDropDown_2.Value = {};
            app.AtlasbasedFCButton_2 = uibutton(app.FrequencyBandsTab, 'push');
            app.AtlasbasedFCButton_2.ButtonPushedFcn = createCallbackFcn(app, @AtlasbasedFCButton_2Pushed, true);
            app.AtlasbasedFCButton_2.Text = 'Atlas based FC';
            app.AtlasfileSelectLabel_2 = uilabel(app.FrequencyBandsTab);
            app.AtlasfileSelectLabel_2.Text = 'Atlas file Select';
            app.AtlasfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.AtlasfileSelectDropDown_2.Items = {};
            app.AtlasfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AtlasfileSelectDropDown_2ValueChanged, true);
            app.AtlasfileSelectDropDown_2.Value = {};
            app.CCfileSelectDropDownLabel_2 = uilabel(app.FrequencyBandsTab);
            app.CCfileSelectDropDownLabel_2.Text = 'CC file Select';
            app.CCfileSelectDropDown_2 = uidropdown(app.FrequencyBandsTab);
            app.CCfileSelectDropDown_2.Items = {};
            app.CCfileSelectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CCfileSelectDropDown_2ValueChanged, true);
            app.CCfileSelectDropDown_2.Value = {};
            app.FrequencyBandSelectLabel = uilabel(app.FrequencyBandsTab);
            app.FrequencyBandSelectLabel.Text = {'Frequency Band'; ' Select'};
            app.Band_selectDropDown = uidropdown(app.FrequencyBandsTab);
            app.Band_selectDropDown.Items = {};
            app.Band_selectDropDown.ValueChangedFcn = createCallbackFcn(app, @Band_selectDropDownValueChanged, true);
            app.Band_selectDropDown.Value = {};
            app.DataTipsCheckBox = uicheckbox(app.StatisticsUIFigure);
            app.DataTipsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataTipsCheckBoxValueChanged, true);
            app.DataTipsCheckBox.Text = 'Data Tips';
            app.CorrectionDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.StatisticsUIFigure);
            app.CorrectionDropDown.Items = {'false Rate Discovery', 'Bonferroni'};
            app.CorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionDropDownValueChanged, true);
            app.CorrectionDropDown.Value = 'false Rate Discovery';
            app.AlphaEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.StatisticsUIFigure, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.05;
            app.UploadCSVforSubjectGroupsButton = uibutton(app.StatisticsUIFigure, 'push');
            app.UploadCSVforSubjectGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCSVforSubjectGroupsButtonPushed, true);
            app.UploadCSVforSubjectGroupsButton.Text = 'Upload CSV for Subject Groups';
            app.Group2label = uilabel(app.StatisticsUIFigure);
            app.Group2label.Text = '';
            app.PlotButton = uibutton(app.StatisticsUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Net1YaxisEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.Net1YaxisEditFieldLabel.Text = {'Net 1'; '(Y-axis)'};
            app.Net1YaxisEditField = uieditfield(app.StatisticsUIFigure, 'numeric');
            app.Net2XaxisEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.Net2XaxisEditFieldLabel.Text = {'Net 2'; '(X-axis)'};
            app.Net2XaxisEditField = uieditfield(app.StatisticsUIFigure, 'numeric');
            app.RegionsEditFieldLabel = uilabel(app.StatisticsUIFigure);
            app.RegionsEditFieldLabel.Text = 'Regions';
            app.RegionsEditField = uieditfield(app.StatisticsUIFigure, 'text');
            app.RegionsEditField.ValueChangedFcn = createCallbackFcn(app, @RegionsEditFieldValueChanged, true);
            app.plotDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.plotDropDownLabel.Text = 'plot';
            app.plotDropDown = uidropdown(app.StatisticsUIFigure);
            app.plotDropDown.Items = {'All values', 'Significant only'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Value = 'All values';
            app.AlphaEditFieldLabel_2 = uilabel(app.StatisticsUIFigure);
            app.AlphaEditFieldLabel_2.Text = 'Bonferonni corrected alpha :';
            app.HasHeaderCheckBox = uicheckbox(app.StatisticsUIFigure);
            app.HasHeaderCheckBox.Text = 'Has Header';
            app.HasHeaderCheckBox.Value = true;
            app.VariablesDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.VariablesDropDownLabel.Text = 'Variables';
            app.VariablesDropDown = uidropdown(app.StatisticsUIFigure);
            app.VariablesDropDown.Items = {};
            app.VariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDownValueChanged, true);
            app.VariablesDropDown.Value = {};
            app.SaveplotasimageButton = uibutton(app.StatisticsUIFigure, 'push');
            app.SaveplotasimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotasimageButtonPushed, true);
            app.SaveplotasimageButton.Text = 'Save plot as image';
            app.NoofSubjectsLabel = uilabel(app.StatisticsUIFigure);
            app.NoofSubjectsLabel.Text = 'No. of Subjects:';
            app.SubjectlistDropDownLabel = uilabel(app.StatisticsUIFigure);
            app.SubjectlistDropDownLabel.Text = 'Subject list';
            app.SubjectlistDropDown = uidropdown(app.StatisticsUIFigure);
            app.SubjectlistDropDown.Items = {};
            app.SubjectlistDropDown.Value = {};
            % Show the figure after all components are created
            app.StatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whifun_statistics_old(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatisticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticsUIFigure)
        end
    end
end